classdef EscherAppMaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EscherMasterFig                 matlab.ui.Figure
        BluemodeLamp                    matlab.ui.control.Lamp
        BluemodeLabel                   matlab.ui.control.Label
        UDPConnectionLamp               matlab.ui.control.Lamp
        UDPConnectionLampLabel          matlab.ui.control.Label
        DeBusyButton                    matlab.ui.control.Button
        BusyStatusLamp                  matlab.ui.control.Lamp
        BusyStatusLampLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        LoadScreenInformationPanel      matlab.ui.container.Panel
        ScreenNotesTxt                  matlab.ui.control.EditField
        NotesLabel                      matlab.ui.control.Label
        CalModeRBGroup                  matlab.ui.container.ButtonGroup
        ScreenFuncTxt                   matlab.ui.control.EditField
        LuminancetransferfunctionLabel  matlab.ui.control.Label
        CalibratedButton                matlab.ui.control.RadioButton
        UncalibratedButton              matlab.ui.control.RadioButton
        SaveScreenFileButton            matlab.ui.control.Button
        ScreenNameTxt                   matlab.ui.control.EditField
        ScreennameEditFieldLabel        matlab.ui.control.Label
        LoadScreenFileButton            matlab.ui.control.Button
        UDPConnectionPanel              matlab.ui.container.Panel
        VerboseCheckBox                 matlab.ui.control.CheckBox
        STAendTxt                       matlab.ui.control.EditField
        SendtoallendaddressEditFieldLabel  matlab.ui.control.Label
        STAstartTxt                     matlab.ui.control.EditField
        SendtoallstartaddressLabel      matlab.ui.control.Label
        UDPBroadcastDropDown            matlab.ui.control.DropDown
        UDPBroadcastDropDownLabel       matlab.ui.control.Label
        BroadcastAddress                matlab.ui.control.EditField
        BroadcastEditFieldLabel         matlab.ui.control.Label
        SubnetMask                      matlab.ui.control.EditField
        SubnetmaskEditFieldLabel        matlab.ui.control.Label
        ResendScreenParams              matlab.ui.control.Button
        TimeoutTime                     matlab.ui.control.NumericEditField
        TimeouttimesEditFieldLabel      matlab.ui.control.Label
        SlaveMac                        matlab.ui.control.EditField
        MACLabel_2                      matlab.ui.control.Label
        MasterMac                       matlab.ui.control.EditField
        MACLabel_3                      matlab.ui.control.Label
        ConnectionStatus                matlab.ui.control.EditField
        StatusEditFieldLabel_2          matlab.ui.control.Label
        GetMasterIPandMACBtn            matlab.ui.control.Button
        MasterPort                      matlab.ui.control.NumericEditField
        PortEditFieldLabel_2            matlab.ui.control.Label
        MasterIp                        matlab.ui.control.EditField
        MasterIPLabel                   matlab.ui.control.Label
        SlavePort                       matlab.ui.control.NumericEditField
        PortEditFieldLabel              matlab.ui.control.Label
        SlaveIp                         matlab.ui.control.EditField
        SlaveIPLabel                    matlab.ui.control.Label
        StartUdpBtn                     matlab.ui.control.Button
        uipanel4                        matlab.ui.container.Panel
        ScreenSizeAngle                 matlab.ui.control.EditField
        AngularsizeofscreenEditFieldLabel  matlab.ui.control.Label
        ScreenHeight                    matlab.ui.control.NumericEditField
        HeightcmLabel                   matlab.ui.control.Label
        ScreenWidth                     matlab.ui.control.NumericEditField
        WidthcmLabel                    matlab.ui.control.Label
        HeightPix                       matlab.ui.control.NumericEditField
        HeightpixelsEditFieldLabel      matlab.ui.control.Label
        WidthPix                        matlab.ui.control.NumericEditField
        WidthpixelsEditFieldLabel       matlab.ui.control.Label
        ScreenDistance                  matlab.ui.control.NumericEditField
        ScreendistancecmLabel           matlab.ui.control.Label
        uipanel6                        matlab.ui.container.Panel
        BLUEMODEButton                  matlab.ui.control.StateButton
        SaveScreenDistortionButton      matlab.ui.control.Button
        SphericaldistortioncorrectionCheckBox  matlab.ui.control.CheckBox
        LabelLumi_1                     matlab.ui.control.Label
        StandbyL                        matlab.ui.control.NumericEditField
        StandbyluminanceLabel           matlab.ui.control.Label
        oculusCk                        matlab.ui.control.CheckBox
        OneScreenMode_ck                matlab.ui.control.CheckBox
        RefreshStBy                     matlab.ui.control.Button
        OpenScreenBtn                   matlab.ui.control.Button
        StimulationTab                  matlab.ui.container.Tab
        uipanel14                       matlab.ui.container.Panel
        SendTTLwhenbaselinestartsCheckBox  matlab.ui.control.CheckBox
        initialDelay                    matlab.ui.control.NumericEditField
        InitialbaselinesLabel           matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        ContrastTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        CheckTab                        matlab.ui.container.Tab
        CheckPeriod                     matlab.ui.control.NumericEditField
        PeriodsLabel_2                  matlab.ui.control.Label
        CheckRepetitions                matlab.ui.control.NumericEditField
        RepetitionsEditField_2Label     matlab.ui.control.Label
        CheckGo                         matlab.ui.control.Button
        GridsTab                        matlab.ui.container.Tab
        generateTTL_postCheckBox        matlab.ui.control.CheckBox
        generateTTL_gridCheckBox        matlab.ui.control.CheckBox
        generateTTL_preCheckBox         matlab.ui.control.CheckBox
        BaselinepostsEditField          matlab.ui.control.NumericEditField
        BaselinepostsEditFieldLabel     matlab.ui.control.Label
        GridssEditField                 matlab.ui.control.NumericEditField
        GridssEditFieldLabel            matlab.ui.control.Label
        BaselinepresEditField           matlab.ui.control.NumericEditField
        BaselinepresEditFieldLabel      matlab.ui.control.Label
        TabGroup6                       matlab.ui.container.TabGroup
        TraditionalTab                  matlab.ui.container.Tab
        GoGrid                          matlab.ui.control.Button
        FastOrientationStimulusSelectionPanel  matlab.ui.container.Panel
        cleartable_orientBtn            matlab.ui.control.Button
        OrientFastN                     matlab.ui.control.NumericEditField
        ChoosestimEditFieldLabel_5      matlab.ui.control.Label
        OrientFastTable                 matlab.ui.control.Table
        OrientFastMaxA                  matlab.ui.control.NumericEditField
        EndAngleEditFieldLabel          matlab.ui.control.Label
        OrientFastMinA                  matlab.ui.control.NumericEditField
        StartAngleEditFieldLabel        matlab.ui.control.Label
        OrientFastTotN                  matlab.ui.control.NumericEditField
        noftotstimLabel_5               matlab.ui.control.Label
        OrientFastLoadTable             matlab.ui.control.Button
        OrientFastSaveTable             matlab.ui.control.Button
        generatetable_orientBtn         matlab.ui.control.Button
        GridRepetitions                 matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel_3     matlab.ui.control.Label
        RandomizedTab                   matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        NrepEditField                   matlab.ui.control.NumericEditField
        NrepEditFieldLabel              matlab.ui.control.Label
        LoadRandStim                    matlab.ui.control.Button
        SaveRandStim                    matlab.ui.control.Button
        GenRandStim                     matlab.ui.control.Button
        MaxlagsEditField                matlab.ui.control.NumericEditField
        MaxlagsEditFieldLabel           matlab.ui.control.Label
        AddrandomlagaftereachstimulusCheckBox  matlab.ui.control.CheckBox
        UsecontrastandorientationvaluesfromLabel  matlab.ui.control.Label
        RandFastTable                   matlab.ui.control.Table
        GoRandGrid                      matlab.ui.control.Button
        ShapeButtonGroup                matlab.ui.container.ButtonGroup
        SquareGrid                      matlab.ui.control.RadioButton
        SineGrid                        matlab.ui.control.RadioButton
        GaussSize_txt                   matlab.ui.control.NumericEditField
        sizepixLabel                    matlab.ui.control.Label
        DriftingSpeed_txt               matlab.ui.control.NumericEditField
        DriftingspeedcyclessecEditFieldLabel  matlab.ui.control.Label
        GridOrientation                 matlab.ui.control.NumericEditField
        OrientationdegLabel_2           matlab.ui.control.Label
        UsestandbyluminanceforBaselinepreandBaselinepostCheckBox  matlab.ui.control.CheckBox
        DrawMask                        matlab.ui.control.CheckBox
        DotsTab                         matlab.ui.container.Tab
        Baselineposts_dotEditField      matlab.ui.control.NumericEditField
        BaselinepostsEditField_2Label   matlab.ui.control.Label
        dots_durationEditField          matlab.ui.control.NumericEditField
        DotssLabel                      matlab.ui.control.Label
        Baselinepres_dotEditField       matlab.ui.control.NumericEditField
        BaselinepresEditField_2Label    matlab.ui.control.Label
        generateTTL_post_dotCheckBox    matlab.ui.control.CheckBox
        generateTTL_dotCheckBox         matlab.ui.control.CheckBox
        generateTTL_pre_dotCheckBox     matlab.ui.control.CheckBox
        DotsRepetitions                 matlab.ui.control.NumericEditField
        RepetitionsEditField_2Label_2   matlab.ui.control.Label
        DotslifetimesEditField          matlab.ui.control.NumericEditField
        DotslifetimesLabel              matlab.ui.control.Label
        DotsdiameterdegEditField        matlab.ui.control.NumericEditField
        DotsdiameterdegLabel            matlab.ui.control.Label
        dotsDriftingSpeed               matlab.ui.control.NumericEditField
        DriftingspeeddegsecLabel        matlab.ui.control.Label
        DotsOrientation                 matlab.ui.control.NumericEditField
        OrientationdegLabel             matlab.ui.control.Label
        DotsdensityEditField            matlab.ui.control.NumericEditField
        Dotsdensitycm2Label             matlab.ui.control.Label
        NdotsEditField                  matlab.ui.control.NumericEditField
        NdotsLabel                      matlab.ui.control.Label
        EquiluminantbaselineCheckBox    matlab.ui.control.CheckBox
        DarkbackgroundCheckBox          matlab.ui.control.CheckBox
        GoDots                          matlab.ui.control.Button
        RandSizeCheckBox                matlab.ui.control.CheckBox
        RandSpeedCheckBox               matlab.ui.control.CheckBox
        RetinotopyTab                   matlab.ui.container.Tab
        RetinotopyCheckFreqEditField    matlab.ui.control.NumericEditField
        CheckerboardfrequencyHzLabel    matlab.ui.control.Label
        RetinotopyRepetitions           matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel_4     matlab.ui.control.Label
        RetinotopyPostGTime             matlab.ui.control.NumericEditField
        BaselinetimesLabel              matlab.ui.control.Label
        IntrRectSizeTxt                 matlab.ui.control.NumericEditField
        BarsizedegLabel                 matlab.ui.control.Label
        IntrDirectionSwitch             matlab.ui.control.ToggleSwitch
        DirectionLabel                  matlab.ui.control.Label
        IntrOrientationSwitch           matlab.ui.control.ToggleSwitch
        MovementLabel                   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        IntrinsicStimBarSpeedEdit       matlab.ui.control.NumericEditField
        BarspeeddegsLabel               matlab.ui.control.Label
        KeepconstantspeedCheckBox       matlab.ui.control.CheckBox
        BartraveltimesEditField         matlab.ui.control.NumericEditField
        BartraveltimesEditFieldLabel    matlab.ui.control.Label
        RetinotopyGOButton              matlab.ui.control.Button
        GeneralPanel                    matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        ConstrasttabTab                 matlab.ui.container.Tab
        generatestimuliCheckBtn_2       matlab.ui.control.Button
        CheckFastMeanL                  matlab.ui.control.NumericEditField
        MeanLEditFieldLabel             matlab.ui.control.Label
        CheckFastTotN                   matlab.ui.control.NumericEditField
        noftotstimLabel_6               matlab.ui.control.Label
        CheckFastN                      matlab.ui.control.NumericEditField
        ChoosestimEditFieldLabel_6      matlab.ui.control.Label
        CheckFastMaxC                   matlab.ui.control.NumericEditField
        MaxLEditFieldLabel_4            matlab.ui.control.Label
        CheckFastMinC                   matlab.ui.control.NumericEditField
        MinLEditFieldLabel_4            matlab.ui.control.Label
        includezeroCheckCk              matlab.ui.control.CheckBox
        logspacedCheckCk                matlab.ui.control.CheckBox
        generatestimuliCheckBtn         matlab.ui.control.Button
        CheckFastLoadTable              matlab.ui.control.Button
        CheckFastSaveTable              matlab.ui.control.Button
        ContrastFastTable               matlab.ui.control.Table
        SpatialFreqtabTab               matlab.ui.container.Tab
        generatestimuliCheckBtn_3       matlab.ui.control.Button
        SfFastN                         matlab.ui.control.NumericEditField
        ChoosestimLabel                 matlab.ui.control.Label
        NstimEditField                  matlab.ui.control.NumericEditField
        NstimEditFieldLabel             matlab.ui.control.Label
        SfFastMaxC                      matlab.ui.control.NumericEditField
        MaxLEditFieldLabel_5            matlab.ui.control.Label
        SfFastMinC                      matlab.ui.control.NumericEditField
        MinLEditFieldLabel_5            matlab.ui.control.Label
        SfFastLoadTable                 matlab.ui.control.Button
        SfFastSaveTable                 matlab.ui.control.Button
        SfFastTable                     matlab.ui.control.Table
        logspacedSfCk                   matlab.ui.control.CheckBox
        generatestimuliSFBtn            matlab.ui.control.Button
        CheckContrast                   matlab.ui.control.NumericEditField
        ContrastLabel                   matlab.ui.control.Label
        CheckLumiW                      matlab.ui.control.NumericEditField
        WhiteluminanceLabel             matlab.ui.control.Label
        CheckLumiB                      matlab.ui.control.NumericEditField
        BlackluminanceLabel             matlab.ui.control.Label
        CheckLumiMean                   matlab.ui.control.NumericEditField
        MeanluminanceLabel              matlab.ui.control.Label
        spatialFrequency                matlab.ui.control.NumericEditField
        FrequencycyclesDegLabel         matlab.ui.control.Label
        LabelLumiEsch_11                matlab.ui.control.Label
        LabelLumiEsch_10                matlab.ui.control.Label
        LabelLumiEsch_9                 matlab.ui.control.Label
        CheckLumiMeanEsch               matlab.ui.control.NumericEditField
        CheckLumiWEsch                  matlab.ui.control.NumericEditField
        CheckLumiBEsch                  matlab.ui.control.NumericEditField
        LabelLumi_11                    matlab.ui.control.Label
        LabelLumi_10                    matlab.ui.control.Label
        LabelLumi_9                     matlab.ui.control.Label
        FlashTab                        matlab.ui.container.Tab
        GeneralPanel_2                  matlab.ui.container.Panel
        ProvideadarkstimulusasbaselineforeveryflashCheckBox  matlab.ui.control.CheckBox
        FlashPeriod                     matlab.ui.control.NumericEditField
        PeriodsLabel_3                  matlab.ui.control.Label
        FlashRepetitions                matlab.ui.control.NumericEditField
        RepetitionsLabel_4              matlab.ui.control.Label
        FlashDuration                   matlab.ui.control.NumericEditField
        FlashdurationsLabel             matlab.ui.control.Label
        FSflashL                        matlab.ui.control.NumericEditField
        FlashluminanceLabel             matlab.ui.control.Label
        FSbaselineL                     matlab.ui.control.NumericEditField
        BaselineluminanceLabel          matlab.ui.control.Label
        LabelLumiEsch_8                 matlab.ui.control.Label
        FSflashLEsch                    matlab.ui.control.NumericEditField
        LabelLumiEsch_7                 matlab.ui.control.Label
        FSbaselineLEsch                 matlab.ui.control.NumericEditField
        LabelLumi_8                     matlab.ui.control.Label
        LabelLumi_7                     matlab.ui.control.Label
        TabGroup5                       matlab.ui.container.TabGroup
        FullscreenflashesTab            matlab.ui.container.Tab
        FastFlashStimulusSelectionPanel  matlab.ui.container.Panel
        generatestimuliFlashBtn_2       matlab.ui.control.Button
        FlashFastN                      matlab.ui.control.NumericEditField
        ChoosestimEditFieldLabel_4      matlab.ui.control.Label
        FlashFastLoadTable              matlab.ui.control.Button
        savetableButton                 matlab.ui.control.Button
        generatestimuliFlashBtn         matlab.ui.control.Button
        includezeroFlashCk              matlab.ui.control.CheckBox
        logspacedFlashCk                matlab.ui.control.CheckBox
        FlashFastMaxL                   matlab.ui.control.NumericEditField
        MaxLEditFieldLabel_3            matlab.ui.control.Label
        FlashFastMinL                   matlab.ui.control.NumericEditField
        MinLEditFieldLabel_3            matlab.ui.control.Label
        FlashFastTotN                   matlab.ui.control.NumericEditField
        noftotstimLabel_4               matlab.ui.control.Label
        FlashFastTable                  matlab.ui.control.Table
        FSFlashGo                       matlab.ui.control.Button
        ReceptivefieldTab               matlab.ui.container.Tab
        OpenlogdirectoryButton          matlab.ui.control.Button
        LogfilenameEditField            matlab.ui.control.EditField
        LogfilenameEditFieldLabel       matlab.ui.control.Label
        columnsEditField                matlab.ui.control.NumericEditField
        columnsEditFieldLabel           matlab.ui.control.Label
        rowsEditField                   matlab.ui.control.NumericEditField
        rowsEditFieldLabel              matlab.ui.control.Label
        RecptiveFieldGo                 matlab.ui.control.Button
        randomizeorderCheckBox          matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        PCOImagingTab                   matlab.ui.container.Tab
        CDataPanel                      matlab.ui.container.Panel
        PcoAutoCLimButton               matlab.ui.control.Button
        PcoCDataMinEditField            matlab.ui.control.NumericEditField
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        PcoCDataMaxEditField            matlab.ui.control.NumericEditField
        VisualizeoutputPanel            matlab.ui.container.Panel
        OpenFieldAnalyzerButton         matlab.ui.control.StateButton
        SeeavgmovieButton               matlab.ui.control.Button
        SeemovieButton                  matlab.ui.control.Button
        TimefilterCheckBox              matlab.ui.control.CheckBox
        sEditField                      matlab.ui.control.NumericEditField
        sEditFieldLabel                 matlab.ui.control.Label
        pxlEditField                    matlab.ui.control.NumericEditField
        pxlEditFieldLabel               matlab.ui.control.Label
        SpatialfilterCheckBox           matlab.ui.control.CheckBox
        ButtonGroup_6                   matlab.ui.container.ButtonGroup
        deltaFFButton                   matlab.ui.control.RadioButton
        RawButton                       matlab.ui.control.RadioButton
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        CLimminEditField                matlab.ui.control.NumericEditField
        CLimminEditFieldLabel           matlab.ui.control.Label
        AcquisitionPanel_3              matlab.ui.container.Panel
        PcoNumberFramesEditField        matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel    matlab.ui.control.Label
        PcoSelectedFreqEditField        matlab.ui.control.NumericEditField
        SelectedFrequencyHzEditFieldLabel  matlab.ui.control.Label
        PcoTimeoutEditField             matlab.ui.control.NumericEditField
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        PcoOutputFileN                  matlab.ui.control.NumericEditField
        FilenEditFieldLabel_2           matlab.ui.control.Label
        PcoFilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        HermesDelaysEditField           matlab.ui.control.NumericEditField
        HermesDelaysEditFieldLabel      matlab.ui.control.Label
        PcoMaxFreqEditField             matlab.ui.control.NumericEditField
        MaxFrequencyHzEditFieldLabel    matlab.ui.control.Label
        SelectOutputFolderButton        matlab.ui.control.Button
        PcoSaveFolderEditField          matlab.ui.control.EditField
        PcoDurationEditField            matlab.ui.control.NumericEditField
        DurationsEditField_2Label       matlab.ui.control.Label
        PcoSaveTifFileCk                matlab.ui.control.CheckBox
        PcoStartPreviewButton           matlab.ui.control.Button
        PcoStartAcquisitionButton       matlab.ui.control.Button
        SettingspanelPanel              matlab.ui.container.Panel
        PcoStatusLamp                   matlab.ui.control.Lamp
        ConnectPCOButton                matlab.ui.control.Button
        HorizontalBinningDropDown       matlab.ui.control.DropDown
        HorizontalBinningDropDownLabel  matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.NumericEditField
        ExposureTimemsLabel             matlab.ui.control.Label
        TimestampsDropDown              matlab.ui.control.DropDown
        TimestampsDropDownLabel         matlab.ui.control.Label
        PcoSyncDropDown                 matlab.ui.control.DropDown
        TriggermodeLabel                matlab.ui.control.Label
        HermesLEDcontrolCheckBox        matlab.ui.control.CheckBox
        VerticalBinningDropDownLabel    matlab.ui.control.Label
        VerticalBinningDropDown         matlab.ui.control.DropDown
        StimulationandPCOPanel          matlab.ui.container.Panel
        IfLabel                         matlab.ui.control.Label
        ButtonGroup_5                   matlab.ui.container.ButtonGroup
        DeadTBeforeTrialEditField       matlab.ui.control.NumericEditField
        DeadtimebetweeneachtrialmsEditFieldLabel  matlab.ui.control.Label
        PCOTrialEditField               matlab.ui.control.NumericEditField
        secondsLabel                    matlab.ui.control.Label
        SettrialacquisitiontimeButton   matlab.ui.control.RadioButton
        AutomaticallydetermineacquisitiontimeButton  matlab.ui.control.RadioButton
        PcoWhileStimulusCk              matlab.ui.control.CheckBox
        OptogeneticsTab                 matlab.ui.container.Tab
        TimingPanel                     matlab.ui.container.Panel
        AfterstimulusonsetLabel         matlab.ui.control.Label
        optoDurEditField                matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel        matlab.ui.control.Label
        optoStartEditField              matlab.ui.control.NumericEditField
        StartmsEditFieldLabel           matlab.ui.control.Label
        TypeButtonGroup                 matlab.ui.container.ButtonGroup
        nofpulsesEditField              matlab.ui.control.NumericEditField
        nofpulsesEditFieldLabel         matlab.ui.control.Label
        DutycycleEditField              matlab.ui.control.NumericEditField
        DutycycleEditFieldLabel         matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        PulsedButton                    matlab.ui.control.RadioButton
        ContinuousButton                matlab.ui.control.RadioButton
        HermesTab                       matlab.ui.container.Tab
        CuePanel                        matlab.ui.container.Panel
        optDtrTimeTxt                   matlab.ui.control.NumericEditField
        DurationofcuemsEditFieldLabel   matlab.ui.control.Label
        calwhite_btn                    matlab.ui.control.Button
        calblack_btn                    matlab.ui.control.Button
        ard_ck                          matlab.ui.control.CheckBox
        UsedefaultwidthandheightCheckBox  matlab.ui.control.CheckBox
        CuepositionPanel                matlab.ui.container.Panel
        CueHorzPos                      matlab.ui.container.ButtonGroup
        CueRightRB                      matlab.ui.control.RadioButton
        CueLeftRB                       matlab.ui.control.RadioButton
        CueVertPos                      matlab.ui.container.ButtonGroup
        CueBotRB                        matlab.ui.control.RadioButton
        CueTopRB                        matlab.ui.control.RadioButton
        wwTxt                           matlab.ui.control.NumericEditField
        CueWidthpxEditFieldLabel        matlab.ui.control.Label
        hhTxt                           matlab.ui.control.NumericEditField
        HeightpxlLabel                  matlab.ui.control.Label
        WorkingconfigurationPanel       matlab.ui.container.Panel
        HermesIdleTimeEditField         matlab.ui.control.NumericEditField
        IdletimemsLabel                 matlab.ui.control.Label
        HermesTtlDurationEditField      matlab.ui.control.NumericEditField
        TTLdurationmsLabel              matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        TriggerDropDown                 matlab.ui.control.DropDown
        TriggerDropDownLabel            matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        SerialPanel                     matlab.ui.container.Panel
        PrintSerialCommCk               matlab.ui.control.CheckBox
        SerialCOMDropDown               matlab.ui.control.DropDown
        SerialCOMDropDownLabel          matlab.ui.control.Label
        RefreshCOMButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        %         For the most common values (dimension of the screen, black and
        %         white values, ...)
        
        %         ComHandle % Former synchronization TTL signal
        w
        screenRect
        ifi
        white
        black
        ScreenBitDepth % is either 'uint8' or 'uint16'
        HermesRect % Like screenRect but for Hermes
        OnePxAngle % Angle subtended by a single pixel
        ScreenFunc % luminance transfer function in use (either gray or blue)
        ScreenFuncGray % luminance transfer function (from 0-1 value to luminance)
        ScreenFuncBlue % luminance transfer function, blue only (from 0-1 value to luminance)
        
        HermesSerialFlag % flag of serial communication with Hermes
        HermesSerialObj % object of serial communication with Hermes
        HermesReplyCheck
        
        %         For UDP connection:
        UdpObj % the actual UDP object
        UdpTimeoutTimer % the timer for making the UDP connection time out
        UdpConnectionFlag % When this is 1, this app works as master
        SendToAllTerminator % When this is 1, the send-to-all sort-of-broadcast is terminated.
        
        %         This is the structure containing all the variables for the pupil
        %         recording.
        PcoStruct
        PcoMeta % Saved structure with the image and other infos.
        
        ScreenOpenFlag % is 1 when the screen is open, it's 0 when it's not open
        
        %         folder of current escher app
        escherAppFolder
        
        %         For logs
        runNumberFile % the log file where the run number is stored.
        logDir % the directory of log files
        runNumber % The run number, progressive number of the next stimulation.
        CurrentLogStruct % structure containing .T (a table) and .logName, the name for current log.
        settingsDir % Directory of settings.
        
        %         Label for luminance of the vector of objects
        LabeLumiVector
        
        %         Store the indices of selected cells of the experiment list
        ExpListSelectedIndices
        %         The colors of the experiment table. First is the background, then
        %         the current exp, then the running exp, then the completed exp.
        
        %         This is the index of current experiment on the Experiment List
        %         table. Not to be confused with ExpListCurrentNum (which is the
        %         number assigned to the current experiment).
        ExpListCurrentIdx
        
        VisualFieldAnalyzerApp
        PCOconnected % Description
    end
    
    methods (Access = public)
        function saveParams(app, filename)
            paramsStruct = struct;
            
            propList = string(properties(app)); % get all the properies of the main app as string vector
            propListLogic = false(size(propList)); % initialize a logic vector, that will be used to tell apart checkboxes and edit fields from the rest
            
            for i=1:length(propList)
                if isobject(app.(propList(i))) && isprop(app.(propList(i)),'Type') % only isprop is not enough
                    if strcmp(app.(propList(i)).Type,'uinumericeditfield') || strcmp(app.(propList(i)).Type,'uicheckbox') || (strcmp(app.(propList(i)).Type,'uieditfield') && app.(propList(i)).Editable==1)
                        % logic 1 for all properties that are either
                        % numeric edit fields or checkboxes. Also added
                        % edit field (non numeric) but only if they are
                        % editable
                        propListLogic(i) = true;
                    end
                end
            end
            
            % paramsTable is 2-column table. First column is for names,
            % second is for values
            paramsTable = table('size', [sum(propListLogic), 2],'VariableTypes', {'string', 'cell'}, 'VariableNames', {'Name', 'Value'});
            paramsTable.Name = propList(propListLogic); % list of only checkboxes and editable textboxes
            
            % Extract all the names, values and callbacks of the checkboxes and editable textboxes of the main App
            for i=1:height(paramsTable)
                paramsTable.Value{i} = app.(paramsTable.Name(i)).Value;
            end
            
            paramsStruct.Table = paramsTable;
            
            save(filename, 'paramsStruct')
        end
        
        function loadParams(app,filename)
            %             This function load the parameters saved in a .mat file. The
            %             parameters are in a structure called paramsStruct.
            
            paramsStruct = [];
            
            %             All parameters from the gui are saved separately in a table,
            %             called paramsTable.
            
            open(filename) % from the filename location, a variable paramsStruct will be loaded
            for i = 1 : height(paramsStruct.paramsTable)
                %                 i
                %                 if i==24
                %                     disp('eccoci')
                %                 end
                app.(paramsStruct.paramsTable.Name(i)).Value = paramsStruct.paramsTable.Value{i};
                if ~isempty(app.(paramsStruct.paramsTable.Name(i)).ValueChangedFcn)
                    try
                        app.(paramsStruct.paramsTable.Name(i)).ValueChangedFcn(app.(paramsStruct.paramsTable.Name(i)),[])
                    catch ME
                        warning('off','backtrace')
                        warning(strcat("Error while executing ", paramsStruct.paramsTable.Name(i), "'s Callback"))
                        warning(ME.message)
                        disp(newline)
                    end
                end
            end
            
            warning('on','backtrace')
            % load handel
            % sound(y,Fs)
            disp('params loaded!')
        end
        
        function EnableGoBtn(app,flag)
            switch flag
                case 1
                    app.FSFlashGo.Enable = 1;
                    app.CheckGo.Enable = 1;
                    app.GoGrid.Enable = 1;
                    app.GoDots.Enable = 1;
                    app.RetinotopyGOButton.Enable = 1;
                    app.RecptiveFieldGo.Enable = 1;
                    app.BusyStatusLamp.Color = [0 1 0];
                    if app.UdpConnectionFlag
                        app.StartUdpBtn.Enable = 1;
                    end
                case 0
                    app.FSFlashGo.Enable = 0;
                    app.CheckGo.Enable = 0;
                    app.GoGrid.Enable = 0;
                    app.GoDots.Enable = 0;
                    app.RetinotopyGOButton.Enable = 0;
                    app.RecptiveFieldGo.Enable = 0;
                    app.BusyStatusLamp.Color = [1 0 0];
                    if app.UdpConnectionFlag
                        app.StartUdpBtn.Enable = 0;
                    end
            end
        end
        
        function UpdateLumiAndContrast(app,updateContrastFlag)
            % When black and white luminance are changed, this function
            %   is called to update contrast and mean luminance. In this
            %   case updateContrastFlag = 1.
            % Conversely, if contrast or mean luminance are changed,
            %   this function is called to update black and white
            %   luminance. In this case updateContrastFlag = 0.
            switch updateContrastFlag
                case 1
                    % Update mean luminance and contrast according to
                    % "black" and "white" luminance values.
                    if app.CalibratedButton.Value
                        % updates both the escher values and the luminance
                        % values for black and white. This is because the true luminance
                        % sometimes is not exactly what is set but it can
                        % be a bit different, according to what the screen
                        % can portray.
                        
                        Blumi = app.CheckLumiB.Value;
                        Slumi = app.CheckLumiW.Value;
                        screenFun = app.ScreenFunc;
                    else
                        Blumi = app.CheckLumiBEsch.Value;
                        Slumi = app.CheckLumiWWsch.Value;
                        screenFun = [];
                    end
                    
                case 0
                    % Update "black" and "white" luminance values according
                    % to mean luminance and contrast.
                    contrast = app.CheckContrast.Value;
                    if app.CalibratedButton.Value
                        % Work with calibrated luminance values
                        [~,Mlumi] = Lumi2Escher(app.CheckLumiMean.Value,app.white,app.ScreenFunc);
                        [Blumi, Slumi] = LumiContrast2BlackWhite(Mlumi, contrast);
                        screenFun = app.ScreenFunc;
                    else
                        % work with "Escher" values
                        Mlumi = app.CheckLumiBEsch.Value;
                        [Blumi, Slumi] = LumiContrast2BlackWhite(Mlumi, contrast);
                        screenFun = [];
                    end
            end
            [Blumi,Slumi,Mlumi,contrast,...
                Blumi_escher,Slumi_escher,Mlumi_escher] = ...
                FixLumiValues(Blumi, Slumi, app.white, screenFun);
            app.CheckLumiBEsch.Value    = Blumi_escher;
            app.CheckLumiWEsch.Value    = Slumi_escher;
            app.CheckLumiMeanEsch.Value = Mlumi_escher;
            app.CheckLumiB.Value    = Blumi;
            app.CheckLumiW.Value    = Slumi;
            app.CheckLumiMean.Value = Mlumi;
            app.CheckContrast.Value = contrast;
        end
                
        function HermesRectCalc(app)
            sR = app.screenRect;
            ww = app.wwTxt.Value; %width of the optical DTR
            hh = app.hhTxt.Value; %height of the optical DTR
            %             The optical DTR must be placed according to the selection
            %             of the position
            % position of DtrRect (x0, y0, x4, y4)
            
            app.HermesRect = [app.CueRightRB.Value*(sR(3)-ww),...
                app.CueBotRB.Value*(sR(4)-hh),...
                sR(3)-app.CueLeftRB.Value*(sR(3)-ww),...
                sR(4)-app.CueTopRB.Value*(sR(4)-hh)];
        end
        
        function PixelAngleCalc(app)
            % Compute angular size of single pixel. This is the angle subtended by a
            % single pixel which is at the center of the screen.
            
            % Number of pixels in the screen width.
            Wpx = app.WidthPix.Value;
            % Screen width in centimeters.
            ScWidth = app.ScreenWidth.Value;
            % Distance of the screen in centimeters.
            ScDist = app.ScreenDistance.Value;
            % The angle of a single pixel is returned in degrees.
            app.OnePxAngle = 2*atand(ScWidth/Wpx/2/ScDist);
        end
        
        function callVisStim(app,stim_function,ParameterVector,TotStimDuration)
            % Paramtere vector is a cell array with stimulus-specific
            % parameters.
            
            % Disables all "Go" buttons
            EnableGoBtn(app,0)
            drawnow
            
            % update the "run number" to keep trace of the number of
            % stimuli delivered. The "run number" compares in the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            app.runNumber = app.runNumber + 1;
            writematrix(app.runNumber, app.runNumberFile)
            
            % Add these 7 general parameters to the parameter vector
            PcoWhileStimFlag = app.PcoWhileStimulusCk.Value;
            OneScreenFlag = app.OneScreenMode_ck.Value;
            CalibrationFlag = app.CalibratedButton.Value;
            ard_flag = app.ard_ck.Value;
            baseline_ttl = app.SendTTLwhenbaselinestartsCheckBox.Value;
            oculusFlag = app.oculusCk.Value;
            optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            
            ParameterVector = [ParameterVector,{PcoWhileStimFlag},{OneScreenFlag},...
                {CalibrationFlag},{ard_flag,baseline_ttl},{oculusFlag},{optDtrTime}];
            
            if app.PcoWhileStimulusCk.Value == 1
                % Start the PCO acquisition, which will collect the frames
                % that arrive from the camera. The camera will wait for Hermes to start
                % acquiring. And Hermes will wait for the phototransistor. Che al mercato mio
                % padre comprò.
                PcoStartAcquisition(app)
                waitfor(app.PcoStruct.vid,'Running','on')
            end
            
            if app.UdpConnectionFlag
                % In this mode, the visual stimulus is called by the Slave, which is a
                % separate MATLAB instance.
                
                % Now, send an UDP message to the Slave with all the
                % parameters relative to this stimulus. The Slave will
                % present the selected stimulus.
                msg=strjoin([{app.MasterMac.Value},{stim_function},...
                    num2str(TotStimDuration),...
                    cellfun(@num2str,ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
                % At the end of the stimulus, the Slave will send an UDP message to the
                % Master. This event will trigger a callback which:
                % 1) Sends "stopTrig=1" to Hermes. Hermes will turn OFF the Solis LED
                %       (if enabled) and set the value of the variable 'synch' to FALSE.
                % 2) Write the log file with stimulus parameters and
                %       timestamps
                % 3) Re-enable all stimulation buttons.
                
            else % Proceed with the stimulation
                % PLEASE NOTE: here is a useful lesson.
                % We have the name of a function stored in 'stim_function'
                % and we want to call it with input arguments 'app' and
                % 'ParameterVector'. We could think of using
                %
                % output = feval(stim_function,app,ParameterVector);
                %
                % However, if an error occurrs inside 'stimfunction', only 
                % the error text with no context will be reported, which is
                % very inconvenient for debugging purposes.
                % It's better to convert the function name to a function
                % handle with the command str2func and then call the handle
                % with app and ParameterVector as arguments
                disp("Stimulation running...")
                fcn_handle = str2func(stim_function);
                [timestamps,stopped] = fcn_handle(app,ParameterVector);
                
                if app.HermesSerialFlag && app.PcoWhileStimulusCk.Value
                    %                     pause(delay) % be sure the last stimulus is concluded.
                    if stopped
                        % This will send "stopTrig=1" to Hermes AND force
                        % the camera to stop the acquisition.
                        PcoStopAcquisition(app)
                    else
                        % Only send "stopTrig=1" to Hermes.
                        HermesMsgMake(app,'parameters','stopTrig')
                    end
                end
                
                % Deal each timestamp to the appropriate element of the log structure
                for i=1:numel(timestamps)
                    app.CurrentLogStruct.Sequence.Stimulus(i).RelativeTimeAttribute = num2str(timestamps(i),'%.3f');
                end
                
                % Write the log file.
                runString = sprintf('%04d', app.runNumber);
                stim_name = app.CurrentLogStruct.Sequence.typeAttribute;
                logName = strcat(app.logDir, runString, '_', stim_name, '.xml');
                writestruct(app.CurrentLogStruct,logName,"StructNodeName","EscherLog")
                
                % Enable the buttons "Go" again
                EnableGoBtn(app,1)
                disp("Stimulation done.")
            end
        end
        
        function UdpDataReceivedCallback(app,obj,ev)
            
            % First, stop the timeout timer.
            if isvalid(app.UdpTimeoutTimer)
                stop(app.UdpTimeoutTimer)
            end
            delete(app.UdpTimeoutTimer)
            
            % Read the datagram
            DatagramIn = read(obj,1,"string");
            %             Remove any white-space character.
            %             MsgIn = regexprep(MsgIn,'\s','');
            MsgIn = regexprep(DatagramIn.Data,'\s','');
            MsgCellArray = strsplit(MsgIn,',');
            
            % If verbose, print the message to the command window
            if app.VerboseCheckBox.Value
                fprintf('\nIncoming message from Slave:\n')
                for i=1:numel(MsgCellArray)
                    fprintf('\t %s \n',MsgCellArray{i})
                end
                fprintf('\n')
            end
            % The first element of the cell array contains usually the MAC address of
            % the slave, but when the connection is started it contains the word
            % 'start'.
            switch MsgCellArray{1}
                case 'start'
                    if strcmp(MsgCellArray{5},'0') && strcmp(MsgCellArray{2},app.SlaveIp.Value) &&...
                            strcmp(MsgCellArray{4},num2str(app.SlavePort.Value))
                    % This is the MsgCellArray{5} == 0 means that no confirmation is required
                    % for the master. The connection will be started as it is. Unless these
                    % checks are false.
                    elseif strcmp(MsgCellArray{5},'1') && strcmp(MsgCellArray{4},num2str(app.SlavePort.Value)) &&...
                            ValidateIPv4(MsgCellArray{2}) && ValidateMac(MsgCellArray{3})
                    % This is if the MsgCellArray{5} == 1, which means that a confirmation is
                    % required for the master to start this connection.
                    selection = uiconfirm(app.EscherMasterFig,['Accept connection from IP ',...
                        MsgCellArray{2},' and MAC ',MsgCellArray{3},'?'],'Connection confirmation');
                    if strcmp(selection,'Cancel')
                        return
                    end
                    app.SendToAllTerminator = 1;
                    
                    % The second and third word contain the IP and MAC respectively.
                    app.SlaveIp.Value = MsgCellArray{2};
                    
                    set(app.UdpObj.Userdata,'RemoteHost', MsgCellArray{2})
                    %                     RemoteHost = MsgCellArray{2};]
                    else
                        return
                    end
                    % The second and third word contain the IP and MAC respectively.
                    app.SlaveMac.Value = MsgCellArray{3};
                    
                    % The connection is set, so the buttons and other stuff must be changed
                    % accordingly.
                    app.ConnectionStatus.Value = 'Connected.';
                    app.ResendScreenParams.Enable = 1;
                    app.UdpConnectionFlag = 1;
                    app.UDPConnectionLamp.Color = [0 1 0];
                    % The button used for starting the connection now is used for canceling the
                    % connection.
                    app.StartUdpBtn.Text = 'Stop Connection';
                    EnableGoBtn(app,1)
                    
                    disp('Connection with Slave estabilished')
                    
                case app.SlaveMac.Value
                    
                    switch MsgCellArray{2}
                        % The second word in the message contains the instruction.
                        case 'screenok'
                            
                            switch MsgCellArray{3}
                                case 'open'
                                    % The screen has been opened successfully
                                    % Remember to set the fields relative to the screen size on the GUI and the
                                    % relative app.variable.
                                    app.WidthPix.Value = str2double(MsgCellArray{4});
                                    app.HeightPix.Value = str2double(MsgCellArray{5});
                                    app.OnePxAngle = str2double(MsgCellArray{6});
                                    
                                    app.OpenScreenBtn.Text = 'Close Screen';
                                    app.ScreenOpenFlag = 1;
                                    
                                    SetScreenSizeAngle(app)
                                    disp('Screen open')
                                    
                                case 'closed'
                                    disp('Screen closed')
                                    
                                case 'lumiupdated'
                                    disp('Standby luminance updated')
                                    
                                case 'parametersrefreshed'
                                    disp('Screen parameters refreshed')
                            end
                            
                            
                        case 'wait'
                            % The Slave is going to send visual stimuli, and will
                            % be busy for the amount of time specified in
                            % MsgCellArray{3}.
                            % A timer for timeout has to be called. Before that, the current timer is
                            % stopped and deleted.
                            timeouttime = round(str2double(MsgCellArray{3}),3); % timer ignores
                            % sub-millisecond precision vallues and
                            % returns a warning.
                            if timeouttime+app.TimeoutTime.Value < 2147484
                                app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                                    timeouttime+app.TimeoutTime.Value,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
                                start(app.UdpTimeoutTimer)
                                fprintf("Slave says: \n\tVisual stimulation is ongoing. Wait %s seconds...\n",MsgCellArray{3})
                            else
                                fprintf("Slave says: \n\tVisual stimulation is ongoing. Wait %s seconds...",MsgCellArray{3})
                                fprintf("However, that amount of time is added to the default timeout time" + ...
                                    "set on the GUI, which is %.2f. Since the total time is higher than the" + ...
                                    "maximum time allowed for timers' StartDelay property (i.e. 2147484 seconds)"+...
                                    "the timeout timer has not started.",app.TimeoutTime.Value);
                            end


                        case {'stimok','stiminterrupted'}
                            % A visual stimulation has been done.
                            
                            % Receive timestamps of stimuli and prints them into current log file.
                            %                             stim_function = MsgCellArray{3};
                            timestamps = strsplit(MsgCellArray{4},';');
                            for i=1:numel(timestamps)
                                app.CurrentLogStruct.Sequence.Stimulus(i).RelativeTimeAttribute = timestamps(i);
                            end
                            %                             % update the "run number"
                            %                             app.runNumber = readmatrix(app.runNumberFile);
                            %                             app.runNumber = app.runNumber + 1;
                            %                             writematrix(app.runNumber, app.runNumberFile)
                            
                            % Write the log file.
                            runString = sprintf('%04d', app.runNumber);
                            stim_name = app.CurrentLogStruct.Sequence.typeAttribute;
                            logName = strcat(app.logDir, runString, '_', stim_name, '.xml');
                            writestruct(app.CurrentLogStruct,logName,"StructNodeName","EscherLog")
                            
                            switch MsgCellArray{2}
                                case 'stimok'
                                    % The stimulus has been performed, therefore the master
                                    % will stop the timer and another stimulation can start.
                                    % GO buttons are re-enabled.
                                    disp('Stimulus completed successfully')
                                    pause(1)
                                    if app.HermesSerialFlag && app.PcoWhileStimulusCk.Value
                                        HermesMsgMake(app,'parameters','stopTrig')
                                    end
                                    
                                case 'stiminterrupted'
                                    % This message is received when the execution of visual
                                    % stimulation is interrupted by pressing "stop" on the
                                    % keyboard. Timestamps are received anyway (non valid
                                    % timestamps are NaNs). Here, we need to stop the
                                    % acquisition with the PCO camera, if running.
                                    disp('Stimulus interrupted prematurely')
                                    if app.HermesSerialFlag && app.PcoWhileStimulusCk.Value
                                        % This will send "stopTrig=1" to Hermes AND force
                                        % the camera to stop the acquisition.
                                        PcoStopAcquisition(app)
                                    end
                                    
                            end
                            EnableGoBtn(app,1)
                            
                            
                        case 'stimerror'
                            
                            warning('off','backtrace')
                            warning("BAD NEWS: AN ERROR OCCURRED WHILE THE SLAVE WAS RUNNING A VISUAL STIMULUS!")
                            warning("See the slave command window for the error log.")
                            warning('on','backtrace')
                            
                            if app.HermesSerialFlag && app.PcoWhileStimulusCk.Value
                                % This will send "stopTrig=1" to Hermes AND force
                                % the camera to stop the acquisition.
                                PcoStopAcquisition(app)
                            end
                            EnableGoBtn(app,1)
                            
                        case 'screenerror'
                            
                            warning('off','backtrace')
                            warning("BAD NEWS: AN ERROR OCCURRED WHILE THE SLAVE WAS OPENING/CLOSING THE SCREEN!")
                            warning("See the slave command window for the error log.")
                            warning('on','backtrace')
                            
                            
                        case 'busy'
                            % If the slave is busy, the timer for timeout must be stopped and the
                            % master can continue listening.
                            disp('Slave is busy.')
                            
                        case 'ok'
                            % If the slave is busy, the timer for timeout must be stopped and the
                            % master can continue listening.
                            disp('Slave is ok.')
                            
                        case 'calibrationok'
                            % If Hermes is connected to Escher with serial communication
                            if app.HermesSerialFlag
                                % Sends a message to Hermes, that is either 'c,b' or 'c,w' for black or
                                % white calibration.
                                HermesMsgMake(app,'cal',MsgCellArray{3});
                            end
                            
                        otherwise
                            warning(['Message not recognized: ', MsgIn])
                    end
            end
        end
        
        function UdpTimeoutCallback(app,obj)
            % This is called only when a message is sent through UDP but no answer is
            % received.
            uialert(app.EscherMasterFig,'UDP connection lost.','Error')
            % The connection status is set to 1, so that the button to cancel the
            % connection is called.
            app.UdpConnectionFlag = 1;
            StartUdpBtnButtonPushed(app,[])
            % Similarly, the ScreenOpenFlag is set to 1 and "Close Screen" button is
            % pushed.
            app.ScreenOpenFlag = 1;
            OpenScreenBtn_Callback(app, [])
            
            % Then, the timer is stopped and deleted.
            stop(obj)
            %             delete(obj)
        end
        
        function SendUdpMsg(app,msg,TimeoutTime)
            
            if TimeoutTime < 2147484 % Max value for 'StartDelay'
                % A timer for timeout has to be (created and) called.
                if ishandle(app.UdpTimeoutTimer)
                    % A valid timer exists. In case it is running, stop it.
                    stop(app.UdpTimeoutTimer)
                    app.UdpTimeoutTimer.StartDelay = TimeoutTime;
                    
                else
                    app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                        TimeoutTime,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
                end
                
                % The timer is started before sending the UDP message. This is
                % to avoid that a UDP answer arrives (and stops the timer)
                % before the timer is started, since the two objects are
                % asynchronous.
                app.UdpTimeoutTimer.UserData = app.UdpObj;
                start(app.UdpTimeoutTimer)
            end
            % Send the message
            write(app.UdpObj,msg,app.SlaveIp.Value,app.SlavePort.Value)
            
            % If verbose, print the message to the command window
            if app.VerboseCheckBox.Value
                MsgCellArray = strsplit(msg,',');
                fprintf('\nMessage sent to Slave:\n')
                for i=1:numel(MsgCellArray)
                    fprintf('\t %s \n',MsgCellArray{i})
                end
                fprintf('\n')
            end
        end
        
        function msg = ScreenParameterMsg(app)
            % The first three components of the message are:
            % 1) Master MAC address. 2) 'screen'. 3) '1'.
            % Then, send the following parameters in this order:
            % 4) Screen distance. 5) Screen width. 6) Screen Height. 7) Screen
            % calibration check. 8) Screen function formula. 9) Hermes Synch check.
            % 10) Hermes rectangle width 11) Hermes rectangle height. 12) Hermes
            % Rectangle ON duration. 13) Hermes rectangle top position. 14) Hermes
            % rectangle left position. 15) One screen mode check. 16) VR headset check.
            % 17) Screen distortion check 18) 'BLUE MODE'
            
            msg=strjoin([{app.MasterMac.Value},{'screen'},{'1'},...
                arrayfun(@num2str,[app.ScreenDistance.Value,app.ScreenWidth.Value,...
                    app.ScreenHeight.Value,app.CalibratedButton.Value],'un',0),...
                {app.ScreenFuncTxt.Value}, ...
                arrayfun(@num2str,[app.ard_ck.Value,app.wwTxt.Value,...
                    app.hhTxt.Value,app.optDtrTimeTxt.Value,app.CueTopRB.Value,...
                    app.CueLeftRB.Value,app.OneScreenMode_ck.Value,app.oculusCk.Value,...
                    app.SphericaldistortioncorrectionCheckBox.Value,...
                    app.BLUEMODEButton.Value],'un',0) ...
                ],',');
        end
        
        function PcoInitialize(app)
            % This function, as well as the acquisition function for PCO camera, is
            % based on the example scripts contained in the PCO for Matlab package.
            % October 2023, Gab: we switched to the Image Acquisition Toolbox adapter.
            % It makes everything MUCH easier with a bunch of standard functions.
            % All the variables related to the camera and to the VideoInput object are
            % stored as fields of the structure (and app property) 'app.PcoStruct'.
            
            %             Close all PCO connections
            PcoClose(app)
            
            % Open a VideoInput object looking for cameras connected to the
            % PCO Maltab Adapter.
            adaptorName=PCO_getAdaptorName();
            %Create video input object
            fprintf('Looking for devices connected to the adaptor "%s"...\n',adaptorName)
            try
                app.PcoStruct.vid = videoinput(adaptorName, 0);
                %Create adaptor source
                app.PcoStruct.cam = getselectedsource(app.PcoStruct.vid);
                app.PcoStruct.Connected = true;
                disp('PCO connected. Initialization starting...')
                
                % Read parameters from the UI and set them
                
                % Exposure time
                exposure_time = app.ExposureTimeEditField.Value;
                app.PcoStruct.cam.E1ExposureTime_unit = 'us'; % Hardcoded
                app.PcoStruct.cam.E2ExposureTime = exposure_time*1000; % in microseconds
                
                % Horizontal binning
                horz_bin = app.HorizontalBinningDropDown.Value;
                app.PcoStruct.cam.B1BinningHorizontal = horz_bin;
                
                % Vertical binning
                vert_bin = app.VerticalBinningDropDown.Value;
                app.PcoStruct.cam.B2BinningVertical = vert_bin;
                
                % Turn "noise filter" OFF
                app.PcoStruct.cam.NFNoiseFilter = 'off'; % Hardcoded
                
                % Set timestamps mode
                switch app.TimestampsDropDown.Value
                    case 'None'
                        app.PcoStruct.cam.TMTimestampMode = 'No Stamp';
                        
                    case 'Binary only'
                        app.PcoStruct.cam.TMTimestampMode = 'Binary';
                        
                    case 'Binary and ASCII'
                        app.PcoStruct.cam.TMTimestampMode = 'BinaryAndAscii';
                end
                
                % Set trigger mode to immediate: the acquisition starts with
                % the command start(obj), where obj is the VideoInput object.
                triggerconfig(app.PcoStruct.vid, 'immediate', 'none', 'none');
                app.PcoStruct.Image = preview(app.PcoStruct.vid);
                prev_axes = app.PcoStruct.Image.Parent;
                app.PcoCDataMinEditField.Value = prev_axes.CLim(1);
                app.PcoCDataMaxEditField.Value = prev_axes.CLim(2);
                stoppreview(app.PcoStruct.vid)
                
                % Set logging to memory. Hardcoded to 'disk&memory' by default.
                % This save frames into a file on the disk as soon as they are
                % acquired. Copies of the acquired frames are also stored in
                % the memory, and they can be retreived using getdata(vid),
                % where 'vid' is the VideoInput object.
                app.PcoStruct.vid.LoggingMode = 'disk&memory';
                
                % Focus again on the main figure.
                figure(app.EscherMasterFig)
                
                % Initialize image axes
                %             PCO_initializeAxes(app)
                
                % Estimate frame rate
                [~,frames]=PCO_updateFrameRate(app);
                
                
                % Get one frame and show it.
                frame = squeeze(frames(:,:,1,end));
                app.PcoStruct.Image.CData = frame;
                
                % Initialize the output structure that will contain the image series.
                
                app.PcoMeta = struct(...
                    'StimInfo',struct('StimType','none','nTrials',[],'FramesPerTrial',...
                    [],'Orientation','','Direction','','StimF',[]),...
                    'AcqInfo',struct('HorizBinning',[],'VertBinning',[],...
                    'Exposure',[],'FrameRate',[]) );
                
                % This will set also the selected frequency according to the
                % maximum possible frequency.
                PcoSyncDropDownValueChanged(app, [])
                
                app.PcoStatusLamp.Color = 'g';
                
                app.PcoStartPreviewButton.Enable = 'on';
                app.PcoStartAcquisitionButton.Enable = 'on';
                
                % initialize sliceViwer object
                app.PcoStruct.sv = [];
                
                % Save the app handle into a structure stored in the
                % 'UserData' property of the videoinput object
                str = struct('app',app);
                app.PcoStruct.vid.UserData = str;
                
                disp('PCO inizialization done.')
                app.PCOconnected = true;
                
            catch err
                app.PCOconnected = false;
                warning('PCO CONNECTION FAILED')
            end
        end
        
        function SetScreenSizeAngle(app)
            % called after any changes of the screen geometry or position.
            % Gab
            WAngle = 2*atand(app.ScreenWidth.Value/2/app.ScreenDistance.Value);
            HAngle = 2*atand(app.ScreenHeight.Value/2/app.ScreenDistance.Value);
            Sz = [int2str(WAngle) '° x ' int2str(HAngle) '°'];
            app.ScreenSizeAngle.Value = Sz;
        end
        
        function HermesConnect(app,COMport)
            if ~strcmp(COMport,'NONE')
                app.HermesReplyCheck = 0;
                app.HermesSerialObj = serialport(COMport,57600);
                % debug %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %                 writeline(app.HermesSerialObj,"*IDN?")
                %                 myResp = readline(app.HermesSerialObj);
                %                 disp(myResp)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.HermesSerialObj.Timeout = 5; % the default value is 10 seconds.
                %                 Set and read
                app.HermesSerialObj.DataBits = 8; % default is 8
                app.HermesSerialObj.StopBits = 1; % default is 1
                app.HermesSerialObj.Parity = 'none'; % default is none
                configureCallback(app.HermesSerialObj,"terminator",@(obj,~) HermesMsgReceivedFcn(app,obj)) % Gab: matlab 2020a update
                
                
                tic
                t=0;
                %             Now wait up to 1 second for a reply from Hermes.
                %             A reply will set app.HermesReplyCheck to 1 vAia the
                %             ByteAvailableFcn of the Hermes Object.
                while t<3 && ~app.HermesReplyCheck
                    drawnow
                    t=toc;
                end
                if ~app.HermesReplyCheck
                    warning('off','backtrace')
                    warning(['No reply received for the initialization using ',...
                        COMport])
                    warning('on','backtrace')
                    return % gab
                end
                % connection estabilished!!
                app.HermesSerialFlag = true;
                pause(1);
                HermesMsgMake(app,'parameters',{'TTL_time','frameP','idleTime','serial_delay','solisEnabled','optoStart','optoDur'})
                
                HermesMsgMake(app,'mod','classic','p')
            else
                delete(app.HermesSerialObj)
                app.HermesSerialFlag = false;
            end
        end
        
        function HermesMsgMake(app,MsgName,MsgValue,varargin)
            
            %                 Input analysis:
            %                 1)app
            %                 2)MsgType  The type of message to be sent to arduino.
            %                       It works in 'Name','Value' pairs. Names:
            %                    'mod' The value for this is either 'camAc', 'camPr', 'classic' or 'opto'
            %                            respectively for: camera acquisition, camera preview, classic or optogenetics
            %                    'trig' The value for this is either 'p' or 's'
            %                            respectively for 'phototransistor' and 'serial'
            %                    'cal' The value for this is either 'w' or 'b'
            %                    'stopTrig' Value: 1
            %                    'endVisualStim' Value: 1
            %                    'parameters' Parameters will be sent. Value is a character
            %                      array or cell array containing the name of parameters
            %                      to pass.
            %                 3)Only when the first name is 'mod' additional parameters are required:
            %                    1.The first additional parameter is 'trig' (the kind of synchronization),
            %                           either 'p' (phototransistor) or 's' (serial).
            %                    The other parameters can be optional and depend on the mod and trig combination
            %
            %                    -if mod = camAc and trig = p
            %                         2. The second additional parameter is the number of frames
            %                           for each trial.
            %
            %                    -if mod = camAc and trig = s
            %                         2. The second additional parameter is the number of frames
            %                             for each trial.
            %                         3. The third additional parameter is the number of
            %                             trials.
            %                         4. The fourth additional parameter is the period between
            %                             one trial and the next one.
            %
            %                    -if mod = camPr and trig = s
            %
            %                    -if mod = opto and trig = p no additional param is needed
            %
            %                    -if mod = classic and trig = p no additional param is needed
            
            
            if strcmp(MsgName, 'parameters')
                if ~iscell(MsgValue)
                    %                     If the parameter is not a cell, then it is just one
                    %                     parameter.
                    MsgValue = {MsgValue};
                end
                MsgOut = '';
                for i=1:length(MsgValue)
                    switch MsgValue{i}
                        case 'TTL_time'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(1000*app.HermesTtlDurationEditField.Value))];
                        case 'frameP'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(1000000/app.PcoSelectedFreqEditField.Value))];
                        case 'idleTime'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(app.HermesIdleTimeEditField.Value))];
                        case 'serial_delay'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(1000*app.HermesDelaysEditField.Value))];
                        case 'optoStart'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(app.optoStartEditField.Value))];
                        case 'optoDur'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(app.optoDurEditField.Value))];
                        case 'optoPer'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(1000/app.FrequencyHzEditField.Value))];
                        case 'nStim'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(app.nofpulsesEditField.Value)];
                            
                        case {'synch', 'stopTrig', 'endVisualStim'}
                            %                             In case the acquisition has to start.
                            MsgOut = [MsgOut ',' MsgValue{i},'=1'];
                    end
                    %                     HermesMsgSend(app,MsgOut)
                end
                %remove the first character, which is a comma
                MsgOut = MsgOut(2:end);
            else
                MsgOut = [MsgName,'=',MsgValue];
                if strcmp(MsgName, 'mod')
                    % the type of trigger is also specified in varargin{1}.
                    tr = varargin{1};
                    % a complete list of combinations follows:
                    switch MsgValue %mode
                        case 'camAc'
                            % acquire with the pco camera.
                            switch tr
                                case 'p' % triggered by the phototransistor
                                    % varargin{2} contains nFrames
                                    % nTrials and trialPeriod depends on the visual stimulus
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin>4
                                        MsgOut = sprintf(['nFrames=%s,',MsgOut],num2str(varargin{2}));
                                    end
                                case 's' % triggered by serial communication
                                    % varargin{2} contains nFrames
                                    % varargin{3} contains nTrials
                                    % varargin{4} contains trialPeriod
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin>4
                                        MsgOut = sprintf(['nFrames=%s,nTrials=%s,',MsgOut,',trialPeriod=%s'],...
                                            num2str(varargin{2}),num2str(varargin{3}),num2str(varargin{4}));
                                    end
                            end
                            
                        case 'camPr'
                            % preview with the pco camera. Can't be
                            % triggered by the phototransistor: only starts
                            % upon serial communication.
                            if strcmp(tr,'s')
                                %                                 MsgOut = sprintf(['nFrames=%s,trig=%s,' MsgOut],tr,varargin{2});
                                MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                            else
                                error('Phototransistor synch with preview. ARE YOU SERIOUS?')
                            end
                            
                        case 'opto'
                            % optogenetic stimulation.
                            switch tr
                                case 'p' % triggered by the phototransistor
                                    % nTrials and trialPeriod depends on the visual stimulus
                                    MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                                case 's' % triggered by serial communication
                                    % varargin{2} contains nTrials
                                    % varargin{3} contains trialPeriod
                                    %                                     MsgOut = ['nFrames=',varargin{2},',','nTrials=',varargin{3},',',MsgOut,...
                                    %                                             'trialPeriod=',varargin{4}];
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin>4
                                        MsgOut = sprintf(['nTrials=%s,',MsgOut,'trialPeriod=%s'],...
                                            num2str(varargin{2}),num2str(varargin{3}));
                                    end
                            end
                            
                        case 'classic'
                            if strcmp(tr,'p')
                                MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                            else
                                error('Serial synch with classic mode. ARE YOU SERIOUS?')
                            end
                    end
                end
                %                 HermesMsgSend(app,MsgOut)
            end
            HermesMsgSend(app,MsgOut)
            %             app.HermesSerialObj.BytesAvailableFcn = @(obj,~) ArduinoMsgReceivedFcn(app,obj);
            %             readasync(app.HermesSerialObj)
            %             app.HermesSerialObj.ReadAsyncMode = 'continuous';
        end
        
        function HermesMsgSend(app,MsgOut)
            % arduino serial buffer is limited to 64 bytes = 32 characters.
            % If MsgOut exceeds 30 characters (better be safe than
            % sorry) select the first part and recursively call HermesMsgSend
            while length(MsgOut)>30
                commas = regexp(MsgOut,',');
                currcomma = commas(find(commas<=30,1,'last'));
                currMsgOut = MsgOut(1:currcomma-1);
                HermesMsgSend(app,currMsgOut)
                MsgOut = MsgOut(currcomma+1:end); % recursive call
            end
            %------------%%%% HermesMsgSend core function %%%%------------------------%
            app.HermesReplyCheck = 0;
            if app.PrintSerialCommCk.Value
                disp(['MsgOut = ''', MsgOut,''''])
            end
            
            writeline(app.HermesSerialObj, MsgOut);
            
            tic
            t=0;
            %  Now wait up to 3 second for a reply from Hermes.
            %  A reply will set app.HermesReplyCheck to 1 vAia the
            %  ByteAvailableFcn of the Hermes Object.
            while t<3 && ~app.HermesReplyCheck
                drawnow
                t=toc;
            end
            if ~app.HermesReplyCheck
                warning('off','backtrace')
                warning(['No reply received for message = ''',MsgOut,''''])
                warning('on','backtrace')
            end
            %------------%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%------------------------%
        end
        
        function HermesMsgReceivedFcn(app,obj)
            %             Here goes stuff.
            MsgIn = readline(obj);
            MsgIn = regexprep(MsgIn,'\r','');
            if app.PrintSerialCommCk.Value
                disp(['MsgIn = ''', char(MsgIn),''''])
            end
            % if strcmp(MsgIn,'<Arduino is ready>') || strcmp(MsgIn,'Roger roger')
            %     app.HermesReplyCheck = 1;
            % end
            switch MsgIn
                case {"Black calibration done","White calibration done"}
                    RefreshStByButtonPushed(app, [])
                case {'<Arduino is ready>', 'Roger roger'}
                    app.HermesReplyCheck = 1;
            end
        end
        
        function PcoSetForStimulation(app,p,n,Bt,varargin)
            if nargin>4 % only for "post" period in Gratings. Warning: when this function
                % is called by "Grating GO!", n is doubled to consider both baseline and gratings.
                % so, divide it by 2 later.
                post=varargin{1};
            else
                post=0;
            end
            %             The number of frames is calculated considering the frame
            %             rate of the camera, the period between each trial, the number
            %             of trials and the dead time before the start of each trial.
            if app.AutomaticallydetermineacquisitiontimeButton.Value
                app.PcoNumberFramesEditField.Value = fix((p-app.DeadTBeforeTrialEditField.Value/1000)*...
                    app.PcoSelectedFreqEditField.Value)*n;
                %             Duration is used only for calculating timeout time and for
                %             showing.
                app.PcoDurationEditField.Value = app.HermesDelaysEditField.Value+Bt+p*n+post*(n/2);
            else
                p = floor(app.PCOTrialEditField.Value * app.PcoSelectedFreqEditField.Value);
                app.PcoNumberFramesEditField.Value = p*n;
                app.PcoDurationEditField.Value = app.HermesDelaysEditField.Value+Bt+p*n+post*(n/2);
            end
        end
        
        function PcoStartAcquisition(app)
            
            % Populate all info about the acquisition
            app.PcoMeta.AcqInfo.HorizBinning = app.HorizontalBinningDropDown.Value;
            app.PcoMeta.AcqInfo.VertBinning = app.VerticalBinningDropDown.Value;
            app.PcoMeta.AcqInfo.Exposure = app.ExposureTimeEditField.Value;
            app.PcoMeta.AcqInfo.FrameRate = app.PcoSelectedFreqEditField.Value;
            
            if ~isrunning(app.PcoStruct.vid)
                % Enter this condition if the camera is not acquiring.
                % Note: isrunning returns TRUE if the you used the command
                % start() on the VideoInput object. This includes when the
                % camera is waiting for a trigger (in case of external
                % triggers) and when the camera is actually acquiring
                % frames. Another property of the VideoInput object is
                % "islogging". islogging is TRUE only when the videoinput
                % object is loading frames from the camera.
                
                % Create the output file name
                fnumber = app.PcoOutputFileN.Value;
                fname = strcat(app.PcoSaveFolderEditField.Value,filesep,...
                    app.PcoFilenameEditField.Value,...
                    '-',num2str(fnumber,'%03i'),...
                    '.mj2');
                
                % check if the file already exists. If so, start from the
                % next available number.
                if ~isempty(dir(fname))
                    movies = dir(strcat(app.PcoSaveFolderEditField.Value,filesep,'*.mj2'));
                    matches=regexp({movies.name},'-\d*.mj',"match");
                    numbers=cellfun(@str2double,regexp(cellfun(@(x)(x{1}),matches,'UniformOutput',0),'\d*','match'),'uniformoutput',1);
                    fnumber = max(numbers)+1;
                    fname = strcat(app.PcoSaveFolderEditField.Value,filesep,...
                        app.PcoFilenameEditField.Value,...
                        '-',num2str(fnumber,'%03i'),...
                        '.mj2');
                end
                
                % update the file number
                app.PcoOutputFileN.Value = fnumber+1;
                
                % Create the VideoWriter object.
                vr=VideoWriter(fname,"Motion JPEG 2000");
                vr.FrameRate = app.PcoSelectedFreqEditField.Value;
                vr.LosslessCompression = true;
                
                app.PcoStruct.vid.DiskLogger = vr;
                
                open(app.PcoStruct.vid.DiskLogger)
                
                % Set the number of frames to acquire
                nFrames = app.PcoNumberFramesEditField.Value;
                app.PcoStruct.vid.FramesPerTrigger = nFrames;
                
                % Update the button
                app.PcoStartAcquisitionButton.Text = 'STOP ACQUISITION';
                
                % Prepare a callback to report the end of the acquisition
                %                 app.PcoStruct.vid.FramesAcquiredFcnCount = nFrames;
                %                 app.PcoStruct.vid.FramesAcquiredFcn = @(obj,event)(pco_acq_done(obj));
                app.PcoStruct.vid.StopFcn = @(obj,event)(pco_acq_done(obj,event));
                
                % save metafile in advance
                metafile_name = strrep(fname,'.mj2','.mat');
                metafile = app.PcoMeta;
                save(metafile_name,'metafile')
                
                % Start acquisition
                disp('Start acquisition...')
                preview(app.PcoStruct.vid,app.PcoStruct.Image)
                start(app.PcoStruct.vid)


                % If the sync-mode is Hermes, then send a message to Hermes
                % to start the TTL sequence.
                if strcmp(app.PcoSyncDropDown.Value,'Hardware (Hermes)')
                    % This message is sent to Hermes to start an acquisition when
                    % its 'trigger mode' is set to 'serial' (baseline recording).
                    % However, this message is sent to Hermes even when its 'trigger
                    % mode' is set to 'phototransistor'. Don't worry about
                    % this, since:
                    % 1) Hermes activity is unaffected by the state of the
                    % variable 'synch' while working in 'trigger mode'.
                    % 2) Hermes is in 'phototransistor' mode only when
                    % stimulating with viaual stimuli. In this case, at the
                    % end of the stimulation, the message "stopTrig=1" is
                    % also sent to Hermes. Once received this message,
                    % Hermes set the value of synch to 0 (and also turns
                    % off the Solis LED, if enabled).
                    % 3) When the 'trigger mode' switches back to 'serial',
                    % synch is turned to 0.
                    HermesMsgSend(app,'synch=1')
                end
            else
                %app.PcoStruct.acq_status = 0;
                disp('Camera is already running or waiting for a trigger')
            end
            
            %             function pco_acq_done(obj)
            %                 stoppreview(obj)
            % %                 close(obj.DiskLogger)
            %                 app.PcoStartAcquisitionButton.Text = 'START ACQUISITION';
            %                 app.PcoStruct.Images = squeeze(getdata(obj));
            %                 disp('Acquisition done.')
            %             end
            
        end
        
        function PcoClose(app)
            if app.PcoStruct.Connected
                delete(app.PcoStruct.vid)
                disp('Camera disconnected')
            end
            app.PcoStruct.Connected = false;
        end
        
        function [fr,frames]=PCO_updateFrameRate(app)
            disp('Actual frame rate estimation...')
            % erase the DiskLogger, since we don't want to save the frames
            % we are going to acquire.
            app.PcoStruct.vid.DiskLogger = [];
            [fr,frames]=PCO_estimateFrameRate(app.PcoStruct.vid);
            app.PcoMaxFreqEditField.Value = fr;
            if app.PcoSelectedFreqEditField.Value > fr || app.PcoSelectedFreqEditField.Value==0
                app.PcoSelectedFreqEditField.Value = fr;
            end
            app.PcoSelectedFreqEditField.Limits = [0, fr];
            fprintf('Max frame rate: %.3f Hz\n',fr)
        end
        
        function LogStruct = PrepareLogStruct(app,stim)
            
            % prepare the Log struct with
            LogStruct = struct('versionAttribute',2, ...
                'dateAttribute',datetime("now"), ...
                'notesAttribute',"", ...
                'SystemSettings',SystemSettingsStruct(app), ...
                'Sequence',struct('typeAttribute',stim,'Stimulus',[]));
            
            function str = SystemSettingsStruct(app)
                
                screenStr = struct( ...
                    'nameAttribute',{...
                    "Name",...
                    "TransferFunction",...
                    "Notes",...
                    "CalibratedMode",...
                    "Distance_cm",...
                    "Width_cm",...
                    "Height_cm",...
                    "Width_pxl",...
                    "Height_pxl",...
                    "Width_deg",...
                    "Height_deg",...
                    "StandbyLumi_cd/m2", ...
                    "Blue_mode"},...
                    'valueAttribute',{ ...
                    app.ScreenNameTxt.Value, ...
                    app.ScreenFuncTxt.Value, ...
                    app.ScreenNotesTxt.Value, ...
                    num2str(app.CalibratedButton.Value,'%i'), ...
                    num2str(app.ScreenDistance.Value,'%.2f'), ...
                    num2str(app.ScreenWidth.Value,'%.2f'), ...
                    num2str(app.ScreenHeight.Value,'%.2f'), ...
                    num2str(app.WidthPix.Value,'%i'), ...
                    num2str(app.HeightPix.Value,'%i'), ...
                    num2str(app.OnePxAngle*app.WidthPix.Value,'%.2f'), ...
                    num2str(app.OnePxAngle*app.HeightPix.Value/2,'%.2f'), ...
                    num2str(app.StandbyL.Value,'%.2f'), ...
                    num2str(app.BLUEMODEButton.Value,'%i')...
                    });
                
                hermesStr = struct( ...
                    'nameAttribute',{...
                    "InUse",...
                    "CueWidth_pxl",...
                    "CueHeight_pxl",...
                    "CuePositionVertical",...
                    "CuePositionHorizontal",...
                    "CueDuration_ms",...
                    "HermesIdleTime_ms",...
                    "TTLDuration_ms"},...
                    'valueAttribute',{ ...
                    app.ard_ck.Value, ...
                    app.wwTxt.Value, ...
                    app.hhTxt.Value, ...
                    get(app.CueVertPos.SelectedObject,'Text'), ...
                    get(app.CueHorzPos.SelectedObject,'Text'), ...
                    app.optDtrTimeTxt.Value, ...
                    app.HermesIdleTimeEditField.Value, ...
                    app.HermesTtlDurationEditField.Value, ...
                    });
                
                str=struct('Screen',struct('Settings',screenStr),...
                    'Hermes',struct('Settings',hermesStr));
            end
            
        end
        
        function saveLogXml(app,stimtype)
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            logName = strcat(app.logDir, runString, stimtype, '.xml');
            writestruct(app.CurrentLogStruct,logName,"StructNodeName","EscherLog")
            
        end
        
        function PcoStopAcquisition(app)
            switch app.PcoSyncDropDown.Value
                case 'Software (Escher)'
                    %                     HermesMsg = []; there is no HermesMsg anymore
                case 'Hardware (Hermes)'
                    %                     Create a message to Hermes thay says to perform a
                    %                     series ('s') of a certain number of frames and with 1
                    %                     trial and trial period zero.
                    HermesMsgSend(app,'stopTrig=1');
            end
            
            stop(app.PcoStruct.vid);
            %                     disp('Acquisition Stopped.')
            app.PcoStartAcquisitionButton.Text = 'START ACQUISITION';
        end


        function LoadScreenFileFunction(app,file,path)
            
            if ~exist([path, file],'file')
                warning('No calibration file selected')
                return
            end
            
            T = readtable([path, file]);
            
            app.ScreenNameTxt.Value = T.name{1};
            app.ScreenWidth.Value = T.width(1);
            app.ScreenHeight.Value = T.height(1);
            app.ScreenNotesTxt.Value = T.notes{1};
            
            app.ScreenFuncGray = T.formula{1};
            if ismember('formula_blue',T.Properties.VariableNames)
                app.ScreenFuncBlue = T.formula_blue{1};
            else
                app.ScreenFuncBlue = '';
            end
            
%             ScreenFuncTxtValueChanged(app, [])
            % change the in-use transfer function 
            SelectScreenFunctionFcn(app,app.BLUEMODEButton.Value)

            CalModeRBGroupSelectionChanged(app, [])
            
            SetScreenSizeAngle(app)
        end


        function SelectScreenFunctionFcn(app,value)
            if app.CalibratedButton.Value
                if value % blue mode on
                    app.ScreenFunc = app.ScreenFuncBlue;
                else % blue mode off
                    app.ScreenFunc = app.ScreenFuncGray;
                end
                app.ScreenFuncTxt.Value = app.ScreenFunc;
            else
                app.ScreenFuncTxt.Value = '';
                app.ScreenFunc = '';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AppFilePath = mfilename('fullpath');
%             Take all the characters up to the last path separator. This
%             is the Escher App Folder.
            AppFolder = regexp(AppFilePath, ['^.*\',filesep],'match');
            app.escherAppFolder = AppFolder{:};
            
            % Setup app environment
            addpath(app.escherAppFolder)
            addpath([app.escherAppFolder,'NetworkingTools',filesep])
            addpath([app.escherAppFolder,'PcoTools',filesep])
            addpath([app.escherAppFolder,'settings',filesep])
            addpath([app.escherAppFolder,'VisualStimulation',filesep])
            
            app.PcoSaveFolderEditField.Value = pwd;
            
%             INITIALIZE ALL FLAGS
            %             The screen is not open in the beginning, so the corresponding
            %             flag is set to zero
            app.ScreenOpenFlag = 0;
            app.UdpConnectionFlag = 0;
            app.HermesSerialFlag = 0;
            
            % Load a screen file.
            screenfolder = [app.escherAppFolder,'settings',filesep];
            screenfiles = dir([screenfolder '*.dat']);
            
            defscreen = 'Acer_Padova.dat';
            if ismember(defscreen,{screenfiles.name})
                % default screen is Asus ROG
                LoadScreenFileFunction(app, defscreen, screenfolder)
            elseif ~isempty(screenfiles)
                % load the first file
                LoadScreenFileFunction(app,screenfiles(1).name,screenfolder)
            else
                % no calibration files.
                app.UncalibratedButton.Value = true;
                
            end
%             The screen function is read:
%             app.ScreenFunc = app.ScreenFuncTxt.Value;
            
%             Preparation of the log output
%             Each time Esher is launched check the log directory *\logs for the
%             presence of a directory named with the current date. If it does not
%             exist it is created.
%             Content of the log directory.
%             Each time a stim event is created (either a single episode or an entire
%             sequence) a new entry is created in the log directory.
%             Sintax of the file name:
%             It is formed by two fields, separated by dot.
%             The first field is the run number (4 digits).
%             The second field define the type of stimulus
%             flash      full field flash
%             check      checkerboard
%             grid       presentation of a grid at a given orientaton
%             Finally the extension .dat
%             Each run file includes a line that contains all the parameters relevant
%             to describe the specific stim protocol. Each presentation is described
%             in subsequent lines. The first field is a time tag, followed by
%             parameters desribing the specific stimulus, i.e. the grid orientation.
            logParentDir = [app.escherAppFolder, 'logs', filesep];
            if exist(logParentDir, 'dir') ~= 7
                mkdir(logParentDir)
            end
            app.logDir = [logParentDir, datestr(datetime("today"),"yyyy-mmm-dd"), filesep];
            app.runNumberFile = [app.logDir, 'run.number.dat'];
            if exist(app.logDir, 'dir') == 7
%             The log directory has already been created. Determine the next run number.
%             This is contained in the ascii file run.number.log that is created at
%             the time of the directory creation. Each time a new log item is added
%             the run.number is updated. If Escher2 is re-initialisated during a
%             single session, this ensure the unicity of the log file names.
                if exist(app.runNumberFile,'file')
                    app.runNumber = readmatrix(app.runNumberFile);
                else
                    writematrix(0, app.runNumberFile)
                end
            else
                % Create the ASCII run.number.log file. run number = 1, of course
                mkdir(app.logDir)
                writematrix(0, app.runNumberFile)
            end
            
            app.settingsDir = [app.escherAppFolder, 'settings', filesep];
            if exist(app.settingsDir, 'dir') ~= 7
                mkdir(app.settingsDir)
            end
            
%             Erase all UDP connections and initialize a UDP object.
%             CloseAllUdp
%             app.UdpObj = udp;            
            
%             Monitor initialisation and geometry
            SetScreenSizeAngle(app)
            
%             app.white is initialized to 255 and the ScreenBitDepth to 'uint8'.
%             This is the default. If the screen is opened with a different
%             bit depth, these values will be updated.
            app.white = 255;
            app.ScreenBitDepth = 'uint8';
            
%             HARD-CODED SETTINGS AND CALLBACKS
%             The table for fast selection of Flash stimuli has to be set
            app.FlashFastTable.Data = [0, 0; 0.1, 0; 0.139, 0;...
                0.194, 0; 0.268, 0; 0.374, 0; 0.516, 0;...
                0.719, 0; 1, 0];
            app.FlashFastTotN.Value = size(app.FlashFastTable.Data, 1);
%             Then executes the callback of the fast set text box.
            FlashFastN_Callback(app,[])
            
%             The table for fast selection of Checkerboard stimuli has to
%             be set
%             app.ContrastFastTable.Data = [0, 0.6; 6, 0.6; 12, 0.6;...
%                 25, 0.6; 50, 0.6; 95, 0.6];
            app.ContrastFastTable.Data = [];
            app.CheckFastTotN.Value = size(app.ContrastFastTable.Data, 1);
%             Then executes the callback of the fast set text box.
            CheckFastNValueChanged(app,[])
            
            app.OrientFastTable.Data = (0:45:315)';
            app.OrientFastTotN.Value = size( app.OrientFastTable.Data, 1);
            OrientFastNValueChanged(app, [])
            
%             app.SfFastTable.Data = [0.03,0.05,0.1,0.15,0.2,0.3,0.4,0.5,...
%                 0.6,0.8,1]';
            app.SfFastTable.Data = [];
            app.SfFastN.Value = max( 1, size(app.SfFastTable.Data, 1));
            SfFastNValueChanged(app, [])
            
            
%             The timer for UDP connection timeout must be initialized.
%             app.UdpTimeoutTimer = timer;
            
%             Other callbacks to be exectued:
%             The callback for the calculation of Escher values from
%             luminances (in case the calibration is on) OR hiding the
%             escher values (in case calibration is off).
%             All this is achieved by invoking the callback of the
%             calibration radio button.
%             CalModeRBGroupSelectionChanged(app,[])
            
% Initialize the IP and MAC for UDP connection.
            GetMasterIPandMACBtnPushed(app,[])
            
% Set a starting value for app.screenRect
            app.screenRect = [0, 0, app.WidthPix.Value, app.HeightPix.Value];
            PixelAngleCalc(app)
            
% Set the bar drifting speed for the Intrinsic signal stimulus
            BartraveltimesEditFieldValueChanged(app,[])

% PCO initialization
            app.PcoStruct.Connected = false;
            app.PcoStruct.acqDuration = app.PcoDurationEditField.Value;

% This will set the selected sync mode (escher-only or Hermes) and will 
% also set the selected frequency according to the maximum possible
% frequency.
            PcoSyncDropDownValueChanged(app, [])
            app.PCOconnected = false;
            
            % set by default the density of dots as 1 over diameter^2
            app.DotsdensityEditField.Value = 1/app.DotsdiameterdegEditField.Value.^2;
            DotsdensityEditFieldValueChanged(app, 1)
            
            UsedefaultwidthandheightCheckBoxValueChanged(app,[])
        end

        % Close request function: EscherMasterFig
        function EscherMasterFigCloseRequest(app, event)
            
            try
                CloseScreen
            end
            
            try
                PcoClose(app)
            end
            
            try
                if isa(app.HermesSerialObj, 'serial')
                    fclose(app.HermesSerialObj);
                end
            catch ME
                warning(ME.message)
            end
            
            try
            % save parameters in an autosettings file
    %             saveParams(app, [app.escherAppFolder '\autosettings.mat'])
                if ishandle(app.UdpTimeoutTimer)
                    stop(app.UdpTimeoutTimer)
                    delete(app.UdpTimeoutTimer)
                end
                
%                 CloseAllUdp
            catch ME
%                 warning('off','backtrace') % maybe not necessary
                warning(ME.message)
            end
            delete(app)
        end

        % Button pushed function: OpenScreenBtn
        function OpenScreenBtn_Callback(app, event)
            switch app.ScreenOpenFlag
                case 0 % Screen in not open
                    if app.UdpConnectionFlag
                        msg = ScreenParameterMsg(app);
                        SendUdpMsg(app,msg,app.TimeoutTime.Value)
                    else
                        if app.CalibratedButton.Value
                            [background,app.StandbyL.Value] = Lumi2Escher(app.StandbyL.Value,app.white,app.ScreenFunc);
                        else
                            background = app.StandbyL.Value;
                        end
                        
                        % screenRect is a 4 element array xmin, ymin, xmax, ymax
                        % strangely, xmin and ymin are set to zero. Bho?
                        OpenScreen(app, background)
                        
                        % Remember to set the fields relative to the screen size on the GUI and the
                        % relative app.variable.
                        app.WidthPix.Value = app.screenRect(3);
                        app.HeightPix.Value = app.screenRect(4);
                        
                        app.OpenScreenBtn.Text = 'Close Screen';
                        
                        app.ScreenOpenFlag = 1;
                        
                        %                     Call the standby button.
                        RefreshStByButtonPushed(app, [])
                    end
                    
                case 1 % Screen is open
                    if app.UdpConnectionFlag
                        msg=strjoin([{app.MasterMac.Value},{'screen'},{'0'}],',');
                        SendUdpMsg(app,msg,app.TimeoutTime.Value)
                    else
                        CloseScreen
                    end
                    app.OpenScreenBtn.Text = 'Open Screen';
                    app.ScreenOpenFlag = 0;
                    EnableGoBtn(app,1)
            end
            
        end

        % Button pushed function: FSFlashGo
        function GoFlash_Callback(app, event)
% April 2023
% gab added the option: "ProvideadarkstimulusasbaselineforeveryflashCheckBox".
% During Pco imaging, imaging happens in trains of frames (trials) triggered
% by visual stimuli. This is great for timing, but prevent us to have a baseline
% period before the actual stimulus. If "ProvideadarkstimulusasbaselineforeveryflashCheckBox"
% is checked, matlab produces twice the flashes, but flash luminance is set 
% to baseline luminance every other stimulus. These "fake" flashes are used
% as baseline.

% December , 2018
% The app has been completey redesigned to implement a server-client 
% architecture
            
% Nov 25, 2013
% the function has been modified to allow the display of a periodically
% alternating checkboard.
% Blumi and Slumi define the luminance of the black and light squares.
% The spatial frequency is defined by the "spatial frequency" field in the
% grid generation module.
% p define the period of the alternation (ON and OFF)
% Bt defines the baseline_txt spent in the baseline_txt equiluminant gray computed
% as (Blumi+Slumi)/2 if a calibration LUT is active.
            
% May 2017.
% This code has been modified to allow the creation of the checkerboad stim
% on the VR googles.
            
% If the "PCO while stimulating" box is checked, Hermes must be also 
% connected with serial communication.
% It is better to do this check here, before disabling the GO buttons and 
% writing any logs.
%             if app.PcoWhileStimulusCk.Value && (~app.HermesSerialFlag ||...
%                     ~app.UdpConnectionFlag)
%             	uialert(app.EscherMasterFig,['To record with PCO while stimulating, both',...
%                  'Hermes and UDP-slave must be connected.'],'Error')
%              return
%             end
            
            
% load parameters in local variables
            Blumi = app.FSbaselineL.Value; % baseline luminance
            Slumi = app.FSflashL.Value; % flash luminance
            Bt = app.initialDelay.Value; % an initial delay (just once)
            St = app.FlashDuration.Value; % The duration of a single flash
            p = app.FlashPeriod.Value; % time between each flash
            n = app.FlashRepetitions.Value;   % number of repetitions
            bsl_check = app.ProvideadarkstimulusasbaselineforeveryflashCheckBox.Value;
            if bsl_check
                n=2*n;
            end
            ParameterVector = num2cell([Blumi,Slumi,Bt,St,p,n,bsl_check]);
            
% GAB March 2024: distinguish between "normal" (fast) and "slow" flashes.
% Flashes are "slow" if their duration is longer than Hermes' idle time.
% The only difference stays in the way synchronization through Hermes is
% achieved:
% - If flash duration < Hermes idle time, then the flash is "fast".
%           This is the traditional way flashes are delivered. Only the
%           flash presentation comes with an optical TTL (cue). Also,
%           the duration of the optical TTL coincides with the duration
%           of the flash, even if a different duration is set on the
%           GUI. "FlashPresent" function is called.
% - If flash duration >= Hermes idle time, then the flash is "slow".
%           Here, the approach used for fast flashes would make Hermes
%           generate unwanted TTLs. E.g. Hermes idle time is 200 ms
%           and flash duration is 5 s. If the optical TTL last 5 s like
%           the flash, Hermes would bwould produces a TTL at flash onset,
%           then idle for 200 ms, then it would produce another TTL
%           -since the flash and optical TTL are still there -, then idle
%           for 200 ms, then produce another TTL... and so on, for the
%           whole length of the flash. For this reason, temporization
%           of slow flashes is handled similar to all the other
%           "non-transient" stimuli: the optical TTL is shown for the
%           amount of time specified as "Duration of cue" on the GUI,
%           then it disappears. The cue is also shown when the flash
%           disappears. "SlowFlashPresent" function is called.

if St < app.HermesIdleTimeEditField.Value/1000
    flashtype ='flash'; %traditional "fast" flash
    flashFcn = 'FlashPresent';
else
    flashtype ='slowflash';
    flashFcn = 'SlowFlashPresent';
end

% Writing the log file.
            % Writing the log file.
            % General log struct
            app.CurrentLogStruct = PrepareLogStruct(app,'Flashes');
            % Allocate visual stimuli elements
            % - Initial baseline
            % - For each cycle, we have 2 timestamps: flash presentation and flash disappearance.
            % - Final baseline.
            n_stimuli = 1 + 2*n + 1;
            placeholder = cell(n_stimuli,1);
            stimStruct = struct('NameAttribute',placeholder, ...
                  "RelativeTimeAttribute",placeholder, ...
                  "StimParam",placeholder);
            % Initial "delay"
            stimStruct(1).NameAttribute         = 'InitialDelay';
            stimStruct(1).RelativeTimeAttribute = []; % later
            stimStruct(1).HermesSyncAttribute   = string(app.SendTTLwhenbaselinestartsCheckBox.Value);
            stimStruct(1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f'),...
                                            num2str(app.initialDelay.Value,'%.2f'),...
                                            }) ...
                    );

            % Flash ON
            for i=1:2:n-1
                stimStruct(1+i).NameAttribute         = "Flash ON";
                stimStruct(1+i).RelativeTimeAttribute = []; % later
                stimStruct(1+i).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+i).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "BlackLuminance_cdm2", ...
                                            "FlashLuminance_cdm2", ...
                                            "FlashDuration_s", ...
                                            "FlashPeriod_s", ...
                                            },...
                                         'ValueAttribute',{ ...
                                            num2str(Blumi,'%.2f'), ...
                                            num2str(Slumi,'%.2f'),...
                                            num2str(St,'%.4f'),  ...
                                            num2str(p,'%.4f'),  ...
                                            }) ...
                    );
            end
            % Flash OFF
            for i=2:2:n
                stimStruct(1+i).NameAttribute         = "Flash OFF";
                stimStruct(1+i).RelativeTimeAttribute = []; % later
                stimStruct(1+i).HermesSyncAttribute   = string(strcmp(flashtype, "slowflash")); % a TTL for OFF switch is given only if "slowflash"
                stimStruct(1+i).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "BlackLuminance", ...
                                            },...
                                         'ValueAttribute',{ ...
                                            num2str(Blumi,'%.2f'), ...
                                            }) ...
                    );
            end
            if bsl_check
                % Baselines
                i_bsl = repmat(1:4:(n_stimuli-2)-1,2,1)+[0;1];
                for i=(i_bsl(:))'
                    stimStruct(1+i).NameAttribute         = "Baseline";
                    stimStruct(1+i).RelativeTimeAttribute = []; % later
                    if rem(i,2) % odd index, baseline onset
                        stimStruct(1+i).HermesSyncAttribute   = string(app.ard_ck.Value);
                    else % even index, baseline offset
                        stimStruct(1+i).HermesSyncAttribute   = string(strcmp(flashtype, "slowflash"));
                    end
                    stimStruct(1+i).StimParams = struct( ...
                        "StimParam", struct('NameAttribute',{ ...
                                                "BlackLuminance_cdm2", ...
                                                },...
                                                'ValueAttribute',{ ...
                                                num2str(Blumi,'%.2f'), ...
                                                }) ...
                                                );
                end
            end
            app.CurrentLogStruct.Sequence.Stimulus = stimStruct;
            
% Hermes has to trigger the PCO acquisition.
% PCO PARAMETERS PREPARATION PART.
            if app.PcoWhileStimulusCk.Value == 1
% Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,p,n,2*Bt)
                
%                 IdleTime = round(app.HermesIdleTimeEditField.Value);
                
% Create a message for Hermes. A check is not necessary because if PCO is
% used, Hermes has to be connected.
% HermesMsg = HermesMsgMake(app,'o',IdleTime);
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/n)
%                 app.ModeDropDown.Value = 'camAc';
                app.TriggerDropDown.Value = 'phototransistor';
% Set the parameters that will be saved.
                app.PcoMeta = struct;
                app.PcoMeta.StimInfo.StimType = flashtype;
                app.PcoMeta.StimInfo.nTrials = n;
                app.PcoMeta.StimInfo.FramesPerTrial = app.PcoNumberFramesEditField.Value/n;
                app.PcoMeta.StimInfo.Orientation = '';
                app.PcoMeta.StimInfo.Direction = '';
                app.PcoMeta.StimInfo.StimF = 1/p;
                app.PcoMeta.StimInfo.Baseline_bool = bsl_check;
                app.PcoMeta.StimInfo.DeadTimeBetweenTrials = p - ...
                    app.PcoMeta.StimInfo.FramesPerTrial / app.PcoSelectedFreqEditField.Value ;
            end
            
            TotStimDur = Bt + n*p;
            callVisStim( app, flashFcn, ParameterVector, TotStimDur)
        end

        % Button pushed function: CheckGo
        function GoCheck_Callback(app, event)
            % Nov 25, 2013
            % the function has been modified to allow the display of a periodically
            % alternating checkboard.
            % Blumi and Slumi define the luminance of the black and light squares.
            % The spatial frequency is defined by the "spatial frequency" field in the
            % grid generation module.
            % p define the period of the alternation (ON and OFF)
            % Bt defines the baseline_txt spent in the baseline_txt equiluminant grey computed
            % as (Blumi+Slumi)/2 if a calibration LUT is active.
            
            % May 2017.
            % This code has been modified to allow the creation of the checkerboad stim
            % on the VR googles.

            % load parameters in local variables
            Blumi = app.CheckLumiB.Value;
            Slumi = app.CheckLumiW.Value;
            %             gray luminance for equiluminant background.
            Glumi = app.CheckLumiMean.Value;
            sF = app.spatialFrequency.Value;
            Bt = app.initialDelay.Value;
            p = app.CheckPeriod.Value/2; % hemi-period. p is the time between a reversal and the next one (2 reversals = 1 period)
            n = app.CheckRepetitions.Value; % Repetitions. The number of reversals is 2*n.
            
            % Writing the log file.
            % General log struct
            app.CurrentLogStruct = PrepareLogStruct(app,'checkerboard');
            % Allocate visual stimuli elements
            % - Initial baseline
            % - For each cycle, we have 2 reversals.
            % - Final baseline.
            n_stimuli = 1 + 2*n + 1;
            placeholder = cell(n_stimuli,1);
            stimStruct = struct('NameAttribute',placeholder, ...
                  "RelativeTimeAttribute",placeholder, ...
                  "StimParam",placeholder);
            % Initial "delay"
            stimStruct(1).NameAttribute         = 'InitialDelay';
            stimStruct(1).RelativeTimeAttribute = []; % later
            stimStruct(1).HermesSyncAttribute   = string(app.SendTTLwhenbaselinestartsCheckBox.Value);
            stimStruct(1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f'),...
                                            num2str(app.initialDelay.Value,'%.2f'),...
                                            }) ...
                    );

            % CheckerboardReversal
            for i=1:2*n
                stimStruct(1+i).NameAttribute         = "CheckerboardReversal";
                stimStruct(1+i).RelativeTimeAttribute = []; % later
                stimStruct(1+i).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+i).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Contrast", ...
                                            "MeanLuminance_cdm2", ...
                                            "SpatialFreq_cyc/deg", ...
                                            },...
                                         'ValueAttribute',{ ...
                                            num2str(app.CheckContrast.Value,'%.2f'), ...
                                            num2str(Glumi,'%.2f'),...
                                            num2str(sF,'%.4f'),  ...
                                            }) ...
                    );
            end
            % Back to standby luminance
            stimStruct(end).NameAttribute         = 'StandbyLuminance';
            stimStruct(end).RelativeTimeAttribute = []; % later
            stimStruct(end).HermesSyncAttribute   = "false";
            stimStruct(end).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f')...
                                            }) ...
                    );
            app.CurrentLogStruct.Sequence.Stimulus = stimStruct;
            
%             ParameterVector is the vector of parameters that is passed as
%             UDP message. If UDP is not used, it is useful anyway as input
%             for the stimulus-presenting function.
            ParameterVector = num2cell([Blumi,Slumi,sF,Bt,p,n]);
            
%             Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
%                 Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,p,2*n,2*Bt)

%                 Create a message for Hermes
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/(2*n))
                app.TriggerDropDown.Value = 'phototransistor';
% Set the parameters that will be saved.
                app.PcoMeta.StimInfo.StimType = 'check';
                app.PcoMeta.StimInfo.nTrials = 2*n;
                app.PcoMeta.StimInfo.FramesPerTrial = app.PcoNumberFramesEditField.Value/(2*n);
                app.PcoMeta.StimInfo.Orientation = '';
                app.PcoMeta.StimInfo.Direction = '';
                app.PcoMeta.StimInfo.StimF = 1/(2*p);
                app.PcoMeta.StimInfo.DeadTimeBetweenTrials = p - ...
                    app.PcoMeta.StimInfo.FramesPerTrial / app.PcoSelectedFreqEditField.Value ;
            end

            TotStimDur = Bt + n * 2 * p;
            callVisStim( app, 'CheckPresent', ParameterVector, TotStimDur)
        end

        % Button pushed function: GoGrid
        function GoGrid_Callback(app, event)
            
            % The two luminance values we need
            Glumi = app.CheckLumiMean.Value; % the average luminance
            inc = (app.CheckLumiW.Value-app.CheckLumiB.Value)/2; % the increment of luminance
%             Other values needed
            sF = app.spatialFrequency.Value; % spatial frequency of the stimulus (in cycles/degree)
            gridType = app.SineGrid.Value; % 1 means sinusoidal, 0 is for square-wave (bar)
            Angle = app.GridOrientation.Value; % angle of the stimulus. 0 means vertical bars.
            dS = app.DriftingSpeed_txt.Value; % drifting speed of the stimulus in cycles/s.
            MaskFlag = app.DrawMask.Value; % 1 means that a gaussian mask is applied.
            GaussSize = app.GaussSize_txt.Value; % size of gaussian mask
            Bt = app.initialDelay.Value; % initial delay
            PreG = app.BaselinepresEditField.Value; % pre-grid duration
            GridT = app.GridssEditField.Value; % grid duration
            PostG = app.BaselinepostsEditField.Value; % post-grid duration
            n = app.GridRepetitions.Value; % number of repetitions of the stimulus 
            dark_bsl = app.UsestandbyluminanceforBaselinepreandBaselinepostCheckBox.Value; % Use dark value for baseline
            
            ParameterVector = num2cell([Glumi;gridType;dS;MaskFlag;...
                GaussSize;Bt;n;dark_bsl;PreG;GridT;...
                1;PostG; ...%number of "PostG" values (here, 1), followed by the value itself
                1;inc; ...  %number of "inc"   values (here, 1), followed by the value itself
                1;sF; ...   %number of "sF"    values (here, 1), followed by the value itself
                1;Angle ... %number of "Angle" values (here, 1), followed by the value itself
                ])';
            
%             ParameterVector = num2cell([Glumi,inc,sF,gridType,Angle,dS,MaskFlag,...
%                 GaussSize,Bt,PreG,GridT,PostG,n,dark_bsl]);
            
            % General log struct
            app.CurrentLogStruct = PrepareLogStruct(app,'Grid');
            % Allocate visual stimuli elements
            % - Initial baseline
            % - For each cycle, we have 3 stimuli: pre, grid and post.
            % - Back to baseline luminance.
            n_stimuli = 1 + 3*n + 1;
            placeholder = cell(n_stimuli,1);
            stimStruct = struct('NameAttribute',placeholder, ...
                  "RelativeTimeAttribute",placeholder, ...
                  "StimParam",placeholder);
            % Initial "delay"
            stimStruct(1).NameAttribute         = 'InitialDelay';
            stimStruct(1).RelativeTimeAttribute = []; % later
            stimStruct(1).HermesSyncAttribute   = string(app.SendTTLwhenbaselinestartsCheckBox.Value);
            stimStruct(1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(Glumi,'%.2f'),...
                                            num2str(app.initialDelay.Value,'%.2f'),...
                                            }) ...
                    );

            if dark_bsl
                baseline_lumi = app.StandbyL.Value;
            else
                baseline_lumi = Glumi;
            end
            % "Pre-grid" baseline
            for i=1:n
                stimStruct(1+3*(i-1)+1).NameAttribute         = 'PreGrid';
                stimStruct(1+3*(i-1)+1).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+1).HermesSyncAttribute   = string(app.PcoWhileStimulusCk.Value);
                stimStruct(1+3*(i-1)+1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(baseline_lumi,'%.2f'),...
                                            num2str(app.BaselinepresEditField.Value,'%.2f'),...
                                            }) ...
                    );
            end
            % Grid
            for i=1:n
                stimStruct(1+3*(i-1)+2).NameAttribute         = "Grid";
                stimStruct(1+3*(i-1)+2).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+2).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+3*(i-1)+2).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Contrast", ...
                                            "MeanLuminance_cdm2", ...
                                            "SpatialFreq_cyc/deg", ...
                                            "DriftingSpeed_cyc/s", ...
                                            "GridShape",...
                                            "GaussianMask",...
                                            "Orientation",...
                                            },...
                                         'ValueAttribute',{ ...
                                            num2str(app.CheckContrast.Value,'%.2f'), ...
                                            num2str(Glumi,'%.2f'),...
                                            num2str(sF,'%.4f'), ...
                                            num2str(app.DriftingSpeed_txt.Value,'%.2f'), ...
                                            get(app.ShapeButtonGroup.SelectedObject,'Text'), ...
                                            struct( 'Attributes', struct(...
                                                'ActiveAttribute', num2str(app.DrawMask.Value,'%.2f'), ...
                                                'Size_pxlAttribute', num2str(app.GaussSize_txt.Value,'%.2f')) ...
                                                ), ...
                                            num2str(Angle,'%.2f') ...
                                            }) ...
                    );
            end
            % "Post-grid" baseline
            for i=1:n
                stimStruct(1+3*(i-1)+3).NameAttribute         = "PostGrid";
                stimStruct(1+3*(i-1)+3).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+3).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+3*(i-1)+3).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                             num2str(baseline_lumi,'%.2f'),...
                                             num2str(PostG,'%.2f'),...
                                             }) ...
                    );
            end
            % Back to standby luminance
            stimStruct(end).NameAttribute         = 'StandbyLuminance';
            stimStruct(end).RelativeTimeAttribute = []; % later
            stimStruct(end).HermesSyncAttribute   = "false";
            stimStruct(end).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f')...
                                            }) ...
                    );


            app.CurrentLogStruct.Sequence.Stimulus = stimStruct;
            
%             Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
% During PCO recording, pre-grating duration is set equal to grating 
% duration.
% Both the pre-grid baseline and the grating will trigger Hermes at their 
% beginning.
% Set PCO acquisition duration and number of frames.
% Right now, no frames are acquired with PCO during post grid baseline.
%                 PcoSetForStimulation(app,GridT,2*n,2*Bt)
                PcoSetForStimulation(app,GridT,2*n,2*Bt)
%                 Create a message for Hermes
%                 HermesMsg = HermesMsgMake(app,'o');
%                 HermesMsgMake(app,'mod','o',app.PcoNumberFramesEditField.Value/(2*n));
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/(2*n))
%                 app.ModeDropDown.Value = 'camAc';
                app.TriggerDropDown.Value = 'phototransistor';
% Set the parameters that will be saved.
                app.PcoMeta.StimInfo.StimType = 'Grid';
                app.PcoMeta.StimInfo.nTrials = 2*n; % acquisition is triggered also for baseline.
                app.PcoMeta.StimInfo.FramesPerTrial = app.PcoNumberFramesEditField.Value/2/n;
                app.PcoMeta.StimInfo.Orientation = num2str(Angle);
                app.PcoMeta.StimInfo.Direction = '';
                app.PcoMeta.StimInfo.StimF = 1/(2*GridT+PostG);
                app.PcoMeta.StimInfo.Baseline_bool = true;
                app.PcoMeta.StimInfo.DeadTimeBetweenTrials = GridT - ...
                    app.PcoMeta.StimInfo.FramesPerTrial / app.PcoSelectedFreqEditField.Value ;
            end

            TotStimDur = Bt + (PreG+GridT+PostG) * n;
            callVisStim( app, 'GridPresent', ParameterVector, TotStimDur)
        end

        % Button pushed function: RecptiveFieldGo
        function RecptiveFieldGoButtonPushed(app, event)

            % GAB, 23-03-2022

            
% Nov 25, 2013
% p define the period of the alternation (ON and OFF)
% Bt defines the baseline_txt spent in the baseline_txt equiluminant gray computed
% as (Blumi+Slumi)/2 if a calibration LUT is active.
            
% May 2017.
% This code has been modified to allow the creation of the checkerboad stim
% on the VR googles.
            
% If the "PCO while stimulating" box is checked, Hermes must be also 
% connected with serial communication.
% It is better to do this check here, before disabling the GO buttons and 
% writing any logs.
%             if app.PcoWhileStimulusCk.Value && (~app.HermesSerialFlag ||...
%                     ~app.UdpConnectionFlag)
%             	uialert(app.EscherMasterFig,['To record with PCO while stimulating, both',...
%                  'Hermes and UDP-slave must be connected.'],'Error')
%              return
%             end

            
% load parameters in local variables
            Blumi = app.FSbaselineL.Value; % baseline luminance
            Slumi = app.FSflashL.Value; % flash luminance
            Bt = app.initialDelay.Value; % an initial delay (just once)
            St = app.FlashDuration.Value; % The duration of a single flash
            p = app.FlashPeriod.Value; % time between each flash
            n = app.FlashRepetitions.Value;   % number of repetitions
            nrows = app.rowsEditField.Value;
            ncols = app.columnsEditField.Value;
            app.UIAxes.XLim = [0 ncols];
            app.UIAxes.YLim = [0 nrows];
            app.UIAxes.XTick = 0:ncols;
            app.UIAxes.YTick = 0:nrows;
            drawnow
            
            n_spots = nrows*ncols;
            if app.randomizeorderCheckBox.Value
                indices = randperm(n_spots);
            else
                indices = 1:n_spots;
            end

            % Writing the log file.
            % Writing the log file.
            % General log struct
            app.CurrentLogStruct = PrepareLogStruct(app,'Flashes_ReceptiveField');
            % Allocate visual stimuli elements
            % - Initial baseline
            % - For each cycle, we have 2 reversals.
            % - Final baseline.
            n_stimuli = 1 + n*nrows*ncols + 1;
            placeholder = cell(n_stimuli,1);
            stimStruct = struct('NameAttribute',placeholder, ...
                  "RelativeTimeAttribute",placeholder, ...
                  "StimParam",placeholder);
            % Initial "delay"
            stimStruct(1).NameAttribute         = 'InitialDelay';
            stimStruct(1).RelativeTimeAttribute = []; % later
            stimStruct(1).HermesSyncAttribute   = string(app.SendTTLwhenbaselinestartsCheckBox.Value);
            stimStruct(1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f'),...
                                            num2str(app.initialDelay.Value,'%.2f'),...
                                            }) ...
                    );

            % Flashes
            order = repmat(indices,1,n);
            for i=2:n_stimuli-1
                stimStruct(i).NameAttribute         = "CheckerboardReversal";
                stimStruct(i).RelativeTimeAttribute = []; % later
                stimStruct(i).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(i).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "BlackLuminance_cdm2", ...
                                            "FlashLuminance_cdm2", ...
                                            "FlashDuration_s", ...
                                            "FlashPeriod_s", ...
                                            "FlashPosition", ...
                                            "Rows_x_Columns", ...
                                            },...
                                         'ValueAttribute',{ ...
                                            num2str(Blumi,'%.2f'), ...
                                            num2str(Slumi,'%.2f'),...
                                            num2str(St,'%.4f'),  ...
                                            num2str(p,'%.4f'),  ...
                                            num2str(order(i),'%i'),  ...
                                            sprintf('%i x %i',nrows,ncols)  ...
                                            }) ...
                    );
            end
            % Back to standby luminance
            stimStruct(end).NameAttribute         = 'StandbyLuminance';
            stimStruct(end).RelativeTimeAttribute = []; % later
            stimStruct(end).HermesSyncAttribute   = "false";
            stimStruct(end).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f')...
                                            }) ...
                    );
            app.CurrentLogStruct.Sequence.Stimulus = stimStruct;
            
            ParameterVector = num2cell([Blumi;Slumi;Bt;St;p;n;nrows;ncols;indices(:)])';
            
% Hermes has to trigger the PCO acquisition.
% PCO PARAMETERS PREPARATION PART.
            if app.PcoWhileStimulusCk.Value == 1
% Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,p,n*nrows*ncols,2*Bt)
                
% Create a message for Hermes. A check is not necessary because if PCO is
% used, Hermes has to be connected.
% HermesMsg = HermesMsgMake(app,'o',IdleTime);
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/n/nrows/ncols)
                app.TriggerDropDown.Value = 'phototransistor';
% Set the parameters that will be saved.
                app.PcoMeta.StimInfo.StimType = 'flash receptive field';
                app.PcoMeta.StimInfo.nTrials = n;
                app.PcoMeta.StimInfo.FramesPerTrial = app.PcoNumberFramesEditField.Value/n/nrows/ncols;
                app.PcoMeta.StimInfo.Orientation = '';
                app.PcoMeta.StimInfo.Direction = '';
                app.PcoMeta.StimInfo.StimF = 1/p;
            end
            
            TotStimDuration = Bt + p*n*nrows*ncols;
            callVisStim( app, 'ReceptiveFieldPresent', ParameterVector, TotStimDuration)
        end

        % Button pushed function: calblack_btn
        function calblack_btn_Callback(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'calibration'},{'b'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
%                 return
            else
                % Starts the OpenGL session if in single screen mode
                if app.OneScreenMode_ck.Value
                    background = app.StandbyL.Value;
                    OpenScreen(background)
                end
                HermesRectCalc(app)
                BaselineColor = cast([[app.white;app.white;app.white], [0;0;0]], app.ScreenBitDepth);
                BaselineScreen = [app.screenRect; app.HermesRect]';
                
                Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                Screen('Flip', app.w);
                
% If Hermes is connected to Escher with serial communication, send a
% message to start calibration.
% If Hermes is connected and so is UDP, a message to Hermes is sent in the
% UDP datareceived callback.
                if app.HermesSerialFlag
                    HermesMsgMake(app,'cal','b')
                end
            end
        end

        % Button pushed function: calwhite_btn
        function calwhite_btn_Callback(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'calibration'},{'w'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
%                 return
            else
                % Fill the screen with white
                if app.OneScreenMode_ck.Value
                    background = app.StandbyL.Value;
                    OpenScreen(background)
                end
                
                HermesRectCalc(app)
                BaselineColor = cast([[0;0;0], [app.white;app.white;app.white]], app.ScreenBitDepth);
                BaselineScreen = [app.screenRect; app.HermesRect]';
    
                Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                Screen('Flip', app.w);
                
% If Hermes is connected to Escher with serial communication, send a
% message to start calibration.
% If Hermes is connected and so is UDP, a message to Hermes is sent in the
% UDP datareceived callback.
                if app.HermesSerialFlag
                    HermesMsgMake(app,'cal','w')
                end
            end

        end

        % Value changed function: FlashFastN
        function FlashFastN_Callback(app, event)
            FlashFastMat = app.FlashFastTable.Data;
            if app.FlashFastN.Value > size(FlashFastMat,1)
                app.FlashFastN.Value = size(FlashFastMat,1);
            end
            setnumber = app.FlashFastN.Value;
            
%             Set the flash luminance
            app.FSflashL.Value = FlashFastMat(setnumber,1);
%             Call the corresponding callback
            FSflashLValueChanged(app,[])
            
%             Set the baseline luminance
            app.FSbaselineL.Value = FlashFastMat(setnumber,2);
%             Call the corresponding callback
            FSbaselineLValueChanged(app,[])
        end

        % Button pushed function: LoadScreenFileButton
        function LoadScreenFileButtonPushed(app, event)
            
            def_folder = [app.escherAppFolder, filesep, 'settings', filesep];
            
            [file,path] = uigetfile({'*.dat;*.csv;*.txt',...
                'Text files (*.dat,*.csv,*.txt)';...
                '*.*',  'All Files (*.*)'}, 'Select a File', def_folder);


            if file==0
                return
            end
            figure(app.EscherMasterFig)
            
            LoadScreenFileFunction(app,file,path)
            
        end

        % Button pushed function: RefreshStBy
        function RefreshStByButtonPushed(app, event)
            if app.UdpConnectionFlag
                % refresh parameters first. This is needed if blue mode has
                % been activated.
                msg = ScreenParameterMsg(app);
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
                % actually tell the Slave to change standby lumi.
                msg=strjoin([{app.MasterMac.Value},{'background'},{num2str(app.StandbyL.Value)}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
            else
                % colormask for blue mode
                if app.BLUEMODEButton.Value
                    colormask = [0;0;1];
                else
                    colormask = [1;1;1];
                end
                
                ard_flag = app.ard_ck.Value;
                Blumi = app.StandbyL.Value;
                if app.CalibratedButton.Value
                    [Blumi,app.StandbyL.Value] = Lumi2Escher(Blumi,app.white,app.ScreenFunc);
                end
                Blumi = app.white*Blumi;
                if ard_flag
                    BaselineColor = cast([Blumi.*colormask, [0;0;0]], app.ScreenBitDepth);
                    HermesRectCalc(app)
                    cellRects = [app.screenRect; app.HermesRect]';
                else
                    BaselineColor = cast(Blumi.*colormask, app.ScreenBitDepth);
                   	cellRects = app.screenRect;
                end
                Screen('FillRect', app.w, BaselineColor, cellRects); % paints the rectangle (entire screen)
                Screen('Flip', app.w);
            end
        end

        % Button pushed function: FlashFastLoadTable
        function FlashFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','FlashStimuliTable.csv');
            if file==0
                return
            end
%             StimTable = readtable([path, file]);
%             app.FlashFastTable.Data = StimTable;
            app.FlashFastTable.Data = readmatrix([path, file]);
        end

        % Value changed function: CheckFastN
        function CheckFastNValueChanged(app, event)
            CheckFastMat = app.ContrastFastTable.Data;
            if isempty(CheckFastMat)
                app.CheckFastN.Value = 1;
                app.CheckFastN.Enable = 'off';
            else
                app.CheckFastN.Enable = 'on';
                if app.CheckFastN.Value > size(CheckFastMat,1)
                    app.CheckFastN.Value = max(1,size(CheckFastMat,1));
                end
                setnumber = app.CheckFastN.Value;
                
                %             Set the flash luminance
                app.CheckContrast.Value = CheckFastMat(setnumber,1);
                %             Set the baseline luminance
                app.CheckLumiMean.Value = CheckFastMat(setnumber,2);
                
                UpdateLumiAndContrast(app,0)
            end
            
        end

        % Button pushed function: CheckFastLoadTable
        function CheckFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','ContrastStimuliTable.csv');
            if file==0
                return
            end
            app.ContrastFastTable.Data = readmatrix([path, file]);
        end

        % Selection changed function: CalModeRBGroup
        function CalModeRBGroupSelectionChanged(app, event)
%             Depending on whether the calibration is selected or not, the
%             unit of measurement of the luminance is changed.
            if app.CalibratedButton.Value
                % If a calibration is available, we need the luminance to be
                % expressed as cd/m2
                mystring = 'cd/m²';
                app.CheckLumiB.Visible      = 'on';
                app.CheckLumiW.Visible      = 'on';
                app.CheckLumiMean.Visible   = 'on';
                app.FSbaselineL.Visible     = 'on';
                app.FSflashL.Visible        = 'on';
                app.CheckLumiBEsch.Editable     = 'off';
                app.CheckLumiWEsch.Editable     = 'off';
                app.CheckLumiMeanEsch.Editable  = 'off';
                app.FSbaselineLEsch.Editable    = 'off';
                app.FSflashLEsch.Editable       = 'off';
                
                % Also update the standby luminance value displayed on the
                % user interface, if the screen is open
                if app.ScreenOpenFlag
                    value_01 = app.StandbyL.Value;
                    app.StandbyL.Value = Escher2Lumi(value_01,app.white,app.ScreenFunc);
                end
                % Also, allow for BLUE MODE activation only if a
                % calibration for blue light is available in the selected
                % Screen File
                if isempty(app.ScreenFuncBlue)
                    app.BLUEMODEButton.Enable = 'off';
                    app.BLUEMODEButton.Value = 0;
                    app.BLUEMODEButtonValueChanged([])
                else
                    app.BLUEMODEButton.Enable = 'on';
                end
                             
                
            else
%                 Otherwise, we set it to be (0-1)
                mystring = '(0-1)';
                app.CheckLumiB.Visible      = 'off';
                app.CheckLumiW.Visible      = 'off';
                app.CheckLumiMean.Visible   = 'off';
                app.FSbaselineL.Visible     = 'off';
                app.FSflashL.Visible        = 'off';
                app.CheckLumiBEsch.Editable     = 'on';
                app.CheckLumiWEsch.Editable     = 'on';
                app.CheckLumiMeanEsch.Editable  = 'on';
                app.FSbaselineLEsch.Editable    = 'on';
                app.FSflashLEsch.Editable       = 'on';
                
                % Also update the standby luminance value displayed on the
                % user interface, if the screen is open
                if app.ScreenOpenFlag
                    value_cdm2 = app.StandbyL.Value;
                    app.StandbyL.Value = Lumi2Escher(value_cdm2,app.white,app.ScreenFunc);
                end

                % You can always actiavte BLUE MODE in uncalibrated mode
                app.BLUEMODEButton.Enable = 'on';
            end
            set(app.LabelLumi_1, 'Text', mystring)
            
            % change the in-use transfer function 
            SelectScreenFunctionFcn(app,app.BLUEMODEButton.Value)
%             ScreenFuncTxtValueChanged(app, event)
        end

        % Value changed function: CheckLumiB, CheckLumiBEsch
        function CheckLumiBValueChanged(app, event)
            UpdateLumiAndContrast(app,1)
        end

        % Value changed function: CheckLumiW, CheckLumiWEsch
        function CheckLumiWValueChanged(app, event)
            UpdateLumiAndContrast(app,1)
        end

        % Value changed function: ScreenWidth
        function ScreenWidthValueChanged(app, event)
            SetScreenSizeAngle(app)
            PixelAngleCalc(app)
        end

        % Value changed function: ScreenHeight
        function ScreenHeightValueChanged(app, event)
            SetScreenSizeAngle(app)
        end

        % Value changed function: CheckLumiMean, CheckLumiMeanEsch
        function CheckLumiMeanValueChanged(app, event)
            UpdateLumiAndContrast(app,0)
        end

        % Value changed function: CheckContrast
        function CheckContrastValueChanged(app, event)
            UpdateLumiAndContrast(app,0)
        end

        % Value changed function: GridOrientation
        function GridOrientationValueChanged(app, event)
            
        end

        % Value changed function: FSbaselineL
        function FSbaselineLValueChanged(app, event)
            if app.CalibratedButton.Value
                [app.FSbaselineLEsch.Value,TrueFSbaselineL] = Lumi2Escher(app.FSbaselineL.Value,app.white,app.ScreenFunc);
                app.FSbaselineL.Value = TrueFSbaselineL;
            end
        end

        % Value changed function: FSflashL
        function FSflashLValueChanged(app, event)
            if app.CalibratedButton.Value
                [app.FSflashLEsch.Value,TrueFSflashL] = Lumi2Escher(app.FSflashL.Value,app.white,app.ScreenFunc);
                app.FSflashL.Value = TrueFSflashL;
            end
        end

        % Value changed function: OrientFastN
        function OrientFastNValueChanged(app, event)
            if ~isempty(app.OrientFastTable.Data)
                OrientFastMat = app.OrientFastTable.Data;
                if app.OrientFastN.Value > size(OrientFastMat,1)
                    app.OrientFastN.Value = max(size(OrientFastMat,1),1);
                end
                setnumber = app.OrientFastN.Value;
                
                %             Set the orientation
                app.GridOrientation.Value = OrientFastMat(setnumber,1);
                GridOrientationValueChanged(app,[])
            end
        end

        % Value changed function: FlashFastTotN
        function FlashFastTotNValueChanged(app, event)
            StimTotN = app.FlashFastTotN.Value;
            TableSize = size(app.FlashFastTable.Data,1);
            
            if StimTotN > TableSize
%             A number of rows are added to the table.
                if app.logspacedFlashCk.Value && app.includezeroFlashCk.Value
                    minL = 0;
                else
                    minL = app.FlashFastMinL.Value;
                end
%                 The extra rows are filled with a blank stimulus.
                app.FlashFastTable.Data = vertcat(app.FlashFastTable.Data,...
                    horzcat((TableSize+1:StimTotN)', repmat(minL,StimTotN-TableSize,2)));
            else
%                 The table is shortened.
                app.FlashFastTable.Data = app.FlashFastTable.Data(1:StimTotN,:);
            end
        end

        % Callback function
        function RandomizeButtonPushed(app, event)
%             If the table is empty, this function is just executed. If
%             it's not empty, a confirmation will be prompted.
            if ~isempty(app.ExperimentTable.Data)
                answ = uiconfirm(app.EscherMasterFig, 'This will delete previously generated table. Do you confirm?', 'Warning');
                switch answ
                    case 'Cancel'
                        return
                end
            end
            
%             Get some dimensions.
            SessionLength = app.ExpListFlashCk.Value*size(app.FlashFastTable.Data,1)+...
                app.ExpListCheckCk.Value*size(app.ContrastFastTable.Data,1)+...
                app.ExpListGratingsOrientCk.Value*size(app.OrientFastTable.Data,1);
            tableN = SessionLength * app.ExpListSessionN.Value;
            app.ExperimentTable.Data = cell(tableN,4);
            StartNum = app.ExpListFirstNum.Value;
            
            for i = 1:app.ExpListSessionN.Value
                if app.ExpListFlashCk.Value
                    FlashTmp = cell(size(app.FlashFastTable.Data,1), 2);
                    FlashTmp(:,1) = repmat({'flash'},size(app.FlashFastTable.Data,1),1);
                    FlashTmp(:,2) = num2cell(1:size(app.FlashFastTable.Data,1));
                else
                    FlashTmp = [];
                end
                
                if app.ExpListCheckCk.Value
                    CheckerTmp = cell(size(app.ContrastFastTable.Data,1), 2);
                    CheckerTmp(:,1) = repmat({'checkerboard'},size(app.ContrastFastTable.Data,1),1);
                    CheckerTmp(:,2) = num2cell(1:size(app.ContrastFastTable.Data,1));
                else
                    CheckerTmp = [];
                end
                
                if app.ExpListGratingsOrientCk.Value
                    GridTmp = cell(size(app.OrientFastTable.Data,1), 2);
                    GridTmp(:,1) = repmat({'grid'},size(app.OrientFastTable.Data,1),1);
                    GridTmp(:,2) = num2cell(1:size(app.OrientFastTable.Data,1));
                else
                    GridTmp = [];
                end
                
                sessionMat = [FlashTmp; CheckerTmp; GridTmp];
                
                permutations = randperm(SessionLength);
                sessionMat = sessionMat(permutations,:);
                
                app.ExperimentTable.Data(1+SessionLength*(i-1) : SessionLength*i, [2 3]) = sessionMat;
            end
            
            
%             Give a number to each experiment, starting from
%             ExpListFirstNum.
            app.ExperimentTable.Data(:,1) = num2cell(StartNum : tableN+StartNum-1);
            app.ExpListCurrentIdx=1;
%             The first experiment has to start from the ExpListFirstNum.
            app.ExpListCurrentNum.Value = StartNum;
%             The first row in app.ExpListColors is the background.
            app.ExperimentTable.BackgroundColor = app.ExpListColors(1,:) .* ones(tableN,3);
%             The second row in app.ExpListColors is the current exp.
            app.ExperimentTable.BackgroundColor(1,:) = app.ExpListColors(2,:);
            
            app.ExpListGo.Enable = 1;
            
        end

        % Callback function
        function ExperimentTableCellSelection(app, event)
            app.ExpListSelectedIndices = event.Indices;
            app.ExpListDeleteRows.Enable = 1;
            app.ExpListAddRows.Enable = 1;
        end

        % Callback function
        function CopytableButtonPushed(app, event)
            % To copy the data from the table of zebrasays to the clipboard
            tablesize = size(app.ExperimentTable.Data);
            
            % Created a function to copy data to clipboard from outtable.
            % selectionIndices is a 4-element vector containing the
            % upper-left corner and the bottom-right corner indices
            SelTab2Clip(app,app.ExperimentTable,[1 1 tablesize(1) tablesize(2)])
        end

        % Callback function
        function ExpMoveUpPushed(app, event)
            %             The whole selected part is moved of one row up
            SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
            %             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1 && (SelRowIndices(1) > app.ExpListCurrentIdx+1 ||...
                    SelRowIndices(2) < app.ExpListCurrentIdx)
                %                 shift a subset of the array circularly to get to the
                %                 app.ExperimentTable.Data(SelRowIndices(1)-1 : SelRowIndices(2), 2:end) =...
                %                     circshift(app.ExperimentTable.Data(SelRowIndices(1)-1 : SelRowIndices(2), 2:end), -1, 1);
                app.ExperimentTable.Data(SelRowIndices(1)-1:SelRowIndices(2), 2:end) =...
                    app.ExperimentTable.Data([SelRowIndices(1):SelRowIndices(2), SelRowIndices(1)-1], 2:end);
                
                %                 The same must be done for colors
                %                 app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :) =...
                %                     circshift(app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :), -1, 1);
                app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :) =...
                    app.ExperimentTable.BackgroundColor([SelRowIndices(1):SelRowIndices(2), SelRowIndices(1)-1], :);
                
                %                 Then the index of current experiment has to be changed.
                %                 Also the number corresponding to current experiment is
                %                 going to change.
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx - 1;
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value - 1;
                elseif app.ExpListCurrentIdx == SelRowIndices(1) - 1
                    app.ExpListCurrentIdx = SelRowIndices(2);
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + (SelRowIndices(2)-SelRowIndices(1)+1);
                end
            end
        end

        % Callback function
        function ExpMoveDownButtonPushed(app, event)
            %             The whole selected part is moved of one row up
            SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
            %             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1
                %                 shift a subset of the array circularly to get to the
                %                 app.ExperimentTable.Data(SelRowIndices(1) : SelRowIndices(2)+1, 2:end) =...
                %                     circshift(app.ExperimentTable.Data(SelRowIndices(1) : SelRowIndices(2)+1, 2:end), 1, 1);
                app.ExperimentTable.Data(SelRowIndices(1):SelRowIndices(2)+1, 2:end) = ...
                    app.ExperimentTable.Data([SelRowIndices(2)+1, SelRowIndices(1):SelRowIndices(2)], 2:end);
                
                %                 The same must be done for colors
                app.ExperimentTable.BackgroundColor(SelRowIndices(1) : SelRowIndices(2)+1, :) =...
                    circshift(app.ExperimentTable.BackgroundColor(SelRowIndices(1) : SelRowIndices(2)+1, :), 1, 1);
                
                %                 Then the index of current experiment has to be changed.
                %                 Also the number corresponding to current experiment is
                %                 going to change.
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx + 1;
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + 1;
                elseif app.ExpListCurrentIdx == SelRowIndices(2) + 1
                    app.ExpListCurrentIdx = SelRowIndices(1);
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + (SelRowIndices(2)-SelRowIndices(1)+1);
                end
            end
        end

        % Callback function
        function ExpListDeleteRowsButtonPushed(app, event)
            answ = uiconfirm(app.EscherMasterFig,'This will delete all the selected columns','Warning','Options',{'Delete','Cancel'});
            if strcmp(answ,'Delete')
                SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
                app.ExperimentTable.Data(SelRowIndices(1):SelRowIndices(2),:) = [];
                app.ExperimentTable.BackgroundColor(SelRowIndices(1):SelRowIndices(2), : ) = [];
                app.ExperimentTable.Data(:,1) = num2cell(app.ExpListFirstNum.Value : ...
                    size(app.ExperimentTable.Data, 1)+app.ExpListFirstNum.Value-1);
                
%                 If the current experiment is in the selected rows
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
%                     If current experiment is not the last row
                    if app.ExpListCurrentIdx < size(app.ExperimentTable.Data, 1)
%                         The index of the first deleted row becomes the
%                         index of the current experiment.
                        app.ExpListCurrentIdx = SelRowIndices(1);
                        app.ExperimentTable.BackgroundColor(app.ExpListCurrentIdx,:) = app.ExpListColors(2,:);
%                         If the current experiment is the last row OR if
%                         the selection encompassing the current
%                         experiment also reaches the end of the table.
                    elseif app.ExpListCurrentIdx == size(app.ExperimentTable.Data, 1) ||...
                            SelRowIndices(2) == size(app.ExperimentTable.Data, 1)
                        app.ExpListCurrentIdx = [];
                        app.ExpListGo.Enable = 0;
                    end
%                     If the selected rows are before the current
%                     experiment.
                elseif app.ExpListCurrentIdx > SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx - (SelRowIndices(2)-SelRowIndices(1)+1);
                end
                app.ExpListCurrentNum.Value = app.ExperimentTable.Data{app.ExpListCurrentIdx,1};
                app.ExpListDeleteRows.Enable = 0;
                app.ExpListAddRows.Enable = 0;
            end
        end

        % Callback function
        function ExpListGoPushed(app, event)
%             Current Index is the index of the current experiment.
            currIdx = app.ExpListCurrentIdx;
%             Current FastNumber is the number of the current experiment on
%             the table (it's the same as Current Index ONLY if the
%             Experiment Table starts from experiment n. 1.
            currFastNum = app.ExperimentTable.Data{currIdx,3};
            
%             If the pupil preview while acquiring is checked
            if app.PupilWhileStimulating.Value && app.UdpConnectionFlag
                StartPreviewButtonPushed(app, [])
            end
            
            switch app.ExperimentTable.Data{currIdx,2}
                case 'flash'
%                     Changes the stimulus
                    app.FlashFastN.Value = currFastNum;
                    FlashFastN_Callback(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);
                    
%                     Starts the stimulation
                    GoFlash_Callback(app,[])
                case 'checkerboard'
%                     Changes the stimulus
                    app.CheckFastN.Value = currFastNum;
                    CheckFastNValueChanged(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);
                    
%                     Starts the stimulation
                    GoCheck_Callback(app,[])
                case {'grid','grating'}
%                     Changes the stimulus
                    app.OrientFastN.Value = currFastNum;
                    OrientFastNValueChanged(app,[])
                    
%                     Set maximum contrast
                    app.CheckFastN.Value = size(app.ContrastFastTable.Data,1);
                    CheckFastNValueChanged(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);

%                     Starts the stimulation
                    OrientFastNValueChanged(app,[])
                    GoGrid_Callback(app,[])
            end
            
%             The current row turns of the fourth color (green)
            app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(4,:);
            
%             Updates the index of the new current exp and it's color in
%             the table.
            if currIdx ~= size(app.ExperimentTable.Data,1)+1
                currIdx = currIdx + 1;
                app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(2,:);
            else
                currIdx=[];
            end
            app.ExpListCurrentIdx = currIdx;
            
%             Updates the current number shown in the GUI.
            app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + 1;
        end

        % Callback function
        function ExpListAddRowsButtonPushed(app, event)
            if isempty(app.ExperimentTable.Data)
                app.ExperimentTable.Data = cell(1,length(app.ExperimentTable.ColumnEditable));
                app.ExperimentTable.BackgroundColor(1,:) = app.ExpListColors(2,:);
                app.ExpListCurrentIdx = 1;
                app.ExpListCurrentNum.Value = 1 + app.ExpListFirstNum.Value;
            else
%                 The new row is added just below the last selected row.
                AddedRowIdx = max(app.ExpListSelectedIndices(:,1))+1;
%                 Add an empty cell at the end of the table.
                app.ExperimentTable.Data = [app.ExperimentTable.Data; cell(1, size(app.ExperimentTable.Data,2))];
%                 And then moves it to the position of the added row.
                app.ExperimentTable.Data(AddedRowIdx:end,2:end) = app.ExperimentTable.Data([end,AddedRowIdx:end-1],2:end);
    %             Updates the number (column n. 1) of the last row
                app.ExperimentTable.Data{end,1} = app.ExperimentTable.Data{end-1,1} + 1;
%                 Then the color of the table is updated too.
                app.ExperimentTable.BackgroundColor = [app.ExperimentTable.BackgroundColor; app.ExpListColors(1,:)];
%                 And the color is moved to the position of the added row.
                app.ExperimentTable.BackgroundColor(AddedRowIdx:end,:) = app.ExperimentTable.BackgroundColor([end,AddedRowIdx:end-1],:);
                
%                 Then the color must be updated.
                if AddedRowIdx < app.ExpListCurrentIdx
%                     If the index of the added row is the current
%                     experiment, the added row is green.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(4,:);
%                     And the current experiment index and number is
%                     changed by one.
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx + 1;
                    app.ExpListCurrentNum.Value = app.ExpListFirstNum.Value + 1;
                elseif AddedRowIdx == app.ExpListCurrentIdx && AddedRowIdx < size(app.ExperimentTable.Data,1)
%                     If the position of the added row is just before the
%                     current experiment, this added row becomes the new
%                     current experiment.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(2,:);
%                     The current experiment number and index is not
%                     changed.
%                     The previous current experiment row color must become
%                     white.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx+1,:) = app.ExpListColors(1,:);
                elseif AddedRowIdx == size(app.ExperimentTable.Data,1) && isempty(app.ExpListCurrentIdx)
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(2,:);
                    app.ExpListCurrentIdx = AddedRowIdx;
                    app.ExpListCurrentNum.Value = AddedRowIdx + app.ExpListFirstNum.Value;
                end
            end
            
        end

        % Callback function
        function ExpListFirstNumValueChanged(app, event)
            StartNum = app.ExpListFirstNum.Value;
            app.ExperimentTable.Data(:,1) = num2cell(StartNum : size(app.ExperimentTable.Data,1)+StartNum-1);
        end

        % Button pushed function: generatestimuliFlashBtn
        function generatestimuliFlashBtnPushed(app, event)
%             This is to generate a certain number of stimuli
        minL = app.FlashFastMinL.Value;
        maxL = app.FlashFastMaxL.Value;
        number = app.FlashFastTotN.Value;
        if app.logspacedFlashCk.Value
            if app.includezeroFlashCk.Value
%                 If zero is included, a 0 is added at the beginning of the
%                 stimulus vector.
                StimVect = [0, logspace(log10(minL),log10(maxL),number-1)];
%                 minL is now set to zero, because this is the value that
%                 is going to be used as baseline in the table.
                minL = 0;
            else
                StimVect = logspace(log10(minL),log10(maxL),number);
            end
        else
            StimVect = linspace(minL,maxL,number);
        end
        app.FlashFastTable.Data = horzcat((1:number)',StimVect',repmat(minL,number,1));
        end

        % Value changed function: FlashFastMinL
        function FlashFastMinLValueChanged(app, event)
            % If the logarithmically-spaced check box is checked, the
            % minimum luminance must be more than 0.
            if app.logspacedFlashCk.Value && app.FlashFastMinL.Value==0
                uialert(app.EscherMasterFig,'For logarithmically-spaced stimuli, zero is not a valid input!','Bad input')
                app.FlashFastMinL.Value = event.PreviousValue;
            end
        end

        % Value changed function: CheckFastMinC
        function CheckFastMinCValueChanged(app, event)
            % If the logarithmically-spaced check box is checked, the
            % minimum luminance must be more than 0.
            if app.logspacedCheckCk.Value && app.CheckFastMinC.Value==0
                uialert(app.EscherMasterFig,'For logarithmically-spaced stimuli, zero is not a valid input!','Bad input')
                app.CheckFastMinC.Value = event.PreviousValue;
            end
        end

        % Value changed function: logspacedFlashCk
        function logspacedFlashCkValueChanged(app, event)
            % If the logarithmically-spaced check box is checked, the
            % "include zero" check box must be enabled to allow zero to be
            % inserted as a value in the list.
            if app.logspacedFlashCk.Value
                app.includezeroFlashCk.Enable = 1;
            else
                app.includezeroFlashCk.Enable = 0;
            end
        end

        % Value changed function: logspacedCheckCk
        function logspacedCheckCkValueChanged(app, event)
            % If the logarithmically-spaced check box is checked, the
            % "include zero" check box must be enabled to allow zero to be
            % inserted as a value in the list.
            if app.logspacedCheckCk.Value
                app.includezeroCheckCk.Enable = 1;
            else
                app.includezeroCheckCk.Enable = 0;
            end
        end

        % Button pushed function: generatestimuliCheckBtn
        function generatestimuliCheckBtnButtonPushed(app, event)
            % This is to generate a certain number of stimuli
            minC = app.CheckFastMinC.Value;
            maxC = app.CheckFastMaxC.Value;
            number = app.CheckFastTotN.Value;
            if number > 0
                if app.logspacedCheckCk.Value
                    if app.includezeroCheckCk.Value
                        % If zero is included, a 0 is added at the beginning of the
                        % stimulus vector.
                        StimVect = [0, logspace(log10(minC),log10(maxC),number-1)];
                    else
                        StimVect = logspace(log10(minC),log10(maxC),number);
                    end
                else
                    StimVect = linspace(minC,maxC,number);
                end
                
                contrast = zeros(size(StimVect));
                meanL = contrast;
                for i=1:numel(StimVect)
                    [blk, wht] = LumiContrast2BlackWhite(app.CheckFastMeanL.Value, StimVect(i));
                    if app.CalibratedButton.Value
                        % If in "calibrated mode", fix the luminance
                        % values: some combinations of mean luminance and
                        % contrast cannot be achieved with certain monitor
                        % models. (e.g: no screen can achieve 100% contrast
                        % if the minimum luminance value is not exaclty 0).
                        [~,~,meanL(i),contrast(i)] = FixLumiValues(blk,wht,app.white,app.ScreenFunc);
                    else
                        [~,~,meanL(i),contrast(i)] = FixLumiValues(blk,wht,app.white);
                    end
                end
                app.ContrastFastTable.Data = horzcat(contrast',meanL');
            else
                warning('At least one stim. required')
            end
        end

        % Value changed function: CheckFastTotN
        function CheckFastTotNValueChanged(app, event)
            
        end

        % Button pushed function: generatetable_orientBtn
        function generatetable_orientBtnButtonPushed(app, event)
            % This is to generate a certain number of stimuli
            minA = app.OrientFastMinA.Value;
            maxA = app.OrientFastMaxA.Value;
            number = app.OrientFastTotN.Value;
            StimVect = linspace(minA,maxA,number);
            app.OrientFastTable.Data = StimVect';
        end

        % Button pushed function: savetableButton
        function savetableButtonPushed(app, event)
            % This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            % Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','FlashStimuliTable.csv');
            if file == 0
                return
            end
            % The file is saved as a matrix.
            writematrix(app.FlashFastTable.Data,[path,file]);
        end

        % Button pushed function: CheckFastSaveTable
        function CheckFastSaveTableButtonPushed(app, event)
            % This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            % Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','ContrastStimuliTable.csv');
            if file == 0
                return
            end
            % The file is saved as a matrix.
            writematrix(app.ContrastFastTable.Data,[path,file]);
        end

        % Button pushed function: OrientFastSaveTable
        function OrientFastSaveTableButtonPushed(app, event)
            % This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            % Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','OrientStimuliTable.csv');
            if file == 0
                return
            end
            % The file is saved as a matrix.
            writematrix(app.OrientFastTable.Data, [path, file])
        end

        % Button pushed function: OrientFastLoadTable
        function OrientFastLoadTableButtonPushed(app, event)
            % Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','OrientStimuliTable.csv');
            if file==0
                return
            end
            app.OrientFastTable.Data = readmatrix([path, file]);
        end

        % Button pushed function: GetMasterIPandMACBtn
        function GetMasterIPandMACBtnPushed(app, event)
            ConnParams = GetConnParams;
            app.MasterIp.Value = ConnParams.IP;
            app.MasterMac.Value = ConnParams.MAC;
            app.SubnetMask.Value = ConnParams.SubMask;
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Button pushed function: StartUdpBtn
        function StartUdpBtnButtonPushed(app, event)
            if ~app.UdpConnectionFlag
                
                % An UDP connection is not running. We want to start it.
                
                % First, check that IP and MAC addresses are valid
                if isempty(app.MasterMac.Value)||...
                        (strcmp(app.UDPBroadcastDropDown.Value, app.UDPBroadcastDropDown.Items{1}) && strcmp(app.SlaveIp.Value,'0.0.0.0'))||...
                        strcmp(app.MasterIp.Value,'0.0.0.0')
                    uialert(app.EscherMasterFig,'Invalid Addresses','Error')
                    return
                end
                
                % If the screen is open the app will ask for confirmation 
                % to start working as master. Indeed, when connected to a
                % Slave, the screen is opened by the Slave itself, se we
                % need to close it before proceeding.                
                if app.ScreenOpenFlag
                    selection = uiconfirm(app.EscherMasterFig, ['The app will start now working as master.' ...
                        'This will close any open screen. Do you confirm?'], 'Warning');
                    if strcmp(selection,'OK')
                        OpenScreenBtn_Callback(app, [])
                    else
                        return
                    end
                end
                
                % Change the status text, while waiting for connection
                % Also, the button for starting the connection is being
                % disabled. When the app receives an answer from the UDP
                % connection OR when the timer times out.
                EnableGoBtn(app,0)
                
                % All the connection parameters cannot be changed while the
                % connection is ongoing.
                set([app.MasterIp,app.MasterMac,app.MasterPort,app.SlaveIp,app.SlavePort,app.SubnetMask],'Editable',0);
                app.ConnectionStatus.Value = 'Connecting';
                
                % Create the actual "udpport" object.
                if ishandle(app.UdpObj); delete(app.UdpObj); end
                try
                    app.UdpObj = udpport("datagram","IPV4", ...
                        "LocalHost",app.MasterIp.Value, ...
                        "Timeout",app.TimeoutTime.Value, ...
                        "EnablePortSharing",false, ...
                        "LocalPort",app.MasterPort.Value, ...
                        "OutputDatagramSize", 2048);
                catch ERR
                    if strcmp('Unable to bind to LOCALHOST and LOCALPORT. Verify that the LOCALPORT is not in use, and that LOCALHOST and LOCALPORT are valid for IPADDRESSVERSION "IPV4".',...
                            ERR.message)
                        app.UdpObj = udpport("datagram","IPV4", ...
                        "LocalHost",app.MasterIp.Value, ...
                        "Timeout",app.TimeoutTime.Value, ...
                        "EnablePortSharing",false, ...
                        "OutputDatagramSize", 2048);
                        
                        app.MasterPort.Value = app.UdpObj.LocalPort;
                        app.MasterPort.BackgroundColor = [1 1 0]; % highlight the GUI element
                        
                        fprintf('\nLocal UDP Port Changed! Port in use:\n\t%i\n',app.UdpObj.LocalPort)
                    end
                end
                % I don't think we'll use these values later, but just in case...
                str = struct("RemoteHost", app.SlaveIp.Value, ...
                                "RemotePort", app.SlavePort.Value, ...
                                "LocalPort", app.MasterPort.Value);
                app.UdpObj.UserData = str; 
                
                % Assign "UdpDataReceivedCallback" as callback function.
                % Whenever a datagram (message) is received, it will trigger
                % the execution of UdpDataReceivedCallback.
                configureCallback(app.UdpObj,"datagram",1,@(obj,ev) UdpDataReceivedCallback(app,obj,ev))
%                 pause(.5)

                % Prepare and send the message
                msg=strjoin([{'start'},{app.MasterIp.Value},{app.MasterMac.Value},...
                        {num2str(app.MasterPort.Value)},{'1'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
            else
                % If the connection was ongoing, the button stops it.
                if ishandle(app.UdpObj)
                    configureCallback(app.UdpObj,"off")
                    delete(app.UdpObj)
                end

                % The button is reenabled, if it was not.
                EnableGoBtn(app,1)
                % All the connection parameters cannot be changed while the
                % connection is ongoing. So now they can be changed again
                set([app.MasterIp,app.MasterMac,app.MasterPort,app.SlaveIp,app.SlavePort,app.SubnetMask],'Editable',1);%                 If for some reason the timer for UDP timeout is running,
                % Stop the timeout timer.
                if isvalid(app.UdpTimeoutTimer)
                    stop(app.UdpTimeoutTimer)
                end
                % The connection is undone. Change the GUI elements accordingly.
                % Revert the button back to its status. Also the flag value
                % is set back to 0.
                app.StartUdpBtn.Text = 'Start Connection';
                app.ConnectionStatus.Value = 'Disconnected.';
                app.ResendScreenParams.Enable = 1;
                app.UDPConnectionLamp.Color = [1 0 0];
                app.UdpConnectionFlag = 0;
            end
        end

        % Button pushed function: ResendScreenParams
        function ResendScreenParamsButtonPushed(app, event)
            msg = ScreenParameterMsg(app);
            SendUdpMsg(app,msg,app.TimeoutTime.Value)
        end

        % Button pushed function: DeBusyButton
        function DeBusyButtonPushed(app, event)
            EnableGoBtn(app,1)
        end

        % Value changed function: ScreenDistance
        function ScreenDistanceValueChanged(app, event)
            SetScreenSizeAngle(app)
            PixelAngleCalc(app)
        end

        % Button pushed function: SaveScreenFileButton
        function SaveScreenFileButtonPushed(app, event)
            def_folder = [app.escherAppFolder, filesep, 'settings', filesep];
            
            [file,path] = uiputfile({'*.dat;*.csv;*.txt',...
                'Text files (*.dat,*.csv,*.txt)';...
                '*.*',  'All Files (*.*)'}, 'Save a File', def_folder);
            
            if file==0
                return
            end
            
            T = table;
            
            T.name{1} = app.ScreenNameTxt.Value;
            T.width(1) = app.ScreenWidth.Value;
            T.height(1) = app.ScreenHeight.Value;
            T.formula{1} = app.ScreenFuncGray;
            if ~isempty(app.ScreenFuncBlue)
                T.formula_blue{1} = app.ScreenFuncBlue;
            end
            T.notes{1} = app.ScreenNotesTxt.Value;
            
            writetable(T,[path, file])
        end

        % Value changed function: MasterIp
        function MasterIpValueChanged(app, event)
            if ~ValidateIPv4(app.MasterIp.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.MasterIp.Value = event.PreviousValue;
            end
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Value changed function: SubnetMask
        function SubnetMaskValueChanged(app, event)
            if ~ValidateIPv4(app.SubnetMask.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.SubnetMask.Value = event.PreviousValue;
            end
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Value changed function: SlaveIp
        function SlaveIpValueChanged(app, event)
            if ~ValidateIPv4(app.SlaveIp.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.SlaveIp.Value = event.PreviousValue;
            end
        end

        % Value changed function: MasterMac
        function MasterMacValueChanged(app, event)
            if ~ValidateMac(app.MasterMac.Value)
                uialert(app.EscherMasterFig,'Invalid MAC','Error')
                app.MasterMac.Value = event.PreviousValue;
            end
        end

        % Value changed function: wwTxt
        function wwTxtValueChanged(app, event)
            if app.ScreenOpenFlag && ~app.UdpConnectionFlag
                HermesRectCalc(app)
            end
        end

        % Value changed function: hhTxt
        function hhTxtValueChanged(app, event)
            if app.ScreenOpenFlag && ~app.UdpConnectionFlag
                HermesRectCalc(app)
            end
        end

        % Selection changed function: CueVertPos
        function CueVertPosSelectionChanged(app, event)
            if app.ScreenOpenFlag && ~app.UdpConnectionFlag
                HermesRectCalc(app)
            end
        end

        % Selection changed function: CueHorzPos
        function CueHorzPosSelectionChanged(app, event)
            if app.ScreenOpenFlag && ~app.UdpConnectionFlag
                HermesRectCalc(app)
            end        
        end

        % Value changed function: ScreenFuncTxt
        function ScreenFuncTxtValueChanged(app, event)
            if app.BLUEMODEButton.Value
                app.ScreenFuncBlue = app.ScreenFuncTxt.Value;
            else
                app.ScreenFuncGray = app.ScreenFuncTxt.Value;
            end
            % Transfer function in use
            app.ScreenFunc = app.ScreenFuncTxt.Value;
        end

        % Value changed function: STAstartTxt
        function STAstartTxtValueChanged(app, event)
            if ~ValidateIPv4(app.STAstartTxt.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.STAstartTxt.Value = event.PreviousValue;
            end            
        end

        % Value changed function: STAendTxt
        function STAendTxtValueChanged(app, event)
            if ~ValidateIPv4(app.STAendTxt.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.STAendTxt.Value = event.PreviousValue;
            end
        end

        % Button pushed function: ConnectPCOButton
        function ConnectPCOButtonPushed(app, event)
            PcoInitialize(app)
        end

        % Button pushed function: PcoStartPreviewButton
        function PcoStartPreviewButtonPushed(app, event)
            switch app.PcoStruct.vid.Previewing
                
                case 'off' % the previw is not running. Start it.
                    % Save the trigger mode and change it to 'immediate' 
                    app.PcoStruct.preious_trigger_mode = app.PcoStruct.vid.TriggerType;
                    triggerconfig(app.PcoStruct.vid, 'immediate', 'none', 'none');
 
                    app.PcoStartPreviewButton.Text = 'Stop Preview';
                    
                    % Start the preview
%                     preview(app.PcoStruct.vid,app.PcoStruct.Image);
                    app.PcoStruct.Image = preview(app.PcoStruct.vid);
                    prev_axes = app.PcoStruct.Image.Parent;
                    app.PcoCDataMinEditField.Value = prev_axes.CLim(1);
                    app.PcoCDataMaxEditField.Value = prev_axes.CLim(2);
                    
                case 'on' % the preview is running. Stop it.
                    % Stop the preview
                    stoppreview(app.PcoStruct.vid)
%                     drawnow
                    % Restore the old trigger mode
                    switch app.PcoStruct.preious_trigger_mode
                        
                        case 'immediate'
                            % do nothing
                            
                        case 'hardware'
                            triggerconfig(app.PcoStruct.vid, 'hardware', '', 'ExternExposureStart');
                            app.PcoStruct.cam.IO_2SignalEnableDisable='off';
                            
                    end
                    app.PcoStartPreviewButton.Text = 'Start Preview';        
            end
        end

        % Value changed function: PcoCDataMinEditField
        function PcoCDataMinEditFieldValueChanged(app, event)
            prev_axes = app.PcoStruct.Image.Parent;
            prev_axes.CLim(1) = app.PcoCDataMinEditField.Value;
            
        end

        % Value changed function: PcoCDataMaxEditField
        function PcoCDataMaxEditFieldValueChanged(app, event)
            prev_axes = app.PcoStruct.Image.Parent;
            prev_axes.CLim(2) = app.PcoCDataMaxEditField.Value;

        end

        % Value changed function: ExposureTimeEditField
        function ExposureTimeEditFieldValueChanged(app, event)
            % When PCO camera is armed, the corresponding flag is set.
            if app.PCOconnected
                app.PcoStruct.cam.E2ExposureTime = app.ExposureTimeEditField.Value*1000;
                fprintf('Exposure time changed to %i ms.\n',app.ExposureTimeEditField.Value)
                
                switch app.PcoSyncDropDown.Value
                    case 'Escher-only'
                        % Just estimate the frame rate
                        PCO_updateFrameRate(app);
                    case 'Hermes'
                        % set 'trigger mode' to 'immediate' to acquire some frames to
                        % estimate the max frame rate
                        triggerconfig(app.PcoStruct.vid, 'immediate', 'none', 'none');
                        % estimate the frame rate
                        PCO_updateFrameRate(app);
                        % set it back to 'hardware'
                        triggerconfig(app.PcoStruct.vid, 'hardware', '', 'ExternExposureStart');
                        app.PcoStruct.cam.IO_2SignalEnableDisable='off';
                end
            end
        end

        % Button pushed function: PcoStartAcquisitionButton
        function PcoStartAcquisitionButtonPushed(app, event)
            
            switch app.PcoStartAcquisitionButton.Text
                case 'START ACQUISITION'
                    
                    % get the number of frames to acquire
                    nFrames = round(app.PcoNumberFramesEditField.Value);
                    
                    switch app.PcoStruct.SelSyncMode
                        case 'software'
                            %                     HermesMsg = []; there is no HermesMsg anymore
                        case 'hardware'
                            %                     Create a message to Hermes thay says to perform a
                            %                     series ('s') of a certain number of frames and with 1
                            %                     trial and trial period zero.
                            HermesMsgMake(app,'mod','camAc','s',nFrames,1,0);
                    end
                    
                    % Remove all info that are for stimulation from the PcoMeta.Info
                    % structure
                    app.PcoMeta.StimInfo.StimType = 'none';
                    app.PcoMeta.StimInfo.nTrials = [];
                    app.PcoMeta.StimInfo.FramesPerTrial = [];
                    app.PcoMeta.StimInfo.Orientation = '';
                    app.PcoMeta.StimInfo.Direction = '';
                    app.PcoMeta.StimInfo.StimF = [];
                    app.PcoMeta.StimInfo.Baseline_bool = [];
                    app.PcoMeta.StimInfo.PxlSize = [];
                    
                                        
                    % PcoStartAcuisition also changes the name of the
                    % button
                    PcoStartAcquisition(app)
                    
                case 'STOP ACQUISITION'
                    
                    PcoStopAcquisition(app)
                    
            end


        end

        % Value changed function: PcoSyncDropDown
        function PcoSyncDropDownValueChanged(app, event)
            % Set the maximum frequency, if it is zero then it is set to
            % Inf.
            if app.PcoMaxFreqEditField.Value ~= 0
                app.PcoSelectedFreqEditField.Limits = [0,app.PcoMaxFreqEditField.Value];
            else
                app.PcoSelectedFreqEditField.Limits = [0,Inf];
            end
            
            % Set the synchronization mode.
            switch app.PcoSyncDropDown.Value
                case 'Software (Escher)'
                    % Auto sequence, no trigger is used.
                    app.PcoStruct.SelSyncMode = 'software';
                    % Independent acquisition: only duration is set. Then
                    % the PCO acquires at maximum speed for the duration.
                    app.PcoSelectedFreqEditField.Enable = 0;
                    app.PcoSelectedFreqEditField.Value = app.PcoMaxFreqEditField.Value;
                    if app.PcoStruct.Connected
                        triggerconfig(app.PcoStruct.vid, 'immediate', '', '');
                    end
                    
                case 'Hardware (Hermes)'
                    % hardware trigger, Hermes is needeed
                    app.PcoStruct.SelSyncMode = 'hardware';
                    % Hermes controls the acquisition frequency
                    app.PcoSelectedFreqEditField.Enable = 1;
                    % Configure trigger type and mode
                    if app.PcoStruct.Connected
                        triggermode = 'ExternExposureStart';
                        triggerconfig(app.PcoStruct.vid, 'hardware', '', triggermode);
                        set(app.PcoStruct.vid,'Timeout',20); % 20s timeout
                        app.PcoStruct.cam.IO_2SignalEnableDisable='off';
                    end
                    % If the selected frequency is zero, its value is set
                    % to half the maximum frequency.
                    if app.PcoSelectedFreqEditField.Value == 0
                        app.PcoSelectedFreqEditField.Value = app.PcoMaxFreqEditField.Value/2;
                    end
                    if app.HermesSerialFlag
                        HermesMsgMake(app,'parameters',{'frameP','serial_delay'})
                    end

            end
            
            
            PcoSelectedFreqEditFieldValueChanged(app,[])
        end

        % Value changed function: PcoSelectedFreqEditField
        function PcoSelectedFreqEditFieldValueChanged(app, event)
            app.PcoNumberFramesEditField.Value = fix(app.PcoSelectedFreqEditField.Value*app.PcoDurationEditField.Value);
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','frameP')
            end
        end

        % Button pushed function: RefreshCOMButton
        function RefreshCOMButtonPushed(app, event)
            app.SerialCOMDropDown.Items = cellstr([serialportlist,"NONE"]);
            i=1;
            app.HermesSerialFlag = false;
            while i<length(app.SerialCOMDropDown.Items) && ~app.HermesSerialFlag
                try
                    app.SerialCOMDropDown.Value = app.SerialCOMDropDown.Items{i};
                    HermesConnect(app,app.SerialCOMDropDown.Value)
                catch ME
%                     warning(ME)
                    
                    warning(['error in %s, line %i\n    %s\n' ...
                        'Couldn''t start serial communication on %s port.'],...
                        ME.stack(1).name,ME.stack(1).line,ME.message,app.SerialCOMDropDown.Value)
                end
                i=i+1;
            end
        end

        % Value changed function: SerialCOMDropDown
        function SerialCOMDropDownValueChanged(app, event)
            
            if ~strcmp(app.SerialCOMDropDown.Value,"NONE")
                try
                    HermesConnect(app,app.SerialCOMDropDown.Value)
                    app.HermesSerialFlag = 1;
                catch ME
                    uialert(app.EscherMasterFig,ME.message,'Warning')
                    app.HermesSerialFlag = 0;
                end
            else
                delete(app.HermesSerialObj)
                app.HermesSerialFlag = 0;
            end            
        end

        % Button pushed function: PcoAutoCLimButton
        function PcoAutoCLimButtonPushed(app, event)
            if isfield(app.PcoStruct,'Image')
                limits = quantile(app.PcoStruct.Image.CData,[0.025,0.975],'all');
                app.PcoCDataMinEditField.Value = cast(limits(1),'double');
                app.PcoCDataMaxEditField.Value = cast(limits(2),'double');
                app.PcoHAxes.CLim = limits;
            end
        end

        % Callback function
        function PcoAutoCLimStimButtonPushed(app, event)
            if isfield(app.PcoStruct,'imahStim')
                limits = quantile(app.PcoStruct.imahStim.CData,[0.025,0.975],'all');
                app.PcoCDataStimMinEditField.Value = cast(limits(1),'double');
                app.PcoCDataStimMaxEditField.Value = cast(limits(2),'double');
                app.PcoHAxesStim.CLim = limits;
            end
        end

        % Value changed function: PcoDurationEditField
        function PcoDurationEditFieldValueChanged(app, event)
            app.PcoNumberFramesEditField.Value = fix(app.PcoSelectedFreqEditField.Value*app.PcoDurationEditField.Value);
        end

        % Value changed function: HermesDelaysEditField
        function HermesDelaysEditFieldValueChanged(app, event)
            % This time is rounded to the third decimal digit.
            app.HermesDelaysEditField.Value = round(app.HermesDelaysEditField.Value,3);
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','serial_delay')
            end
        end

        % Value changed function: HermesTtlDurationEditField
        function HermesTtlDurationEditFieldValueChanged(app, event)
            app.HermesTtlDurationEditField.Value = round(app.HermesTtlDurationEditField.Value,3);
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','TTL_time')
            end
        end

        % Callback function
        function HermesLedDurationEditFieldValueChanged(app, event)
            value = round(app.HermesLedDurationEditField.Value,3);
            if value ~= 0 || value<app.HermesTtlDurationEditField.Value
                value = app.HermesTtlDurationEditField.Value;
                uialert(app.EscherMasterFig,'The value must be either >= TTL duration or equal to zero.','Warning')
            end
            app.HermesLedDurationEditField.Value = value;
        end

        % Value changed function: HermesIdleTimeEditField
        function HermesIdleTimeEditFieldValueChanged(app, event)
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','idleTime')
            end
        end

        % Button pushed function: RetinotopyGOButton
        function GoRetinotopy_Callback(app, event)
            % If the "PCO while stimulating" box is checked, Hermes must be
            % also connected.
            
            % load parameters in local variables
            Blumi = app.CheckLumiB.Value;
            Slumi = app.CheckLumiW.Value;
            % gray luminance for equiluminant background.
            %                 Glumi = app.CheckLumiMean.Value;
            % spatial frequency of the checkerboard
            sF = app.spatialFrequency.Value;
            Bt = app.initialDelay.Value;
            % hemi-period. p is the time between a reversal and the next one (2 reversals = 1 period)
            p = 1/app.RetinotopyCheckFreqEditField.Value;
            % drifting speed of the stimulus in degrees/s.
            dS = app.IntrinsicStimBarSpeedEdit.Value;
            % post-grid duration.
            % Remember that one full trial is as long as the travel time of the bar
            % plus this post-grid time.
            PostG = app.RetinotopyPostGTime.Value; % post-grid duration
            % number of repetitions of the stimulus
            n = app.RetinotopyRepetitions.Value;
            
            Glumi = 0;
            
            % The dead time for each trial, expressed in s (originally it was in ms).
            % This time has to be subtracted from the first baseline (the pre-stimulus
            % baseline) to have the actual baseline. The second baseline (the
            % after-stimulus baseline) lasts actually its full duration, but only the
            % duration minus the dead time is acquired by the camera.
            TrialDeadTime = app.DeadTBeforeTrialEditField.Value/1000;
            
            % angle of the stimulus. 0 means vertical bars moving horizontally
            % from left to right
            if strcmp(app.IntrOrientationSwitch.Value,'Horizontal')
                Angle = 0;
            else
                Angle = 90;
            end
            
            % The default grating movement will always be from left to right,
            % then the angle is changed.
            if ismember(app.IntrDirectionSwitch.Value,{'Right-to-left','Top-to-bottom'})
                Angle = Angle + 180;
            end
            
            % Rectangle size in degrees
            RectangleSizeDeg = app.IntrRectSizeTxt.Value;
  
            % Preparing the log file.
            % General log structure
            app.CurrentLogStruct = PrepareLogStruct(app,'retinotopy');
            % Allocate visual stimuli elements
            % - Initial baseline
            % - For each cycle, pre-baseline, bar, post-baseline.
            % - Back to standby.
            n_stimuli = 1 + 3*n + 1;
            placeholder = cell(n_stimuli,1);
            stimStruct = struct('NameAttribute',placeholder, ...
                  "RelativeTimeAttribute",placeholder, ...
                  "StimParam",placeholder);
            % Initial "delay"
            stimStruct(1).NameAttribute         = 'InitialDelay';
            stimStruct(1).RelativeTimeAttribute = []; % later
            stimStruct(1).HermesSyncAttribute   = string(app.SendTTLwhenbaselinestartsCheckBox.Value);
            stimStruct(1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f'),...
                                            num2str(Bt,'%.2f'),...
                                            }) ...
                    );

            % CheckerboardReversal
            for i=1:n
                stimStruct(1+i).NameAttribute         = "CheckerboardReversal";
                stimStruct(1+i).RelativeTimeAttribute = []; % later
                stimStruct(1+i).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+i).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "BlackLuminance_cdm2", ...
                                            "WhiteLuminance_cdm2", ...
                                            "MeanLuminance_cdm2", ...
                                            "SpatialFreq_cyc/deg", ...
                                            "BarSize_deg", ...
                                            "PrePostBaseline_s", ...
                                            "BarTravelTime_s", ...
                                            "BarDriftingSpeed_s", ...
                                            "Orientation_deg" ...
                                            },...
                                         'ValueAttribute',{ ...
                                            num2str(Blumi,'%.2f'), ...
                                            num2str(Slumi,'%.2f'), ...
                                            num2str(Glumi,'%.2f'),...
                                            num2str(sF,'%.4f'),  ...
                                            num2str(RectangleSizeDeg,'%.2f'), ...
                                            num2str(PostG,'%.2f'),...
                                            num2str(app.BartraveltimesEditField.Value,'%.2f'),  ...
                                            num2str(dS,'%.2f'), ...
                                            num2str(Angle,'%.2f')
                                            }) ...
                    );
            end
            % Final standby
            stimStruct(end).NameAttribute         = 'StandbyLuminance';
            stimStruct(end).RelativeTimeAttribute = []; % later
            stimStruct(end).HermesSyncAttribute   = string(app.SendTTLwhenbaselinestartsCheckBox.Value);
            stimStruct(end).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f'),...
                                            }) ...
                    );
            app.CurrentLogStruct.Sequence.Stimulus = stimStruct;
            
            % ParameterVector is the vector of parameters for the 
            % stimulus-presenting function. If UDP is used, it is is passed 
            % as datagram to the slave App.
            ParameterVector = num2cell([Blumi,Slumi,Glumi,sF,Bt,p,Angle,...
                RectangleSizeDeg,dS,PostG,n,TrialDeadTime]);
            
            if Angle==0 || Angle==180
                % vertical bar
                TravelDistance = app.WidthPix.Value;
                dSPx = dS/app.OnePxAngle; % drifting speed in pxls/s
                travelAngle = 2*atand(app.ScreenWidth.Value/2/app.ScreenDistance.Value);
            else
                % horizontal
                TravelDistance = app.HeightPix.Value;
                dSPx = dS/app.OnePxAngle;
                travelAngle = 2*atand(app.ScreenHeight.Value/2/app.ScreenDistance.Value);
            end
            TravelTime = TravelDistance/dSPx;
            disp(['Travel distance = ',num2str(TravelDistance)])
            disp(['Travel time = ',num2str(TravelTime)])
            disp(['PostG = ',num2str(PostG)])
            
            % Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
                % Set PCO acquisition duration and number of frames.
                % The PCO MUST acquire for all the duration of each trial (which is the
                % travel time of the bar, plus the post-grid time) apart from the very last
                % part of the trial (dead time) which is not recorded.
                % This means that here, the exact calculation of the travel time is needed.


                % For this stimulation, the period is equal to the travel time plus a
                % baseline that will be repeated twice - one before the bar travel and one
                % after.
                % A dead time will need to be subtracted from the pre-travel baseline, so.
                % It needs also to be subtracted from the after-travel baseline, but that
                % will be done in the function PcoSetForStimulation.
                PcoSetForStimulation(app,TravelTime+2*PostG-...
                    TrialDeadTime,n,Bt)

                % Create a message for Hermes
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/(n));
                 
                % Set the parameters that will be saved.
                app.PcoMeta = struct;
                app.PcoMeta.StimInfo.StimType = 'runningBar';
                app.PcoMeta.StimInfo.nTrials = n;
                app.PcoMeta.StimInfo.FramesPerTrial = app.PcoNumberFramesEditField.Value/n;
                app.PcoMeta.StimInfo.BaselineTime = 2*PostG;
                app.PcoMeta.StimInfo.DeadTime = TrialDeadTime;
                app.PcoMeta.StimInfo.BarSpeed = dS;
                app.PcoMeta.StimInfo.ScreenSize = travelAngle;
                
                % The dead time (variable "TrialDeadTime") and baseline time (variable
                % "PostG") must be added to info.
                % PostG is repeated both at the beginning and at the end of the
                % stimulation.
                
                switch Angle
                    case 0
                        % Vertical bar moving from left to right.
                        tempO = 'horz';
                        tempD = '+;';
                    case 90
                        % Horizontal bar moving from bottom to top.
                        tempO = 'vert';
                        tempD = '+;';
                    case 180
                        % Vertical bar moving from right to left.
                        tempO = 'horz';
                        tempD = '-;';
                    case 270
                        % Horizontal bar moving from top to bottom.
                        tempO = 'vert';
                        tempD = '-;';
                end
                app.PcoMeta.StimInfo.Orientation = tempO;
                app.PcoMeta.StimInfo.Direction = tempD;
                app.PcoMeta.StimInfo.StimF = 1/p;
            end
            
            TotStimDur = (TravelTime+2*PostG)*n;
            callVisStim( app, 'RetinotopyPresent', ParameterVector, TotStimDur)
        end

        % Value changed function: IntrOrientationSwitch
        function IntrOrientationSwitchValueChanged(app, event)
            if strcmp(app.IntrOrientationSwitch.Value, 'Horizontal')
                app.IntrDirectionSwitch.Items = {'Left-to-right','Right-to-left'};
            else
                app.IntrDirectionSwitch.Items = {'Bottom-to-top','Top-to-bottom'};
            end
            if app.KeepconstantspeedCheckBox.Value
                % update stimulus period
                IntrinsicStimBarSpeedEditValueChanged(app,[]);
            else
                % update bar speed
                BartraveltimesEditFieldValueChanged(app,[]);
            end
        end

        % Value changed function: OpenFieldAnalyzerButton
        function OpenFieldAnalyzerButtonValueChanged(app, event)
            value = event.Value;
            if value
%                 If selected value is 1, it means that the analyzer app
%                 will be opened, and the button must be used to close it
%                 next time it will be pressed.
%                 Here, code to open the analyzer must be placed.
                app.VisualFieldAnalyzerApp = visualFieldAnalyzer2_App(app);
                app.OpenFieldAnalyzerButton.Text = 'Close WideField Analyzer';
            else
                selection = uiconfirm(app.EscherMasterFig,'Do you really want to close the Analyzer?','Warning');
                if strcmp(selection,'OK')
%                     Here code to close it must be placed.
                    delete(app.VisualFieldAnalyzerApp)
                    app.OpenFieldAnalyzerButton.Text = 'Open WideField Analyzer';
                else
                    app.OpenFieldAnalyzerButton.Value = 1;
                    app.OpenFieldAnalyzerButton.Text = 'Close WideField Analyzer';
                end
            end
        end

        % Callback function
        function PcoCDataStimMaxEditFieldValueChanged(app, event)
            app.PcoHAxesStim.CLim(2) = app.PcoCDataStimMaxEditField.Value;
        end

        % Callback function
        function PcoCDataStimMinEditFieldValueChanged(app, event)
            app.PcoHAxesStim.CLim(1) = app.PcoCDataStimMinEditField.Value;
        end

        % Value changed function: HermesLEDcontrolCheckBox
        function HermesLEDcontrolCheckBoxValueChanged(app, event)
            val = double(app.HermesLEDcontrolCheckBox.Value);
            if app.HermesSerialFlag
                HermesMsgSend(app,['solisEnabled=' num2str(val)])
            end
        end

        % Value changed function: BartraveltimesEditField
        function BartraveltimesEditFieldValueChanged(app, event)
            period = app.BartraveltimesEditField.Value;
            switch app.IntrOrientationSwitch.Value
                case 'Vertical'
                    dim = app.screenRect(4);
                case 'Horizontal'
                    dim = app.screenRect(3);
            end
            bar_spatial_period_deg = app.IntrRectSizeTxt.Value;
            bar_spatial_period_pxl = bar_spatial_period_deg/app.OnePxAngle;
            % the center of the bar has to travel the whole screen plus one
            % period, so that we can se the bar appearing from one side and
            % disappearing completely on the other side. If we only
            % consider "dim", half the bar suddenly appears on one side,
            % and it disappears when its center reaches the other side.
            speed_pxl = (dim+bar_spatial_period_pxl)/period; 
            app.IntrinsicStimBarSpeedEdit.Value = speed_pxl*app.OnePxAngle;
        end

        % Value changed function: IntrinsicStimBarSpeedEdit
        function IntrinsicStimBarSpeedEditValueChanged(app, event)
            speed_deg = app.IntrinsicStimBarSpeedEdit.Value; % deg/s
            speed_pxl = speed_deg/app.OnePxAngle; % pxl/s
            switch app.IntrOrientationSwitch.Value
                case 'Vertical'
                    dim = app.screenRect(4);
                case 'Horizontal'
                    dim = app.screenRect(3);
            end
            bar_spatial_period_deg = app.IntrRectSizeTxt.Value;
            bar_spatial_period_pxl = bar_spatial_period_deg/app.OnePxAngle;
            app.BartraveltimesEditField.Value = (dim+bar_spatial_period_pxl)/speed_pxl;
        end

        % Value changed function: KeepconstantspeedCheckBox
        function KeepconstantspeedCheckBoxValueChanged(app, event)
            value = app.KeepconstantspeedCheckBox.Value;
            if value
                app.BartraveltimesEditField.Editable = 'off';
            else
                app.BartraveltimesEditField.Editable = 'on';
            end 
        end

        % Value changed function: PcoWhileStimulusCk
        function PcoWhileStimulusCkValueChanged(app, event)
            % set Hermes mode
            ApplyButtonPushed(app)
            
            % control the behavior of gratings and dots
            ButtonGroup_5SelectionChanged(app,[])
            
        end

        % Value changed function: GridssEditField
        function GridDurationValueChanged(app, event)
            % While recording a movie during visual stimulation, the
            % duration of grids and pre-grid baseline has to be the same.
            if app.PcoWhileStimulusCk.Value
                app.BaselinepresEditField.Value = app.GridssEditField.Value;
            end
        end

        % Callback function
        function PcoSavefileCkValueChanged(app, event)
            if app.PcoSavefileCk.Value
                app.PcoSaveTifFileCk.Enable = 1;
            else
                app.PcoSaveTifFileCk.Enable = 0;
                app.PcoSaveTifFileCk.Value = 0;
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.HermesSerialFlag
                mode = app.ModeDropDown.Value;
                switch app.TriggerDropDown.Value
                    case 'phototransistor'
                        trig = 'p';
                    case 'serial'
                        trig = 's';
                end
                HermesMsgMake(app,'mod',mode,trig)
            end
        end

        % Value changed function: optoStartEditField
        function optoStartEditFieldValueChanged(app, event)
            HermesMsgMake(app,'parameters','optoStart')
        end

        % Value changed function: optoDurEditField
        function optoDurEditFieldValueChanged(app, event)
            HermesMsgMake(app,'parameters','optoDur')
        end

        % Button pushed function: SeemovieButton
        function SeemovieButtonPushed(app, event)
            mat = app.PcoStruct.Images;
            [~,t]=getPcoBinaryTimestamps(mat);
            t=seconds(t-t(1));
            fps = app.PcoMeta.AcqInfo.FrameRate; 
            
            mat=double(mat);
            if app.SpatialfilterCheckBox.Value
                sigma = app.pxlEditField.Value;
                mat=imgaussfilt(mat,sigma);
            end
            if app.TimefilterCheckBox.Value
                sigma = app.sEditField.Value./fps;
                kernel_half_length = min(floor(4*sigma),floor((size(mat,3)-1)/2));
                kernel = pdf('Normal',-kernel_half_length:1:kernel_half_length,0,sigma);
                mat = imfilter(mat,reshape(kernel,1,1,[]),'replicate','same');
            end
            if app.deltaFFButton.Value
                f0 = quantile(mat,0.1,3);
                mat=(mat-f0)./f0;
            end
            out = app.PcoMeta;
            info = out.StimInfo;


            mov = mat;
            mov(isnan(mov) | mov==-Inf | mov==Inf)=0;
            
            
            if ~isempty(app.PcoStruct.sv)
                if isvalid(app.PcoStruct.sv)
                    close(ancestor(app.PcoStruct.sv,'figure'))
                end
            end
            figure
            app.PcoStruct.sv=sliceViewer(mov);
            colorbar
            app.PcoStruct.sv.DisplayRange = [app.CLimminEditField.Value;...
                                                    app.maxEditField.Value];
            if isempty(info.FramesPerTrial)
            str = struct('data',mov,'x',t);
            else
                str = struct('data',mov,'x',t,'stimuli',t(1:info.FramesPerTrial:end));
            end
            sv_axes = findobj(app.PcoStruct.sv.Parent,'type','axes');
            set(sv_axes,'UserData',str);
            
           
        end

        % Button pushed function: GoDots
        function GoDotsButtonPushed(app, event)
            % GAB 2021/11/30
            % If the "PCO while stimulating" box is checked, Hermes must be also 
            % connected with serial communication.
            % It is better to do this check here, before disabling the GO buttons and 
            % writing any logs.
            
            % The two luminance values we need
            back_lumi = app.CheckLumiB.Value; % background luminance
            dots_lumi = app.CheckLumiW.Value; % luminance of the dots
%             Other values needed
            Angle = app.DotsOrientation.Value; % angle of the stimulus. 0 means vertical bars.
            dS = app.dotsDriftingSpeed.Value; % drifting speed of the dots in deg/s.
            Bt = app.initialDelay.Value; % initial delay
            Pre = app.Baselinepres_dotEditField.Value; % pre-stim duration
            StimT = app.dots_durationEditField.Value; % stim duration
            Post = app.Baselineposts_dotEditField.Value; % post-stim duration
            n = app.DotsRepetitions.Value; % number of repetitions of the stimulus
            halflife = app.DotslifetimesEditField.Value;
            dotsSize = app.DotsdiameterdegEditField.Value;
            densdots = app.DotsdensityEditField.Value;
            randSize = app.RandSizeCheckBox.Value;
            randSpeed = app.RandSpeedCheckBox.Value;
            darkBack = app.DarkbackgroundCheckBox.Value;
            equiluminantBaseline = app.EquiluminantbaselineCheckBox.Value;
            
            % Writing the log file.
%             app.runNumber = readmatrix(app.runNumberFile);
%             runString = sprintf('%04d', app.runNumber);
%             app.CurrentLogStruct.logName = [app.logDir, runString, '.dots', '.dat'];
% %             Initial writing of the file. The file contains the whole
% %             table, but the last column (the timestamps) is empty.
%             app.CurrentLogStruct.T = table('Size',[1, 15],'VariableTypes',[{'datetime'},...
%                 repmat({'double'},[1, 13]),{'string'}],'VariableNames',...
%                 {'StartTime','BackgrLumi','DotsLumi','K','angle','speed',...
%                 'dotsSize','dotsN','Bt','pre','stimT','post','halflife','n','Timestamps'});
%             app.CurrentLogStruct.T.StartTime(1) = datetime;
%             app.CurrentLogStruct.T{1,2:end-1} = [back_lumi,dots_lumi,...
%                 app.CheckContrast.Value,Angle,dS,dotsSize,densdots,...
%                 Bt,Pre,StimT,Post,halflife,n];
%             % Write the table to the log file for this stimulation.
%             writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
% %             Updates the runNumber file.
%             app.runNumber = app.runNumber +1;
%             writematrix(app.runNumber, app.runNumberFile)
            
            % General log struct
            app.CurrentLogStruct = PrepareLogStruct(app,'dots');
            % Allocate visual stimuli elements
            % - Initial baseline
            % - For each cycle, we have 3 stimuli: pre, dopts and post.
            % - Back to baseline luminance.
            n_stimuli = 1 + 3*n + 1;
            placeholder = cell(n_stimuli,1);
            stimStruct = struct('NameAttribute',placeholder, ...
                  "RelativeTimeAttribute",placeholder, ...
                  "StimParam",placeholder);
            % Initial "delay"
            if equiluminantBaseline
                baseline_lumi = app.CheckLumiMean.Value;
            else
                baseline_lumi = app.CheckLumiB.Value;
            end
            stimStruct(1).NameAttribute         = 'InitialDelay';
            stimStruct(1).RelativeTimeAttribute = []; % later
            stimStruct(1).HermesSyncAttribute   = string(app.SendTTLwhenbaselinestartsCheckBox.Value);
            stimStruct(1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(baseline_lumi,'%.2f'),...
                                            num2str(app.initialDelay.Value,'%.2f'),...
                                            }) ...
                    );

            % "Pre-dots" baseline
            for i=1:n
                stimStruct(1+3*(i-1)+1).NameAttribute         = 'PreDots';
                stimStruct(1+3*(i-1)+1).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+1).HermesSyncAttribute   = string(app.PcoWhileStimulusCk.Value);
                stimStruct(1+3*(i-1)+1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(baseline_lumi,'%.2f'),...
                                            num2str(Pre,'%.2f'),...
                                            }) ...
                    );
            end
            % Dots
            for i=1:n
                stimStruct(1+3*(i-1)+2).NameAttribute         = "Dots";
                stimStruct(1+3*(i-1)+2).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+2).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+3*(i-1)+2).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "DotsLumi", ...
                                            "BackLumi", ...
                                            "DotsDensity_deg-2", ...
                                            "NDots", ...
                                            "DotsDiam_deg",...
                                            "RandomDotsDiam",...
                                            "Orientation", ...
                                            "DriftingSpeed_deg/s", ...
                                            "RandomDriftingSpeed", ...
                                            "DotsHalflife_s", ...
                                            "Duration_s" ...
                                            },...
                                         'ValueAttribute',{ ...
                                            num2str(dots_lumi,'%.2f'), ...
                                            num2str(back_lumi,'%.2f'),...
                                            num2str(densdots,'%.4f'), ...
                                            num2str(app.NdotsEditField.Value,'%.2f'), ...
                                            num2str(dotsSize,'%.2f'), ...
                                            string(logical(randSize)), ...
                                            num2str(Angle,'%.2f'), ...
                                            num2str(dS,'%.2f'), ...
                                            string(logical(randSpeed)), ...
                                            num2str(halflife,'%.2f'), ...
                                            num2str(StimT,'%.2f') ...
                                            }) ...
                    );
            end
            % "Post-stim" baseline
            for i=1:n
                stimStruct(1+3*(i-1)+3).NameAttribute         = "PostDots";
                stimStruct(1+3*(i-1)+3).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+3).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+3*(i-1)+3).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                             num2str(baseline_lumi,'%.2f'),...
                                             num2str(Post,'%.2f'),...
                                             }) ...
                    );
            end
            % Back to standby luminance
            stimStruct(end).NameAttribute         = 'StandbyLuminance';
            stimStruct(end).RelativeTimeAttribute = []; % later
            stimStruct(end).HermesSyncAttribute   = "false";
            stimStruct(end).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f')...
                                            }) ...
                    );
            app.CurrentLogStruct.Sequence.Stimulus = stimStruct;

            
            ParameterVector = num2cell([back_lumi,dots_lumi,Angle,dS,Bt,Pre,StimT,Post,...
                halflife,dotsSize,densdots,randSize,randSpeed,darkBack,equiluminantBaseline,...
                n]);
            
%             Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
% During PCO recording, pre-grating duration is set equal to grating 
% duration.
% Both the pre-grid baseline and the grating will trigger Hermes at their 
% beginning.
% Set PCO acquisition duration and number of frames.
% Right now, no frames are acquired with PCO during post grid baseline.
                PcoSetForStimulation(app,StimT,2*n,2*Bt)

%                 Create a message for Hermes
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/n)
                app.TriggerDropDown.Value = 'phototransistor';
% Set the parameters that will be saved.
                app.PcoMeta.StimInfo.StimType = 'grating';
                app.PcoMeta.StimInfo.nTrials = n;
                app.PcoMeta.StimInfo.FramesPerTrial = app.PcoNumberFramesEditField.Value/n;
                app.PcoMeta.StimInfo.Orientation = num2str(Angle);
                app.PcoMeta.StimInfo.Direction = '';
                app.PcoMeta.StimInfo.StimF = 1/(2*StimT+Post);
                
            end
            
            TotStimDur = Bt + (Pre+StimT+Post) * n;
            callVisStim( app, 'DotsPresent', ParameterVector, TotStimDur)
        end

        % Value changed function: DotsdensityEditField
        function DotsdensityEditFieldValueChanged(app, event)
            w = app.ScreenWidth.Value; %#ok<ADPROPLC> 
            h = app.ScreenHeight.Value;
            d = app.ScreenDistance.Value;
            solid_angle = screenSolidAngle(w,h,d); %#ok<ADPROPLC> 
            n_dots = round(app.DotsdensityEditField.Value .* solid_angle);
            % update the dot number rounding the result of the
            % multiplication of the dots density by the screen solid angle
            app.NdotsEditField.Value = round(n_dots);
            % adjust this value after rounding
            NdotsEditFieldValueChanged(app, [])

        end

        % Value changed function: NdotsEditField
        function NdotsEditFieldValueChanged(app, event)
            w = app.ScreenWidth.Value; %#ok<ADPROPLC> 
            h = app.ScreenHeight.Value;
            d = app.ScreenDistance.Value;
            solid_angle = screenSolidAngle(w,h,d); %#ok<ADPROPLC> 
            n_dots = app.NdotsEditField.Value;
            app.DotsdensityEditField.Value = n_dots./solid_angle;
            
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeButtonGroup.SelectedObject;
            switch selectedButton.Text 
                case 'Continuous'
                    app.FrequencyHzEditField.Editable = 'off';
                    app.DutycycleEditField.Editable = 'off';
                    app.optoDurEditField.Editable = 'on';
                    
                case 'Pulsed'
                    app.FrequencyHzEditField.Editable = 'on';
                    app.DutycycleEditField.Editable = 'on';
                    app.optoDurEditField.Editable = 'off';
                
            end
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            app.optoDurEditField.Value = 1000*(1/value)/100*app.DutycycleEditField.Value;
            HermesMsgMake(app,'parameters',{'nStim','optoDur','optoPer'})
        end

        % Value changed function: DutycycleEditField
        function DutycycleEditFieldValueChanged(app, event)
            value = app.DutycycleEditField.Value;
            app.optoDurEditField.Value = 1000*(1/app.FrequencyHzEditField.Value)/100*value;
            HermesMsgMake(app,'parameters',{'nStim','optoDur','optoPer'})
        end

        % Value changed function: nofpulsesEditField
        function nofpulsesEditFieldValueChanged(app, event)
            HermesMsgMake(app,'parameters','nStim')
        end

        % Value changed function: rowsEditField
        function rowsEditFieldValueChanged(app, event)
            value = app.rowsEditField.Value;
            app.UIAxes.YLim = [0,value];
            app.UIAxes.YTick = 0:1:value;
        end

        % Value changed function: columnsEditField
        function columnsEditFieldValueChanged(app, event)
            value = app.columnsEditField.Value;
            app.UIAxes.XLim = [0,value];
            app.UIAxes.XTick = 0:1:value;
        end

        % Value changed function: UsedefaultwidthandheightCheckBox
        function UsedefaultwidthandheightCheckBoxValueChanged(app, event)
            value = app.UsedefaultwidthandheightCheckBox.Value;
            if value
                app.wwTxt.Editable = 'off';
                app.hhTxt.Editable = 'off';
                if app.SphericaldistortioncorrectionCheckBox.Value
                    app.wwTxt.Value = 470;
                    app.hhTxt.Value = 320;
                else
                    app.wwTxt.Value = 110;
                    app.hhTxt.Value = 110;
                end
                wwTxtValueChanged(app,[])
            else
                app.wwTxt.Editable = 'on';
                app.hhTxt.Editable = 'on';
            end
        end

        % Value changed function: SphericaldistortioncorrectionCheckBox
        function SphericaldistortioncorrectionCheckBoxValueChanged(app, event)
            value = app.SphericaldistortioncorrectionCheckBox.Value;
            UsedefaultwidthandheightCheckBoxValueChanged(app, [])
        end

        % Button pushed function: OpenlogdirectoryButton
        function OpenlogdirectoryButtonPushed(app, event)
            winopen(app.logDir)
        end

        % Button pushed function: SeeavgmovieButton
        function SeeavgmovieButtonPushed(app, event)
            
            % get acquired raw images
            mat = double(app.PcoStruct.Images);
            
            % get structures with acquisition and stimulation info
            out = app.PcoMeta;
            info = out.StimInfo;
            
            % apply spatial filter to individual frames
            if app.SpatialfilterCheckBox.Value
                sigma = app.pxlEditField.Value;
                for i=1:size(mat,3)
                    mat(:,:,i)=imgaussfilt(mat(:,:,i),sigma);
                end
            end
            
            % retrieve trials. mat4d dimensions are y*x*trials*time
            mat4d = retrieveTrials(mat,info.nTrials,info.FramesPerTrial);
            
            % check number of frames
            [ny,nx,ntr,nt] = size(mat4d);
            assert(nt==info.FramesPerTrial);
            
            % if required, apply a time filter
            if app.TimefilterCheckBox.Value
                sigma = app.sEditField.Value./out.AcqInfo.FrameRate;
                kernel_half_length = min(floor(4*sigma),floor((size(mat4d,4)-1)/2));
                kernel = pdf('Normal',-kernel_half_length:1:kernel_half_length,0,sigma);
                mat4d_filt = nan(size(mat4d));
                for i = 1:ntr
                    mat4d_filt(:,:,i,:) = imfilter(reshape(mat4d(:,:,i,:),ny,nx,nt),reshape(kernel,1,1,[]),'replicate','same');
                end
            else
                mat4d_filt = mat4d;
            end
            
            % if a baseline was alternated with a stimulus, concatenate in
            % time odd trials (baseline) with even trials (actual
            % stimulation)
            if info.Baseline_bool
                mat4d_filt = cat(4, mat4d_filt(:,:,1:2:end-1,:), mat4d_filt(:,:,2:2:end,:));
            end
            
            % compute mean among trials
            mov = squeeze(mean(mat4d_filt,3));
            
            if app.deltaFFButton.Value
                if info.Baseline_bool
                    % compute f0 during baseline
                    f0 = mean( mov(:,:,1:nt), 3, 'omitnan');
                else
                    % use the 10th centile as f0
                    f0 = quantile( mov, 0.1, 3);
                end
                mov=(mov-f0)./f0;
            end
            
            % generate time vector
            if info.Baseline_bool
                t_basel = (-(nt-1):1:0) ./ out.AcqInfo.FrameRate - info.DeadTimeBetweenTrials;
                t_stim = (0:nt-1) ./ out.AcqInfo.FrameRate;
                t = [t_basel, t_stim];
            else
                t = (0:nt-1)./out.AcqInfo.FrameRate;
            end
            
                
            % replace old figure
            if ~isempty(app.PcoStruct.sv)
                if isvalid(app.PcoStruct.sv)
                    close(ancestor(app.PcoStruct.sv,'figure'))
                end
            end
            
            mov_2_show = mov;
            mov_2_show(isnan(mov_2_show))=0;
            mov_2_show(mov_2_show==inf|mov_2_show==-inf)=0;
            
            figure
            app.PcoStruct.sv=sliceViewer(mov_2_show);
            colorbar
            app.PcoStruct.sv.DisplayRange=[app.PcoCDataStimMinEditField.Value, app.PcoCDataStimMaxEditField.Value];
            
            str = struct('data',mov,'x',t);
            sv_axes = findobj(app.PcoStruct.sv.Parent,'type','axes');
            set(sv_axes,'UserData',str);
            
        end

        % Value changed function: CLimminEditField, maxEditField
        function CLimminEditFieldValueChanged(app, event)
            if ~isempty(app.PcoStruct.sv)
                if isvalid(app.PcoStruct.sv)
                    app.PcoStruct.sv.DisplayRange = [app.CLimminEditField.Value;...
                                                    app.maxEditField.Value];
                end
            end
        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            tmp = uigetdir(app.PcoSaveFolderEditField.Value);
            if tmp==0
                return
            else
                app.PcoSaveFolderEditField.Value = tmp;
            end
        end

        % Button pushed function: generatestimuliSFBtn
        function generatestimuliSFBtnButtonPushed(app, event)
            minim = app.SfFastMinC.Value;
            maxim = app.SfFastMaxC.Value;
            n = app.NstimEditField.Value;
            if app.logspacedSfCk.Value
                sf = logspace(log10(minim),log10(maxim),n);
            else
                sf = linspace(minim,maxim,n);
            end
            app.SfFastTable.Data = sf';
            if n>1
                app.SfFastN.Enable = 'on';
            else
                app.SfFastN.Enable = 'off';
                app.SfFastN.Value = 1;
                SfFastNValueChanged(app,[])
            end
        end

        % Button pushed function: GoRandGrid
        function GoRandGridButtonPushed(app, event)
            
            % The two luminance values we need
            Glumi = app.RandFastTable.Data(1,2); % the average luminance
            contrast = app.RandFastTable.Data(:,1); % from the table
            inc = Glumi .* contrast ./ 100; % the increment of luminance

%             Other values needed
            sF = app.RandFastTable.Data(:,4); % spatial frequency of the stimulus (in cycles/degree)
            gridType = app.SineGrid.Value; % 1 means sinusoidal, 0 is for square-wave (bar)
            Angle = app.RandFastTable.Data(:,3); % angle of the stimulus. 0 means vertical bars.
            dS = app.DriftingSpeed_txt.Value; % drifting speed of the stimulus in cycles/s.
            MaskFlag = app.DrawMask.Value; % 1 means that a gaussian mask is applied.
            GaussSize = app.GaussSize_txt.Value; % size of gaussian mask
            Bt = app.initialDelay.Value; % initial delay
            PreG = app.BaselinepresEditField.Value; % pre-grid duration
            GridT = app.GridssEditField.Value; % grid duration
            lag = app.RandFastTable.Data(:,5);
            PostG = app.BaselinepostsEditField.Value + lag; % post-grid duration
            n = size(app.RandFastTable.Data,1); % number of repetitions of the stimulus
            dark_bsl = app.UsestandbyluminanceforBaselinepreandBaselinepostCheckBox.Value; % Use dark value for baseline
            
            
%             ParameterVector = {Glumi,inc,sF,gridType,Angle,dS,MaskFlag,...
%                 GaussSize,Bt,PreG,GridT,PostG,n,dark_bsl};
            ParameterVector = num2cell([Glumi;gridType;dS;MaskFlag;...
                GaussSize;Bt;n;dark_bsl;PreG;GridT;...
                numel(PostG);PostG(:);... %number of "PostG" values followed by the values themselves
                numel(inc);inc(:);...     %number of "inc"   values followed by the values themselves
                numel(sF);sF(:);...       %number of "sF"    values followed by the values themselves
                numel(Angle);Angle(:)...  %number of "Angle" values followed by the values themselves
                ])';
%             Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
% During PCO recording, pre-grating duration is set equal to grating 
% duration.
% Both the pre-grid baseline and the grating will trigger Hermes at their 
% beginning.
% Set PCO acquisition duration and number of frames.
% Right now, no frames are acquired with PCO during post grid baseline.
%                 PcoSetForStimulation(app,GridT,2*n,2*Bt)
                PcoSetForStimulation(app,GridT,2*n,2*Bt)
%                 Create a message for Hermes
%                 HermesMsg = HermesMsgMake(app,'o');
%                 HermesMsgMake(app,'mod','o',app.PcoNumberFramesEditField.Value/(2*n));
                HermesMsgMake(app,'mod','camAc','p',app.PcoNumberFramesEditField.Value/(2*n))
%                 app.ModeDropDown.Value = 'camAc';
                app.TriggerDropDown.Value = 'phototransistor';
% Set the parameters that will be saved.
                app.PcoMeta.StimInfo.StimType = 'Grid';
                app.PcoMeta.StimInfo.nTrials = 2*n; % acquisition is triggered also for baseline.
                app.PcoMeta.StimInfo.FramesPerTrial = app.PcoNumberFramesEditField.Value/2/n;
                app.PcoMeta.StimInfo.Orientation = num2str(Angle);
                app.PcoMeta.StimInfo.Direction = '';
                app.PcoMeta.StimInfo.StimF = 1/(2*GridT+PostG);
                app.PcoMeta.StimInfo.Baseline_bool = true;
                app.PcoMeta.StimInfo.DeadTimeBetweenTrials = GridT - ...
                    app.PcoMeta.StimInfo.FramesPerTrial / app.PcoSelectedFreqEditField.Value ;
            end

            TotStimDur = Bt + (PreG+GridT)*n + sum(PostG);
            
            % General log struct
            app.CurrentLogStruct = PrepareLogStruct(app,'Grid');
            % Allocate visual stimuli elements
            % - Initial baseline
            % - For each cycle, we have 3 stimuli: pre, grid and post.
            % - Back to baseline luminance.
            n_stimuli = 1 + 3*n + 1;
            placeholder = cell(n_stimuli,1);
            stimStruct = struct('NameAttribute',placeholder, ...
                  "RelativeTimeAttribute",placeholder, ...
                  "StimParam",placeholder);
            % Initial "delay"
            stimStruct(1).NameAttribute         = 'InitialDelay';
            stimStruct(1).RelativeTimeAttribute = []; % later
            stimStruct(1).HermesSyncAttribute   = string(app.SendTTLwhenbaselinestartsCheckBox.Value);
            stimStruct(1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(Glumi,'%.2f'),...
                                            num2str(app.initialDelay.Value,'%.2f'),...
                                            }) ...
                    );

            if dark_bsl
                baseline_lumi = app.StandbyL.Value;
            else
                baseline_lumi = Glumi;
            end
            % "Pre-grid" baseline
            for i=1:n
                stimStruct(1+3*(i-1)+1).NameAttribute         = 'PreGrid';
                stimStruct(1+3*(i-1)+1).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+1).HermesSyncAttribute   = string(app.PcoWhileStimulusCk.Value);
                stimStruct(1+3*(i-1)+1).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(baseline_lumi,'%.2f'),...
                                            num2str(app.BaselinepresEditField.Value,'%.2f'),...
                                            }) ...
                    );
            end
            % Grid
            for i=1:n
                stimStruct(1+3*(i-1)+2).NameAttribute         = "Grid";
                stimStruct(1+3*(i-1)+2).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+2).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+3*(i-1)+2).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Contrast", ...
                                            "MeanLuminance_cdm2", ...
                                            "SpatialFreq_cyc/deg", ...
                                            "DriftingSpeed_cyc/s", ...
                                            "GridShape",...
                                            "GaussianMask",...
                                            "Orientation",...
                                            },...
                                         'ValueAttribute',{ ...
                                            num2str(contrast(i),'%.2f'), ...
                                            num2str(Glumi,'%.2f'),...
                                            num2str(sF(i),'%.4f'), ...
                                            num2str(app.DriftingSpeed_txt.Value,'%.2f'), ...
                                            get(app.ShapeButtonGroup.SelectedObject,'Text'), ...
                                            struct( 'Attributes', struct(...
                                                'ActiveAttribute', num2str(app.DrawMask.Value,'%.2f'), ...
                                                'Size_pxlAttribute', num2str(app.GaussSize_txt.Value,'%.2f')) ...
                                                ), ...
                                            num2str(Angle(i),'%.2f') ...
                                            }) ...
                    );
            end
            % "Post-grid" baseline
            for i=1:n
                stimStruct(1+3*(i-1)+3).NameAttribute         = "PostGrid";
                stimStruct(1+3*(i-1)+3).RelativeTimeAttribute = []; % later
                stimStruct(1+3*(i-1)+3).HermesSyncAttribute   = string(app.ard_ck.Value);
                stimStruct(1+3*(i-1)+3).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "Luminance", ...
                                            "Duration_s" , ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                             num2str(baseline_lumi,'%.2f'),...
                                             num2str(PostG(i),'%.2f'),...
                                             }) ...
                    );
            end
            % Back to standby luminance
            stimStruct(end).NameAttribute         = 'StandbyLuminance';
            stimStruct(end).RelativeTimeAttribute = []; % later
            stimStruct(end).HermesSyncAttribute   = "false";
            stimStruct(end).StimParams = struct( ...
                    "StimParam", struct('NameAttribute',{ ...
                                            "StandbyLuminance", ...
                                            }, ...
                                        'ValueAttribute',{ ...
                                            num2str(app.StandbyL.Value,'%.2f')...
                                            }) ...
                    );


            app.CurrentLogStruct.Sequence.Stimulus = stimStruct;
            
            % call the stimulation
            callVisStim( app, 'GridPresent', ParameterVector, PostG, TotStimDur)
        end

        % Button pushed function: GenRandStim
        function GenRandStimButtonPushed(app, event)
            % constrast value(s)
            if isempty(app.ContrastFastTable.Data)
                data_contr = app.CheckContrast.Value;
                data_meanL = app.CheckLumiMean.Value;
            else
                data_contr = app.ContrastFastTable.Data(:,1);
                data_meanL = app.ContrastFastTable.Data(1,2);
            end
            
            % orientation value(s)
            if isempty(app.OrientFastTable.Data)
                data_ori = app.GridOrientation.Value;
            else
                data_ori = app.OrientFastTable.Data;
            end
            % spatial frequency value(s)
            if isempty(app.SfFastTable.Data)
                data_sf = app.spatialFrequency.Value;
            else
                data_sf = app.SfFastTable.Data;
            end
            
            % all combinations of contrast, orientation and spatial freq
            [Contr,Ori,Sf] = meshgrid(data_contr,data_ori,data_sf);
            
            % add the mean luminance (always the same)
            combs = [Contr(:),repmat(data_meanL, numel(Contr),1), Ori(:), Sf(:)];
            
            % replicate N times
            N = app.NrepEditField.Value;
            combs=repmat(combs,N,1);
            
            % randomize order
            combs=combs(randperm(size(combs,1)),:);
            
            % add a random delay, if selected. The random delay is
            % uniformly distributed between 0 and a maximum delay specified
            % in 'MaxlagsEditField'
            nstim=size(combs,1);
            if app.AddrandomlagaftereachstimulusCheckBox.Value
                delay=rand(nstim,1)*app.MaxlagsEditField.Value;
            else
                delay=zeros(nstim,1);
            end
            
            % assign the combined values to the table data
            app.RandFastTable.Data=[combs,delay];
        end

        % Value changed function: SfFastN
        function SfFastNValueChanged(app, event)
            if ~isempty(app.SfFastTable.Data)
                value = min(app.SfFastN.Value,length(app.SfFastTable.Data));
                app.spatialFrequency.Value = app.SfFastTable.Data(value);
            end
        end

        % Value changed function: ard_ck
        function ard_ckValueChanged(app, event)
            value = app.ard_ck.Value;
            if value
                app.SendTTLwhenbaselinestartsCheckBox.Enable = 'on';
                app.generateTTL_gridCheckBox.Value = 1;
                app.generateTTL_dotCheckBox.Value = 1;
            else
                app.SendTTLwhenbaselinestartsCheckBox.Enable = 'off';
                app.generateTTL_gridCheckBox.Value = 0;
                app.generateTTL_dotCheckBox.Value = 0;
            end
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            if app.PcoWhileStimulusCk.Value
                % when recodring a movie, limit the choice for "grids"
                % and "dots" stimulation.
                
                selectedButton = app.ButtonGroup_5.SelectedObject;
                switch selectedButton
                    case app.AutomaticallydetermineacquisitiontimeButton
                        % In this case, do as follows:
                        % - set the "post" baseline to 0.
                        % - force the "pre" baseline to be as long as the actual
                        %       stimulus.
                        % - generate a TTL also for the baseline.
                        
                        % do it for grids first...
                        app.BaselinepresEditField.Editable = 'off';
                        app.BaselinepresEditField.Value = app.GridssEditField.Value;
                        app.BaselinepostsEditField.Editable = 'off';
                        app.BaselinepostsEditField.Limits = [0, inf];
                        app.BaselinepostsEditField.Value = 0;
                        app.generateTTL_preCheckBox.Value = 1;
                        
                         % ... and then for dots
                         app.Baselinepres_dotEditField.Editable = 'off';
                         app.Baselinepres_dotEditField.Value = app.dots_durationEditField.Value;
                         app.Baselineposts_dotEditField.Editable = 'off';
                         app.Baselineposts_dotEditField.Limits = [0, inf];
                         app.Baselineposts_dotEditField.Value = 0;
                         app.generateTTL_pre_dotCheckBox.Value = 1;
                         
                         
                    case app.SettrialacquisitiontimeButton
                        % In this case, do as follows:
                        % - set the "pre" baseline to 0.
                        % - force the sum of stimulus and "post" duration to
                        %       be AT LEAST as long as the actual trial
                        %       acquisition time plus dead time.
                        
                        % do it for grids first...
                        app.BaselinepresEditField.Editable = 'off';
                        app.BaselinepresEditField.Value = 0;
                        app.BaselinepostsEditField.Editable = 'on';
                        post_minimum_length = app.PCOTrialEditField.Value ...
                                         + app.DeadTBeforeTrialEditField.Value/1000 ...
                                         - app.GridssEditField.Value;
                        app.BaselinepostsEditField.Limits = [post_minimum_length, inf];
                        app.generateTTL_preCheckBox.Value = 0;
                        
                        % ... and then for dots
                        app.Baselinepres_dotEditField.Editable = 'off';
                        app.Baselinepres_dotEditField.Value = 0;
                        app.Baselineposts_dotEditField.Editable = 'on';
                        post_minimum_length = app.PCOTrialEditField.Value ...
                                         + app.DeadTBeforeTrialEditField.Value/1000 ...
                                         - app.dots_durationEditField.Value;
                        app.Baselineposts_dotEditField.Limits = [post_minimum_length, inf];
                        app.generateTTL_pre_dotCheckBox.Value = 0;
                end


            else
                app.BaselinepresEditField.Editable = 'on';
                app.BaselinepostsEditField.Editable = 'on';
                app.generateTTL_preCheckBox.Value = 0;
            end
        end

        % Button pushed function: generatestimuliCheckBtn_3
        function generatestimuliCheckBtn_3ButtonPushed(app, event)
            app.SfFastTable.Data = [];
        end

        % Button pushed function: generatestimuliCheckBtn_2
        function generatestimuliCheckBtn_2ButtonPushed(app, event)
            app.ContrastFastTable.Data = [];
        end

        % Button pushed function: cleartable_orientBtn
        function cleartable_orientBtnButtonPushed(app, event)
            app.OrientFastTable.Data = [];
        end

        % Button pushed function: SaveRandStim
        function SaveRandStimButtonPushed(app, event)
            
        end

        % Button pushed function: LoadRandStim
        function LoadRandStimButtonPushed(app, event)
            
        end

        % Button pushed function: SfFastSaveTable
        function SfFastSaveTableButtonPushed(app, event)
            % This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            % Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','SpatialFreqStimuliTable.csv');
            if file == 0
                return
            end
            % The file is saved as a matrix.
            writematrix(app.ContrastFastTable.Data,[path,file]);
        end

        % Value changed function: BLUEMODEButton
        function BLUEMODEButtonValueChanged(app, event)
            value = app.BLUEMODEButton.Value;
            if value
                app.BluemodeLamp.Color = [0 0 1];
                app.BLUEMODEButton.Text = 'BLUE MODE: ON';
            else
                app.BluemodeLamp.Color = [0.8 0.8 0.8];
                app.BLUEMODEButton.Text = 'BLUE MODE: OFF';
            end
            % change the in-use transfer function 
            SelectScreenFunctionFcn(app,value)
            
            % If the screen is open, update standby luminance and
            % contrast/flash luminance values.
            if app.ScreenOpenFlag
                RefreshStByButtonPushed(app, event)
                
                UpdateLumiAndContrast(app,0)
                FSbaselineLValueChanged(app, [])
                FSflashLValueChanged(app,[])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EscherMasterFig = uifigure('Visible', 'off');
            colormap(app.EscherMasterFig, 'parula');
            app.EscherMasterFig.Name = 'EscherMaster';
            app.EscherMasterFig.CloseRequestFcn = createCallbackFcn(app, @EscherMasterFigCloseRequest, true);
            app.TabGroup = uitabgroup(app.EscherMasterFig);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.uipanel6 = uipanel(app.SettingsTab);
            app.uipanel6.Title = 'Screen settings';
            app.OpenScreenBtn = uibutton(app.uipanel6, 'push');
            app.OpenScreenBtn.ButtonPushedFcn = createCallbackFcn(app, @OpenScreenBtn_Callback, true);
            app.OpenScreenBtn.Tooltip = {'Open screen when in dual monitor mode (default)'};
            app.OpenScreenBtn.Text = 'Open Screen';
            app.RefreshStBy = uibutton(app.uipanel6, 'push');
            app.RefreshStBy.ButtonPushedFcn = createCallbackFcn(app, @RefreshStByButtonPushed, true);
            app.RefreshStBy.Tooltip = {'Fill screen with stand by luminance'};
            app.RefreshStBy.Text = 'Standby';
            app.OneScreenMode_ck = uicheckbox(app.uipanel6);
            app.OneScreenMode_ck.Text = 'One Screen Mode';
            app.oculusCk = uicheckbox(app.uipanel6);
            app.oculusCk.Text = 'VR goggles';
            app.StandbyluminanceLabel = uilabel(app.uipanel6);
            app.StandbyluminanceLabel.Text = 'Standby luminance';
            app.StandbyL = uieditfield(app.uipanel6, 'numeric');
            app.LabelLumi_1 = uilabel(app.uipanel6);
            app.LabelLumi_1.Text = 'cd/m²';
            app.SphericaldistortioncorrectionCheckBox = uicheckbox(app.uipanel6);
            app.SphericaldistortioncorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SphericaldistortioncorrectionCheckBoxValueChanged, true);
            app.SphericaldistortioncorrectionCheckBox.Text = 'Spherical distortion correction';
            app.SaveScreenDistortionButton = uibutton(app.uipanel6, 'push');
            app.SaveScreenDistortionButton.Text = 'Save Screen Distortion';
            app.BLUEMODEButton = uibutton(app.uipanel6, 'state');
            app.BLUEMODEButton.ValueChangedFcn = createCallbackFcn(app, @BLUEMODEButtonValueChanged, true);
            app.BLUEMODEButton.Text = 'BLUE MODE: OFF';
            app.uipanel4 = uipanel(app.SettingsTab);
            app.uipanel4.Title = 'Monitor Size and Geometry';
            app.ScreendistancecmLabel = uilabel(app.uipanel4);
            app.ScreendistancecmLabel.Tooltip = {'Enter the three parameters definig the screen angular size. '};
            app.ScreendistancecmLabel.Text = 'Screen distance (cm):';
            app.ScreenDistance = uieditfield(app.uipanel4, 'numeric');
            app.ScreenDistance.Limits = [0 120];
            app.ScreenDistance.RoundFractionalValues = 'on';
            app.ScreenDistance.ValueDisplayFormat = '%4.3g';
            app.ScreenDistance.ValueChangedFcn = createCallbackFcn(app, @ScreenDistanceValueChanged, true);
            app.ScreenDistance.Value = 15;
            app.WidthpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.WidthpixelsEditFieldLabel.Text = 'Width (pixels):';
            app.WidthPix = uieditfield(app.uipanel4, 'numeric');
            app.WidthPix.Limits = [0 Inf];
            app.WidthPix.RoundFractionalValues = 'on';
            app.WidthPix.Editable = 'off';
            app.WidthPix.Value = 1920;
            app.HeightpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.HeightpixelsEditFieldLabel.Text = 'Height (pixels):';
            app.HeightPix = uieditfield(app.uipanel4, 'numeric');
            app.HeightPix.Limits = [0 Inf];
            app.HeightPix.RoundFractionalValues = 'on';
            app.HeightPix.Editable = 'off';
            app.HeightPix.Value = 1080;
            app.WidthcmLabel = uilabel(app.uipanel4);
            app.WidthcmLabel.Text = 'Width (cm):';
            app.ScreenWidth = uieditfield(app.uipanel4, 'numeric');
            app.ScreenWidth.Limits = [0 Inf];
            app.ScreenWidth.ValueChangedFcn = createCallbackFcn(app, @ScreenWidthValueChanged, true);
            app.ScreenWidth.Value = 100;
            app.HeightcmLabel = uilabel(app.uipanel4);
            app.HeightcmLabel.Text = 'Height (cm):';
            app.ScreenHeight = uieditfield(app.uipanel4, 'numeric');
            app.ScreenHeight.Limits = [0 Inf];
            app.ScreenHeight.ValueChangedFcn = createCallbackFcn(app, @ScreenHeightValueChanged, true);
            app.ScreenHeight.Value = 100;
            app.AngularsizeofscreenEditFieldLabel = uilabel(app.uipanel4);
            app.AngularsizeofscreenEditFieldLabel.Text = 'Angular size of screen';
            app.ScreenSizeAngle = uieditfield(app.uipanel4, 'text');
            app.UDPConnectionPanel = uipanel(app.SettingsTab);
            app.UDPConnectionPanel.Title = 'UDP Connection';
            app.StartUdpBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.StartUdpBtn.ButtonPushedFcn = createCallbackFcn(app, @StartUdpBtnButtonPushed, true);
            app.StartUdpBtn.Tooltip = {'Start UDP connection to the slave Escher app. The slave must be in listening mode.'};
            app.StartUdpBtn.Text = 'Start Connection';
            app.SlaveIPLabel = uilabel(app.UDPConnectionPanel);
            app.SlaveIPLabel.Text = 'Slave IP:';
            app.SlaveIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveIp.ValueChangedFcn = createCallbackFcn(app, @SlaveIpValueChanged, true);
            app.SlaveIp.Tooltip = {'Set here the IP address of the slave app.'};
            app.SlaveIp.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port:';
            app.SlavePort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.SlavePort.Limits = [1023 65535];
            app.SlavePort.RoundFractionalValues = 'on';
            app.SlavePort.ValueDisplayFormat = '%8.5g';
            app.SlavePort.Tooltip = {'Port for the UDP connection on the slave side. It must be the same port on which the slave is listening. Default: 8866.'};
            app.SlavePort.Value = 8866;
            app.MasterIPLabel = uilabel(app.UDPConnectionPanel);
            app.MasterIPLabel.Text = 'Master IP:';
            app.MasterIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterIp.ValueChangedFcn = createCallbackFcn(app, @MasterIpValueChanged, true);
            app.MasterIp.Tooltip = {'Set here the IP address of the master (this app).'};
            app.MasterIp.Value = '0.0.0.0';
            app.PortEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel_2.Text = 'Port:';
            app.MasterPort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.MasterPort.Limits = [1023 65535];
            app.MasterPort.RoundFractionalValues = 'on';
            app.MasterPort.ValueDisplayFormat = '%8.5g';
            app.MasterPort.Tooltip = {'Port for the UDP connection on the master side. Default: 8844.'};
            app.MasterPort.Value = 8844;
            app.GetMasterIPandMACBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.GetMasterIPandMACBtn.ButtonPushedFcn = createCallbackFcn(app, @GetMasterIPandMACBtnPushed, true);
            app.GetMasterIPandMACBtn.Tooltip = {'Get the IP and MAC of this computer (master).'};
            app.GetMasterIPandMACBtn.Text = 'Get Master IP and MAC';
            app.StatusEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.StatusEditFieldLabel_2.Text = 'Status:';
            app.ConnectionStatus = uieditfield(app.UDPConnectionPanel, 'text');
            app.ConnectionStatus.Editable = 'off';
            app.ConnectionStatus.Value = 'Disconnected.';
            app.MACLabel_3 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_3.Text = 'MAC:';
            app.MasterMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterMac.ValueChangedFcn = createCallbackFcn(app, @MasterMacValueChanged, true);
            app.MasterMac.Tooltip = {'The physical address of the master (this app). It is used by the app as identifier.'};
            app.MasterMac.Value = '000000000000';
            app.MACLabel_2 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_2.Text = 'MAC:';
            app.SlaveMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveMac.Editable = 'off';
            app.SlaveMac.Tooltip = {'The physical address of the slave app. It is going to be given by the slave itself.'};
            app.SlaveMac.Value = '000000000000';
            app.TimeouttimesEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.TimeouttimesEditFieldLabel.Text = 'Timeout time (s)';
            app.TimeoutTime = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.TimeoutTime.Limits = [0 Inf];
            app.TimeoutTime.Tooltip = {'The time after which'; ' if the master does not receive any answer from the slave'; ' the UDP connection will be cut.'};
            app.TimeoutTime.Value = 5;
            app.ResendScreenParams = uibutton(app.UDPConnectionPanel, 'push');
            app.ResendScreenParams.ButtonPushedFcn = createCallbackFcn(app, @ResendScreenParamsButtonPushed, true);
            app.ResendScreenParams.Enable = 'off';
            app.ResendScreenParams.Tooltip = {'Send again the screen parameters of the Settings Tab to the slave app.'};
            app.ResendScreenParams.Text = {'Send Screen'; 'Parameters'};
            app.SubnetmaskEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.SubnetmaskEditFieldLabel.Enable = 'off';
            app.SubnetmaskEditFieldLabel.Text = 'Subnet mask';
            app.SubnetMask = uieditfield(app.UDPConnectionPanel, 'text');
            app.SubnetMask.ValueChangedFcn = createCallbackFcn(app, @SubnetMaskValueChanged, true);
            app.SubnetMask.Enable = 'off';
            app.SubnetMask.Tooltip = {'Subnet mask of the network (only for general UDP broadcast)'};
            app.SubnetMask.Value = '0.0.0.0';
            app.BroadcastEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.BroadcastEditFieldLabel.Enable = 'off';
            app.BroadcastEditFieldLabel.Text = 'Broadcast';
            app.BroadcastAddress = uieditfield(app.UDPConnectionPanel, 'text');
            app.BroadcastAddress.Enable = 'off';
            app.BroadcastAddress.Value = '0.0.0.0.';
            app.UDPBroadcastDropDownLabel = uilabel(app.UDPConnectionPanel);
            app.UDPBroadcastDropDownLabel.Enable = 'off';
            app.UDPBroadcastDropDownLabel.Text = 'UDP Broadcast:';
            app.UDPBroadcastDropDown = uidropdown(app.UDPConnectionPanel);
            app.UDPBroadcastDropDown.Items = {'No broadcast', 'Broadcast', 'Send to all'};
            app.UDPBroadcastDropDown.Enable = 'off';
            app.UDPBroadcastDropDown.Tooltip = {'Choose how the connection is started:'; '-No broadcast. The communication is started only with slave IP typed by the user.'; '-Broadcast. The communication is started through a true UDP broadcast. Slave IP is ignored and the connection is set with the computer that answers.'; '-Send to all. A communication is sent to all IPs in the subnet.'};
            app.UDPBroadcastDropDown.Value = 'No broadcast';
            app.SendtoallstartaddressLabel = uilabel(app.UDPConnectionPanel);
            app.SendtoallstartaddressLabel.Enable = 'off';
            app.SendtoallstartaddressLabel.Text = {'Send-to-all'; 'start address'};
            app.STAstartTxt = uieditfield(app.UDPConnectionPanel, 'text');
            app.STAstartTxt.ValueChangedFcn = createCallbackFcn(app, @STAstartTxtValueChanged, true);
            app.STAstartTxt.Enable = 'off';
            app.STAstartTxt.Value = '0.0.0.0';
            app.SendtoallendaddressEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.SendtoallendaddressEditFieldLabel.Enable = 'off';
            app.SendtoallendaddressEditFieldLabel.Text = {'Send-to-all'; 'end address'};
            app.STAendTxt = uieditfield(app.UDPConnectionPanel, 'text');
            app.STAendTxt.ValueChangedFcn = createCallbackFcn(app, @STAendTxtValueChanged, true);
            app.STAendTxt.Enable = 'off';
            app.STAendTxt.Value = '0.0.0.0';
            app.VerboseCheckBox = uicheckbox(app.UDPConnectionPanel);
            app.VerboseCheckBox.Text = 'Verbose';
            app.LoadScreenInformationPanel = uipanel(app.SettingsTab);
            app.LoadScreenInformationPanel.Title = 'Load Screen Information';
            app.LoadScreenFileButton = uibutton(app.LoadScreenInformationPanel, 'push');
            app.LoadScreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScreenFileButtonPushed, true);
            app.LoadScreenFileButton.Text = 'Load Screen File';
            app.ScreennameEditFieldLabel = uilabel(app.LoadScreenInformationPanel);
            app.ScreennameEditFieldLabel.Text = 'Screen name';
            app.ScreenNameTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenNameTxt.Value = 'no screen loaded';
            app.SaveScreenFileButton = uibutton(app.LoadScreenInformationPanel, 'push');
            app.SaveScreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScreenFileButtonPushed, true);
            app.SaveScreenFileButton.Text = 'Save Screen File';
            app.CalModeRBGroup = uibuttongroup(app.LoadScreenInformationPanel);
            app.CalModeRBGroup.SelectionChangedFcn = createCallbackFcn(app, @CalModeRBGroupSelectionChanged, true);
            app.CalModeRBGroup.Title = 'Mode:';
            app.UncalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.UncalibratedButton.Text = 'Uncalibrated';
            app.CalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.CalibratedButton.Text = 'Calibrated';
            app.CalibratedButton.Value = true;
            app.LuminancetransferfunctionLabel = uilabel(app.CalModeRBGroup);
            app.LuminancetransferfunctionLabel.Text = 'Luminance transfer function:';
            app.ScreenFuncTxt = uieditfield(app.CalModeRBGroup, 'text');
            app.ScreenFuncTxt.ValueChangedFcn = createCallbackFcn(app, @ScreenFuncTxtValueChanged, true);
            app.NotesLabel = uilabel(app.LoadScreenInformationPanel);
            app.NotesLabel.Text = 'Notes:';
            app.ScreenNotesTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.TabGroup2 = uitabgroup(app.StimulationTab);
            app.ContrastTab = uitab(app.TabGroup2);
            app.ContrastTab.Title = 'Contrast';
            app.GeneralPanel = uipanel(app.ContrastTab);
            app.GeneralPanel.Title = 'General';
            app.LabelLumi_9 = uilabel(app.GeneralPanel);
            app.LabelLumi_9.Text = 'cd/m²';
            app.LabelLumi_10 = uilabel(app.GeneralPanel);
            app.LabelLumi_10.Text = 'cd/m²';
            app.LabelLumi_11 = uilabel(app.GeneralPanel);
            app.LabelLumi_11.Text = 'cd/m²';
            app.CheckLumiBEsch = uieditfield(app.GeneralPanel, 'numeric');
            app.CheckLumiBEsch.Limits = [0 Inf];
            app.CheckLumiBEsch.ValueChangedFcn = createCallbackFcn(app, @CheckLumiBValueChanged, true);
            app.CheckLumiBEsch.Editable = 'off';
            app.CheckLumiBEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiWEsch = uieditfield(app.GeneralPanel, 'numeric');
            app.CheckLumiWEsch.Limits = [0 Inf];
            app.CheckLumiWEsch.ValueChangedFcn = createCallbackFcn(app, @CheckLumiWValueChanged, true);
            app.CheckLumiWEsch.Editable = 'off';
            app.CheckLumiWEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiWEsch.Value = 0.6;
            app.CheckLumiMeanEsch = uieditfield(app.GeneralPanel, 'numeric');
            app.CheckLumiMeanEsch.Limits = [0 Inf];
            app.CheckLumiMeanEsch.ValueDisplayFormat = '%5.3g';
            app.CheckLumiMeanEsch.ValueChangedFcn = createCallbackFcn(app, @CheckLumiMeanValueChanged, true);
            app.CheckLumiMeanEsch.Editable = 'off';
            app.CheckLumiMeanEsch.Tooltip = {'Luminance of the background'};
            app.CheckLumiMeanEsch.Value = 0.3;
            app.LabelLumiEsch_9 = uilabel(app.GeneralPanel);
            app.LabelLumiEsch_9.Text = '(0-1)';
            app.LabelLumiEsch_10 = uilabel(app.GeneralPanel);
            app.LabelLumiEsch_10.Text = '(0-1)';
            app.LabelLumiEsch_11 = uilabel(app.GeneralPanel);
            app.LabelLumiEsch_11.Text = '(0-1)';
            app.FrequencycyclesDegLabel = uilabel(app.GeneralPanel);
            app.FrequencycyclesDegLabel.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.FrequencycyclesDegLabel.Text = 'Frequency (cycles/Deg):';
            app.spatialFrequency = uieditfield(app.GeneralPanel, 'numeric');
            app.spatialFrequency.Limits = [0 Inf];
            app.spatialFrequency.ValueDisplayFormat = '%5.3g';
            app.spatialFrequency.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.spatialFrequency.Value = 0.04;
            app.MeanluminanceLabel = uilabel(app.GeneralPanel);
            app.MeanluminanceLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.MeanluminanceLabel.Text = 'Mean luminance:';
            app.CheckLumiMean = uieditfield(app.GeneralPanel, 'numeric');
            app.CheckLumiMean.Limits = [0 Inf];
            app.CheckLumiMean.ValueDisplayFormat = '%5.3g';
            app.CheckLumiMean.ValueChangedFcn = createCallbackFcn(app, @CheckLumiMeanValueChanged, true);
            app.CheckLumiMean.Tooltip = {'Luminance of the background'};
            app.CheckLumiMean.Value = 0.6;
            app.BlackluminanceLabel = uilabel(app.GeneralPanel);
            app.BlackluminanceLabel.Text = {'Black luminance:'; ''};
            app.CheckLumiB = uieditfield(app.GeneralPanel, 'numeric');
            app.CheckLumiB.Limits = [0 Inf];
            app.CheckLumiB.ValueChangedFcn = createCallbackFcn(app, @CheckLumiBValueChanged, true);
            app.CheckLumiB.Tooltip = {'Luminance of the flash stimulus'};
            app.WhiteluminanceLabel = uilabel(app.GeneralPanel);
            app.WhiteluminanceLabel.Text = {'White luminance:'; ''};
            app.CheckLumiW = uieditfield(app.GeneralPanel, 'numeric');
            app.CheckLumiW.Limits = [0 Inf];
            app.CheckLumiW.ValueChangedFcn = createCallbackFcn(app, @CheckLumiWValueChanged, true);
            app.CheckLumiW.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiW.Value = 0.6;
            app.ContrastLabel = uilabel(app.GeneralPanel);
            app.ContrastLabel.Text = 'Contrast (%):';
            app.CheckContrast = uieditfield(app.GeneralPanel, 'numeric');
            app.CheckContrast.Limits = [0 100];
            app.CheckContrast.ValueChangedFcn = createCallbackFcn(app, @CheckContrastValueChanged, true);
            app.CheckContrast.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckContrast.Value = 100;
            app.TabGroup3 = uitabgroup(app.GeneralPanel);
            app.ConstrasttabTab = uitab(app.TabGroup3);
            app.ConstrasttabTab.Title = 'Constrast tab';
            app.ContrastFastTable = uitable(app.ConstrasttabTab);
            app.ContrastFastTable.ColumnName = {'Contrast'; 'Mean L'};
            app.ContrastFastTable.ColumnEditable = true;
            app.CheckFastSaveTable = uibutton(app.ConstrasttabTab, 'push');
            app.CheckFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @CheckFastSaveTableButtonPushed, true);
            app.CheckFastSaveTable.Text = 'save';
            app.CheckFastLoadTable = uibutton(app.ConstrasttabTab, 'push');
            app.CheckFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @CheckFastLoadTableButtonPushed, true);
            app.CheckFastLoadTable.Text = 'load';
            app.generatestimuliCheckBtn = uibutton(app.ConstrasttabTab, 'push');
            app.generatestimuliCheckBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliCheckBtnButtonPushed, true);
            app.generatestimuliCheckBtn.Text = 'generate table';
            app.logspacedCheckCk = uicheckbox(app.ConstrasttabTab);
            app.logspacedCheckCk.ValueChangedFcn = createCallbackFcn(app, @logspacedCheckCkValueChanged, true);
            app.logspacedCheckCk.Text = 'log spaced';
            app.logspacedCheckCk.Value = true;
            app.includezeroCheckCk = uicheckbox(app.ConstrasttabTab);
            app.includezeroCheckCk.Text = 'include zero';
            app.includezeroCheckCk.Value = true;
            app.MinLEditFieldLabel_4 = uilabel(app.ConstrasttabTab);
            app.MinLEditFieldLabel_4.Text = 'Min C';
            app.CheckFastMinC = uieditfield(app.ConstrasttabTab, 'numeric');
            app.CheckFastMinC.Limits = [0 100];
            app.CheckFastMinC.ValueChangedFcn = createCallbackFcn(app, @CheckFastMinCValueChanged, true);
            app.CheckFastMinC.Value = 6;
            app.MaxLEditFieldLabel_4 = uilabel(app.ConstrasttabTab);
            app.MaxLEditFieldLabel_4.Text = 'Max C';
            app.CheckFastMaxC = uieditfield(app.ConstrasttabTab, 'numeric');
            app.CheckFastMaxC.Limits = [0 100];
            app.CheckFastMaxC.Value = 95;
            app.ChoosestimEditFieldLabel_6 = uilabel(app.ConstrasttabTab);
            app.ChoosestimEditFieldLabel_6.Text = 'Choose stim.';
            app.CheckFastN = uieditfield(app.ConstrasttabTab, 'numeric');
            app.CheckFastN.Limits = [1 Inf];
            app.CheckFastN.RoundFractionalValues = 'on';
            app.CheckFastN.ValueDisplayFormat = '%.0f';
            app.CheckFastN.ValueChangedFcn = createCallbackFcn(app, @CheckFastNValueChanged, true);
            app.CheckFastN.Value = 1;
            app.noftotstimLabel_6 = uilabel(app.ConstrasttabTab);
            app.noftotstimLabel_6.Text = 'n. of tot stim';
            app.CheckFastTotN = uieditfield(app.ConstrasttabTab, 'numeric');
            app.CheckFastTotN.Limits = [0 Inf];
            app.CheckFastTotN.RoundFractionalValues = 'on';
            app.CheckFastTotN.ValueChangedFcn = createCallbackFcn(app, @CheckFastTotNValueChanged, true);
            app.CheckFastTotN.Value = 6;
            app.MeanLEditFieldLabel = uilabel(app.ConstrasttabTab);
            app.MeanLEditFieldLabel.Text = 'Mean L';
            app.CheckFastMeanL = uieditfield(app.ConstrasttabTab, 'numeric');
            app.CheckFastMeanL.Limits = [0 Inf];
            app.CheckFastMeanL.Value = 0.6;
            app.generatestimuliCheckBtn_2 = uibutton(app.ConstrasttabTab, 'push');
            app.generatestimuliCheckBtn_2.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliCheckBtn_2ButtonPushed, true);
            app.generatestimuliCheckBtn_2.Text = 'clear table';
            app.SpatialFreqtabTab = uitab(app.TabGroup3);
            app.SpatialFreqtabTab.Title = 'Spatial Freq tab';
            app.generatestimuliSFBtn = uibutton(app.SpatialFreqtabTab, 'push');
            app.generatestimuliSFBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliSFBtnButtonPushed, true);
            app.generatestimuliSFBtn.Text = 'generate table';
            app.logspacedSfCk = uicheckbox(app.SpatialFreqtabTab);
            app.logspacedSfCk.Text = 'log spaced';
            app.logspacedSfCk.Value = true;
            app.SfFastTable = uitable(app.SpatialFreqtabTab);
            app.SfFastTable.ColumnName = {'Spat freq (cyc/deg)'};
            app.SfFastTable.ColumnEditable = true;
            app.SfFastSaveTable = uibutton(app.SpatialFreqtabTab, 'push');
            app.SfFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @SfFastSaveTableButtonPushed, true);
            app.SfFastSaveTable.Text = 'save';
            app.SfFastLoadTable = uibutton(app.SpatialFreqtabTab, 'push');
            app.SfFastLoadTable.Text = 'load';
            app.MinLEditFieldLabel_5 = uilabel(app.SpatialFreqtabTab);
            app.MinLEditFieldLabel_5.Text = 'Min sf (cyc/deg)';
            app.SfFastMinC = uieditfield(app.SpatialFreqtabTab, 'numeric');
            app.SfFastMinC.Limits = [0 100];
            app.SfFastMinC.Value = 0.01;
            app.MaxLEditFieldLabel_5 = uilabel(app.SpatialFreqtabTab);
            app.MaxLEditFieldLabel_5.Text = 'Max sf (cyc/deg)';
            app.SfFastMaxC = uieditfield(app.SpatialFreqtabTab, 'numeric');
            app.SfFastMaxC.Limits = [0 100];
            app.SfFastMaxC.Value = 1;
            app.NstimEditFieldLabel = uilabel(app.SpatialFreqtabTab);
            app.NstimEditFieldLabel.Text = 'N stim';
            app.NstimEditField = uieditfield(app.SpatialFreqtabTab, 'numeric');
            app.NstimEditField.Limits = [0 Inf];
            app.NstimEditField.Value = 2;
            app.ChoosestimLabel = uilabel(app.SpatialFreqtabTab);
            app.ChoosestimLabel.Text = 'Choose stim.';
            app.SfFastN = uieditfield(app.SpatialFreqtabTab, 'numeric');
            app.SfFastN.Limits = [1 Inf];
            app.SfFastN.RoundFractionalValues = 'on';
            app.SfFastN.ValueDisplayFormat = '%.0f';
            app.SfFastN.ValueChangedFcn = createCallbackFcn(app, @SfFastNValueChanged, true);
            app.SfFastN.Value = 1;
            app.generatestimuliCheckBtn_3 = uibutton(app.SpatialFreqtabTab, 'push');
            app.generatestimuliCheckBtn_3.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliCheckBtn_3ButtonPushed, true);
            app.generatestimuliCheckBtn_3.Text = 'clear table';
            app.TabGroup4 = uitabgroup(app.ContrastTab);
            app.CheckTab = uitab(app.TabGroup4);
            app.CheckTab.Title = 'Check.';
            app.CheckGo = uibutton(app.CheckTab, 'push');
            app.CheckGo.ButtonPushedFcn = createCallbackFcn(app, @GoCheck_Callback, true);
            app.CheckGo.Text = 'Checkerboard GO!';
            app.RepetitionsEditField_2Label = uilabel(app.CheckTab);
            app.RepetitionsEditField_2Label.Text = 'Repetitions:';
            app.CheckRepetitions = uieditfield(app.CheckTab, 'numeric');
            app.CheckRepetitions.Value = 20;
            app.PeriodsLabel_2 = uilabel(app.CheckTab);
            app.PeriodsLabel_2.Text = 'Period (s):';
            app.CheckPeriod = uieditfield(app.CheckTab, 'numeric');
            app.CheckPeriod.Limits = [0 Inf];
            app.CheckPeriod.Value = 4;
            app.GridsTab = uitab(app.TabGroup4);
            app.GridsTab.Title = 'Grids';
            app.DrawMask = uicheckbox(app.GridsTab);
            app.DrawMask.Text = 'Gaussian mask';
            app.UsestandbyluminanceforBaselinepreandBaselinepostCheckBox = uicheckbox(app.GridsTab);
            app.UsestandbyluminanceforBaselinepreandBaselinepostCheckBox.Tooltip = {'...instead of "Mean luminance"'};
            app.UsestandbyluminanceforBaselinepreandBaselinepostCheckBox.Text = {'Use standby luminance for'; 'Baseline "pre" and Baseline "post"'};
            app.OrientationdegLabel_2 = uilabel(app.GridsTab);
            app.OrientationdegLabel_2.Text = 'Orientation (deg):';
            app.GridOrientation = uieditfield(app.GridsTab, 'numeric');
            app.GridOrientation.Limits = [0 360];
            app.GridOrientation.ValueChangedFcn = createCallbackFcn(app, @GridOrientationValueChanged, true);
            app.DriftingspeedcyclessecEditFieldLabel = uilabel(app.GridsTab);
            app.DriftingspeedcyclessecEditFieldLabel.Text = 'Drifting speed (cycles/sec):';
            app.DriftingSpeed_txt = uieditfield(app.GridsTab, 'numeric');
            app.DriftingSpeed_txt.Limits = [0 Inf];
            app.DriftingSpeed_txt.Value = 2;
            app.sizepixLabel = uilabel(app.GridsTab);
            app.sizepixLabel.Text = 'size (pix):';
            app.GaussSize_txt = uieditfield(app.GridsTab, 'numeric');
            app.GaussSize_txt.Value = 300;
            app.ShapeButtonGroup = uibuttongroup(app.GridsTab);
            app.ShapeButtonGroup.Title = 'Shape';
            app.SineGrid = uiradiobutton(app.ShapeButtonGroup);
            app.SineGrid.Text = 'Sinusoidal';
            app.SquareGrid = uiradiobutton(app.ShapeButtonGroup);
            app.SquareGrid.Text = 'Square';
            app.SquareGrid.Value = true;
            app.TabGroup6 = uitabgroup(app.GridsTab);
            app.TraditionalTab = uitab(app.TabGroup6);
            app.TraditionalTab.Title = 'Traditional';
            app.RepetitionsEditFieldLabel_3 = uilabel(app.TraditionalTab);
            app.RepetitionsEditFieldLabel_3.Text = 'Repetitions';
            app.GridRepetitions = uieditfield(app.TraditionalTab, 'numeric');
            app.GridRepetitions.Limits = [0 Inf];
            app.GridRepetitions.Value = 20;
            app.FastOrientationStimulusSelectionPanel = uipanel(app.TraditionalTab);
            app.FastOrientationStimulusSelectionPanel.Title = 'Fast Orientation Stimulus Selection';
            app.generatetable_orientBtn = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.generatetable_orientBtn.ButtonPushedFcn = createCallbackFcn(app, @generatetable_orientBtnButtonPushed, true);
            app.generatetable_orientBtn.Text = 'generate table';
            app.OrientFastSaveTable = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.OrientFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @OrientFastSaveTableButtonPushed, true);
            app.OrientFastSaveTable.Text = 'save ';
            app.OrientFastLoadTable = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.OrientFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @OrientFastLoadTableButtonPushed, true);
            app.OrientFastLoadTable.Text = 'load ';
            app.noftotstimLabel_5 = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.noftotstimLabel_5.Text = 'n. of tot stim';
            app.OrientFastTotN = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastTotN.Limits = [0 Inf];
            app.OrientFastTotN.Value = 4;
            app.StartAngleEditFieldLabel = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.StartAngleEditFieldLabel.Text = 'Start Angle';
            app.OrientFastMinA = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.EndAngleEditFieldLabel = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.EndAngleEditFieldLabel.Text = 'End Angle';
            app.OrientFastMaxA = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastMaxA.Value = 270;
            app.OrientFastTable = uitable(app.FastOrientationStimulusSelectionPanel);
            app.OrientFastTable.ColumnName = {'Orientation'};
            app.ChoosestimEditFieldLabel_5 = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel_5.Text = 'Choose stim.';
            app.OrientFastN = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastN.Limits = [1 Inf];
            app.OrientFastN.RoundFractionalValues = 'on';
            app.OrientFastN.ValueDisplayFormat = '%.0f';
            app.OrientFastN.ValueChangedFcn = createCallbackFcn(app, @OrientFastNValueChanged, true);
            app.OrientFastN.Value = 1;
            app.cleartable_orientBtn = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.cleartable_orientBtn.ButtonPushedFcn = createCallbackFcn(app, @cleartable_orientBtnButtonPushed, true);
            app.cleartable_orientBtn.Text = 'clear table';
            app.GoGrid = uibutton(app.TraditionalTab, 'push');
            app.GoGrid.ButtonPushedFcn = createCallbackFcn(app, @GoGrid_Callback, true);
            app.GoGrid.Text = 'Grating GO!';
            app.RandomizedTab = uitab(app.TabGroup6);
            app.RandomizedTab.Title = 'Randomized';
            app.Panel_2 = uipanel(app.RandomizedTab);
            app.GoRandGrid = uibutton(app.Panel_2, 'push');
            app.GoRandGrid.ButtonPushedFcn = createCallbackFcn(app, @GoRandGridButtonPushed, true);
            app.GoRandGrid.Text = 'Randomized Grating GO!';
            app.RandFastTable = uitable(app.Panel_2);
            app.RandFastTable.ColumnName = {'Constrast'; 'Mean Lumi'; 'Orient'; 'Spat freq'; 'Lag'};
            app.RandFastTable.ColumnEditable = [false false false false true];
            app.UsecontrastandorientationvaluesfromLabel = uilabel(app.Panel_2);
            app.UsecontrastandorientationvaluesfromLabel.Text = 'Use spatial frequency and orientation values from "Fast Spatial freq selection" and "Fast orientation stimulus selection" tables. Randomizes order. Use grating params from Gratings tab.';
            app.AddrandomlagaftereachstimulusCheckBox = uicheckbox(app.Panel_2);
            app.AddrandomlagaftereachstimulusCheckBox.Text = 'Add random lag after each stimulus';
            app.AddrandomlagaftereachstimulusCheckBox.Value = true;
            app.MaxlagsEditFieldLabel = uilabel(app.Panel_2);
            app.MaxlagsEditFieldLabel.Text = 'Max lag (s):';
            app.MaxlagsEditField = uieditfield(app.Panel_2, 'numeric');
            app.MaxlagsEditField.Value = 0.5;
            app.GenRandStim = uibutton(app.Panel_2, 'push');
            app.GenRandStim.ButtonPushedFcn = createCallbackFcn(app, @GenRandStimButtonPushed, true);
            app.GenRandStim.Text = {'Generate'; 'table'};
            app.SaveRandStim = uibutton(app.Panel_2, 'push');
            app.SaveRandStim.ButtonPushedFcn = createCallbackFcn(app, @SaveRandStimButtonPushed, true);
            app.SaveRandStim.Text = 'Save';
            app.LoadRandStim = uibutton(app.Panel_2, 'push');
            app.LoadRandStim.ButtonPushedFcn = createCallbackFcn(app, @LoadRandStimButtonPushed, true);
            app.LoadRandStim.Text = 'Load';
            app.NrepEditFieldLabel = uilabel(app.Panel_2);
            app.NrepEditFieldLabel.Text = 'N rep';
            app.NrepEditField = uieditfield(app.Panel_2, 'numeric');
            app.NrepEditField.Value = 5;
            app.BaselinepresEditFieldLabel = uilabel(app.GridsTab);
            app.BaselinepresEditFieldLabel.Text = 'Baseline "pre" (s):';
            app.BaselinepresEditField = uieditfield(app.GridsTab, 'numeric');
            app.GridssEditFieldLabel = uilabel(app.GridsTab);
            app.GridssEditFieldLabel.Text = 'Grids (s):';
            app.GridssEditField = uieditfield(app.GridsTab, 'numeric');
            app.GridssEditField.ValueChangedFcn = createCallbackFcn(app, @GridDurationValueChanged, true);
            app.BaselinepostsEditFieldLabel = uilabel(app.GridsTab);
            app.BaselinepostsEditFieldLabel.Text = 'Baseline "post" (s):';
            app.BaselinepostsEditField = uieditfield(app.GridsTab, 'numeric');
            app.generateTTL_preCheckBox = uicheckbox(app.GridsTab);
            app.generateTTL_preCheckBox.Enable = 'off';
            app.generateTTL_preCheckBox.Text = 'generate TTL';
            app.generateTTL_gridCheckBox = uicheckbox(app.GridsTab);
            app.generateTTL_gridCheckBox.Enable = 'off';
            app.generateTTL_gridCheckBox.Text = 'generate TTL';
            app.generateTTL_gridCheckBox.Value = true;
            app.generateTTL_postCheckBox = uicheckbox(app.GridsTab);
            app.generateTTL_postCheckBox.Enable = 'off';
            app.generateTTL_postCheckBox.Text = 'generate TTL';
            app.DotsTab = uitab(app.TabGroup4);
            app.DotsTab.Title = 'Dots';
            app.RandSpeedCheckBox = uicheckbox(app.DotsTab);
            app.RandSpeedCheckBox.Text = 'Random';
            app.RandSizeCheckBox = uicheckbox(app.DotsTab);
            app.RandSizeCheckBox.Text = 'Random';
            app.GoDots = uibutton(app.DotsTab, 'push');
            app.GoDots.ButtonPushedFcn = createCallbackFcn(app, @GoDotsButtonPushed, true);
            app.GoDots.Text = 'Dots GO!';
            app.DarkbackgroundCheckBox = uicheckbox(app.DotsTab);
            app.DarkbackgroundCheckBox.Tooltip = {'If checked, dots are lighter than background. Dots and background intensity depends on contrast'};
            app.DarkbackgroundCheckBox.Text = 'Dark background';
            app.DarkbackgroundCheckBox.Value = true;
            app.EquiluminantbaselineCheckBox = uicheckbox(app.DotsTab);
            app.EquiluminantbaselineCheckBox.Tooltip = {''};
            app.EquiluminantbaselineCheckBox.Text = 'Equiluminant baseline';
            app.EquiluminantbaselineCheckBox.Value = true;
            app.NdotsLabel = uilabel(app.DotsTab);
            app.NdotsLabel.Text = 'N dots:';
            app.NdotsEditField = uieditfield(app.DotsTab, 'numeric');
            app.NdotsEditField.ValueChangedFcn = createCallbackFcn(app, @NdotsEditFieldValueChanged, true);
            app.Dotsdensitycm2Label = uilabel(app.DotsTab);
            app.Dotsdensitycm2Label.Text = 'Dots density (cm^-2):';
            app.DotsdensityEditField = uieditfield(app.DotsTab, 'numeric');
            app.DotsdensityEditField.ValueChangedFcn = createCallbackFcn(app, @DotsdensityEditFieldValueChanged, true);
            app.OrientationdegLabel = uilabel(app.DotsTab);
            app.OrientationdegLabel.Text = 'Orientation (deg):';
            app.DotsOrientation = uieditfield(app.DotsTab, 'numeric');
            app.DotsOrientation.Limits = [0 360];
            app.DriftingspeeddegsecLabel = uilabel(app.DotsTab);
            app.DriftingspeeddegsecLabel.Text = 'Drifting speed (deg/sec):';
            app.dotsDriftingSpeed = uieditfield(app.DotsTab, 'numeric');
            app.dotsDriftingSpeed.Limits = [0 Inf];
            app.dotsDriftingSpeed.Value = 50;
            app.DotsdiameterdegLabel = uilabel(app.DotsTab);
            app.DotsdiameterdegLabel.Text = 'Dots diameter (deg):';
            app.DotsdiameterdegEditField = uieditfield(app.DotsTab, 'numeric');
            app.DotsdiameterdegEditField.Value = 12;
            app.DotslifetimesLabel = uilabel(app.DotsTab);
            app.DotslifetimesLabel.Text = 'Dots lifetime (s):';
            app.DotslifetimesEditField = uieditfield(app.DotsTab, 'numeric');
            app.DotslifetimesEditField.Tooltip = {'If ''Inf'', no dot is killed.'};
            app.DotslifetimesEditField.Value = 4;
            app.RepetitionsEditField_2Label_2 = uilabel(app.DotsTab);
            app.RepetitionsEditField_2Label_2.Text = 'Repetitions:';
            app.DotsRepetitions = uieditfield(app.DotsTab, 'numeric');
            app.DotsRepetitions.Value = 20;
            app.generateTTL_pre_dotCheckBox = uicheckbox(app.DotsTab);
            app.generateTTL_pre_dotCheckBox.Enable = 'off';
            app.generateTTL_pre_dotCheckBox.Text = 'generate TTL';
            app.generateTTL_dotCheckBox = uicheckbox(app.DotsTab);
            app.generateTTL_dotCheckBox.Enable = 'off';
            app.generateTTL_dotCheckBox.Text = 'generate TTL';
            app.generateTTL_dotCheckBox.Value = true;
            app.generateTTL_post_dotCheckBox = uicheckbox(app.DotsTab);
            app.generateTTL_post_dotCheckBox.Enable = 'off';
            app.generateTTL_post_dotCheckBox.Text = 'generate TTL';
            app.BaselinepresEditField_2Label = uilabel(app.DotsTab);
            app.BaselinepresEditField_2Label.Text = 'Baseline "pre" (s):';
            app.Baselinepres_dotEditField = uieditfield(app.DotsTab, 'numeric');
            app.DotssLabel = uilabel(app.DotsTab);
            app.DotssLabel.Text = 'Dots (s):';
            app.dots_durationEditField = uieditfield(app.DotsTab, 'numeric');
            app.BaselinepostsEditField_2Label = uilabel(app.DotsTab);
            app.BaselinepostsEditField_2Label.Text = 'Baseline "post" (s):';
            app.Baselineposts_dotEditField = uieditfield(app.DotsTab, 'numeric');
            app.RetinotopyTab = uitab(app.TabGroup4);
            app.RetinotopyTab.Title = 'Retinotopy';
            app.RetinotopyGOButton = uibutton(app.RetinotopyTab, 'push');
            app.RetinotopyGOButton.ButtonPushedFcn = createCallbackFcn(app, @GoRetinotopy_Callback, true);
            app.RetinotopyGOButton.Text = 'Retinotopy GO!';
            app.Panel = uipanel(app.RetinotopyTab);
            app.BartraveltimesEditFieldLabel = uilabel(app.Panel);
            app.BartraveltimesEditFieldLabel.Tooltip = {'The time needed for the rectangle to run through the whole screen.'};
            app.BartraveltimesEditFieldLabel.Text = 'Bar travel time (s):';
            app.BartraveltimesEditField = uieditfield(app.Panel, 'numeric');
            app.BartraveltimesEditField.ValueChangedFcn = createCallbackFcn(app, @BartraveltimesEditFieldValueChanged, true);
            app.BartraveltimesEditField.Tooltip = {'The time needed for the rectangle to run through the largest dimension of the screen.'};
            app.BartraveltimesEditField.Value = 10;
            app.KeepconstantspeedCheckBox = uicheckbox(app.Panel);
            app.KeepconstantspeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepconstantspeedCheckBoxValueChanged, true);
            app.KeepconstantspeedCheckBox.Text = 'Keep constant speed';
            app.KeepconstantspeedCheckBox.Value = true;
            app.BarspeeddegsLabel = uilabel(app.Panel);
            app.BarspeeddegsLabel.Tooltip = {'The time needed for the rectangle to run through the whole screen.'};
            app.BarspeeddegsLabel.Text = 'Bar speed (deg/s):';
            app.IntrinsicStimBarSpeedEdit = uieditfield(app.Panel, 'numeric');
            app.IntrinsicStimBarSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @IntrinsicStimBarSpeedEditValueChanged, true);
            app.IntrinsicStimBarSpeedEdit.Tooltip = {'The time needed for the rectangle to run through the largest dimension of the screen.'};
            app.IntrinsicStimBarSpeedEdit.Value = 12;
            app.MovementLabel = uilabel(app.RetinotopyTab);
            app.MovementLabel.Text = 'Movement';
            app.IntrOrientationSwitch = uiswitch(app.RetinotopyTab, 'toggle');
            app.IntrOrientationSwitch.Items = {'Horizontal', 'Vertical'};
            app.IntrOrientationSwitch.ValueChangedFcn = createCallbackFcn(app, @IntrOrientationSwitchValueChanged, true);
            app.IntrOrientationSwitch.Value = 'Horizontal';
            app.DirectionLabel = uilabel(app.RetinotopyTab);
            app.DirectionLabel.Text = 'Direction';
            app.IntrDirectionSwitch = uiswitch(app.RetinotopyTab, 'toggle');
            app.IntrDirectionSwitch.Items = {'Left-to-right', 'Right-to-left'};
            app.IntrDirectionSwitch.Value = 'Left-to-right';
            app.BarsizedegLabel = uilabel(app.RetinotopyTab);
            app.BarsizedegLabel.Text = 'Bar size (deg):';
            app.IntrRectSizeTxt = uieditfield(app.RetinotopyTab, 'numeric');
            app.IntrRectSizeTxt.Limits = [0 Inf];
            app.IntrRectSizeTxt.Value = 10;
            app.BaselinetimesLabel = uilabel(app.RetinotopyTab);
            app.BaselinetimesLabel.Text = 'Baseline time (s):';
            app.RetinotopyPostGTime = uieditfield(app.RetinotopyTab, 'numeric');
            app.RetinotopyPostGTime.Limits = [0 Inf];
            app.RetinotopyPostGTime.Value = 1;
            app.RepetitionsEditFieldLabel_4 = uilabel(app.RetinotopyTab);
            app.RepetitionsEditFieldLabel_4.Text = 'Repetitions:';
            app.RetinotopyRepetitions = uieditfield(app.RetinotopyTab, 'numeric');
            app.RetinotopyRepetitions.Limits = [0 Inf];
            app.RetinotopyRepetitions.RoundFractionalValues = 'on';
            app.RetinotopyRepetitions.Value = 10;
            app.CheckerboardfrequencyHzLabel = uilabel(app.RetinotopyTab);
            app.CheckerboardfrequencyHzLabel.Text = {'Checkerboard'; 'frequency (Hz):'};
            app.RetinotopyCheckFreqEditField = uieditfield(app.RetinotopyTab, 'numeric');
            app.RetinotopyCheckFreqEditField.Limits = [0 Inf];
            app.RetinotopyCheckFreqEditField.RoundFractionalValues = 'on';
            app.RetinotopyCheckFreqEditField.Value = 5;
            app.FlashTab = uitab(app.TabGroup2);
            app.FlashTab.Title = 'Flash';
            app.TabGroup5 = uitabgroup(app.FlashTab);
            app.FullscreenflashesTab = uitab(app.TabGroup5);
            app.FullscreenflashesTab.Title = 'Full screen flashes';
            app.FSFlashGo = uibutton(app.FullscreenflashesTab, 'push');
            app.FSFlashGo.ButtonPushedFcn = createCallbackFcn(app, @GoFlash_Callback, true);
            app.FSFlashGo.Text = 'Flash GO!';
            app.FastFlashStimulusSelectionPanel = uipanel(app.FullscreenflashesTab);
            app.FastFlashStimulusSelectionPanel.Title = 'Fast Flash Stimulus Selection';
            app.FlashFastTable = uitable(app.FastFlashStimulusSelectionPanel);
            app.FlashFastTable.ColumnName = {'Flash L'; 'Base L'};
            app.FlashFastTable.RowName = {};
            app.FlashFastTable.ColumnEditable = true;
            app.noftotstimLabel_4 = uilabel(app.FastFlashStimulusSelectionPanel);
            app.noftotstimLabel_4.Text = 'n. of tot stim';
            app.FlashFastTotN = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastTotN.Limits = [1 Inf];
            app.FlashFastTotN.RoundFractionalValues = 'on';
            app.FlashFastTotN.ValueChangedFcn = createCallbackFcn(app, @FlashFastTotNValueChanged, true);
            app.FlashFastTotN.Value = 9;
            app.MinLEditFieldLabel_3 = uilabel(app.FastFlashStimulusSelectionPanel);
            app.MinLEditFieldLabel_3.Text = 'Min L';
            app.FlashFastMinL = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastMinL.Limits = [0 Inf];
            app.FlashFastMinL.ValueChangedFcn = createCallbackFcn(app, @FlashFastMinLValueChanged, true);
            app.FlashFastMinL.Value = 0.1;
            app.MaxLEditFieldLabel_3 = uilabel(app.FastFlashStimulusSelectionPanel);
            app.MaxLEditFieldLabel_3.Text = 'Max L';
            app.FlashFastMaxL = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastMaxL.Limits = [0 Inf];
            app.FlashFastMaxL.Value = 0.6;
            app.logspacedFlashCk = uicheckbox(app.FastFlashStimulusSelectionPanel);
            app.logspacedFlashCk.ValueChangedFcn = createCallbackFcn(app, @logspacedFlashCkValueChanged, true);
            app.logspacedFlashCk.Text = 'log spaced';
            app.logspacedFlashCk.Value = true;
            app.includezeroFlashCk = uicheckbox(app.FastFlashStimulusSelectionPanel);
            app.includezeroFlashCk.Text = 'include zero';
            app.includezeroFlashCk.Value = true;
            app.generatestimuliFlashBtn = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.generatestimuliFlashBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliFlashBtnPushed, true);
            app.generatestimuliFlashBtn.Text = {'generate stimuli'; 'table'};
            app.savetableButton = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.savetableButton.ButtonPushedFcn = createCallbackFcn(app, @savetableButtonPushed, true);
            app.savetableButton.Text = 'save table';
            app.FlashFastLoadTable = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.FlashFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @FlashFastLoadTableButtonPushed, true);
            app.FlashFastLoadTable.Text = 'load table';
            app.ChoosestimEditFieldLabel_4 = uilabel(app.FastFlashStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel_4.Text = 'Choose stim.';
            app.FlashFastN = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastN.Limits = [1 Inf];
            app.FlashFastN.RoundFractionalValues = 'on';
            app.FlashFastN.ValueDisplayFormat = '%.0f';
            app.FlashFastN.ValueChangedFcn = createCallbackFcn(app, @FlashFastN_Callback, true);
            app.FlashFastN.Value = 1;
            app.generatestimuliFlashBtn_2 = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.generatestimuliFlashBtn_2.Text = {'clear stimuli'; 'table'};
            app.ReceptivefieldTab = uitab(app.TabGroup5);
            app.ReceptivefieldTab.Title = 'Receptive field';
            app.UIAxes = uiaxes(app.ReceptivefieldTab);
            title(app.UIAxes, 'Screen')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = {''; ''};
            app.randomizeorderCheckBox = uicheckbox(app.ReceptivefieldTab);
            app.randomizeorderCheckBox.Text = 'randomize order';
            app.RecptiveFieldGo = uibutton(app.ReceptivefieldTab, 'push');
            app.RecptiveFieldGo.ButtonPushedFcn = createCallbackFcn(app, @RecptiveFieldGoButtonPushed, true);
            app.RecptiveFieldGo.Text = 'Receptivefield GO!';
            app.rowsEditFieldLabel = uilabel(app.ReceptivefieldTab);
            app.rowsEditFieldLabel.Text = '# rows';
            app.rowsEditField = uieditfield(app.ReceptivefieldTab, 'numeric');
            app.rowsEditField.ValueChangedFcn = createCallbackFcn(app, @rowsEditFieldValueChanged, true);
            app.rowsEditField.Value = 9;
            app.columnsEditFieldLabel = uilabel(app.ReceptivefieldTab);
            app.columnsEditFieldLabel.Text = '# columns';
            app.columnsEditField = uieditfield(app.ReceptivefieldTab, 'numeric');
            app.columnsEditField.ValueChangedFcn = createCallbackFcn(app, @columnsEditFieldValueChanged, true);
            app.columnsEditField.Value = 16;
            app.LogfilenameEditFieldLabel = uilabel(app.ReceptivefieldTab);
            app.LogfilenameEditFieldLabel.Text = 'Log file name';
            app.LogfilenameEditField = uieditfield(app.ReceptivefieldTab, 'text');
            app.LogfilenameEditField.Value = 'name';
            app.OpenlogdirectoryButton = uibutton(app.ReceptivefieldTab, 'push');
            app.OpenlogdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenlogdirectoryButtonPushed, true);
            app.OpenlogdirectoryButton.Text = 'Open log directory';
            app.GeneralPanel_2 = uipanel(app.FlashTab);
            app.GeneralPanel_2.Title = 'General';
            app.LabelLumi_7 = uilabel(app.GeneralPanel_2);
            app.LabelLumi_7.Text = 'cd/m²';
            app.LabelLumi_8 = uilabel(app.GeneralPanel_2);
            app.LabelLumi_8.Text = 'cd/m²';
            app.FSbaselineLEsch = uieditfield(app.GeneralPanel_2, 'numeric');
            app.FSbaselineLEsch.Limits = [0 Inf];
            app.FSbaselineLEsch.ValueDisplayFormat = '%5.3g';
            app.FSbaselineLEsch.Editable = 'off';
            app.FSbaselineLEsch.Tooltip = {'Luminance of the background'};
            app.LabelLumiEsch_7 = uilabel(app.GeneralPanel_2);
            app.LabelLumiEsch_7.Text = '(0-1)';
            app.FSflashLEsch = uieditfield(app.GeneralPanel_2, 'numeric');
            app.FSflashLEsch.Limits = [0 Inf];
            app.FSflashLEsch.Editable = 'off';
            app.FSflashLEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashLEsch.Value = 0.8;
            app.LabelLumiEsch_8 = uilabel(app.GeneralPanel_2);
            app.LabelLumiEsch_8.Text = '(0-1)';
            app.BaselineluminanceLabel = uilabel(app.GeneralPanel_2);
            app.BaselineluminanceLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.BaselineluminanceLabel.Text = 'Baseline luminance:';
            app.FSbaselineL = uieditfield(app.GeneralPanel_2, 'numeric');
            app.FSbaselineL.Limits = [0 Inf];
            app.FSbaselineL.ValueDisplayFormat = '%5.3g';
            app.FSbaselineL.ValueChangedFcn = createCallbackFcn(app, @FSbaselineLValueChanged, true);
            app.FSbaselineL.Tooltip = {'Luminance of the background'};
            app.FlashluminanceLabel = uilabel(app.GeneralPanel_2);
            app.FlashluminanceLabel.Text = {'Flash luminance:'; ''};
            app.FSflashL = uieditfield(app.GeneralPanel_2, 'numeric');
            app.FSflashL.Limits = [0 Inf];
            app.FSflashL.ValueChangedFcn = createCallbackFcn(app, @FSflashLValueChanged, true);
            app.FSflashL.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashL.Value = 0.8;
            app.FlashdurationsLabel = uilabel(app.GeneralPanel_2);
            app.FlashdurationsLabel.Text = 'Flash duration (s):';
            app.FlashDuration = uieditfield(app.GeneralPanel_2, 'numeric');
            app.FlashDuration.Limits = [0 Inf];
            app.FlashDuration.Value = 0.1;
            app.RepetitionsLabel_4 = uilabel(app.GeneralPanel_2);
            app.RepetitionsLabel_4.Text = 'Repetitions:';
            app.FlashRepetitions = uieditfield(app.GeneralPanel_2, 'numeric');
            app.FlashRepetitions.Limits = [0 Inf];
            app.FlashRepetitions.Value = 20;
            app.PeriodsLabel_3 = uilabel(app.GeneralPanel_2);
            app.PeriodsLabel_3.Text = 'Period (s):';
            app.FlashPeriod = uieditfield(app.GeneralPanel_2, 'numeric');
            app.FlashPeriod.Limits = [0 Inf];
            app.FlashPeriod.Value = 4;
            app.ProvideadarkstimulusasbaselineforeveryflashCheckBox = uicheckbox(app.GeneralPanel_2);
            app.ProvideadarkstimulusasbaselineforeveryflashCheckBox.Tooltip = {'useful for widefiled imaging. THIS DOUBLES THE STIMOLATION TIME!!'};
            app.ProvideadarkstimulusasbaselineforeveryflashCheckBox.Text = {'Provide a dark stimulus as'; 'baseline for every flash'};
            app.uipanel14 = uipanel(app.StimulationTab);
            app.uipanel14.Title = 'General Settings';
            app.InitialbaselinesLabel = uilabel(app.uipanel14);
            app.InitialbaselinesLabel.Text = 'Initial baseline(s):';
            app.initialDelay = uieditfield(app.uipanel14, 'numeric');
            app.initialDelay.Limits = [0 Inf];
            app.initialDelay.Tooltip = {'Initial delay from the start of the sequence to the apparition of the first stimulus. The screen is set to the baseline or mean luminance'};
            app.initialDelay.Value = 2;
            app.SendTTLwhenbaselinestartsCheckBox = uicheckbox(app.uipanel14);
            app.SendTTLwhenbaselinestartsCheckBox.Tooltip = {'Only works if Hermes is connected and properly configured.'};
            app.SendTTLwhenbaselinestartsCheckBox.Text = 'Send TTL when "baseline" starts';
            app.PCOImagingTab = uitab(app.TabGroup);
            app.PCOImagingTab.Title = 'PCO Imaging';
            app.StimulationandPCOPanel = uipanel(app.PCOImagingTab);
            app.StimulationandPCOPanel.Title = 'Stimulation and PCO';
            app.PcoWhileStimulusCk = uicheckbox(app.StimulationandPCOPanel);
            app.PcoWhileStimulusCk.ValueChangedFcn = createCallbackFcn(app, @PcoWhileStimulusCkValueChanged, true);
            app.PcoWhileStimulusCk.Text = {'PCO imaging'; 'while stimulating'};
            app.ButtonGroup_5 = uibuttongroup(app.StimulationandPCOPanel);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.AutomaticallydetermineacquisitiontimeButton = uiradiobutton(app.ButtonGroup_5);
            app.AutomaticallydetermineacquisitiontimeButton.Text = {'Automatically determine'; 'acquisition time'};
            app.AutomaticallydetermineacquisitiontimeButton.Value = true;
            app.SettrialacquisitiontimeButton = uiradiobutton(app.ButtonGroup_5);
            app.SettrialacquisitiontimeButton.Text = {'Set trial acquisition'; 'time'};
            app.secondsLabel = uilabel(app.ButtonGroup_5);
            app.secondsLabel.Text = 'seconds:';
            app.PCOTrialEditField = uieditfield(app.ButtonGroup_5, 'numeric');
            app.PCOTrialEditField.Limits = [0 Inf];
            app.PCOTrialEditField.Tooltip = {'Amount of time the PCO will stop acquiring before the start of each trial. Rounded to microsecond precision.'};
            app.PCOTrialEditField.Value = 6;
            app.DeadtimebetweeneachtrialmsEditFieldLabel = uilabel(app.ButtonGroup_5);
            app.DeadtimebetweeneachtrialmsEditFieldLabel.Text = {'Dead time between'; 'each trial (ms):'};
            app.DeadTBeforeTrialEditField = uieditfield(app.ButtonGroup_5, 'numeric');
            app.DeadTBeforeTrialEditField.Limits = [0 Inf];
            app.DeadTBeforeTrialEditField.Tooltip = {'Amount of time the PCO will stop acquiring before the start of each trial. Rounded to microsecond precision.'};
            app.DeadTBeforeTrialEditField.Value = 150;
            app.IfLabel = uilabel(app.StimulationandPCOPanel);
            app.IfLabel.Text = {'Pay attention to "Gratings":'; ''; 'If "Automatically determine'; 'acquisition time" is selected,'; 'make sure that "Baseline" time'; 'is not shorter than "Grating" time.'; ''; 'If "Set trial acquisition'; 'time" is selected, make sure that'; '"Baseline" time is not shorter than'; 'the selected time for imaging.'; 'Also the sum of "Grating" time '; 'and "Post" time should not be'; 'shorter than it.'; ''; ''; ''};
            app.SettingspanelPanel = uipanel(app.PCOImagingTab);
            app.SettingspanelPanel.Title = 'Settings panel';
            app.VerticalBinningDropDown = uidropdown(app.SettingspanelPanel);
            app.VerticalBinningDropDown.Items = {'01', '02', '04'};
            app.VerticalBinningDropDown.Value = '04';
            app.VerticalBinningDropDownLabel = uilabel(app.SettingspanelPanel);
            app.VerticalBinningDropDownLabel.Text = 'Vertical Binning';
            app.HermesLEDcontrolCheckBox = uicheckbox(app.SettingspanelPanel);
            app.HermesLEDcontrolCheckBox.ValueChangedFcn = createCallbackFcn(app, @HermesLEDcontrolCheckBoxValueChanged, true);
            app.HermesLEDcontrolCheckBox.Text = 'Hermes LED control';
            app.HermesLEDcontrolCheckBox.Value = true;
            app.TriggermodeLabel = uilabel(app.SettingspanelPanel);
            app.TriggermodeLabel.Text = 'Trigger mode:';
            app.PcoSyncDropDown = uidropdown(app.SettingspanelPanel);
            app.PcoSyncDropDown.Items = {'Software (Escher)', 'Hardware (Hermes)'};
            app.PcoSyncDropDown.ValueChangedFcn = createCallbackFcn(app, @PcoSyncDropDownValueChanged, true);
            app.PcoSyncDropDown.Value = 'Software (Escher)';
            app.TimestampsDropDownLabel = uilabel(app.SettingspanelPanel);
            app.TimestampsDropDownLabel.Text = 'Timestamps:';
            app.TimestampsDropDown = uidropdown(app.SettingspanelPanel);
            app.TimestampsDropDown.Items = {'None', 'Binary only', 'Binary and ASCII'};
            app.TimestampsDropDown.Value = 'Binary and ASCII';
            app.ExposureTimemsLabel = uilabel(app.SettingspanelPanel);
            app.ExposureTimemsLabel.Text = 'Exposure Time (ms):';
            app.ExposureTimeEditField = uieditfield(app.SettingspanelPanel, 'numeric');
            app.ExposureTimeEditField.Limits = [0 Inf];
            app.ExposureTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeEditFieldValueChanged, true);
            app.ExposureTimeEditField.Value = 20;
            app.HorizontalBinningDropDownLabel = uilabel(app.SettingspanelPanel);
            app.HorizontalBinningDropDownLabel.Text = 'Horizontal Binning';
            app.HorizontalBinningDropDown = uidropdown(app.SettingspanelPanel);
            app.HorizontalBinningDropDown.Items = {'01', '02', '04'};
            app.HorizontalBinningDropDown.Value = '04';
            app.ConnectPCOButton = uibutton(app.SettingspanelPanel, 'push');
            app.ConnectPCOButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectPCOButtonPushed, true);
            app.ConnectPCOButton.Text = 'Connect PCO';
            app.PcoStatusLamp = uilamp(app.SettingspanelPanel);
            app.AcquisitionPanel_3 = uipanel(app.PCOImagingTab);
            app.AcquisitionPanel_3.Title = 'Acquisition Panel';
            app.PcoStartAcquisitionButton = uibutton(app.AcquisitionPanel_3, 'push');
            app.PcoStartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @PcoStartAcquisitionButtonPushed, true);
            app.PcoStartAcquisitionButton.Enable = 'off';
            app.PcoStartAcquisitionButton.Text = 'START ACQUISITION';
            app.PcoStartPreviewButton = uibutton(app.AcquisitionPanel_3, 'push');
            app.PcoStartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PcoStartPreviewButtonPushed, true);
            app.PcoStartPreviewButton.Enable = 'off';
            app.PcoStartPreviewButton.Text = 'START PREVIEW';
            app.PcoSaveTifFileCk = uicheckbox(app.AcquisitionPanel_3);
            app.PcoSaveTifFileCk.Text = 'Add .tif file';
            app.DurationsEditField_2Label = uilabel(app.AcquisitionPanel_3);
            app.DurationsEditField_2Label.Text = 'Duration (s):';
            app.PcoDurationEditField = uieditfield(app.AcquisitionPanel_3, 'numeric');
            app.PcoDurationEditField.ValueChangedFcn = createCallbackFcn(app, @PcoDurationEditFieldValueChanged, true);
            app.PcoDurationEditField.Value = 80;
            app.PcoSaveFolderEditField = uieditfield(app.AcquisitionPanel_3, 'text');
            app.SelectOutputFolderButton = uibutton(app.AcquisitionPanel_3, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.MaxFrequencyHzEditFieldLabel = uilabel(app.AcquisitionPanel_3);
            app.MaxFrequencyHzEditFieldLabel.Text = 'Max Frequency (Hz):';
            app.PcoMaxFreqEditField = uieditfield(app.AcquisitionPanel_3, 'numeric');
            app.PcoMaxFreqEditField.Limits = [0 Inf];
            app.PcoMaxFreqEditField.Editable = 'off';
            app.PcoMaxFreqEditField.Value = 50;
            app.HermesDelaysEditFieldLabel = uilabel(app.AcquisitionPanel_3);
            app.HermesDelaysEditFieldLabel.Text = 'Hermes Delay (s):';
            app.HermesDelaysEditField = uieditfield(app.AcquisitionPanel_3, 'numeric');
            app.HermesDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @HermesDelaysEditFieldValueChanged, true);
            app.HermesDelaysEditField.Tooltip = {'Amount of time Hermes will wait before starting a TTL sequence. It doesn''t work with stimuli. Rounded to millisecond precision.'};
            app.HermesDelaysEditField.Value = 1;
            app.FilenameEditFieldLabel = uilabel(app.AcquisitionPanel_3);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.PcoFilenameEditField = uieditfield(app.AcquisitionPanel_3, 'text');
            app.PcoFilenameEditField.Value = 'file';
            app.FilenEditFieldLabel_2 = uilabel(app.AcquisitionPanel_3);
            app.FilenEditFieldLabel_2.Text = 'File n.';
            app.PcoOutputFileN = uieditfield(app.AcquisitionPanel_3, 'numeric');
            app.PcoOutputFileN.Limits = [1 Inf];
            app.PcoOutputFileN.RoundFractionalValues = 'on';
            app.PcoOutputFileN.ValueDisplayFormat = '%03i';
            app.PcoOutputFileN.Value = 1;
            app.TimeoutsEditFieldLabel = uilabel(app.AcquisitionPanel_3);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s):';
            app.PcoTimeoutEditField = uieditfield(app.AcquisitionPanel_3, 'numeric');
            app.PcoTimeoutEditField.Limits = [0 Inf];
            app.PcoTimeoutEditField.Value = 5;
            app.SelectedFrequencyHzEditFieldLabel = uilabel(app.AcquisitionPanel_3);
            app.SelectedFrequencyHzEditFieldLabel.Enable = 'off';
            app.SelectedFrequencyHzEditFieldLabel.Text = 'Selected Frequency (Hz):';
            app.PcoSelectedFreqEditField = uieditfield(app.AcquisitionPanel_3, 'numeric');
            app.PcoSelectedFreqEditField.Limits = [0 Inf];
            app.PcoSelectedFreqEditField.ValueChangedFcn = createCallbackFcn(app, @PcoSelectedFreqEditFieldValueChanged, true);
            app.PcoSelectedFreqEditField.Enable = 'off';
            app.PcoSelectedFreqEditField.Value = 50;
            app.NumberofframesEditFieldLabel = uilabel(app.AcquisitionPanel_3);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames:';
            app.PcoNumberFramesEditField = uieditfield(app.AcquisitionPanel_3, 'numeric');
            app.PcoNumberFramesEditField.Limits = [0 Inf];
            app.PcoNumberFramesEditField.Editable = 'off';
            app.VisualizeoutputPanel = uipanel(app.PCOImagingTab);
            app.VisualizeoutputPanel.Title = 'Visualize output';
            app.CLimminEditFieldLabel = uilabel(app.VisualizeoutputPanel);
            app.CLimminEditFieldLabel.Text = 'CLim: min';
            app.CLimminEditField = uieditfield(app.VisualizeoutputPanel, 'numeric');
            app.CLimminEditField.ValueChangedFcn = createCallbackFcn(app, @CLimminEditFieldValueChanged, true);
            app.maxEditFieldLabel = uilabel(app.VisualizeoutputPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.VisualizeoutputPanel, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%8.4g';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @CLimminEditFieldValueChanged, true);
            app.maxEditField.Value = 65535;
            app.ButtonGroup_6 = uibuttongroup(app.VisualizeoutputPanel);
            app.RawButton = uiradiobutton(app.ButtonGroup_6);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.deltaFFButton = uiradiobutton(app.ButtonGroup_6);
            app.deltaFFButton.Text = 'deltaF/F';
            app.SpatialfilterCheckBox = uicheckbox(app.VisualizeoutputPanel);
            app.SpatialfilterCheckBox.Text = 'Spatial filter';
            app.pxlEditFieldLabel = uilabel(app.VisualizeoutputPanel);
            app.pxlEditFieldLabel.Text = '(pxl):';
            app.pxlEditField = uieditfield(app.VisualizeoutputPanel, 'numeric');
            app.pxlEditField.Value = 2;
            app.sEditFieldLabel = uilabel(app.VisualizeoutputPanel);
            app.sEditFieldLabel.Text = '(s):';
            app.sEditField = uieditfield(app.VisualizeoutputPanel, 'numeric');
            app.sEditField.Value = 0.1;
            app.TimefilterCheckBox = uicheckbox(app.VisualizeoutputPanel);
            app.TimefilterCheckBox.Text = 'Time filter';
            app.SeemovieButton = uibutton(app.VisualizeoutputPanel, 'push');
            app.SeemovieButton.ButtonPushedFcn = createCallbackFcn(app, @SeemovieButtonPushed, true);
            app.SeemovieButton.Text = 'See movie';
            app.SeeavgmovieButton = uibutton(app.VisualizeoutputPanel, 'push');
            app.SeeavgmovieButton.ButtonPushedFcn = createCallbackFcn(app, @SeeavgmovieButtonPushed, true);
            app.SeeavgmovieButton.Text = 'See avg movie';
            app.OpenFieldAnalyzerButton = uibutton(app.VisualizeoutputPanel, 'state');
            app.OpenFieldAnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @OpenFieldAnalyzerButtonValueChanged, true);
            app.OpenFieldAnalyzerButton.Text = 'Open Field Analyzer';
            app.CDataPanel = uipanel(app.PCOImagingTab);
            app.CDataPanel.Title = 'CData';
            app.PcoCDataMaxEditField = uieditfield(app.CDataPanel, 'numeric');
            app.PcoCDataMaxEditField.Limits = [0 65536];
            app.PcoCDataMaxEditField.RoundFractionalValues = 'on';
            app.PcoCDataMaxEditField.ValueDisplayFormat = '%.0f';
            app.PcoCDataMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PcoCDataMaxEditFieldValueChanged, true);
            app.PcoCDataMaxEditField.Value = 65536;
            app.MaxLabel = uilabel(app.CDataPanel);
            app.MaxLabel.Text = 'Max:';
            app.MinLabel = uilabel(app.CDataPanel);
            app.MinLabel.Text = 'Min:';
            app.PcoCDataMinEditField = uieditfield(app.CDataPanel, 'numeric');
            app.PcoCDataMinEditField.Limits = [0 65536];
            app.PcoCDataMinEditField.RoundFractionalValues = 'on';
            app.PcoCDataMinEditField.ValueDisplayFormat = '%.0f';
            app.PcoCDataMinEditField.ValueChangedFcn = createCallbackFcn(app, @PcoCDataMinEditFieldValueChanged, true);
            app.PcoAutoCLimButton = uibutton(app.CDataPanel, 'push');
            app.PcoAutoCLimButton.ButtonPushedFcn = createCallbackFcn(app, @PcoAutoCLimButtonPushed, true);
            app.PcoAutoCLimButton.Text = 'Auto';
            app.OptogeneticsTab = uitab(app.TabGroup);
            app.OptogeneticsTab.Title = 'Optogenetics';
            app.TypeButtonGroup = uibuttongroup(app.OptogeneticsTab);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.ContinuousButton = uiradiobutton(app.TypeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.PulsedButton = uiradiobutton(app.TypeButtonGroup);
            app.PulsedButton.Text = 'Pulsed';
            app.FrequencyHzEditFieldLabel = uilabel(app.TypeButtonGroup);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.TypeButtonGroup, 'numeric');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 30;
            app.DutycycleEditFieldLabel = uilabel(app.TypeButtonGroup);
            app.DutycycleEditFieldLabel.Text = 'Duty cycle (%)';
            app.DutycycleEditField = uieditfield(app.TypeButtonGroup, 'numeric');
            app.DutycycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutycycleEditFieldValueChanged, true);
            app.DutycycleEditField.Value = 50;
            app.nofpulsesEditFieldLabel = uilabel(app.TypeButtonGroup);
            app.nofpulsesEditFieldLabel.Text = 'n of pulses';
            app.nofpulsesEditField = uieditfield(app.TypeButtonGroup, 'numeric');
            app.nofpulsesEditField.ValueChangedFcn = createCallbackFcn(app, @nofpulsesEditFieldValueChanged, true);
            app.nofpulsesEditField.Value = 30;
            app.TimingPanel = uipanel(app.OptogeneticsTab);
            app.TimingPanel.Title = 'Timing';
            app.StartmsEditFieldLabel = uilabel(app.TimingPanel);
            app.StartmsEditFieldLabel.Text = 'Start (ms)';
            app.optoStartEditField = uieditfield(app.TimingPanel, 'numeric');
            app.optoStartEditField.ValueChangedFcn = createCallbackFcn(app, @optoStartEditFieldValueChanged, true);
            app.optoStartEditField.Value = 400;
            app.DurationmsEditFieldLabel = uilabel(app.TimingPanel);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.optoDurEditField = uieditfield(app.TimingPanel, 'numeric');
            app.optoDurEditField.ValueChangedFcn = createCallbackFcn(app, @optoDurEditFieldValueChanged, true);
            app.optoDurEditField.Value = 800;
            app.AfterstimulusonsetLabel = uilabel(app.TimingPanel);
            app.AfterstimulusonsetLabel.Text = 'After stimulus onset';
            app.HermesTab = uitab(app.TabGroup);
            app.HermesTab.Title = 'Hermes';
            app.SerialPanel = uipanel(app.HermesTab);
            app.SerialPanel.Title = 'Serial';
            app.RefreshCOMButton = uibutton(app.SerialPanel, 'push');
            app.RefreshCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMButtonPushed, true);
            app.RefreshCOMButton.Text = 'Refresh COM';
            app.SerialCOMDropDownLabel = uilabel(app.SerialPanel);
            app.SerialCOMDropDownLabel.Text = 'Serial COM:';
            app.SerialCOMDropDown = uidropdown(app.SerialPanel);
            app.SerialCOMDropDown.Items = {};
            app.SerialCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialCOMDropDownValueChanged, true);
            app.SerialCOMDropDown.Value = {};
            app.PrintSerialCommCk = uicheckbox(app.SerialPanel);
            app.PrintSerialCommCk.Text = 'Print Serial Comm';
            app.PrintSerialCommCk.Value = true;
            app.WorkingconfigurationPanel = uipanel(app.HermesTab);
            app.WorkingconfigurationPanel.Tooltip = {'Only for phototransistor mode. When Hermes detects an optical TTL, it turns unresponsive for this amount of time, in order to prevent a second response from the same optical TTL. For this to make sense, Idle time should be longer than the duration of the cue (default values are 200 ms and 100 ms respectively)'};
            app.WorkingconfigurationPanel.Title = 'Working configuration';
            app.ModeDropDownLabel = uilabel(app.WorkingconfigurationPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.WorkingconfigurationPanel);
            app.ModeDropDown.Items = {'classic', 'opto'};
            app.ModeDropDown.Value = 'classic';
            app.TriggerDropDownLabel = uilabel(app.WorkingconfigurationPanel);
            app.TriggerDropDownLabel.Text = 'Trigger';
            app.TriggerDropDown = uidropdown(app.WorkingconfigurationPanel);
            app.TriggerDropDown.Items = {'phototransistor', 'serial'};
            app.TriggerDropDown.Value = 'phototransistor';
            app.ApplyButton = uibutton(app.WorkingconfigurationPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TTLdurationmsLabel = uilabel(app.WorkingconfigurationPanel);
            app.TTLdurationmsLabel.Text = 'TTL duration (ms):';
            app.HermesTtlDurationEditField = uieditfield(app.WorkingconfigurationPanel, 'numeric');
            app.HermesTtlDurationEditField.Limits = [0 Inf];
            app.HermesTtlDurationEditField.ValueChangedFcn = createCallbackFcn(app, @HermesTtlDurationEditFieldValueChanged, true);
            app.HermesTtlDurationEditField.Value = 2;
            app.IdletimemsLabel = uilabel(app.WorkingconfigurationPanel);
            app.IdletimemsLabel.Text = 'Idle time (ms):';
            app.HermesIdleTimeEditField = uieditfield(app.WorkingconfigurationPanel, 'numeric');
            app.HermesIdleTimeEditField.Limits = [0 Inf];
            app.HermesIdleTimeEditField.RoundFractionalValues = 'on';
            app.HermesIdleTimeEditField.ValueChangedFcn = createCallbackFcn(app, @HermesIdleTimeEditFieldValueChanged, true);
            app.HermesIdleTimeEditField.Tooltip = {'Amount of time Hermes will wait to start a new TTL sognal after a TTL signal. Rounded to millisecond precision.'};
            app.HermesIdleTimeEditField.Value = 200;
            app.CuePanel = uipanel(app.HermesTab);
            app.CuePanel.Title = 'Cue';
            app.HeightpxlLabel = uilabel(app.CuePanel);
            app.HeightpxlLabel.Text = 'Height (pxl):';
            app.hhTxt = uieditfield(app.CuePanel, 'numeric');
            app.hhTxt.Limits = [0 Inf];
            app.hhTxt.ValueChangedFcn = createCallbackFcn(app, @hhTxtValueChanged, true);
            app.hhTxt.Editable = 'off';
            app.hhTxt.Value = 320;
            app.CueWidthpxEditFieldLabel = uilabel(app.CuePanel);
            app.CueWidthpxEditFieldLabel.Text = 'Cue Width (px)';
            app.wwTxt = uieditfield(app.CuePanel, 'numeric');
            app.wwTxt.Limits = [0 Inf];
            app.wwTxt.ValueChangedFcn = createCallbackFcn(app, @wwTxtValueChanged, true);
            app.wwTxt.Editable = 'off';
            app.wwTxt.Value = 470;
            app.CuepositionPanel = uipanel(app.CuePanel);
            app.CuepositionPanel.Title = 'Cue position';
            app.CueVertPos = uibuttongroup(app.CuepositionPanel);
            app.CueVertPos.SelectionChangedFcn = createCallbackFcn(app, @CueVertPosSelectionChanged, true);
            app.CueTopRB = uiradiobutton(app.CueVertPos);
            app.CueTopRB.Text = 'Top';
            app.CueBotRB = uiradiobutton(app.CueVertPos);
            app.CueBotRB.Text = 'Bottom';
            app.CueBotRB.Value = true;
            app.CueHorzPos = uibuttongroup(app.CuepositionPanel);
            app.CueHorzPos.SelectionChangedFcn = createCallbackFcn(app, @CueHorzPosSelectionChanged, true);
            app.CueLeftRB = uiradiobutton(app.CueHorzPos);
            app.CueLeftRB.Text = 'Left';
            app.CueRightRB = uiradiobutton(app.CueHorzPos);
            app.CueRightRB.Text = 'Right';
            app.CueRightRB.Value = true;
            app.UsedefaultwidthandheightCheckBox = uicheckbox(app.CuePanel);
            app.UsedefaultwidthandheightCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsedefaultwidthandheightCheckBoxValueChanged, true);
            app.UsedefaultwidthandheightCheckBox.Text = 'Use default width and height';
            app.UsedefaultwidthandheightCheckBox.Value = true;
            app.ard_ck = uicheckbox(app.CuePanel);
            app.ard_ck.ValueChangedFcn = createCallbackFcn(app, @ard_ckValueChanged, true);
            app.ard_ck.Tooltip = {'Display the Hermes synchronisation cue'};
            app.ard_ck.Text = 'Show cue (a.k.a. "optical TTL")';
            app.ard_ck.Value = true;
            app.calblack_btn = uibutton(app.CuePanel, 'push');
            app.calblack_btn.ButtonPushedFcn = createCallbackFcn(app, @calblack_btn_Callback, true);
            app.calblack_btn.Text = 'Calibrate Black';
            app.calwhite_btn = uibutton(app.CuePanel, 'push');
            app.calwhite_btn.ButtonPushedFcn = createCallbackFcn(app, @calwhite_btn_Callback, true);
            app.calwhite_btn.Text = 'Calibrate White';
            app.DurationofcuemsEditFieldLabel = uilabel(app.CuePanel);
            app.DurationofcuemsEditFieldLabel.Text = 'Duration of cue (ms):';
            app.optDtrTimeTxt = uieditfield(app.CuePanel, 'numeric');
            app.optDtrTimeTxt.Limits = [0 Inf];
            app.optDtrTimeTxt.Value = 100;
            app.BusyStatusLampLabel = uilabel(app.EscherMasterFig);
            app.BusyStatusLampLabel.Text = 'Busy Status:';
            app.BusyStatusLamp = uilamp(app.EscherMasterFig);
            app.DeBusyButton = uibutton(app.EscherMasterFig, 'push');
            app.DeBusyButton.ButtonPushedFcn = createCallbackFcn(app, @DeBusyButtonPushed, true);
            app.DeBusyButton.Text = 'De-Busy';
            app.UDPConnectionLampLabel = uilabel(app.EscherMasterFig);
            app.UDPConnectionLampLabel.Text = 'UDP Connection:';
            app.UDPConnectionLamp = uilamp(app.EscherMasterFig);
            app.BluemodeLabel = uilabel(app.EscherMasterFig);
            app.BluemodeLabel.Text = 'Blue mode:';
            app.BluemodeLamp = uilamp(app.EscherMasterFig);
            % Show the figure after all components are created
            app.EscherMasterFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EscherAppMaster

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EscherMasterFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EscherMasterFig)
        end
    end
end