classdef EscherAppMaster_boston_newpupil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EscherMasterFig                 matlab.ui.Figure
        UDPConnectionLamp               matlab.ui.control.Lamp
        UDPConnectionLampLabel          matlab.ui.control.Label
        DeBusyButton                    matlab.ui.control.Button
        BusyStatusLamp                  matlab.ui.control.Lamp
        BusyStatusLampLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        LoadScreenInformationPanel      matlab.ui.container.Panel
        CalModeRBGroup                  matlab.ui.container.ButtonGroup
        CalibratedButton                matlab.ui.control.RadioButton
        UncalibratedButton              matlab.ui.control.RadioButton
        SaveScreenFileButton            matlab.ui.control.Button
        ScreenNotesTxt                  matlab.ui.control.EditField
        NotesLabel                      matlab.ui.control.Label
        ScreenFuncTxt                   matlab.ui.control.EditField
        ScreenTransferFormulaLabel      matlab.ui.control.Label
        ScreenNameTxt                   matlab.ui.control.EditField
        ScreennameEditFieldLabel        matlab.ui.control.Label
        LoadScreenFileButton            matlab.ui.control.Button
        UDPConnectionPanel              matlab.ui.container.Panel
        STAendTxt                       matlab.ui.control.EditField
        SendtoallendaddressEditFieldLabel  matlab.ui.control.Label
        STAstartTxt                     matlab.ui.control.EditField
        SendtoallstartaddressLabel      matlab.ui.control.Label
        UDPBroadcastDropDown            matlab.ui.control.DropDown
        UDPBroadcastDropDownLabel       matlab.ui.control.Label
        BroadcastAddress                matlab.ui.control.EditField
        BroadcastEditFieldLabel         matlab.ui.control.Label
        SubnetMask                      matlab.ui.control.EditField
        SubnetmaskEditFieldLabel        matlab.ui.control.Label
        ResendScreenParams              matlab.ui.control.Button
        TimeoutTime                     matlab.ui.control.NumericEditField
        TimeouttimesEditFieldLabel      matlab.ui.control.Label
        SlaveMac                        matlab.ui.control.EditField
        MACLabel_2                      matlab.ui.control.Label
        MasterMac                       matlab.ui.control.EditField
        MACLabel_3                      matlab.ui.control.Label
        ConnectionStatus                matlab.ui.control.EditField
        StatusEditFieldLabel_2          matlab.ui.control.Label
        GetMasterIPandMACBtn            matlab.ui.control.Button
        MasterPort                      matlab.ui.control.NumericEditField
        PortEditFieldLabel_2            matlab.ui.control.Label
        MasterIp                        matlab.ui.control.EditField
        MasterIPLabel                   matlab.ui.control.Label
        SlavePort                       matlab.ui.control.NumericEditField
        PortEditFieldLabel              matlab.ui.control.Label
        SlaveIp                         matlab.ui.control.EditField
        SlaveIPLabel                    matlab.ui.control.Label
        StartUdpBtn                     matlab.ui.control.Button
        uipanel4                        matlab.ui.container.Panel
        SaveScreenDistortionButton      matlab.ui.control.Button
        ScreenSizeAngle                 matlab.ui.control.EditField
        AngularsizeofscreenEditFieldLabel  matlab.ui.control.Label
        ScreenHeight                    matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel          matlab.ui.control.Label
        ScreenWidth                     matlab.ui.control.NumericEditField
        WidthcmLabel                    matlab.ui.control.Label
        HeightPix                       matlab.ui.control.NumericEditField
        HeightpixelsEditFieldLabel      matlab.ui.control.Label
        WidthPix                        matlab.ui.control.NumericEditField
        WidthpixelsEditFieldLabel       matlab.ui.control.Label
        ScreenDistance                  matlab.ui.control.NumericEditField
        ScreendistancecmLabel           matlab.ui.control.Label
        uipanel6                        matlab.ui.container.Panel
        ApplysphericaldistortionCheckBox  matlab.ui.control.CheckBox
        LabelLumi_1                     matlab.ui.control.Label
        StandbyL                        matlab.ui.control.NumericEditField
        SetstandbyluminanceEditFieldLabel  matlab.ui.control.Label
        oculusCk                        matlab.ui.control.CheckBox
        OneScreenMode_ck                matlab.ui.control.CheckBox
        RefreshStBy                     matlab.ui.control.Button
        OpenScreenBtn                   matlab.ui.control.Button
        StimulationTab                  matlab.ui.container.Tab
        uipanel14                       matlab.ui.container.Panel
        initialDelay                    matlab.ui.control.NumericEditField
        InitialdelaysLabel              matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        ContrastTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        ContraststimtabTab              matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        CheckFastMeanL                  matlab.ui.control.NumericEditField
        MeanLEditFieldLabel             matlab.ui.control.Label
        CheckFastTotN                   matlab.ui.control.NumericEditField
        noftotstimLabel_6               matlab.ui.control.Label
        CheckFastMaxC                   matlab.ui.control.NumericEditField
        MaxLEditFieldLabel_4            matlab.ui.control.Label
        CheckFastMinC                   matlab.ui.control.NumericEditField
        MinLEditFieldLabel_4            matlab.ui.control.Label
        includezeroCheckCk              matlab.ui.control.CheckBox
        logspacedCheckCk                matlab.ui.control.CheckBox
        generatestimuliCheckBtn         matlab.ui.control.Button
        CheckFastLoadTable              matlab.ui.control.Button
        CheckFastSaveTable              matlab.ui.control.Button
        CheckFastN                      matlab.ui.control.NumericEditField
        ChoosestimEditFieldLabel_6      matlab.ui.control.Label
        CheckFastTable                  matlab.ui.control.Table
        SpatialfreqtabTab               matlab.ui.container.Tab
        NstimEditField                  matlab.ui.control.NumericEditField
        NstimEditFieldLabel             matlab.ui.control.Label
        SfFastMaxC                      matlab.ui.control.NumericEditField
        MaxLEditFieldLabel_5            matlab.ui.control.Label
        SfFastMinC                      matlab.ui.control.NumericEditField
        MinLEditFieldLabel_5            matlab.ui.control.Label
        SfFastN                         matlab.ui.control.NumericEditField
        ChoosestimLabel                 matlab.ui.control.Label
        SfFastLoadTable                 matlab.ui.control.Button
        SfFastSaveTable                 matlab.ui.control.Button
        SfFastTable                     matlab.ui.control.Table
        logspacedSfCk                   matlab.ui.control.CheckBox
        generatestimuliSFBtn            matlab.ui.control.Button
        RandomizedorientationandcontrastPanel  matlab.ui.container.Panel
        NrepEditField                   matlab.ui.control.NumericEditField
        NrepEditFieldLabel              matlab.ui.control.Label
        LoadRandStim                    matlab.ui.control.Button
        SaveRandStim                    matlab.ui.control.Button
        GenRandStim                     matlab.ui.control.Button
        MaxlagsEditField                matlab.ui.control.NumericEditField
        MaxlagsLabel                    matlab.ui.control.Label
        AddrandomlagbetweentrialsCheckBox  matlab.ui.control.CheckBox
        UsecontrastandorientationvaluesfromLabel  matlab.ui.control.Label
        RandFastTable                   matlab.ui.control.Table
        GoRandGrid                      matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        CheckerbTab                     matlab.ui.container.Tab
        CheckPeriod                     matlab.ui.control.NumericEditField
        PeriodsEditField_2Label         matlab.ui.control.Label
        CheckRepetitions                matlab.ui.control.NumericEditField
        RepetitionsEditField_2Label     matlab.ui.control.Label
        CheckGo                         matlab.ui.control.Button
        GratingsTab                     matlab.ui.container.Tab
        GaussSize_txt                   matlab.ui.control.NumericEditField
        SizegaussianmaskpixEditFieldLabel  matlab.ui.control.Label
        DriftingSpeed_txt               matlab.ui.control.NumericEditField
        DriftingspeedcyclessecEditFieldLabel  matlab.ui.control.Label
        PreGridDuration                 matlab.ui.control.NumericEditField
        BaselineGratingPostsLabel       matlab.ui.control.Label
        GridRepetitions                 matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel_3     matlab.ui.control.Label
        GridOrientation                 matlab.ui.control.NumericEditField
        GridorientationdegEditFieldLabel  matlab.ui.control.Label
        FastOrientationStimulusSelectionPanel  matlab.ui.container.Panel
        OrientFastN                     matlab.ui.control.NumericEditField
        ChoosestimEditFieldLabel_5      matlab.ui.control.Label
        OrientFastTable                 matlab.ui.control.Table
        OrientFastMaxA                  matlab.ui.control.NumericEditField
        EndAngleEditFieldLabel          matlab.ui.control.Label
        OrientFastMinA                  matlab.ui.control.NumericEditField
        StartAngleEditFieldLabel        matlab.ui.control.Label
        OrientFastTotN                  matlab.ui.control.NumericEditField
        noftotstimLabel_5               matlab.ui.control.Label
        OrientFastLoadTable             matlab.ui.control.Button
        OrientFastSaveTable             matlab.ui.control.Button
        generatestimuliOrientBtn        matlab.ui.control.Button
        PostGridDuration                matlab.ui.control.NumericEditField
        GridDuration                    matlab.ui.control.NumericEditField
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        SquareGrid                      matlab.ui.control.RadioButton
        SineGrid                        matlab.ui.control.RadioButton
        DrawMask                        matlab.ui.control.CheckBox
        GoGrid                          matlab.ui.control.Button
        SliderOrientation               matlab.ui.control.Slider
        DotsTab                         matlab.ui.container.Tab
        GoGrid_2                        matlab.ui.control.Button
        DotsRepetitions                 matlab.ui.control.NumericEditField
        RepetitionsEditField_2Label_2   matlab.ui.control.Label
        EquiluminantbaselineCheckBox    matlab.ui.control.CheckBox
        DotDuration                     matlab.ui.control.NumericEditField
        PostDotDuration                 matlab.ui.control.NumericEditField
        RandSpeedCheckBox               matlab.ui.control.CheckBox
        RandSizeCheckBox                matlab.ui.control.CheckBox
        NdotsEditField                  matlab.ui.control.NumericEditField
        NdotsEditFieldLabel             matlab.ui.control.Label
        DotslifetimesEditField          matlab.ui.control.NumericEditField
        DotslifetimesEditFieldLabel     matlab.ui.control.Label
        PreDotDuration                  matlab.ui.control.NumericEditField
        BaselineGratingPostEditFieldLabel_2  matlab.ui.control.Label
        DotsdiameterdegEditField        matlab.ui.control.NumericEditField
        DotsdiameterdegLabel            matlab.ui.control.Label
        dotsDriftingSpeed               matlab.ui.control.NumericEditField
        DriftingspeeddegsecLabel        matlab.ui.control.Label
        DotsOrientation                 matlab.ui.control.NumericEditField
        OrientationdegLabel             matlab.ui.control.Label
        DotsdensityEditField            matlab.ui.control.NumericEditField
        Dotsdensitydeg2Label            matlab.ui.control.Label
        DarkbackgroundCheckBox          matlab.ui.control.CheckBox
        RetinotopyTab                   matlab.ui.container.Tab
        RetinotopyRepetitions           matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel_4     matlab.ui.control.Label
        RetinotopyPostGTime             matlab.ui.control.NumericEditField
        BaselinetimesEditFieldLabel     matlab.ui.control.Label
        IntrRectSizeTxt                 matlab.ui.control.NumericEditField
        RectanglesizedegLabel           matlab.ui.control.Label
        IntrDirectionSwitch             matlab.ui.control.ToggleSwitch
        DirectionLabel                  matlab.ui.control.Label
        IntrOrientationSwitch           matlab.ui.control.ToggleSwitch
        MovementLabel                   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        IntrinsicStimBarSpeedEdit       matlab.ui.control.NumericEditField
        BarspeeddegsLabel               matlab.ui.control.Label
        KeepconstantspeedCheckBox       matlab.ui.control.CheckBox
        TrialperiodsEditField           matlab.ui.control.NumericEditField
        TrialperiodsEditFieldLabel      matlab.ui.control.Label
        RetinotopyGOButton              matlab.ui.control.Button
        RetinotopyDefaultParametersCheckBox  matlab.ui.control.CheckBox
        CheckContrast                   matlab.ui.control.NumericEditField
        ContrastLabel                   matlab.ui.control.Label
        CheckLumiW                      matlab.ui.control.NumericEditField
        WhiteluminanceLabel             matlab.ui.control.Label
        CheckLumiB                      matlab.ui.control.NumericEditField
        BlackluminanceLabel             matlab.ui.control.Label
        CheckLumiMean                   matlab.ui.control.NumericEditField
        MeanluminanceLabel              matlab.ui.control.Label
        spatialFrequency                matlab.ui.control.NumericEditField
        FrequencycyclesDegLabel         matlab.ui.control.Label
        LabelLumiEsch_11                matlab.ui.control.Label
        LabelLumiEsch_10                matlab.ui.control.Label
        LabelLumiEsch_9                 matlab.ui.control.Label
        CheckLumiMeanEsch               matlab.ui.control.NumericEditField
        CheckLumiWEsch                  matlab.ui.control.NumericEditField
        CheckLumiBEsch                  matlab.ui.control.NumericEditField
        LabelLumi_11                    matlab.ui.control.Label
        LabelLumi_10                    matlab.ui.control.Label
        LabelLumi_9                     matlab.ui.control.Label
        FlashTab                        matlab.ui.container.Tab
        ReceptivefieldPanel             matlab.ui.container.Panel
        OpenlogdirectoryButton          matlab.ui.control.Button
        LogfilenameEditField            matlab.ui.control.EditField
        LogfilenameEditFieldLabel       matlab.ui.control.Label
        RecptiveFieldGo                 matlab.ui.control.Button
        randomizeorderCheckBox          matlab.ui.control.CheckBox
        columnsEditField                matlab.ui.control.NumericEditField
        columnsEditFieldLabel           matlab.ui.control.Label
        rowsEditField                   matlab.ui.control.NumericEditField
        rowsEditFieldLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        FSFlashGo                       matlab.ui.control.Button
        FastFlashStimulusSelectionPanel  matlab.ui.container.Panel
        FlashFastN                      matlab.ui.control.NumericEditField
        ChoosestimEditFieldLabel_4      matlab.ui.control.Label
        FlashFastLoadTable              matlab.ui.control.Button
        savetableButton                 matlab.ui.control.Button
        generatestimuliFlashBtn         matlab.ui.control.Button
        includezeroFlashCk              matlab.ui.control.CheckBox
        logspacedFlashCk                matlab.ui.control.CheckBox
        FlashFastMaxL                   matlab.ui.control.NumericEditField
        MaxLEditFieldLabel_3            matlab.ui.control.Label
        FlashFastMinL                   matlab.ui.control.NumericEditField
        MinLEditFieldLabel_3            matlab.ui.control.Label
        FlashFastTotN                   matlab.ui.control.NumericEditField
        noftotstimLabel_4               matlab.ui.control.Label
        FlashFastTable                  matlab.ui.control.Table
        FlashPeriod                     matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel           matlab.ui.control.Label
        FlashRepetitions                matlab.ui.control.NumericEditField
        RepetitionsLabel_4              matlab.ui.control.Label
        FlashDuration                   matlab.ui.control.NumericEditField
        FlashdurationsLabel             matlab.ui.control.Label
        FSflashL                        matlab.ui.control.NumericEditField
        FlashluminanceLabel             matlab.ui.control.Label
        FSbaselineL                     matlab.ui.control.NumericEditField
        BaselineluminanceLabel          matlab.ui.control.Label
        LabelLumiEsch_8                 matlab.ui.control.Label
        FSflashLEsch                    matlab.ui.control.NumericEditField
        LabelLumiEsch_7                 matlab.ui.control.Label
        FSbaselineLEsch                 matlab.ui.control.NumericEditField
        LabelLumi_8                     matlab.ui.control.Label
        LabelLumi_7                     matlab.ui.control.Label
        SomatosensoryTab                matlab.ui.container.Tab
        SomatosensoryPanel              matlab.ui.container.Panel
        PuffGo                          matlab.ui.control.Button
        PuffPeriod                      matlab.ui.control.NumericEditField
        PeriodsLabel                    matlab.ui.control.Label
        PuffDuration                    matlab.ui.control.NumericEditField
        PuffdurationmsLabel             matlab.ui.control.Label
        PuffRepetitions                 matlab.ui.control.NumericEditField
        RepetitionsLabel_5              matlab.ui.control.Label
        ExperimentlistTab               matlab.ui.container.Tab
        ExpListFirstNum                 matlab.ui.control.CheckBox
        RepeatStimBtn                   matlab.ui.control.Button
        ExpListCurrentNum               matlab.ui.control.NumericEditField
        CurrentrunnEditFieldLabel       matlab.ui.control.Label
        CopytableButton                 matlab.ui.control.Button
        ExpMoveDown                     matlab.ui.control.Button
        ExpMoveUp                       matlab.ui.control.Button
        ExpListDeleteRows               matlab.ui.control.Button
        ExpListAddRows                  matlab.ui.control.Button
        ExpListGo                       matlab.ui.control.Button
        RandomizeexperimentPanel        matlab.ui.container.Panel
        ExpListGratingsContrastCk       matlab.ui.control.CheckBox
        ExpListSessionN                 matlab.ui.control.NumericEditField
        NumberofsessionsEditFieldLabel  matlab.ui.control.Label
        RandomizeButton                 matlab.ui.control.Button
        ExpListGratingsOrientCk         matlab.ui.control.CheckBox
        ExpListCheckCk                  matlab.ui.control.CheckBox
        ExpListFlashCk                  matlab.ui.control.CheckBox
        ExperimentTable                 matlab.ui.control.Table
        PupilTab                        matlab.ui.container.Tab
        Threshold_EditField             matlab.ui.control.NumericEditField
        AcquisitionPanel                matlab.ui.container.Panel
        NframestoacquireEditField       matlab.ui.control.NumericEditField
        NframestoacquireEditFieldLabel  matlab.ui.control.Label
        ActualframerateHzEditField      matlab.ui.control.NumericEditField
        ActualframerateHzEditFieldLabel  matlab.ui.control.Label
        GrabaframeeveryEditField        matlab.ui.control.NumericEditField
        GrabaframeeveryEditFieldLabel   matlab.ui.control.Label
        SelectButton                    matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        IR_led_switch                   matlab.ui.control.Switch
        ControlIRLEDwithHermesCheckBox  matlab.ui.control.CheckBox
        PupilFilenameEditField          matlab.ui.control.EditField
        FilenameEditFieldLabel_2        matlab.ui.control.Label
        acqLamp                         matlab.ui.control.Lamp
        skipPupilAcqCk                  matlab.ui.control.CheckBox
        acqStatusTxt                    matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        OutputFileN                     matlab.ui.control.NumericEditField
        FilenEditFieldLabel             matlab.ui.control.Label
        SaveFolderEditField             matlab.ui.control.EditField
        SaveFolderEditFieldLabel        matlab.ui.control.Label
        PreviewWhileAcquiringCheckBox   matlab.ui.control.CheckBox
        PupilStartAcquisitionButton     matlab.ui.control.Button
        DurationsEditField              matlab.ui.control.NumericEditField
        DurationsEditFieldLabel         matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        PreviewPanel                    matlab.ui.container.Panel
        ResetAxesButton                 matlab.ui.control.Button
        skipPupilPrevCk                 matlab.ui.control.CheckBox
        StartPreviewButton              matlab.ui.control.Button
        NumberofPointsinPreviewGraphEditField  matlab.ui.control.NumericEditField
        NumberofPointsinPreviewGraphEditFieldLabel  matlab.ui.control.Label
        CameraPanel                     matlab.ui.container.Panel
        FramerateHzEditField            matlab.ui.control.NumericEditField
        FramerateHzEditFieldLabel       matlab.ui.control.Label
        NocameraLamp                    matlab.ui.control.Lamp
        NocameraLampLabel               matlab.ui.control.Label
        ImageSizeDropDown               matlab.ui.control.DropDown
        ImageSizeDropDownLabel          matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        CameraDropDownLabel             matlab.ui.control.Label
        RefreshDevicesButton            matlab.ui.control.Button
        PupilGraphUIAxes                matlab.ui.control.UIAxes
        PupilImage2UIAxes               matlab.ui.control.UIAxes
        PupilImage1UIAxes               matlab.ui.control.UIAxes
        HermesTab                       matlab.ui.container.Tab
        TimingPanel                     matlab.ui.container.Panel
        HermesIdleTimeEditField         matlab.ui.control.NumericEditField
        HermesIdletimemsLabel           matlab.ui.control.Label
        HermesTtlDurationEditField      matlab.ui.control.NumericEditField
        TTLdurationmsLabel              matlab.ui.control.Label
        CalibrationPanel                matlab.ui.container.Panel
        calwhite_btn                    matlab.ui.control.Button
        calblack_btn                    matlab.ui.control.Button
        ard_ck                          matlab.ui.control.CheckBox
        VisualCuePanel                  matlab.ui.container.Panel
        UsedefaultwidthandheightCheckBox  matlab.ui.control.CheckBox
        optDtrTimeTxt                   matlab.ui.control.NumericEditField
        DurationofcuemsEditFieldLabel   matlab.ui.control.Label
        CuepositionPanel                matlab.ui.container.Panel
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        CueRightRB                      matlab.ui.control.RadioButton
        CueLeftRB                       matlab.ui.control.RadioButton
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        CueBotRB                        matlab.ui.control.RadioButton
        CueTopRB                        matlab.ui.control.RadioButton
        wwTxt                           matlab.ui.control.NumericEditField
        CueWidthpxEditFieldLabel        matlab.ui.control.Label
        hhTxt                           matlab.ui.control.NumericEditField
        HeightpxEditFieldLabel          matlab.ui.control.Label
        WorkingconfigurationPanel       matlab.ui.container.Panel
        ApplyButton                     matlab.ui.control.Button
        TriggerDropDown                 matlab.ui.control.DropDown
        TriggerDropDownLabel            matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        SerialPanel                     matlab.ui.container.Panel
        PrintSerialCommCk               matlab.ui.control.CheckBox
        SerialCOMDropDown               matlab.ui.control.DropDown
        SerialCOMDropDownLabel          matlab.ui.control.Label
        RefreshCOMButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        %         For the most common values (dimension of the screen, black and
        %         white values, ...)
        
        %         ComHandle % Former synchronization TTL signal
        w
        screenRect
        ifi
        white
        black
        ScreenBitDepth % is either 'uint8' or 'uint16'
        HermesRect % Like screenRect but for Hermes
        OnePxAngle % Angle subtended by a single pixel
        ScreenFunc % Screen function (from 0-1 value to luminance)
        
        HermesSerialFlag % flag of serial communication with Hermes
        HermesSerialObj % object of serial communication with Hermes
        HermesReplyCheck
        
        %         For UDP connection:
        UdpObj % the actual UDP object
        UdpTimeoutTimer % the timer for making the UDP connection time out
        UdpConnectionFlag % When this is 1, this app works as master
        SendToAllTerminator % When this is 1, the send-to-all sort-of-broadcast is terminated.
        
        %         This is the structure containing all the variables for the pupil
        %         recording.
        PupilStruct
        PcoStruct
        OutPcoStruct % Saved structure with the image and other infos.
        
        ScreenOpenFlag % is 1 when the screen is open, it's 0 when it's not open
        
        %         folder of current escher app
        escherAppFolder
        PathSeparator
        %         For logs
        runNumberFile % the log file where the run number is stored.
        logDir % the directory of log files
        runNumber % The run number, progressive number of the next stimulation.
        CurrentLogStruct % structure containing .T (a table) and .logName, the name for current log.
        settingsDir % Directory of settings.
        
        %         Label for luminance of the vector of objects
        LabeLumiVector
        
        %         Store the indices of selected cells of the experiment list
        ExpListSelectedIndices
        %         The colors of the experiment table. First is the background, then
        %         the current exp, then the running exp, then the completed exp.
        ExpListColors
        
        %         This is the index of current experiment on the Experiment List
        %         table. Not to be confused with ExpListCurrentNum (which is the
        %         number assigned to the current experiment).
        ExpListCurrentIdx
        
        VisualFieldAnalyzerApp
    end
    
    methods (Access = public)        
        function saveParams(app, filename)
            paramsStruct = struct;
            
            propList = string(properties(app)); % get all the properies of the main app as string vector
            propListLogic = false(size(propList)); % initialize a logic vector, that will be used to tell apart checkboxes and edit fields from the rest
            
            for i=1:length(propList)
                if isobject(app.(propList(i))) && isprop(app.(propList(i)),'Type') % only isprop is not enough
                    if strcmp(app.(propList(i)).Type,'uinumericeditfield') || strcmp(app.(propList(i)).Type,'uicheckbox') || (strcmp(app.(propList(i)).Type,'uieditfield') && app.(propList(i)).Editable==1)
                        % logic 1 for all properties that are either
                        % numeric edit fields or checkboxes. Also added
                        % edit field (non numeric) but only if they are
                        % editable
                        propListLogic(i) = true;
                    end
                end
            end
            
            % paramsTable is 2-column table. First column is for names,
            % second is for values
            paramsTable = table('size', [sum(propListLogic), 2],'VariableTypes', {'string', 'cell'}, 'VariableNames', {'Name', 'Value'});
            paramsTable.Name = propList(propListLogic); % list of only checkboxes and editable textboxes
            
            % Extract all the names, values and callbacks of the checkboxes and editable textboxes of the main App
            for i=1:height(paramsTable)
                paramsTable.Value{i} = app.(paramsTable.Name(i)).Value;
            end
            
            paramsStruct.Table = paramsTable;
            
            save(filename, 'paramsStruct')
        end
        
        function loadParams(app,filename)
            %             This function load the parameters saved in a .mat file. The
            %             parameters are in a structure called paramsStruct.
            
            paramsStruct = [];
            
            %             All parameters from the gui are saved separately in a table,
            %             called paramsTable.
            
            open(filename) % from the filename location, a variable paramsStruct will be loaded
            for i = 1 : height(paramsStruct.paramsTable)
                %                 i
                %                 if i==24
                %                     disp('eccoci')
                %                 end
                app.(paramsStruct.paramsTable.Name(i)).Value = paramsStruct.paramsTable.Value{i};
                if ~isempty(app.(paramsStruct.paramsTable.Name(i)).ValueChangedFcn)
                    try
                        app.(paramsStruct.paramsTable.Name(i)).ValueChangedFcn(app.(paramsStruct.paramsTable.Name(i)),[])
                    catch ME
                        warning('off','backtrace')
                        warning(strcat("Error while executing ", paramsStruct.paramsTable.Name(i), "'s Callback"))
                        warning(ME.message)
                        disp(newline)
                    end
                end
            end
            
            warning('on','backtrace')
            % load handel
            % sound(y,Fs)
            disp('params loaded!')
        end
        
        function EnableGoBtn(app,flag)
            switch flag
                case 1
                    app.FSFlashGo.Enable = 1;
                    app.CheckGo.Enable = 1;
                    app.GoGrid.Enable = 1;
                    app.ExpListGo.Enable = 1;
                    app.BusyStatusLamp.Color = [0 1 0];
                    if app.UdpConnectionFlag
                        app.StartUdpBtn.Enable = 1;
                    end
                case 0
                    app.FSFlashGo.Enable = 0;
                    app.CheckGo.Enable = 0;
                    app.GoGrid.Enable = 0;
                    app.ExpListGo.Enable = 0;
                    app.BusyStatusLamp.Color = [1 0 0];
                    if app.UdpConnectionFlag
                        app.StartUdpBtn.Enable = 0;
                    end
            end
        end
        
        function CheckUpdateLumi(app,updateContrastFlag)
            switch updateContrastFlag
%                 If updateContrastFlag is 1, updates mean and contrast.
%                 Otherwise it updates black and white lumi.
                
%                 So, usually when black and white luminance are changed,
%                 the CheckUpdateLumi is called to update contrast and mean
%                 luminance.
%                 Conversely, if contrast or mean luminance are changed,
%                 CheckUpdateLumi is called to update black and white
%                 luminance.

                case 1
%                     This function updates the mean luminance value and contrast
%                     value according to the black and white luminances that are
%                     set.
                    if app.CalibratedButton.Value
%                         updates both the escher values and the luminance
%                         values for black and white. This is because the true luminance
%                         sometimes is not exactly what is set but it can
%                         be a bit different, according to what the screen
%                         can portray.
                        [app.CheckLumiBEsch.Value,app.CheckLumiB.Value] = Lumi2Escher(app.CheckLumiB.Value,app.white,app.ScreenFunc);
                        [app.CheckLumiWEsch.Value,app.CheckLumiW.Value] = Lumi2Escher(app.CheckLumiW.Value,app.white,app.ScreenFunc);
                        [app.CheckLumiMeanEsch.Value, app.CheckLumiMean.Value] = Lumi2Escher(app.CheckLumiMean.Value,app.white,app.ScreenFunc);
                    end
                    Blumi = app.CheckLumiB.Value;
                    Slumi = app.CheckLumiW.Value;
                    app.CheckLumiMean.Value = (Blumi+Slumi)/2;
                    app.CheckContrast.Value = (Slumi-Blumi)/(Blumi+Slumi)*100;
                case 0
                    %                     Update the black luminance and white luminance
                    %                     according to the mean luminance and contrast.
                    
                    Mlumi = app.CheckLumiMean.Value;
                    contrast = app.CheckContrast.Value/100;
                    
                    Blumi = Mlumi*(1-contrast);
                    Slumi = Mlumi*(1+contrast);
                    if app.CalibratedButton.Value
%                         updates both the escher values and the luminance
%                         values.
                        [app.CheckLumiBEsch.Value,Blumi] = Lumi2Escher(Blumi,app.white,app.ScreenFunc);
                        [app.CheckLumiWEsch.Value,Slumi] = Lumi2Escher(Slumi,app.white,app.ScreenFunc);
%                         Since the luminance values have now been changed,
%                         also the mean and contrast have to be changed.
                        Mlumi = (Blumi+Slumi)/2;
                        [app.CheckLumiMeanEsch.Value, Mlumi] = Lumi2Escher(Mlumi,app.white,app.ScreenFunc);
                        app.CheckLumiMean.Value = Mlumi;
                        app.CheckContrast.Value = (Slumi-Blumi)/(Blumi+Slumi)*100;
                    end
                        app.CheckLumiB.Value = Blumi;
                        app.CheckLumiW.Value = Slumi;
            end
        end
        
        function HermesRectCalc(app)
            sR = app.screenRect;
            ww = app.wwTxt.Value; %width of the optical DTR
            hh = app.hhTxt.Value; %height of the optical DTR
            %             The optical DTR must be placed according to the selection
            %             of the position
            % position of DtrRect (x0, y0, x4, y4)
            
            app.HermesRect = [app.CueRightRB.Value*(sR(3)-ww),...
                app.CueBotRB.Value*(sR(4)-hh),...
                sR(3)-app.CueLeftRB.Value*(sR(3)-ww),...
                sR(4)-app.CueTopRB.Value*(sR(4)-hh)];
        end
        
        function PixelAngleCalc(app)
% Compute angular size of single pixel. This is the angle subtended by a 
% single pixel which is at the center of the screen.

% Number of pixels in the screen width.
            Wpx = app.WidthPix.Value;
% Screen width in centimeters.
            ScWidth = app.ScreenWidth.Value;
% Distance of the screen in centimeters.
            ScDist = app.ScreenDistance.Value;
% The angle of a single pixel is returned in degrees.
            app.OnePxAngle = 2*atand(ScWidth/Wpx/2/ScDist);
        end
        
        function myFramesAcquiredFcn(app,vid,~)
            % vid = videoinput object
%             disp("ciao");
            [tmpdata,tmptime]=getdata(vid,app.PupilStruct.camobj.FramesAcquiredFcnCount);
            app.PupilStruct.hPreviewImage.CData = tmpdata(:,:,end);
            clear tmpdata
            PreviewTimerFcn(app,tmptime(end))
        end
    
        function refreshcamera(app)
            % Closing the device
            closedevice(app)
            
            app.PupilStruct.imaqhwinfoStruct = imaqhwinfo('winvideo'); % Open the structure imaqhwinfo with the adaptor 'winvideo'
            app.PupilStruct.camlist = cell(1,length(app.PupilStruct.imaqhwinfoStruct.DeviceInfo));
            for i=1:length(app.PupilStruct.imaqhwinfoStruct.DeviceInfo) % Extract the camera list
                app.PupilStruct.camlist{i} = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(i).DeviceName;
            end
            
            % Refreshing the popup menu
            app.CameraDropDown.Items = app.PupilStruct.camlist;
            
            % If possible, find the index of the USB camera
            usbcamIdx = find(contains(app.PupilStruct.camlist,'usb','IgnoreCase',true),1,'first'); % usb camera
            if ~isempty(usbcamIdx)
                app.PupilStruct.selcamIdx = usbcamIdx; % if there is a usb camera, select it
            elseif ~isempty(app.PupilStruct.camlist)
                app.PupilStruct.selcamIdx = 1; %if a usb camera is not there, select the first camera
            else
                app.PupilStruct.selcamIdx = [];
            end
            % app.selcamIdx will now contain the index of the selected camera
            
            selectcam(app)
        end
        
        function closedevice(app)
            %closepreview
            %             Check if the camera object is there
            if isfield(app.PupilStruct,'camobj')
                delete(app.PupilStruct.camobj)
            end
            
            app.NocameraLamp.Color = [1 0 0];
            app.NocameraLampLabel.Text = 'Camera Unconnected';
        end
        
        function selectcam(app)
            closedevice(app)
            
            if isempty(app.PupilStruct.selcamIdx)
                return
            end
            
            app.CameraDropDown.Value = app.PupilStruct.camlist{app.PupilStruct.selcamIdx};
            
            % Refreshing the formats
            camformats = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(app.PupilStruct.selcamIdx).SupportedFormats;
            
            % Parsing of the formats
%             camformats_nCols = zeros(size(camformats)); % double array containing the number of columns (pixel size of X Axis)
%             camformats_nRows = zeros(size(camformats)); % y
%             camformats_CA = cell(size(camformats));
%             
%             for i = 1:length(camformats)
%                 underscore_pos = find(camformats{i} == '_',1,'last');
%                 x_pos = find(camformats{i} == 'x',1,'last');
%                 camformats_nCols(i) = str2double(camformats{i}(x_pos+1:end));
%                 camformats_nRows(i) = str2double(camformats{i}(underscore_pos+1:x_pos-1));
%                 
%                 % camformats is a cell array that contains one column: the cell
%                 % array of strings describing all the resolution as "Xdimension x
%                 % Ydimenion"
%                 camformats_CA{i} = [num2str(camformats_nRows(i)),'x',num2str(camformats_nCols(i))];
%             end
            
            % Let's refresh the list of formats
%             app.ImageSizeDropDown.Items = camformats_CA;
            app.ImageSizeDropDown.Items = camformats;

            % Let's take the format corresponging to the smallest image size
%             [~,app.PupilStruct.selformatIdx] = min(camformats_nCols .* camformats_nRows);
%             app.PupilStruct.selformat = [camformats_nRows(app.PupilStruct.selformatIdx), camformats_nCols(app.PupilStruct.selformatIdx)];
%             app.ImageSizeDropDown.Value = app.ImageSizeDropDown.Items(app.PupilStruct.selformatIdx);
            app.ImageSizeDropDown.Value = app.ImageSizeDropDown.Items{1};
            app.PupilStruct.selformatIdx = 1;
            selectformat(app)
        end
        
        function selectformat(app)
            %% If the format changes, the device needs to be closed and connected again
            closedevice(app)
            % Recreate a format list
            camID = app.PupilStruct.selcamIdx;
            camformats = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(camID).SupportedFormats;

            %% Camera Object Creation
            % Select webcam and the correct format, and connect
            selected_format = camformats{app.PupilStruct.selformatIdx};
            app.PupilStruct.camobj = videoinput('winvideo',camID,selected_format);
            app.PupilStruct.camobj.ReturnedColorSpace = 'grayscale';
            app.NocameraLamp.Color = [0 1 0];
            app.NocameraLampLabel.Text = 'Camera Connected!';
            src = getselectedsource(app.PupilStruct.camobj);
            % Display frame rate
            app.FramerateHzEditField.Value = str2double(src.FrameRate);
            GrabaframeeveryEditFieldValueChanged(app, [])
            
            %% (Re-)Initializing the axes

            % selected format and encoding
            underscore_pos = find(selected_format == '_',1,'last');
            x_pos = find(selected_format == 'x',1,'last');
            camformat_nCols = str2double(selected_format(x_pos+1:end));
            camformat_nRows= str2double(selected_format(underscore_pos+1:x_pos-1));
            app.PupilStruct.selformat = [camformat_nRows, camformat_nCols];

            % The preview image is initialized
            frame = getsnapshot(app.PupilStruct.camobj);
            app.PupilStruct.hPreviewImage = image(app.PupilImage1UIAxes, frame);
            set(app.PupilImage1UIAxes,'XTick',[], 'YTick', [],...
                'XLim',[0 app.PupilStruct.selformat(1)],...
                'YLim',[0 app.PupilStruct.selformat(2)],...
                'Colormap',gray,...
                'PlotBoxAspectRatio',[app.PupilStruct.selformat(1)/app.PupilStruct.selformat(2),1,1],...
                'Visible','off');
            
            % Draw the "rectangle" (actually an ellipse) on image 1
            app.PupilStruct.hRoi = rectangle(app.PupilImage1UIAxes,'Position',...
                [1,1,app.PupilStruct.selformat(1),app.PupilStruct.selformat(2)], ...
                'EdgeColor',[1 0 0], 'Curvature', [1,1],'LineWidth',2);
            app.PupilStruct.hRoi.Visible = 'off';
            
            % % ------- THIS CAN BE UNCOMMENTED TO SPEED THE REFRESHING UP A LITTLE BIT
            % handles.image1_ax.Units = 'pixels';
            % % The axis dimensions are set to the same as the image size to speed
            % % everything up
            % handles.image1_ax.Position = [handles.image1_ax.Position(1),handles.image1_ax.Position(2),handles.selformat(1),handles.selformat(2)];
            % set(handles.image1_ax, 'xlimmode','manual','ylimmode','manual','zlimmode','manual','climmode','manual','alimmode','manual');
            % % -------
            
            % Axis 2 is for the binary image
            app.PupilStruct.hImage2 = image(app.PupilImage2UIAxes,...
                frame<=app.ThresholdSlider.Value);
            set(app.PupilImage2UIAxes,'XTick',[], 'YTick', [],...
                'XLim',[0 app.PupilStruct.selformat(1)],...
                'YLim',[0 app.PupilStruct.selformat(2)],...
                'Colormap',gray,...
                'PlotBoxAspectRatio',[app.PupilStruct.selformat(1)/app.PupilStruct.selformat(2),1,1],...
                'Visible','off');
            app.PupilImage2UIAxes.InnerPosition(3,4)=app.PupilImage1UIAxes.InnerPosition(3,4);
            % % ------- THIS CAN BE UNCOMMENTED TO SPEED THE REFRESHING UP A LITTLE BIT
            % handles.image2_ax.Units = 'pixels';
            % % The axis dimensions are set to the same as the image size to speed
            % % everything up
            % handles.image2_ax.Position = [handles.image2_ax.Position(1),handles.image2_ax.Position(2),handles.selformat(1),handles.selformat(2)];
            % set(handles.image2_ax, 'xlimmode','manual','ylimmode','manual','zlimmode','manual','climmode','manual','alimmode','manual');
            % % -------
        end
        
        % This is for the segmentation of the pupil
        function [diametros,boundingBox,bw] = pupilSegment(app, image)
            if size(image,3)>1 % rgb image
                image = rgb2gray(image);
            end
            bw = ~imbinarize(image,app.ThresholdSlider.Value);
            %     --
            bw = bwmorph(bw,'close');
            bw = bwmorph(bw,'open');

            bw = bwareaopen(bw,200);
            bw = imclose(bw,ones(3));
            bw = imfill(bw,'holes');
            
            % Tagged objects in BW image
%             L = logical(bwlabel(piel));
            % Get areas and tracking rectangle
            out_a = regionprops(bw,"Area","BoundingBox");
            % Count the number of objects
            N = size(out_a,1);
            
            if N>=1 && ~isempty(out_a)
                % ---
                % Select larger area
                areas = [out_a.Area];
                [~, pam] = max(areas); % [area_max, pam] = max(areas);
                
                diametros = sqrt(out_a(pam).BoundingBox(3)*out_a(pam).BoundingBox(4));
                
                boundingBox = out_a(pam).BoundingBox;
            else
                diametros = NaN;
                boundingBox = NaN;
            end
        end
        
        % This is the timerFcn for the acquisition
        function AcquisitionTimerFcn(app)
            switch app.PupilStruct.acqTimerStatus
                case 0 % First time the callback is executing
                    app.PupilStruct.camobj.FramesPerTrigger = inf; % acquire until execution of method stop
                    %                     app.PupilStruct.camobj.FrameGrabInterval = app.frameGrabInterval; % acquire one frame every n frames
                    
                    % The start time of the acquisition, at the moment it's not used because the getdata function already gets the relative time of each frame from the beginning of the acquisition
                    %                    app.acqStartTime = clock;
                    
                    start(app.PupilStruct.camobj)
                    app.PupilStruct.acqTimerStatus = 1;
                    
                case 1 % Second time the callback is executing
                    stop(app.PupilStruct.camobj)
                    
                    if app.PreviewWhileAcquiringCheckBox.Value
                        PreviewStop(app)
                    end
                    
                    app.acqStatusTxt.Value = 'Retrieving data...';
                    
                    [app.PupilStruct.acqFrames, app.PupilStruct.acqtimeStamp] = getdata(app.PupilStruct.camobj);
                    
                    % analysis of the pupil size (can be skipped)
                    if app.skipPupilAcqCk.Value == 0
                        
                        diametros = zeros(size(app.PupilStruct.acqFrames,4),1);
                        
                        % Loop to extract the pupil segment from each acquired frame
                        for i=1:size(app.PupilStruct.acqFrames,3)
                            [diametros(i),~,~] = pupilSegment(app, app.PupilStruct.acqFrames(:,:,i));
                        end
                        
                        % At the end, the graph is updated with the plot of the pupil dimension
                        app.PupilStruct.hPupilSizePlot = plot(app.PupilGraphUIAxes, app.PupilStruct.acqtimeStamp, diametros);
                    end
                    
                    app.PupilStruct.acqStatus = 0;
                    
                    % Save file
                    app.acqStatusTxt.Value = 'Saving data...';
                    outputPath = [app.SaveFolderEditField.Value,'\',app.PupilFilenameEditField.Value,'_',num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                    while exist(outputPath,'file') == 2
                        app.OutputFileN.Value = app.OutputFileN.Value+1;
                        outputPath = [app.SaveFolderEditField.Value,'\',app.PupilFilenameEditField.Value,'_',num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                    end
%                     outputPath = [app.SaveFolderEditField.Value,'\',app.PupilFilenameEditField.Value,'_',num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                    outputArray = app.PupilStruct.acqFrames;
                    timeStamps = app.PupilStruct.acqtimeStamp;
                    save(outputPath, 'outputArray','timeStamps')
                    app.OutputFileN.Value = app.OutputFileN.Value+1;
                    app.acqStatusTxt.Value = 'Data saved...';
                    % restore the acquisition button
                    app.acqLamp.Color = [0 1 0];
                    app.PupilStartAcquisitionButton.Enable = 1;
            end
        end
        
        function PreviewTimerFcn(app,timestamp)
%             app.PupilStruct.hPupilSizePlot.XData;
            
            % Let's take the approximate time
%             timefromstart = etime(clock,app.PupilStruct.previewstarttime); % etime gives the time in seconds
            if isempty(timestamp)
                timestamp = seconds(datetime("now")-app.PupilStruct.previewstarttime);
            end

            if app.skipPupilPrevCk.Value == 0
                app.PupilStruct.hRoi.Visible = 'on';
                [diametros,boundingBox,piel] = pupilSegment(app, app.PupilStruct.hPreviewImage.CData);
                
                app.PupilStruct.hImage2.CData = uint8(piel);
                
                if ~isnan(diametros) && ~isempty(diametros)
                    app.PupilStruct.hRoi.Position = boundingBox;
                    points_to_show = app.NumberofPointsinPreviewGraphEditField.Value;
                    first_index = max(1,...
                        length(app.PupilStruct.hPupilSizePlot.XData) - points_to_show + 1);
                    app.PupilStruct.hPupilSizePlot.XData = [app.PupilStruct.hPupilSizePlot.XData(first_index:end),timestamp];
                    xlim(app.PupilGraphUIAxes, [-inf inf])
                    app.PupilStruct.hPupilSizePlot.YData = [app.PupilStruct.hPupilSizePlot.YData(first_index:end),diametros];
                    %ylim(handles.graph_ax, [0 inf])
                end
            else
                app.PupilStruct.hRoi.Visible = 'off';
            end
        end


        function PreviewInitialize(app)
            % Initialize the preview plot
            app.PupilStruct.hPupilSizePlot = plot(app.PupilGraphUIAxes, -(0:(app.PupilStruct.previewgraph_size-1))', NaN(app.PupilStruct.previewgraph_size,1)); % Initialize
            app.PupilGraphUIAxes.XLimMode = 'manual';
            app.PupilGraphUIAxes.YLimMode = 'auto';
            xlabel(app.PupilGraphUIAxes,'Time (s)')
            ylabel(app.PupilGraphUIAxes,'Pupil Diameter (pixels)')
            
            preview(app.PupilStruct.camobj, app.PupilStruct.hPreviewImage) % Start the preview on the image "hPreviewImage"
            app.PupilImage2UIAxes.Colormap = [0,0,0; 1,1,1]; % This is a binary image of the pupil. Don't know why, this works only in here (not in selecformat)
            app.PupilStruct.previewstarttime = datetime("now");
            
            % Creating the preview timer
            % This is a timer object for the preview that executes at a high rate.
            % The busymode 'drop' makes sure that if the preview rate is too high, the
            % timer execution is just dropped and it doesn't clog anything
            period = 1/app.ActualframerateHzEditField.Value;
            app.PupilStruct.PreviewTimer = timer('BusyMode','drop','ExecutionMode','fixedRate','Period',period,'StartDelay',0.001,'TimerFcn',@(~,~) PreviewTimerFcn(app,[]));
            
            start(app.PupilStruct.PreviewTimer)
        end
        
        function PreviewStop(app)
            closepreview % close the preview image
            stop(app.PupilStruct.PreviewTimer) % Stop the preview timer
            delete(app.PupilStruct.PreviewTimer) % Delete the preview timer
        end
        
        function UdpDataReceivedCallback(app,obj)
            MsgIn = fscanf(obj);
%             Remove any white-space character.
            MsgIn = regexprep(MsgIn,'\s','');

            MsgCellArray = strsplit(MsgIn,',');
% The first element of the cell array contains usually the MAC address of 
% the slave, but when the connection is started it contains the word 
% 'start'.
            if strcmp(MsgCellArray{1},'start')
                if strcmp(MsgCellArray{5},'0') && strcmp(MsgCellArray{2},app.SlaveIp.Value) &&...
                        strcmp(MsgCellArray{4},num2str(app.SlavePort.Value))
% This is the MsgCellArray{5} == 0 means that no confirmation is required 
% for the master. The connection will be started as it is. Unless these
% checks are false.
% The timeout timer must be stopped and deleted.
                    stop(app.UdpTimeoutTimer)
                    delete(app.UdpTimeoutTimer)
                elseif strcmp(MsgCellArray{5},'1') && strcmp(MsgCellArray{4},num2str(app.SlavePort.Value)) &&...
                        ValidateIPv4(MsgCellArray{2}) && ValidateMac(MsgCellArray{3})
% This is if the MsgCellArray{5} == 1, which means that a confirmation is 
% required for the master to start this connection.
                    selection = uiconfirm(app.EscherMasterFig,['Accept connection from IP ',...
                        MsgCellArray{2},' and MAC ',MsgCellArray{3},'?'],'Connection confirmation');
                    if strcmp(selection,'Cancel')
                        return
                    end
                    app.SendToAllTerminator = 1;
% The timeout timer must be stopped and deleted.
                    stop(app.UdpTimeoutTimer)
                    delete(app.UdpTimeoutTimer)
% The second and third word contain the IP and MAC respectively.
                    app.SlaveIp.Value = MsgCellArray{2};
                    
                    app.UdpObj.RemoteHost = MsgCellArray{2};
                else
                    return
                end
% The second and third word contain the IP and MAC respectively.
                app.SlaveMac.Value = MsgCellArray{3};
                
% The connection is set, so the buttons and other stuff must be changed
% accordingly.
                app.ConnectionStatus.Value = 'Connected.';
                app.ResendScreenParams.Enable = 1;
                app.UdpConnectionFlag = 1;
                app.UDPConnectionLamp.Color = [0 1 0];
% The button used for starting the connection now is used for canceling the
% connection.
                app.StartUdpBtn.Text = 'Stop Connection';
                EnableGoBtn(app,1)
                
            elseif strcmp(MsgCellArray{1},app.SlaveMac.Value)
% The timeout timer must be stopped and deleted.
                stop(app.UdpTimeoutTimer)
                delete(app.UdpTimeoutTimer)
                
                switch MsgCellArray{2}
% The second word in the message contains the instruction.
                    case 'screenok'
% Remember to set the fields relative to the screen size on the GUI and the
% relative app.variable.
                        app.WidthPix.Value = str2double(MsgCellArray{3});
                        app.HeightPix.Value = str2double(MsgCellArray{4});
                        app.OnePxAngle = str2double(MsgCellArray{5});
                        
                        app.OpenScreenBtn.Text = 'Close Screen';
                        app.ScreenOpenFlag = 1;
                        
                        SetScreenSizeAngle(app)
                    case 'wait'
% A timer for timeout has to be called. Before that, the current timer is 
% stopped and deleted.
                        if isvalid(app.UdpTimeoutTimer)
                            stop(app.UdpTimeoutTimer)
                        end
                        delete(app.UdpTimeoutTimer) % maybe it's an overkill?
                        app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                            str2double(MsgCellArray{3})+app.TimeoutTime.Value,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
                    case 'stimok'
% Receive timestamps of all stimuli and prints them into current log file.
                        app.CurrentLogStruct.T.Timestamps(1)=MsgCellArray{3};
                        writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
% The stimulus has been performed, therefore the master will stop the timer
% and another stimulation can start.
% GO buttons are re-enabled.
                        EnableGoBtn(app,1)
                        if app.HermesSerialFlag
                            HermesMsgMake(app,'parameters','stopTrig')
                        end
                    case 'busy'
% If the slave is busy, the timer for timeout must be stopped and the 
% master can continue listening.
                        disp('Slave is busy.')
                    case 'ok'
% If the slave is busy, the timer for timeout must be stopped and the 
% master can continue listening.
                        disp('Slave is ok.')
                    case 'calibrationok'
% If Hermes is connected to Escher with serial communication
                        if app.HermesSerialFlag
% Sends a message to Hermes, that is either 'c,b' or 'c,w' for black or 
% white calibration.
                            HermesMsgMake(app,'cal',MsgCellArray{3});
                        end
                    otherwise
                        warning(['Message not recognized: ', MsgIn])
                end
            end
        end
        
        function UdpTimeoutCallback(app,obj)
% This is called only when a message is sent through UDP but no answer is 
% received.
            uialert(app.EscherMasterFig,'UDP connection lost.','Error')
% The connection status is set to 1, so that the button to cancel the 
% connection is called.
            app.UdpConnectionFlag = 1;
            StartUdpBtnButtonPushed(app,[])
% Similarly, the ScreenOpenFlag is set to 1 and "Close Screen" button is 
% pushed.
            app.ScreenOpenFlag = 1;
            OpenScreenBtn_Callback(app, [])
            
% Then, the timer is stopped and deleted.
            stop(obj)
            delete(obj)
        end
        
        function SendUdpMsg(app,msg,TimeoutTime)
%             A timer for timeout has to be called. Before that, the
%             current timer is stopped and deleted.
            if isvalid(app.UdpTimeoutTimer)
                stop(app.UdpTimeoutTimer)
            end
            delete(app.UdpTimeoutTimer) % maybe it's an overkill?
            app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                TimeoutTime,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
%             The timer is started before sending the UDP message. This is
%             to avoid that a UDP answer arrives (and stops the timer)
%             before the timer is started, since the two objects are
%             asynchronous.
            start(app.UdpTimeoutTimer)
            readasync(app.UdpObj)
            app.UdpObj.ReadAsyncMode = 'continuous';
            fprintf(app.UdpObj,msg);
        end
        
        function msg = ScreenParameterMsg(app)
% The first three components of the message are:
% 1) Master MAC address. 2) 'screen'. 3) '1'.
% Then, send the following parameters in this order:
% 4) Screen distance. 5) Screen width. 6) Screen Height. 7) Screen
% calibration check. 8) Screen function formula. 9) Hermes Synch check.
% 10) Hermes rectangle width 11) Hermes rectangle height. 12) Hermes
% Rectangle ON duration. 13) Hermes rectangle top position. 14) Hermes
% rectangle left position. 15) One screen mode check. 16) VR headset check.
% 17) Screen distortion check.

            msg=strjoin([{app.MasterMac.Value},{'screen'},{'1'},...
                arrayfun(@num2str,[app.ScreenDistance.Value,app.ScreenWidth.Value,...
                app.ScreenHeight.Value,app.CalibratedButton.Value],'un',0),...
                {app.ScreenFuncTxt.Value},arrayfun(@num2str,[app.ard_ck.Value,app.wwTxt.Value,...
                app.hhTxt.Value,app.optDtrTimeTxt.Value,app.CueTopRB.Value,...
                app.CueLeftRB.Value,app.OneScreenMode_ck.Value,app.oculusCk.Value,...
                app.ApplysphericaldistortionCheckBox.Value],'un',0)],',');
        end


        function SetScreenSizeAngle(app)
% called after any changes of the screen geometry or position.
% Gab
            WAngle = 2*atand(app.ScreenWidth.Value/2/app.ScreenDistance.Value);
            HAngle = 2*atand(app.ScreenHeight.Value/2/app.ScreenDistance.Value);
            Sz = [int2str(WAngle) '° x ' int2str(HAngle) '°'];
            app.ScreenSizeAngle.Value = Sz;
        end
        
        function HermesConnect(app,COMport)
            if ~strcmp(COMport,'NONE')
                app.HermesReplyCheck = 0;
                app.HermesSerialObj = serialport(COMport,57600);
                % debug %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 writeline(app.HermesSerialObj,"*IDN?")
%                 myResp = readline(app.HermesSerialObj);
%                 disp(myResp)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.HermesSerialObj.Timeout = 5; % the default value is 10 seconds.
%                 Set and read
                app.HermesSerialObj.DataBits = 8; % default is 8
                app.HermesSerialObj.StopBits = 1; % default is 1
                app.HermesSerialObj.Parity = 'none'; % default is none
                configureCallback(app.HermesSerialObj,"terminator",@(obj,~) HermesMsgReceivedFcn(app,obj)) % Gab: matlab 2020a update

                
                tic
                t=0;
    %             Now wait up to 1 second for a reply from Hermes.
    %             A reply will set app.HermesReplyCheck to 1 vAia the
    %             ByteAvailableFcn of the Hermes Object.
                while t<3 && ~app.HermesReplyCheck
                    drawnow
                    t=toc;
                end
                if ~app.HermesReplyCheck
                    warning(['No reply received for the initialization using ',...
                        COMport])
                    return % gab
                end
                % connection estabilished!!
                app.HermesSerialFlag = true;
                pause(1);
                HermesMsgMake(app,'parameters',{'TTL_time','idleTime'})

                HermesMsgMake(app,'mod','classic','p')

                if app.HermesSerialFlag && app.ControlIRLEDwithHermesCheckBox.Value
                    % if Hermes controls the LED for pupillometry, set the
                    % led OFF.
                    switch app.IR_led_switch.Value
                        case "ON = HIGH"
                            HermesMsgSend(app,'TTL2=0')
                        case "ON = LOW"
                            HermesMsgSend(app,'TTL2=1')
                    end
                end
            else
                delete(app.HermesSerialObj)
                app.HermesSerialFlag = false;
            end
        end
        
        function HermesMsgMake(app,MsgName,MsgValue,varargin)

%                 Input analysis:
%                 1)app
%                 2)MsgType  The type of message to be sent to arduino.
%                       It works in 'Name','Value' pairs. Names:
%                    'mod' The value for this is either 'camAc', 'camPr', 'classic' or 'opto'
%                            respectively for: camera acquisition, camera preview, classic or optogenetics
%                    'trig' The value for this is either 'p' or 's'
%                            respectively for 'phototransistor' and 'serial'
%                    'cal' The value for this is either 'w' or 'b'
%                    'stopTrig' Value: 1
%                    'endVisualStim' Value: 1
%                    'parameters' Parameters will be sent. Value is a character
%                      array or cell array containing the name of parameters
%                      to pass.
%                 3)Only when the first name is 'mod' additional parameters are required:
%                    1.The first additional parameter is 'trig' (the kind of synchronization),
%                           either 'p' (phototransistor) or 's' (serial).
%                    The other parameters can be optional and depend on the mod and trig combination
%                    
%                    -if mod = camAc and trig = p
%                         2. The second additional parameter is the number of frames
%                           for each trial.
%
%                    -if mod = camAc and trig = s
%                         2. The second additional parameter is the number of frames
%                             for each trial.
%                         3. The third additional parameter is the number of
%                             trials.
%                         4. The fourth additional parameter is the period between
%                             one trial and the next one.
% 
%                    -if mod = camPr and trig = s
% 
%                    -if mod = opto and trig = p no additional param is needed    
% 
%                    -if mod = classic and trig = p no additional param is needed  

            
            if strcmp(MsgName, 'parameters')
                if ~iscell(MsgValue)
%                     If the parameter is not a cell, then it is just one
%                     parameter.
                    MsgValue = {MsgValue};
                end
                MsgOut = '';
                for i=1:length(MsgValue)
                    switch MsgValue{i}
                        case 'TTL_time'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(1000*app.HermesTtlDurationEditField.Value))];
                        case 'idleTime'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(round(app.HermesIdleTimeEditField.Value))];
                        case 'nStim'
                            MsgOut = [MsgOut ',' MsgValue{i},'=',num2str(app.nofpulsesEditField.Value)];
                            
                        case {'synch', 'stopTrig', 'endVisualStim'}
%                             In case the acquisition has to start.
                            MsgOut = [MsgOut ',' MsgValue{i},'=1'];
                    end
%                     HermesMsgSend(app,MsgOut)
                end
                %remove the first character, which is a comma
                MsgOut = MsgOut(2:end);
            else
                MsgOut = [MsgName,'=',MsgValue];
                if strcmp(MsgName, 'mod')
                    % the type of trigger is also specified in varargin{1}.
                    tr = varargin{1};
                    % a complete list of combinations follows:
                    switch MsgValue %mode
                        case 'camAc'
                            % acquire with the pco camera.
                            switch tr
                                case 'p' % triggered by the phototransistor
                                    % varargin{2} contains nFrames
                                    % nTrials and trialPeriod depends on the visual stimulus
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin>4
                                        MsgOut = sprintf(['nFrames=%s,',MsgOut],num2str(varargin{2}));
                                    end
                                case 's' % triggered by serial communication
                                    % varargin{2} contains nFrames
                                    % varargin{3} contains nTrials
                                    % varargin{4} contains trialPeriod
                                    %                                     MsgOut = ['nFrames=',varargin{2},',','nTrials=',varargin{3},',',MsgOut,...
                                    %                                             'trialPeriod=',varargin{4}];
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin>4
                                        MsgOut = sprintf(['nFrames=%s,nTrials=%s,',MsgOut,'trialPeriod=%s'],...
                                            num2str(varargin{2}),num2str(varargin{3}),num2str(varargin{4}));
                                    end
                            end
                            
                        case 'camPr'
                            % preview with the pco camera. Can't be
                            % triggered by the phototransistor: only starts
                            % upon serial communication.
                            if strcmp(tr,'s')
%                                 MsgOut = sprintf(['nFrames=%s,trig=%s,' MsgOut],tr,varargin{2});
                                MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                            else
                                error('Phototransistor synch with preview. ARE YOU SERIOUS?')
                            end
                            
                        case 'opto'
                            % optogenetic stimulation. 
                            switch tr
                                case 'p' % triggered by the phototransistor
                                    % nTrials and trialPeriod depends on the visual stimulus
                                    MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                                case 's' % triggered by serial communication
                                    % varargin{2} contains nTrials
                                    % varargin{3} contains trialPeriod
%                                     MsgOut = ['nFrames=',varargin{2},',','nTrials=',varargin{3},',',MsgOut,...
%                                             'trialPeriod=',varargin{4}];
                                    MsgOut = sprintf(['trig=%s,',MsgOut],tr);
                                    if nargin>4
                                        MsgOut = sprintf(['nTrials=%s,',MsgOut,'trialPeriod=%s'],...
                                                        num2str(varargin{2}),num2str(varargin{3}));
                                    end
                            end
                            
                        case 'classic'
                            if strcmp(tr,'p')
                                MsgOut = sprintf(['trig=%s,' MsgOut],tr);
                            else
                                error('Serial synch with classic mode. ARE YOU SERIOUS?')
                            end
                    end
                end
%                 HermesMsgSend(app,MsgOut)
            end
            HermesMsgSend(app,MsgOut)
%             app.HermesSerialObj.BytesAvailableFcn = @(obj,~) ArduinoMsgReceivedFcn(app,obj);
%             readasync(app.HermesSerialObj)
%             app.HermesSerialObj.ReadAsyncMode = 'continuous';
        end
        
        function HermesMsgSend(app,MsgOut)
            % arduino serial buffer is limited to 64 bytes = 32 characters.
            % If MsgOut exceeds 30 characters (better be safe than
            % sorry) select the first part and recursively call HermesMsgSend
            while length(MsgOut)>30
                commas = regexp(MsgOut,',');
                currcomma = commas(find(commas<=30,1,'last'));
                currMsgOut = MsgOut(1:currcomma-1);
                HermesMsgSend(app,currMsgOut)
                MsgOut = MsgOut(currcomma+1:end); % recursive call
            end
%------------%%%% HermesMsgSend core function %%%%------------------------%
                app.HermesReplyCheck = 0;
                if app.PrintSerialCommCk.Value
                    disp(['MsgOut = ''', MsgOut,''''])
                end
                
                writeline(app.HermesSerialObj, MsgOut);
                
                tic
                t=0;
                %  Now wait up to 3 second for a reply from Hermes.
                %  A reply will set app.HermesReplyCheck to 1 vAia the
                %  ByteAvailableFcn of the Hermes Object.
                while t<3 && ~app.HermesReplyCheck
                    drawnow
                    t=toc;
                end
                if ~app.HermesReplyCheck
                    warning(['No reply received for message = ''',MsgOut,''''])
                end
%------------%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%------------------------%
        end
        
        function HermesMsgReceivedFcn(app,obj)
%             Here goes stuff.
            MsgIn = readline(obj);
            MsgIn = regexprep(MsgIn,'\r','');
            if app.PrintSerialCommCk.Value
                disp(['MsgIn = ''', char(MsgIn),''''])
            end
            if strcmp(MsgIn,'<Arduino is ready>') || strcmp(MsgIn,'Roger roger')
                app.HermesReplyCheck = 1;
            end
        end
        
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AppFilePath = mfilename('fullpath');
%             According to the operating system, sets a different separator
%             used for folder paths.
            if ispc
                app.PathSeparator = '\';
            elseif isunix
                app.PathSeparator = '/';
            else
                app.PathSeparator = '';
                error('Platform not recognized. Please check the startup function of the app.\n')
            end
%             Take all the characters up to the last path separator. This
%             is the Escher App Folder.
            AppFolder = regexp(AppFilePath, ['^.*\',app.PathSeparator],'match');
            app.escherAppFolder = AppFolder{:};
            app.SaveFolderEditField.Value = pwd;
%             INITIALIZE ALL FLAGS
            %             The screen is not open in the beginning, so the corresponding
            %             flag is set to zero
            app.ScreenOpenFlag = 0;
            app.UdpConnectionFlag = 0;
            app.HermesSerialFlag = 0;
            
%             The screen function is read:
            app.ScreenFunc = app.ScreenFuncTxt.Value;
            
%             Preparation of the log output
%             Each time Esher is launched check the log directory *\logs for the
%             presence of a directory named with the current date. If it does not
%             exist it is created.
%             Content of the log directory.
%             Each time a stim event is created (either a single episode or an entire
%             sequence) a new entry is created in the log directory.
%             Sintax of the file name:
%             It is formed by two fields, separated by dot.
%             The first field is the run number (4 digits).
%             The second field define the type of stimulus
%             flash      full field flash
%             check      checkerboard
%             grid       presentation of a grid at a given orientaton
%             Finally the extension .dat
%             Each run file includes a line that contains all the parameters relevant
%             to describe the specific stim protocol. Each presentation is described
%             in subsequent lines. The first field is a time tag, followed by
%             parameters desribing the specific stimulus, i.e. the grid orientation.
            logParentDir = [app.escherAppFolder, 'logs', app.PathSeparator];
            if exist(logParentDir, 'dir') ~= 7
                mkdir(logParentDir)
            end
            app.logDir = [logParentDir, date, app.PathSeparator];
            app.runNumberFile = [app.logDir, 'run.number.dat'];
            if exist(app.logDir, 'dir') == 7
%             The log directory has already been created. Determine the next run number.
%             This is contained in the ascii file run.number.log that is created at
%             the time of the directory creation. Each time a new log item is added
%             the run.number is updated. If Escher2 is re-initialisated during a
%             single session, this ensure the unicity of the log file names.
                app.runNumber = readmatrix(app.runNumberFile);
            else
                % Create the ASCII run.number.log file. run number = 1, of course
                mkdir(app.logDir)
                writematrix(1, app.runNumberFile)
            end
            
            app.settingsDir = [app.escherAppFolder, 'settings', app.PathSeparator];
            if exist(app.settingsDir, 'dir') ~= 7
                mkdir(app.settingsDir)
            end
            
%             Erase all UDP connections and initialize a UDP object.
            CloseAllUdp
            app.UdpObj = udpport();
            
%             Monitor initialisation and geometry
            SetScreenSizeAngle(app)
            
%             app.white is initialized to 255 and the ScreenBitDepth to 'uint8'.
%             This is the default. If the screen is opened with a different
%             bit depth, these values will be updated.
            app.white = 255;
            app.ScreenBitDepth = 'uint8';
            
%             HARD-CODED SETTINGS AND CALLBACKS
%             The table for fast selection of Flash stimuli has to be set
            app.FlashFastTable.Data = [0, 0; 0.1, 0; 0.139, 0;...
                0.194, 0; 0.268, 0; 0.374, 0; 0.516, 0;...
                0.719, 0; 1, 0];
            app.FlashFastTotN.Value = size(app.FlashFastTable.Data, 1);
%             Then executes the callback of the fast set text box.
            FlashFastN_Callback(app,[])
            
%             The table for fast selection of Checkerboard stimuli has to
%             be set
            app.CheckFastTable.Data = [0, 0.6; 6, 0.6; 12, 0.6;...
                25, 0.6; 50, 0.6; 95, 0.6];
            app.CheckFastTotN.Value = size(app.CheckFastTable.Data, 1);
%             Then executes the callback of the fast set text box.
            CheckFastNValueChanged(app,[])
            
            app.OrientFastTable.Data = [0; 30; 60; 90; 120; 150; 180;...
                210; 240; 270; 300; 330];
            app.OrientFastTotN.Value = size(app.OrientFastTable.Data, 1);
            OrientFastNValueChanged(app, [])


            app.SfFastTable.Data = [0.03,0.05,0.1,0.15,0.2,0.3,0.4,0.5,...
                0.6,0.8,1]';
            app.SfFastN.Value = size(app.SfFastTable.Data, 1);
            SfFastNValueChanged(app, [])
            
%             The timer for UDP connection timeout must be initialized.
            app.UdpTimeoutTimer = timer;
            
%             Other callbacks to be exectued:
%             The callback for the calculation of Escher values from
%             luminances (in case the calibration is on) OR hiding the
%             escher values (in case calibration is off).
%             All this is achieved by invoking the callback of the
%             calibration radio button.
            CalModeRBGroupSelectionChanged(app,[])
            
%             This is the initialization of the colors of the ExpList.
%             The first is the background color, then the current exp
%             color, then the running exp and the completed exp.
            app.ExpListColors = [1 1 1; 0.9 0.9 0.9; 1 1 0.8; 0.85 1 0.85];
            
% Creates a vector of graphical objects, containing all the labels of 
% luminance, which have to change between cd/m2 and 0-1, depending on 
% whether the absolute luminance or the "Escher Value" (value between 0 and
% 1) is selected.
%             app.LabeLumiVector = [app.LabelLumi_1, app.LabelLumi_2,...
%                 app.LabelLumi_3, app.LabelLumi_4,app.LabelLumi_5,...
%                 app.LabelLumi_6];
            
% Initialize the ExperimentTable so that the stimulus type can be selected 
% with a dropdown menu.
            app.ExperimentTable.ColumnFormat = {'numeric',{'flash','checkerboard','grid'},'numeric','char'};
            
% Initialize the IP and MAC for UDP connection.
            GetMasterIPandMACBtnPushed(app,[])
            
% Pupillometry initialization
            app.PupilStruct.preview_status = 0; % This is 1 while the preview is on
            app.PupilStruct.acqStatus = 0; % This is set to 1 when the acquisition has started
            app.PupilStruct.threshold = app.ThresholdSlider.Value;
% set(gcf,'doublebuffer','off') % Set the DoubleBuffer property on for to obtain flicker-free animation. However, to maximize rendering speed, set DoubleBuffer to off.
            app.PupilStruct.previewgraph_size = app.NumberofPointsinPreviewGraphEditField.Value; % number of points composing the preview graph
            app.PupilStruct.acqDuration = app.DurationsEditField.Value;
            
% Set a starting value for app.screenRect
            app.screenRect = [0, 0, app.WidthPix.Value, app.HeightPix.Value];
            PixelAngleCalc(app)
            
% Set the bar drifting speed for the Intrinsic signal stimulus
            TrialperiodsEditFieldValueChanged(app,[])

% This will set the selected sync mode (escher-only or Hermes) and will 
% also set the selected frequency according to the maximum possible
% frequency.
            
            % set by default the density of dots as 1 over diameter^2
            app.DotsdensityEditField.Value = 1/app.DotsdiameterdegEditField.Value.^2;
            DotsdensityEditFieldValueChanged(app, 1)
            
            UsedefaultwidthandheightCheckBoxValueChanged(app,[])
        end

        % Button pushed function: FSFlashGo
        function GoFlash_Callback(app, event)
% December , 2018
% The app has been completey redesigned to implement a server-client 
% architecture
            
% Nov 25, 2013
% the function has been modified to allow the display of a periodically
% alternating checkboard.
% Blumi and Slumi define the luminance of the black and light squares.
% The spatial frequency is defined by the "spatial frequency" field in the
% grid generation module.
% p define the period of the alternation (ON and OFF)
% Bt defines the baseline_txt spent in the baseline_txt equiluminant gray computed
% as (Blumi+Slumi)/2 if a calibration LUT is active.
            
% May 2017.
% This code has been modified to allow the creation of the checkerboad stim
% on the VR googles.
            
% If the "PCO while stimulating" box is checked, Hermes must be also 
% connected with serial communication.
% It is better to do this check here, before disabling the GO buttons and 
% writing any logs.

            
% Disables all "Go" buttons
            EnableGoBtn(app,0)
            
% load parameters in local variables
            Blumi = app.FSbaselineL.Value; % baseline luminance
            Slumi = app.FSflashL.Value; % flash luminance
            Bt = app.initialDelay.Value; % an initial delay (just once)
            St = app.FlashDuration.Value; % The duration of a single flash
            p = app.FlashPeriod.Value; % time between each flash
            n = app.FlashRepetitions.Value;   % number of repetitions
            
% Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.flash', '.dat'];
% Initial writing of the file. The file contains the whole
% table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1 8],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 6]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','Bt','St','p','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [Blumi,Slumi,Bt,St,p,n];
% Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
% Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
            ParameterVector = [Blumi,Slumi,Bt,St,p,n];
            
            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'flash'},arrayfun(@num2str,ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
                
            else
                OneScreenFlag = app.OneScreenMode_ck.Value;
                CalibrationFlag = app.CalibratedButton.Value;
                ard_flag = app.ard_ck.Value;
                ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,ard_flag]);
    %             Calling the function that presents the visual stimulus.
                timestamps = FlashPresent(app,ParameterVector);
                            
% Timestamps are stored in the log file as a character vector, in which 
% timestamps are separated by a semi-colon.
% Timestamps are saved with 6 significant digits after decimal point 
% (microseconds).
                app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
% Write the table to the log file for this stimulation.
                writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
% Enable the buttons "Go" again
                EnableGoBtn(app,1)
            end
        end

        % Button pushed function: GoGrid
        function GoGrid_Callback(app, event)
%             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % The two luminance values we need
            Glumi = app.CheckLumiMean.Value; % the average luminance
            inc = (app.CheckLumiW.Value-app.CheckLumiB.Value)/2; % the increment of luminance
%             Other values needed
            sF = app.spatialFrequency.Value; % spatial frequency of the stimulus (in cycles/degree)
            gridType = app.SineGrid.Value; % 1 means sinusoidal, 0 is for square-wave (bar)
            Angle = app.GridOrientation.Value; % angle of the stimulus. 0 means vertical bars.
            dS = app.DriftingSpeed_txt.Value; % drifting speed of the stimulus in cycles/s.
            MaskFlag = app.DrawMask.Value; % 1 means that a gaussian mask is applied.
            GaussSize = app.GaussSize_txt.Value; % size of gaussian mask
            Bt = app.initialDelay.Value; % initial delay
            PreG = app.PreGridDuration.Value; % pre-grid duration
            GridT = app.GridDuration.Value; % grid duration
            PostG = app.PostGridDuration.Value; % post-grid duration
            n = app.GridRepetitions.Value; % number of repetitions of the stimulus
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.grid', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 17],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 15]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','K','sF', 'sine','angle','speed',...
                'maskflag','masksize','Bt','preG','gridT','postG','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [app.CheckLumiB.Value,app.CheckLumiW.Value,...
                Glumi,app.CheckContrast.Value,sF,gridType,Angle,dS,MaskFlag,GaussSize,...
                Bt,PreG,GridT,PostG,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
% Extra parameter:
            
            ParameterVector = [Glumi,inc,sF,gridType,Angle,dS,MaskFlag,...
                GaussSize,Bt,PreG,GridT,PostG,n];
            
%             Hermes has to trigger the PCO acquisition
            

            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'grid'},arrayfun(@num2str,...
                    ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
            else
                OneScreenFlag = app.OneScreenMode_ck.Value;
                CalibrationFlag = app.CalibratedButton.Value;
                oculusFlag = app.oculusCk.Value;
                ard_flag = app.ard_ck.Value;
                optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
                
                ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,...
                    oculusFlag,ard_flag,optDtrTime]);
                timestamps = GridPresent(app,ParameterVector);
                                
% Timestamps are stored in the log file as a character vector, in which 
% timestamps are separated by a semi-colon.
% Timestamps are saved with 6 significant digits after decimal point 
% (microseconds).
                app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
% Write the table to the log file for this stimulation.
                writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
% Enables all "Go" buttons
                EnableGoBtn(app,1)
            end
        end

        % Button pushed function: RecptiveFieldGo
        function RecptiveFieldGoButtonPushed(app, event)

            % GAB, 23-03-2022

            
% Nov 25, 2013
% p define the period of the alternation (ON and OFF)
% Bt defines the baseline_txt spent in the baseline_txt equiluminant gray computed
% as (Blumi+Slumi)/2 if a calibration LUT is active.
            
% May 2017.
% This code has been modified to allow the creation of the checkerboad stim
% on the VR googles.
            
            
% Disables all "Go" buttons
            EnableGoBtn(app,0)
            
% load parameters in local variables
            Blumi = app.FSbaselineL.Value; % baseline luminance
            Slumi = app.FSflashL.Value; % flash luminance
            Bt = app.initialDelay.Value; % an initial delay (just once)
            St = app.FlashDuration.Value; % The duration of a single flash
            p = app.FlashPeriod.Value; % time between each flash
            n = app.FlashRepetitions.Value;   % number of repetitions
            nrows = app.rowsEditField.Value;
            ncols = app.columnsEditField.Value;
            app.UIAxes.XLim = [0 ncols];
            app.UIAxes.YLim = [0 nrows];
            app.UIAxes.XTick = 0:ncols;
            app.UIAxes.YTick = 0:nrows;
            drawnow
            
% Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, app.LogfilenameEditField.Value, runString, '.receptiveField', '.mat'];
% Initial writing of the file.
%             app.CurrentLogStruct.T = table('Size',[n*nrows*ncols, 13],'VariableTypes',[{'datetime'},...
%                 repmat({'double'},[1, 10]),{'string'},{'string'}],'VariableNames',...
%                 {'StartTime','Blumi','Slumi','Bt','St','p','n','nrows','ncols','screenSize','order','TimeStart','TimeEnd'});
%             app.CurrentLogStruct.T.StartTime(1) = datetime;
%             app.CurrentLogStruct.T(1,2:end-3) = {Blumi,Slumi,Bt,St,p,n,nrows,ncols,app.screenRect([2,3])};
%             app.CurrentLogStruct.T = table(datetime,Blumi,Slumi,Bt,St,p,n,nrows,ncols,app.screenRect([2,3]),0,0,0,'VariableNames',...
%                 {'StartTime','Blumi','Slumi','Bt','St','p','n','nrows','ncols','screenSize','order','TimeStart','TimeEnd'});
% % Write the table to the log file for this stimulation.
%             writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
% Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
            ParameterVector = [Blumi,Slumi,Bt,St,p,n,nrows,ncols];
      
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'receptiveField'},arrayfun(@num2str,ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
                
            else
                OneScreenFlag = app.OneScreenMode_ck.Value;
                CalibrationFlag = app.CalibratedButton.Value;
                ard_flag = app.ard_ck.Value;
                ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,ard_flag]);
    %             Calling the function that presents the visual stimulus.
                [order,timestamps] = ReceptiveFieldPresent(app,ParameterVector);
                            
% Timestamps are stored in the log file as a character vector, in which 
% timestamps are separated by a semi-colon.
% Timestamps are saved with 6 significant digits after decimal point 
% (microseconds).
%                 app.CurrentLogStruct.T.TimeStart = {num2str(timestamps(:,1),'%.6f')};
%                 app.CurrentLogStruct.T.TimeEnd = {num2str(timestamps(:,2),'%.6f')};
%                 app.CurrentLogStruct.T.order = {order'};
% % Write the table to the log file for this stimulation.
%                 writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
                r = app.screenRect([3,4]);
                save(app.CurrentLogStruct.logName,'n','nrows','ncols','r','order')
            
% Enable the buttons "Go" again
                EnableGoBtn(app,1)
            end
        end

        % Button pushed function: OpenScreenBtn
        function OpenScreenBtn_Callback(app, event)
            switch app.ScreenOpenFlag
                case 0 % Screen in not open
                    if app.UdpConnectionFlag
                        msg = ScreenParameterMsg(app);
                        SendUdpMsg(app,msg,app.TimeoutTime.Value)
                        return
                    end
                    
                    if app.CalibratedButton.Value
                        [background,app.StandbyL.Value] = Lumi2Escher(app.StandbyL.Value,app.white,app.ScreenFunc);
                    else
                        background = app.StandbyL.Value;
                    end
                    
                    % screenRect is a 4 element array xmin, ymin, xmax, ymax
                    % strangely, xmin and ymin are set to zero. Bho?
                    OpenScreen(app, background)
                    
                    % Remember to set the fields relative to the screen size on the GUI and the
                    % relative app.variable.
                    app.WidthPix.Value = app.screenRect(3);
                    app.HeightPix.Value = app.screenRect(4);
                    
                    app.OpenScreenBtn.Text = 'Close Screen';
                    
                    app.ScreenOpenFlag = 1;
                    
%                     Call the standby button.
                    RefreshStByButtonPushed(app, [])
                case 1 % Screen is open
                    CloseScreen
                    app.OpenScreenBtn.Text = 'Open Screen';
                    app.ScreenOpenFlag = 0;
                    EnableGoBtn(app,1)
            end
            
        end

        % Value changed function: SliderOrientation
        function SliderOrientation_Callback(app, event)
            app.GridOrientation.Value = app.SliderOrientation.Value;
        end

        % Button pushed function: calblack_btn
        function calblack_btn_Callback(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'calibration'},{'b'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
%                 return
            else
                % Starts the OpenGL session if in single screen mode
                if app.OneScreenMode_ck.Value
                    background = app.StandbyL.Value;
                    OpenScreen(background)
                end
                HermesRectCalc(app)
                BaselineColor = cast([[app.white;app.white;app.white], [0;0;0]], app.ScreenBitDepth);
                BaselineScreen = [app.screenRect; app.HermesRect]';
                
                Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                Screen('Flip', app.w);
                
% If Hermes is connected to Escher with serial communication, send a
% message to start calibration.
% If Hermes is connected and so is UDP, a message to Hermes is sent in the
% UDP datareceived callback.
                if app.HermesSerialFlag
                    HermesMsgMake(app,'cal','b')
                end
            end
        end

        % Button pushed function: calwhite_btn
        function calwhite_btn_Callback(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'calibration'},{'w'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
%                 return
            else
                % Fill the screen with white
                if app.OneScreenMode_ck.Value
                    background = app.StandbyL.Value;
                    OpenScreen(background)
                end
                
                HermesRectCalc(app)
                BaselineColor = cast([[0;0;0], [app.white;app.white;app.white]], app.ScreenBitDepth);
                BaselineScreen = [app.screenRect; app.HermesRect]';
    
                Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                Screen('Flip', app.w);
                
% If Hermes is connected to Escher with serial communication, send a
% message to start calibration.
% If Hermes is connected and so is UDP, a message to Hermes is sent in the
% UDP datareceived callback.
                if app.HermesSerialFlag
                    HermesMsgMake(app,'cal','w')
                end
            end

        end

        % Value changed function: FlashFastN
        function FlashFastN_Callback(app, event)
            FlashFastMat = app.FlashFastTable.Data;
            if app.FlashFastN.Value > size(FlashFastMat,1)
                app.FlashFastN.Value = size(FlashFastMat,1);
            end
            setnumber = app.FlashFastN.Value;
            
%             Set the flash luminance
            app.FSflashL.Value = FlashFastMat(setnumber,1);
%             Call the corresponding callback
            FSflashLValueChanged(app,[])
            
%             Set the baseline luminance
            app.FSbaselineL.Value = FlashFastMat(setnumber,2);
%             Call the corresponding callback
            FSbaselineLValueChanged(app,[])
        end

        % Close request function: EscherMasterFig
        function EscherMasterFigCloseRequest(app, event)
            
            if isa(app.HermesSerialObj, 'serial')
                try
                    fclose(app.HermesSerialObj);
                catch ME
                    warning(ME.message)
                end
            end
            
            try
            % save parameters in an autosettings file
    %             saveParams(app, [app.escherAppFolder '\autosettings.mat'])
                if isvalid(app.UdpTimeoutTimer)
                    stop(app.UdpTimeoutTimer)
                end
                delete(app.UdpTimeoutTimer)
                CloseAllUdp
            catch ME
%                 warning('off','backtrace') % maybe not necessary
                warning(ME.message)
            end
            delete(app)
        end

        % Button pushed function: LoadScreenFileButton
        function LoadScreenFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.dat;*.csv;*.txt',...
                'Text files (*.dat,*.csv,*.txt)';...
                '*.*',  'All Files (*.*)'}, 'Select a File');
            if file==0
                return
            end
            
            T = readtable([path, file]);
            
            app.ScreenNameTxt.Value = T.name{1};
            app.ScreenWidth.Value = T.width(1);
            app.ScreenHeight.Value = T.height(1);
            app.ScreenFuncTxt.Value = T.formula{1};
            app.ScreenNotesTxt.Value = T.notes{1};
            
            SetScreenSizeAngle(app)
            
            ScreenFuncTxtValueChanged(app, [])
        end

        % Button pushed function: RefreshStBy
        function RefreshStByButtonPushed(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'background'},{num2str(app.StandbyL.Value)}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
                return
            end
            
            ard_flag = app.ard_ck.Value;
            Blumi = app.StandbyL.Value;
            if app.CalibratedButton.Value
                [Blumi,app.StandbyL.Value] = Lumi2Escher(Blumi,app.white,app.ScreenFunc);
            end
            Blumi = app.white*Blumi;
            if ard_flag
                BaselineColor = cast([[Blumi;Blumi;Blumi], [0;0;0]], app.ScreenBitDepth);
                cellRects = [app.screenRect; app.HermesRect]';
                %     This is commented because in flashes, the optical DTR
                %     duration is the same as the flash duration
                %     optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            else
                BaselineColor = cast(Blumi, app.ScreenBitDepth);
               	cellRects = app.screenRect;
            end
            Screen('FillRect', app.w, BaselineColor, cellRects); % paints the rectangle (entire screen)
            Screen('Flip', app.w);
        end

        % Button pushed function: FlashFastLoadTable
        function FlashFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','FlashStimuliTable.csv');
            if file==0
                return
            end
%             StimTable = readtable([path, file]);
%             app.FlashFastTable.Data = StimTable;
            app.FlashFastTable.Data = readmatrix([path, file]);
        end

        % Button pushed function: CheckGo
        function GoCheck_Callback(app, event)
            % Nov 25, 2013
            % the function has been modified to allow the display of a periodically
            % alternating checkboard.
            % Blumi and Slumi define the luminance of the black and light squares.
            % The spatial frequency is defined by the "spatial frequency" field in the
            % grid generation module.
            % p define the period of the alternation (ON and OFF)
            % Bt defines the baseline_txt spent in the baseline_txt equiluminant grey computed
            % as (Blumi+Slumi)/2 if a calibration LUT is active.
            
            % May 2017.
            % This code has been modified to allow the creation of the checkerboad stim
            % on the VR googles.
            
            
            %             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % load parameters in local variables
            Blumi = app.CheckLumiB.Value;
            Slumi = app.CheckLumiW.Value;
            %             gray luminance for equiluminant background.
            Glumi = app.CheckLumiMean.Value;
            sF = app.spatialFrequency.Value;
            Bt = app.initialDelay.Value;
            p = app.CheckPeriod.Value/2; % hemi-period. p is the time between a reversal and the next one (2 reversals = 1 period)
            n = app.CheckRepetitions.Value; % Repetitions. The number of reversals is 2*n.
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.check', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 10],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 8]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','K','sF', 'Bt','p','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [Blumi,Slumi,Glumi,app.CheckContrast.Value,...
                sF,Bt,p,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
%             ParameterVector is the vector of parameters that is passed as
%             UDP message. If UDP is not used, it is useful anyway as input
%             for the stimulus-presenting function.
            ParameterVector = [Blumi,Slumi,sF,Bt,p,n];
            
            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'check'},arrayfun(@num2str,ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
            else
% Additional parameters to be passed: flags and synchronization parameters.
                OneScreenFlag = app.OneScreenMode_ck.Value;
                CalibrationFlag = app.CalibratedButton.Value;
                oculusFlag = app.oculusCk.Value;
                ard_flag = app.ard_ck.Value;
                optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            
% Let's add some parameters to the ParameterVector for the CheckPresent 
% function.
                ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,oculusFlag,ard_flag,optDtrTime]);
% Now starts the function for the presentation of the stimulus.
                timestamps = CheckPresent(app,ParameterVector);
                                
% Log writing.
% Timestamps are stored in the log file as a character vector, in which 
% timestamps are separated by a semi-colon.
% Timestamps are saved with 6 significant digits after decimal point 
% (microseconds).
                app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
% Write the table to the log file for this stimulation.
                writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
% Enables all "Go" buttons
                EnableGoBtn(app,1)
            end
        end

        % Value changed function: CheckFastN
        function CheckFastNValueChanged(app, event)
            CheckFastMat = app.CheckFastTable.Data;
            if app.CheckFastN.Value > size(CheckFastMat,1)
                app.CheckFastN.Value = size(CheckFastMat,1);
            end
            setnumber = app.CheckFastN.Value;
            
%             Set the flash luminance
            app.CheckContrast.Value = CheckFastMat(setnumber,1);
%             Set the baseline luminance
            app.CheckLumiMean.Value = CheckFastMat(setnumber,2);
            
            CheckUpdateLumi(app,0)
        end

        % Button pushed function: CheckFastLoadTable
        function CheckFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','CheckStimuliTable.csv');
            if file==0
                return
            end
%             StimTable = readtable([path, file]);
%             app.CheckFastTable.Data = StimTable;
            app.CheckFastTable.Data = readmatrix([path, file]);
        end

        % Selection changed function: CalModeRBGroup
        function CalModeRBGroupSelectionChanged(app, event)
%             Depending on whether the calibration is selected or not, the
%             unit of measurement of the luminance is changed.
            if app.CalibratedButton.Value
%                 If the calibration is there, we need the luminance to be
%                 expressed as cd/m2
                mystring = 'cd/m²';
            else
%                 Otherwise, we set it to be (0-1)
                mystring = '(0-1)';
            end
            
%             set(app.LabeLumiVector, 'Text', mystring)
        end

        % Value changed function: CheckLumiB
        function CheckLumiBValueChanged(app, event)
            CheckUpdateLumi(app,1)
        end

        % Value changed function: CheckLumiW
        function CheckLumiWValueChanged(app, event)
            CheckUpdateLumi(app,1)
        end

        % Value changed function: ScreenWidth
        function ScreenWidthValueChanged(app, event)
            SetScreenSizeAngle(app)
            PixelAngleCalc(app)
        end

        % Value changed function: ScreenHeight
        function ScreenHeightValueChanged(app, event)
            SetScreenSizeAngle(app)
        end

        % Value changed function: CheckLumiMean
        function CheckLumiMeanValueChanged(app, event)
            CheckUpdateLumi(app,0)
        end

        % Value changed function: CheckContrast
        function CheckContrastValueChanged(app, event)
            CheckUpdateLumi(app,0)
        end

        % Value changed function: GridOrientation
        function GridOrientationValueChanged(app, event)
            app.SliderOrientation.Value = app.GridOrientation.Value;
        end

        % Value changed function: FSbaselineL
        function FSbaselineLValueChanged(app, event)
            if app.CalibratedButton.Value
                [app.FSbaselineLEsch.Value,TrueFSbaselineL] = Lumi2Escher(app.FSbaselineL.Value,app.white,app.ScreenFunc);
                app.FSbaselineL.Value = TrueFSbaselineL;
            end
        end

        % Value changed function: FSflashL
        function FSflashLValueChanged(app, event)
            if app.CalibratedButton.Value
                [app.FSflashLEsch.Value,TrueFSflashL] = Lumi2Escher(app.FSflashL.Value,app.white,app.ScreenFunc);
                app.FSflashL.Value = TrueFSflashL;
            end
        end

        % Value changed function: OrientFastN
        function OrientFastNValueChanged(app, event)
            OrientFastMat = app.OrientFastTable.Data;
            if app.OrientFastN.Value > size(OrientFastMat,1)
                app.OrientFastN.Value = size(OrientFastMat,1);
            end
            setnumber = app.OrientFastN.Value;
            
%             Set the orientation
            app.GridOrientation.Value = OrientFastMat(setnumber,1);
            GridOrientationValueChanged(app,[])
        end

        % Value changed function: FlashFastTotN
        function FlashFastTotNValueChanged(app, event)
            StimTotN = app.FlashFastTotN.Value;
            TableSize = size(app.FlashFastTable.Data,1);
            
            if StimTotN > TableSize
%             A number of rows are added to the table.
                if app.logspacedFlashCk.Value && app.includezeroFlashCk.Value
                    minL = 0;
                else
                    minL = app.FlashFastMinL.Value;
                end
%                 The extra rows are filled with a blank stimulus.
                app.FlashFastTable.Data = vertcat(app.FlashFastTable.Data,...
                    horzcat((TableSize+1:StimTotN)', repmat(minL,StimTotN-TableSize,2)));
            else
%                 The table is shortened.
                app.FlashFastTable.Data = app.FlashFastTable.Data(1:StimTotN,:);
            end
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
%             If the table is empty, this function is just executed. If
%             it's not empty, a confirmation will be prompted.
            if ~isempty(app.ExperimentTable.Data)
                answ = uiconfirm(app.EscherMasterFig, 'This will delete previously generated table. Do you confirm?', 'Warning');
                switch answ
                    case 'Cancel'
                        return
                end
            end
            
%             Get some dimensions.
            SessionLength = app.ExpListFlashCk.Value*size(app.FlashFastTable.Data,1)+...
                app.ExpListCheckCk.Value*size(app.CheckFastTable.Data,1)+...
                app.ExpListGratingsOrientCk.Value*size(app.OrientFastTable.Data,1);
            tableN = SessionLength * app.ExpListSessionN.Value;
            app.ExperimentTable.Data = cell(tableN,4);
            StartNum = app.ExpListFirstNum.Value;
            
            for i = 1:app.ExpListSessionN.Value
                if app.ExpListFlashCk.Value
                    FlashTmp = cell(size(app.FlashFastTable.Data,1), 2);
                    FlashTmp(:,1) = repmat({'flash'},size(app.FlashFastTable.Data,1),1);
                    FlashTmp(:,2) = num2cell(1:size(app.FlashFastTable.Data,1));
                else
                    FlashTmp = [];
                end
                
                if app.ExpListCheckCk.Value
                    CheckerTmp = cell(size(app.CheckFastTable.Data,1), 2);
                    CheckerTmp(:,1) = repmat({'checkerboard'},size(app.CheckFastTable.Data,1),1);
                    CheckerTmp(:,2) = num2cell(1:size(app.CheckFastTable.Data,1));
                else
                    CheckerTmp = [];
                end
                
                if app.ExpListGratingsOrientCk.Value
                    GridTmp = cell(size(app.OrientFastTable.Data,1), 2);
                    GridTmp(:,1) = repmat({'grid'},size(app.OrientFastTable.Data,1),1);
                    GridTmp(:,2) = num2cell(1:size(app.OrientFastTable.Data,1));
                else
                    GridTmp = [];
                end
                
                sessionMat = [FlashTmp; CheckerTmp; GridTmp];
                
                permutations = randperm(SessionLength);
                sessionMat = sessionMat(permutations,:);
                
                app.ExperimentTable.Data(1+SessionLength*(i-1) : SessionLength*i, [2 3]) = sessionMat;
            end
            
            
%             Give a number to each experiment, starting from
%             ExpListFirstNum.
            app.ExperimentTable.Data(:,1) = num2cell(StartNum : tableN+StartNum-1);
            app.ExpListCurrentIdx=1;
%             The first experiment has to start from the ExpListFirstNum.
            app.ExpListCurrentNum.Value = StartNum;
%             The first row in app.ExpListColors is the background.
            app.ExperimentTable.BackgroundColor = app.ExpListColors(1,:) .* ones(tableN,3);
%             The second row in app.ExpListColors is the current exp.
            app.ExperimentTable.BackgroundColor(1,:) = app.ExpListColors(2,:);
            
            app.ExpListGo.Enable = 1;
            
        end

        % Cell selection callback: ExperimentTable
        function ExperimentTableCellSelection(app, event)
            app.ExpListSelectedIndices = event.Indices;
            app.ExpListDeleteRows.Enable = 1;
            app.ExpListAddRows.Enable = 1;
        end

        % Button pushed function: CopytableButton
        function CopytableButtonPushed(app, event)
            % To copy the data from the table of zebrasays to the clipboard
            tablesize = size(app.ExperimentTable.Data);
            
            % Created a function to copy data to clipboard from outtable.
            % selectionIndices is a 4-element vector containing the
            % upper-left corner and the bottom-right corner indices
            SelTab2Clip(app,app.ExperimentTable,[1 1 tablesize(1) tablesize(2)])
        end

        % Button pushed function: ExpMoveUp
        function ExpMoveUpPushed(app, event)
            %             The whole selected part is moved of one row up
            SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
            %             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1 && (SelRowIndices(1) > app.ExpListCurrentIdx+1 ||...
                    SelRowIndices(2) < app.ExpListCurrentIdx)
                %                 shift a subset of the array circularly to get to the
                %                 app.ExperimentTable.Data(SelRowIndices(1)-1 : SelRowIndices(2), 2:end) =...
                %                     circshift(app.ExperimentTable.Data(SelRowIndices(1)-1 : SelRowIndices(2), 2:end), -1, 1);
                app.ExperimentTable.Data(SelRowIndices(1)-1:SelRowIndices(2), 2:end) =...
                    app.ExperimentTable.Data([SelRowIndices(1):SelRowIndices(2), SelRowIndices(1)-1], 2:end);
                
                %                 The same must be done for colors
                %                 app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :) =...
                %                     circshift(app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :), -1, 1);
                app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :) =...
                    app.ExperimentTable.BackgroundColor([SelRowIndices(1):SelRowIndices(2), SelRowIndices(1)-1], :);
                
                %                 Then the index of current experiment has to be changed.
                %                 Also the number corresponding to current experiment is
                %                 going to change.
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx - 1;
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value - 1;
                elseif app.ExpListCurrentIdx == SelRowIndices(1) - 1
                    app.ExpListCurrentIdx = SelRowIndices(2);
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + (SelRowIndices(2)-SelRowIndices(1)+1);
                end
            end
        end

        % Button pushed function: ExpMoveDown
        function ExpMoveDownButtonPushed(app, event)
            %             The whole selected part is moved of one row up
            SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
            %             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1
                %                 shift a subset of the array circularly to get to the
                %                 app.ExperimentTable.Data(SelRowIndices(1) : SelRowIndices(2)+1, 2:end) =...
                %                     circshift(app.ExperimentTable.Data(SelRowIndices(1) : SelRowIndices(2)+1, 2:end), 1, 1);
                app.ExperimentTable.Data(SelRowIndices(1):SelRowIndices(2)+1, 2:end) = ...
                    app.ExperimentTable.Data([SelRowIndices(2)+1, SelRowIndices(1):SelRowIndices(2)], 2:end);
                
                %                 The same must be done for colors
                app.ExperimentTable.BackgroundColor(SelRowIndices(1) : SelRowIndices(2)+1, :) =...
                    circshift(app.ExperimentTable.BackgroundColor(SelRowIndices(1) : SelRowIndices(2)+1, :), 1, 1);
                
                %                 Then the index of current experiment has to be changed.
                %                 Also the number corresponding to current experiment is
                %                 going to change.
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx + 1;
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + 1;
                elseif app.ExpListCurrentIdx == SelRowIndices(2) + 1
                    app.ExpListCurrentIdx = SelRowIndices(1);
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + (SelRowIndices(2)-SelRowIndices(1)+1);
                end
            end
        end

        % Button pushed function: ExpListDeleteRows
        function ExpListDeleteRowsButtonPushed(app, event)
            answ = uiconfirm(app.EscherMasterFig,'This will delete all the selected columns','Warning','Options',{'Delete','Cancel'});
            if strcmp(answ,'Delete')
                SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
                app.ExperimentTable.Data(SelRowIndices(1):SelRowIndices(2),:) = [];
                app.ExperimentTable.BackgroundColor(SelRowIndices(1):SelRowIndices(2), : ) = [];
                app.ExperimentTable.Data(:,1) = num2cell(app.ExpListFirstNum.Value : ...
                    size(app.ExperimentTable.Data, 1)+app.ExpListFirstNum.Value-1);
                
%                 If the current experiment is in the selected rows
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
%                     If current experiment is not the last row
                    if app.ExpListCurrentIdx < size(app.ExperimentTable.Data, 1)
%                         The index of the first deleted row becomes the
%                         index of the current experiment.
                        app.ExpListCurrentIdx = SelRowIndices(1);
                        app.ExperimentTable.BackgroundColor(app.ExpListCurrentIdx,:) = app.ExpListColors(2,:);
%                         If the current experiment is the last row OR if
%                         the selection encompassing the current
%                         experiment also reaches the end of the table.
                    elseif app.ExpListCurrentIdx == size(app.ExperimentTable.Data, 1) ||...
                            SelRowIndices(2) == size(app.ExperimentTable.Data, 1)
                        app.ExpListCurrentIdx = [];
                        app.ExpListGo.Enable = 0;
                    end
%                     If the selected rows are before the current
%                     experiment.
                elseif app.ExpListCurrentIdx > SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx - (SelRowIndices(2)-SelRowIndices(1)+1);
                end
                app.ExpListCurrentNum.Value = app.ExperimentTable.Data{app.ExpListCurrentIdx,1};
                app.ExpListDeleteRows.Enable = 0;
                app.ExpListAddRows.Enable = 0;
            end
        end

        % Button pushed function: ExpListGo
        function ExpListGoPushed(app, event)
%             Current Index is the index of the current experiment.
            currIdx = app.ExpListCurrentIdx;
%             Current FastNumber is the number of the current experiment on
%             the table (it's the same as Current Index ONLY if the
%             Experiment Table starts from experiment n. 1.
            currFastNum = app.ExperimentTable.Data{currIdx,3};
            
%             If the pupil preview while acquiring is checked
            if app.PupilWhileStimulating.Value && app.UdpConnectionFlag
                StartPreviewButtonPushed(app, [])
            end
            
            switch app.ExperimentTable.Data{currIdx,2}
                case 'flash'
%                     Changes the stimulus
                    app.FlashFastN.Value = currFastNum;
                    FlashFastN_Callback(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);
                    
%                     Starts the stimulation
                    GoFlash_Callback(app,[])
                case 'checkerboard'
%                     Changes the stimulus
                    app.CheckFastN.Value = currFastNum;
                    CheckFastNValueChanged(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);
                    
%                     Starts the stimulation
                    GoCheck_Callback(app,[])
                case {'grid','grating'}
%                     Changes the stimulus
                    app.OrientFastN.Value = currFastNum;
                    OrientFastNValueChanged(app,[])
                    
%                     Set maximum contrast
                    app.CheckFastN.Value = size(app.CheckFastTable.Data,1);
                    CheckFastNValueChanged(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);

%                     Starts the stimulation
                    OrientFastNValueChanged(app,[])
                    GoGrid_Callback(app,[])
            end
            
%             The current row turns of the fourth color (green)
            app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(4,:);
            
%             Updates the index of the new current exp and it's color in
%             the table.
            if currIdx ~= size(app.ExperimentTable.Data,1)+1
                currIdx = currIdx + 1;
                app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(2,:);
            else
                currIdx=[];
            end
            app.ExpListCurrentIdx = currIdx;
            
%             Updates the current number shown in the GUI.
            app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + 1;
        end

        % Button pushed function: ExpListAddRows
        function ExpListAddRowsButtonPushed(app, event)
            if isempty(app.ExperimentTable.Data)
                app.ExperimentTable.Data = cell(1,length(app.ExperimentTable.ColumnEditable));
                app.ExperimentTable.BackgroundColor(1,:) = app.ExpListColors(2,:);
                app.ExpListCurrentIdx = 1;
                app.ExpListCurrentNum.Value = 1 + app.ExpListFirstNum.Value;
            else
%                 The new row is added just below the last selected row.
                AddedRowIdx = max(app.ExpListSelectedIndices(:,1))+1;
%                 Add an empty cell at the end of the table.
                app.ExperimentTable.Data = [app.ExperimentTable.Data; cell(1, size(app.ExperimentTable.Data,2))];
%                 And then moves it to the position of the added row.
                app.ExperimentTable.Data(AddedRowIdx:end,2:end) = app.ExperimentTable.Data([end,AddedRowIdx:end-1],2:end);
    %             Updates the number (column n. 1) of the last row
                app.ExperimentTable.Data{end,1} = app.ExperimentTable.Data{end-1,1} + 1;
%                 Then the color of the table is updated too.
                app.ExperimentTable.BackgroundColor = [app.ExperimentTable.BackgroundColor; app.ExpListColors(1,:)];
%                 And the color is moved to the position of the added row.
                app.ExperimentTable.BackgroundColor(AddedRowIdx:end,:) = app.ExperimentTable.BackgroundColor([end,AddedRowIdx:end-1],:);
                
%                 Then the color must be updated.
                if AddedRowIdx < app.ExpListCurrentIdx
%                     If the index of the added row is the current
%                     experiment, the added row is green.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(4,:);
%                     And the current experiment index and number is
%                     changed by one.
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx + 1;
                    app.ExpListCurrentNum.Value = app.ExpListFirstNum.Value + 1;
                elseif AddedRowIdx == app.ExpListCurrentIdx && AddedRowIdx < size(app.ExperimentTable.Data,1)
%                     If the position of the added row is just before the
%                     current experiment, this added row becomes the new
%                     current experiment.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(2,:);
%                     The current experiment number and index is not
%                     changed.
%                     The previous current experiment row color must become
%                     white.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx+1,:) = app.ExpListColors(1,:);
                elseif AddedRowIdx == size(app.ExperimentTable.Data,1) && isempty(app.ExpListCurrentIdx)
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(2,:);
                    app.ExpListCurrentIdx = AddedRowIdx;
                    app.ExpListCurrentNum.Value = AddedRowIdx + app.ExpListFirstNum.Value;
                end
            end
            
        end

        % Callback function
        function ExpListFirstNumValueChanged(app, event)
            StartNum = app.ExpListFirstNum.Value;
            app.ExperimentTable.Data(:,1) = num2cell(StartNum : size(app.ExperimentTable.Data,1)+StartNum-1);
        end

        % Button pushed function: RefreshDevicesButton
        function RefreshDevicesButtonPushed(app, event)
            refreshcamera(app)
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            app.selcamIdx = find(strcmp(app.CameraDropDown.Value,app.CameraDropDown.Items),1);
            selectcam(app)
        end

        % Value changed function: ImageSizeDropDown
        function ImageSizeDropDownValueChanged(app, event)
            app.PupilStruct.selformatIdx = find(strcmp(app.ImageSizeDropDown.Value,app.ImageSizeDropDown.Items),1);
            vals=strsplit(app.ImageSizeDropDown.Value,'x');
            app.PupilStruct.selformat = [str2double(vals{1}), str2double(vals{2})];
            selectformat(app)
        end

        % Value changed function: NumberofPointsinPreviewGraphEditField
        function NumberofPointsinPreviewGraphEditFieldValueChanged(app, event)
            app.PupilStruct.previewgraph_size = app.NumberofPointsinPreviewGraphEditField.Value;
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            app.PupilImage1UIAxes.CameraUpVectorMode = 'manual';
            app.PupilImage1UIAxes.CameraUpVector = [0 1 0];
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            switch app.PupilStruct.preview_status
                case 0
                    % turn the IR LED ON
                    if app.HermesSerialFlag && app.ControlIRLEDwithHermesCheckBox.Value
                        switch app.IR_led_switch.Value
                            case "ON = HIGH"
                                HermesMsgSend(app,'TTL2=1')
                            case "ON = LOW"
                                HermesMsgSend(app,'TTL2=0')
                        end
                    end

                    % Initialize the preview plot
                    PreviewInitialize(app)
                    
                    app.StartPreviewButton.Text = 'Stop Preview';
                    app.PupilStruct.preview_status = 1;
                case 1
                    if app.HermesSerialFlag && app.ControlIRLEDwithHermesCheckBox.Value
                        % turn the IR LED OFF
                        switch app.IR_led_switch.Value
                            case "ON = HIGH"
                                HermesMsgSend(app,'TTL2=0')
                            case "ON = LOW"
                                HermesMsgSend(app,'TTL2=1')
                        end
                    end
                    PreviewStop(app)

                    app.StartPreviewButton.Text = 'Start Preview';
                    app.PupilStruct.preview_status = 0;
            end
        end

        % Value changed function: DurationsEditField
        function DurationsEditFieldValueChanged(app, event)
            app.PupilStruct.acqDuration = app.DurationsEditField.Value;
            app.NframestoacquireEditField.Value = ...
                app.ActualframerateHzEditField.Value * app.DurationsEditField.Value;
        end

        % Button pushed function: PupilStartAcquisitionButton
        function PupilStartAcquisitionButtonPushed(app, event)
            switch app.PupilStruct.acqStatus % Status of the button
                case 0 % START recording
                    
%                     If there is already a preview open, close it.
                    if app.PupilStruct.preview_status
                        StartPreviewButtonPushed(app, [])
                    end
                    
                    if isfield(app.PupilStruct,'AcquisitionTimer')
                        % Remove any acquisition timer
                        delete(app.PupilStruct.AcquisitionTimer)
                    end
                    
%                     Checking if the specified folder where to save the
%                     files is there. If it's not there, then it is
%                     created.
                    if  exist(app.SaveFolderEditField.Value, 'dir') ~= 7 % 7 is when a name is a folder
                        mkdir(app.SaveFolderEditField.Value)
                    end

%                     Initialize a videoWriter object
                    outputPath = [app.SaveFolderEditField.Value,filesep ,app.PupilFilenameEditField.Value,'_',num2str(app.OutputFileN.Value,'%03.f') '.avi'];
                    while exist(outputPath,'file') == 2
                        app.OutputFileN.Value = app.OutputFileN.Value+1;
                        outputPath = [app.SaveFolderEditField.Value, filesep ,app.PupilFilenameEditField.Value,'_',num2str(app.OutputFileN.Value,'%03.f') '.avi'];
                    end
                    wr=VideoWriter(outputPath,'Motion JPEG AVI');
                    wr.FrameRate = app.ActualframerateHzEditField.Value; 
                    
                    
%                     app.PupilStruct.camobj.LoggingMode='disk';
                    app.PupilStruct.camobj.LoggingMode='disk&memory';
                    app.PupilStruct.camobj.DiskLogger=wr;
                    if app.DurationsEditField.Value==Inf
                        app.PupilStruct.camobj.FramesPerTrigger=Inf;
                    else
                        app.PupilStruct.camobj.FramesPerTrigger = app.NframestoacquireEditField.Value;
                        app.PupilStruct.camobj.StopFcn = @(x,y)PupilStartAcquisitionButtonPushed(app, []);
                    end
                    
                    app.PupilStruct.camobj.FrameGrabInterval = app.GrabaframeeveryEditField.Value;
                    app.PupilStruct.camobj.FramesAcquiredFcnCount = 1;
                    app.PupilStruct.camobj.FramesAcquiredFcn = @(x,y)myFramesAcquiredFcn(app,x,y);
                    app.PupilStruct.hPupilSizePlot.XData = nan;
                    app.PupilStruct.hPupilSizePlot.YData = nan;
                    app.acqStatusTxt.Value = 'Acquiring...';
                    app.acqLamp.Color = [1 0 0];
                    app.PupilStruct.acqStatus = 1;
                    app.PupilStartAcquisitionButton.Text = 'STOP acquisition';
                    % start acquisition
                    start(app.PupilStruct.camobj)
                    pause(2)
                    if app.HermesSerialFlag && app.ControlIRLEDwithHermesCheckBox.Value
%                         while app.PupilStruct.camobj.DiskLoggerFrameCount < 10 
%                             % wait until few frames are acquired.
%                             % 10 frames at 30 Hz = 333 ms.
%                         end
                        switch app.IR_led_switch.Value
                            case "ON = HIGH"
                                HermesMsgSend(app,'TTL2=1')
                            case "ON = LOW"
                                HermesMsgSend(app,'TTL2=0')
                        end
                    end

                case 1 % STOP recording
                    if app.HermesSerialFlag && app.ControlIRLEDwithHermesCheckBox.Value
                        switch app.IR_led_switch.Value
                            case "ON = HIGH"
                                HermesMsgSend(app,'TTL2=0')
                            case "ON = LOW"
                                HermesMsgSend(app,'TTL2=1')
                        end
                    end
                    if app.PupilStruct.camobj.Running == "on"
                        % Stop called manually by the user, since the
                        % number of frame to acquire is Inf. Stop the
                        % recording, then!
                        stop(app.PupilStruct.camobj)
                    else
                        % Stop called by the StopFcn of videoinput, since
                        % the number of frames to acquire has been reached.
                        % The recording already stopped.
                        app.PupilStruct.camobj.StopFcn = [];
                    end
                    app.acqStatusTxt.Value = 'Not acquiring';
                    app.acqLamp.Color = [0 1 0];
                    app.PupilStruct.acqStatus = 0;
                    app.PupilStartAcquisitionButton.Text = 'START ACQUISITION';
            end
        end

        % Button pushed function: generatestimuliFlashBtn
        function generatestimuliFlashBtnPushed(app, event)
%             This is to generate a certain number of stimuli
        minL = app.FlashFastMinL.Value;
        maxL = app.FlashFastMaxL.Value;
        number = app.FlashFastTotN.Value;
        if app.logspacedFlashCk.Value
            if app.includezeroFlashCk.Value
%                 If zero is included, a 0 is added at the beginning of the
%                 stimulus vector.
                StimVect = [0, logspace(log10(minL),log10(maxL),number-1)];
%                 minL is now set to zero, because this is the value that
%                 is going to be used as baseline in the table.
                minL = 0;
            else
                StimVect = logspace(log10(minL),log10(maxL),number);
            end
        else
            StimVect = linspace(minL,maxL,number);
        end
        app.FlashFastTable.Data = horzcat(StimVect',repmat(minL,number,1));
        end

        % Value changed function: FlashFastMinL
        function FlashFastMinLValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             minimum luminance must be more than 0.
            if app.logspacedFlashCk.Value && app.FlashFastMinL.Value==0
                uialert(app.EscherMasterFig,'For logarithmically-spaced stimuli, zero is not a valid input!','Bad input')
                app.FlashFastMinL.Value = event.PreviousValue;
            end
        end

        % Value changed function: CheckFastMinC
        function CheckFastMinCValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             minimum luminance must be more than 0.
            if app.logspacedCheckCk.Value && app.CheckFastMinC.Value==0
                uialert(app.EscherMasterFig,'For logarithmically-spaced stimuli, zero is not a valid input!','Bad input')
                app.CheckFastMinC.Value = event.PreviousValue;
            end
        end

        % Value changed function: logspacedFlashCk
        function logspacedFlashCkValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             "include zero" check box must be enabled to allow zero to be
%             inserted as a value in the list.
            if app.logspacedFlashCk.Value
                app.includezeroFlashCk.Enable = 1;
            else
                app.includezeroFlashCk.Enable = 0;
            end
        end

        % Value changed function: logspacedCheckCk
        function logspacedCheckCkValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             "include zero" check box must be enabled to allow zero to be
%             inserted as a value in the list.
            if app.logspacedCheckCk.Value
                app.includezeroCheckCk.Enable = 1;
            else
                app.includezeroCheckCk.Enable = 0;
            end
        end

        % Button pushed function: generatestimuliCheckBtn
        function generatestimuliCheckBtnButtonPushed(app, event)
%             This is to generate a certain number of stimuli
        minC = app.CheckFastMinC.Value;
        maxC = app.CheckFastMaxC.Value;
        number = app.CheckFastTotN.Value;
        if app.logspacedCheckCk.Value
            if app.includezeroCheckCk.Value
%                 If zero is included, a 0 is added at the beginning of the
%                 stimulus vector.
                StimVect = [0, logspace(log10(minC),log10(maxC),number-1)];
            else
                StimVect = logspace(log10(minC),log10(maxC),number);
            end
        else
            StimVect = linspace(minC,maxC,number);
        end
        app.CheckFastTable.Data = horzcat(StimVect',repmat(app.CheckFastMeanL.Value,number,1));
        end

        % Value changed function: CheckFastTotN
        function CheckFastTotNValueChanged(app, event)
            StimTotN = app.CheckFastTotN.Value;
            TableSize = size(app.CheckFastTable.Data,1);
            
            if StimTotN > TableSize
%             A number of rows are added to the table.
                if app.logspacedCheckCk.Value && app.includezeroCheckCk.Value
                    minC = 0;
                else
                    minC = app.CheckFastMinC.Value;
                end
%                 The extra rows are filled with a blank stimulus.
                app.CheckFastTable.Data = vertcat(app.CheckFastTable.Data,...
                    horzcat((TableSize+1:StimTotN)',repmat([minC,app.CheckFastMeanL.Value],StimTotN-TableSize,1)) );
            else
%                 The table is shortened.
                app.CheckFastTable.Data = app.CheckFastTable.Data(1:StimTotN,:);
            end
        end

        % Button pushed function: generatestimuliOrientBtn
        function generatestimuliOrientBtnButtonPushed(app, event)
%             This is to generate a certain number of stimuli
        minA = app.OrientFastMinA.Value;
        maxA = app.OrientFastMaxA.Value;
        number = app.OrientFastTotN.Value;
        StimVect = linspace(minA,maxA,number);
        app.OrientFastTable.Data = StimVect';
        end

        % Button pushed function: savetableButton
        function savetableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
%             StimTable = array2table(app.FlashFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','FlashStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
%             writetable(StimTable,[file,path])
            writematrix(app.FlashFastTable.Data,[path,file]);
        end

        % Button pushed function: CheckFastSaveTable
        function CheckFastSaveTableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
%             StimTable = array2table(app.CheckFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','CheckStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
%             writetable(StimTable,[file,path])
            writematrix(app.CheckFastTable.Data,[path,file]);
        end

        % Button pushed function: OrientFastSaveTable
        function OrientFastSaveTableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
%             StimTable = array2table(app.OrientFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','OrientStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
%             writetable(StimTable,[file,path])
            writematrix(app.OrientFastTable.Data, [path, file])
        end

        % Button pushed function: OrientFastLoadTable
        function OrientFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','OrientStimuliTable.csv');
            if file==0
                return
            end
%             StimTable = readtable([path, file]);
%             app.OrientFastTable.Data = StimTable;
            app.OrientFastTable.Data = readmatrix([path, file]);
        end

        % Button pushed function: RepeatStimBtn
        function RepeatStimBtnButtonPushed(app, event)
            currIdx = app.ExpListCurrentIdx;
            if app.ExpListCurrentIdx > 1
                app.ExperimentTable.Data = vertcat(app.ExperimentTable.Data(1:currIdx-1,:),...
                    app.ExperimentTable.Data(currIdx-1,:), app.ExperimentTable.Data(currIdx:end,:));
                app.ExperimentTable.Data(currIdx:end,1) = ...
                    num2cell(currIdx:app.ExperimentTable.Data{end,1}+1);
            else
                %                 If the index of current experiment is 1 or 0, there is
                %                 nothing to repeat!
                uialert(app.EscherMasterFig,'There is nothing to repeat!','Error')
            end
        end

        % Button pushed function: GetMasterIPandMACBtn
        function GetMasterIPandMACBtnPushed(app, event)
            ConnParams = GetConnParams;
            app.MasterIp.Value = ConnParams.IP;
            app.MasterMac.Value = ConnParams.MAC;
            app.SubnetMask.Value = ConnParams.SubMask;
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Button pushed function: StartUdpBtn
        function StartUdpBtnButtonPushed(app, event)
            if ~app.UdpConnectionFlag
                if isempty(app.MasterMac.Value)||...
                        (strcmp(app.UDPBroadcastDropDown.Value, app.UDPBroadcastDropDown.Items{1}) && strcmp(app.SlaveIp.Value,'0.0.0.0'))||...
                        strcmp(app.MasterIp.Value,'0.0.0.0')
                    uialert(app.EscherMasterFig,'Invalid Addresses','Error')
                    return
                end
                if app.ScreenOpenFlag
%                 If the screen is open the app will ask for confirmation to start working as master.
                    selection = uiconfirm(app.EscherMasterFig, ['The app will start now working as master.' ...
                        'This will close any open screen. Do you confirm?'], 'Warning');
                    if strcmp(selection,'OK')
                        OpenScreenBtn_Callback(app, [])
                    else
                        return
                    end
                end
                
%                 Change the status text, while waiting for connection
%                 Also, the button for starting the connection is being
%                 disabled. When the app receives an answer from the UDP
%                 connection OR when the timer times out.
                EnableGoBtn(app,0)
%                 All the connection parameters cannot be changed while the
%                 connection is ongoing.
                set([app.MasterIp,app.MasterMac,app.MasterPort,app.SlaveIp,app.SlavePort,app.SubnetMask],'Editable',0);
                app.ConnectionStatus.Value = 'Connecting';
                
                
                if strcmp(app.UDPBroadcastDropDown.Value,app.UDPBroadcastDropDown.Items{1})||...
                        strcmp(app.UDPBroadcastDropDown.Value,app.UDPBroadcastDropDown.Items{2})
%                 This is the UDP object, used to make the master (this app)
%                 communicate with the slave (the app actually performing the
%                 stimulation).
                    switch app.UDPBroadcastDropDown.Value
                        case app.UDPBroadcastDropDown.Items{1}
% No broadcast
                            SlaveIpAddress = app.SlaveIp.Value;
                            msg=strjoin([{'start'},{app.MasterIp.Value},{app.MasterMac.Value},...
                                {num2str(app.MasterPort.Value)},{'0'}],',');
                        case app.UDPBroadcastDropDown.Items{2}
% With broadcast
                            SlaveIpAddress = app.BroadcastAddress.Value;
                            msg=strjoin([{'start'},{app.MasterIp.Value},{app.MasterMac.Value},...
                                {num2str(app.MasterPort.Value)},{'1'}],',');
                    end
                    
                    try
                        app.UdpObj.RemoteHost = SlaveIpAddress;
                        app.UdpObj.RemotePort = app.SlavePort.Value;
                        app.UdpOnj.LocalPort = app.MasterPort.Value;
    %                     Open the UDP object.
                        fopen(app.UdpObj);
                    catch
    %                     If for some reason, the UDP connection cannot be
    %                     opened, we must close all connections.
    %                     Erase all UDP connections and initialize a UDP object.
                        CloseAllUdp
                        app.UdpObj = udp(SlaveIpAddress, 'RemotePort',...
                            app.SlavePort.Value, 'LocalPort', app.MasterPort.Value);
                        fopen(app.UdpObj);
                    end
    %                 Callback function for when a datagram is received. If a
    %                 confirmation message is read, the timer is stopped.
                    app.UdpObj.DatagramReceivedFcn = @(obj,~) UdpDataReceivedCallback(app,obj);
                    
    %                 And start reading.
                    readasync(app.UdpObj)
                    app.UdpObj.ReadAsyncMode = 'continuous';
                    
    %                 Send a message to start the connection. It contains the
    %                 IP, MAC and port of the master.
                    SendUdpMsg(app,msg,app.TimeoutTime.Value)
                else
%                     In this case, the Send-to-all option has been
%                     selected, which means that a sort of manual broadcast
%                     is going to be executed.
%                     First of all, a list of all IP addresses to be used
%                     is generated.
                    CloseAllUdp
                    app.UdpObj = udp('127.0.0.1', 'RemotePort',...
                        app.SlavePort.Value, 'LocalPort', app.MasterPort.Value);
                    fopen(app.UdpObj);
                    readasync(app.UdpObj)
                    app.UdpObj.ReadAsyncMode = 'continuous';
    %                 Callback function for when a datagram is received. If a
    %                 confirmation message is read, the timer is stopped.
                    app.UdpObj.DatagramReceivedFcn = @(obj,~) UdpDataReceivedCallback(app,obj);
                    
%                     The list of all possible IPs is generated and is
%                     saved as a property of the app. In this way, we
%                     avoid any conflict with the asynchronous callback
%                     function, called when data are received.
                    IpList = SendToAllIpList(app.STAstartTxt.Value,app.STAendTxt.Value);
                    msg=strjoin([{'start'},{app.MasterIp.Value},{app.MasterMac.Value},...
                        {num2str(app.MasterPort.Value)},{'1'}],',');
                    app.SendToAllTerminator = 0;
                    i=1;
                    while ~app.SendToAllTerminator || i<=length(IpList)
                        app.UdpObj.RemoteHost = IpList{i};
        %                 Send a message to start the connection. It contains the
        %                 IP, MAC and port of the master.
                        fprintf(app.UdpObj,msg);
                        i=i+1;
                    end
                    if ~app.SendToAllTerminator
            %             A timer for timeout has to be called. Before that, the
            %             current timer is stopped and deleted.
                        if isvalid(app.UdpTimeoutTimer)
                            stop(app.UdpTimeoutTimer)
                        end
                        delete(app.UdpTimeoutTimer) % maybe it's an overkill?
                        app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                            app.TimeoutTime.Value,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
            %             The timer is started before sending the UDP message. This is
            %             to avoid that a UDP answer arrives (and stops the timer)
            %             before the timer is started, since the two objects are
            %             asynchronous.
                        start(app.UdpTimeoutTimer)
                    end
                end
                
            else
%                 If the connection was ongoing, the button is used to
%                 cancel it.
                stopasync(app.UdpObj)
                fclose(app.UdpObj);
%                 The button is reenabled, if it was not.
                EnableGoBtn(app,1)
%                 All the connection parameters cannot be changed while the
%                 connection is ongoing. So now they can be changed again
                set([app.MasterIp,app.MasterMac,app.MasterPort,app.SlaveIp,app.SlavePort,app.SubnetMask],'Editable',1);%                 If for some reason the timer for UDP timeout is running,
%                 it is stopped.
                if isvalid(app.UdpTimeoutTimer)
                    stop(app.UdpTimeoutTimer)
                end
%                 The connection is undone, so the buttons and other stuff must be changed
%                 accordingly.
%                 Revert the button back to its status. Also the flag value
%                 is set back to 0.
                app.StartUdpBtn.Text = 'Start Connection';
                app.ConnectionStatus.Value = 'Disconnected.';
                app.ResendScreenParams.Enable = 1;
                app.UDPConnectionLamp.Color = [1 0 0];
                app.UdpConnectionFlag = 0;
            end
        end

        % Button pushed function: ResendScreenParams
        function ResendScreenParamsButtonPushed(app, event)
            msg = ScreenParameterMsg(app);
            SendUdpMsg(app,msg,app.TimeoutTime.Value)
        end

        % Button pushed function: DeBusyButton
        function DeBusyButtonPushed(app, event)
            EnableGoBtn(app,1)
        end

        % Value changed function: ScreenDistance
        function ScreenDistanceValueChanged(app, event)
            SetScreenSizeAngle(app)
            PixelAngleCalc(app)
        end

        % Button pushed function: SaveScreenFileButton
        function SaveScreenFileButtonPushed(app, event)
            [file,path] = uiputfile({'*.dat;*.csv;*.txt',...
                'Text files (*.dat,*.csv,*.txt)';...
                '*.*',  'All Files (*.*)'}, 'Save a File');
            if file==0
                return
            end
            
            T = table;
            
            T.name{1} = app.ScreenNameTxt.Value;
            T.width(1) = app.ScreenWidth.Value;
            T.height(1) = app.ScreenHeight.Value;
            T.formula{1} = app.ScreenFuncTxt.Value;
            T.notes{1} = app.ScreenNotesTxt.Value;
            
            writetable(T,[path, file])
        end

        % Value changed function: MasterIp
        function MasterIpValueChanged(app, event)
            if ~ValidateIPv4(app.MasterIp.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.MasterIp.Value = event.PreviousValue;
            end
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Value changed function: SubnetMask
        function SubnetMaskValueChanged(app, event)
            if ~ValidateIPv4(app.SubnetMask.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.SubnetMask.Value = event.PreviousValue;
            end
            [app.BroadcastAddress.Value, app.STAstartTxt.Value, app.STAendTxt.Value] =...
                CalculateBroadcastAddress(app.MasterIp.Value,app.SubnetMask.Value);
        end

        % Value changed function: SlaveIp
        function SlaveIpValueChanged(app, event)
            if ~ValidateIPv4(app.SlaveIp.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.SlaveIp.Value = event.PreviousValue;
            end
        end

        % Value changed function: MasterMac
        function MasterMacValueChanged(app, event)
            if ~ValidateMac(app.MasterMac.Value)
                uialert(app.EscherMasterFig,'Invalid MAC','Error')
                app.MasterMac.Value = event.PreviousValue;
            end
        end

        % Value changed function: wwTxt
        function wwTxtValueChanged(app, event)
            if app.ScreenOpenFlag && ~app.UdpConnectionFlag
                HermesRectCalc(app)
            end
        end

        % Value changed function: hhTxt
        function hhTxtValueChanged(app, event)
            if app.ScreenOpenFlag && ~app.UdpConnectionFlag
                HermesRectCalc(app)
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            if app.ScreenOpenFlag && ~app.UdpConnectionFlag
                HermesRectCalc(app)
            end
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            if app.ScreenOpenFlag && ~app.UdpConnectionFlag
                HermesRectCalc(app)
            end        
        end

        % Value changed function: ScreenFuncTxt
        function ScreenFuncTxtValueChanged(app, event)
            app.ScreenFunc = app.ScreenFuncTxt.Value;
        end

        % Value changed function: STAstartTxt
        function STAstartTxtValueChanged(app, event)
            if ~ValidateIPv4(app.STAstartTxt.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.STAstartTxt.Value = event.PreviousValue;
            end            
        end

        % Value changed function: STAendTxt
        function STAendTxtValueChanged(app, event)
            if ~ValidateIPv4(app.STAendTxt.Value)
                uialert(app.EscherMasterFig,'Invalid IP','Error')
                app.STAendTxt.Value = event.PreviousValue;
            end
        end

        % Callback function
        function ExposureTimeEditFieldValueChanged(app, event)
%             When PCO camera is armed, the corresponding flag is set.
            app.PcoStruct.ArmedFlag = 0;
            app.PCOarmedLamp.Color = [1,0,0];
            app.PCOunarmedLabel.Text = 'PCO un-armed';
%             And the preview and acquire button are disabled
            app.PcoStartPreviewButton.Enable = 0;
            app.PcoStartAcquisitionButton.Enable = 0;
            
        end

        % Button pushed function: RefreshCOMButton
        function RefreshCOMButtonPushed(app, event)
            app.SerialCOMDropDown.Items = cellstr([serialportlist,"NONE"]);
            i=1;
            app.HermesSerialFlag = false;
            while i<length(app.SerialCOMDropDown.Items) && ~app.HermesSerialFlag
                try
                    app.SerialCOMDropDown.Value = app.SerialCOMDropDown.Items{i};
                    HermesConnect(app,app.SerialCOMDropDown.Value)
                catch ME
%                     warning(ME)
                    warning(['error in %s, line %i\n    %s\n' ...
                        'Couldn''t start serial communication on %s port.'],...
                        ME.stack(1).name,ME.stack(1).line,ME.message,app.SerialCOMDropDown.Value)
                end
                i=i+1;
            end
        end

        % Value changed function: SerialCOMDropDown
        function SerialCOMDropDownValueChanged(app, event)
%             if isa(app.HermesSerialObj, 'serial')
%                 fclose(app.HermesSerialObj);
%             end
            
            if ~strcmp(app.SerialCOMDropDown.Value,"NONE")
                try
                    HermesConnect(app,app.SerialCOMDropDown.Value)
                    app.HermesSerialFlag = 1;
                catch ME
                    uialert(app.EscherMasterFig,ME.message,'Warning')
                    app.HermesSerialFlag = 0;
                end
            else
                delete(app.HermesSerialObj)
                app.HermesSerialFlag = 0;
            end            
        end

        % Callback function
        function HermesDelaysEditFieldValueChanged(app, event)
%             This time is rounded to the third decimal digit.
            app.HermesDelaysEditField.Value = round(app.HermesDelaysEditField.Value,3);
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','serial_delay')
            end
        end

        % Value changed function: HermesTtlDurationEditField
        function HermesTtlDurationEditFieldValueChanged(app, event)
            app.HermesTtlDurationEditField.Value = round(app.HermesTtlDurationEditField.Value,3);
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','TTL_time')
            end
        end

        % Callback function
        function HermesLedDurationEditFieldValueChanged(app, event)
            value = round(app.HermesLedDurationEditField.Value,3);
            if value ~= 0 || value<app.HermesTtlDurationEditField.Value
                value = app.HermesTtlDurationEditField.Value;
                uialert(app.EscherMasterFig,'The value must be either >= TTL duration or equal to zero.','Warning')
            end
            app.HermesLedDurationEditField.Value = value;
        end

        % Button pushed function: PuffGo
        function PuffGoButtonPushed(app, event)
            % GAB 2021/04/04 this function does not work anymore
            p = app.PuffPeriod.Value;
            n = app.PuffRepetitions.Value;
            
            
            if app.PcoStruct.ArmedFlag == 1
%                 Set PCO acquisition duration and number of frames.
                PcoSetForStimulation(app,p,n,0)
                
%                 FrameN is the number of frames acquired in each trial
                FrameN = fix(app.PcoSelectedFreqEditField.Value*p)-2;
                app.PcoNumberFramesEditField.Value = FrameN*n;
                
%                 Create a message for Hermes
                HermesMsgMake(app,'mod','s',FrameN,n,1000*p);
                disp(HermesMsg)

%                 Send the message to Hermes.
                PcoStartAcquisition(app,app.PcoStruct.imahStim,HermesMsg)
            else
                FrameN = 1;
%                 Create a message for Hermes
            HermesMsg = HermesMsgMake(app,'p','p',FrameN,1000*p);
            disp(HermesMsg)
            writeline(app.HermesSerialObj,HermesMsg);
            end
            
        end

        % Value changed function: HermesIdleTimeEditField
        function HermesIdleTimeEditFieldValueChanged(app, event)
%             value = app.HermesIdleTimeEditField.Value;
            if app.HermesSerialFlag
                HermesMsgMake(app,'parameters','idleTime')
            end
        end

        % Button pushed function: RetinotopyGOButton
        function GoRetinotopy_Callback(app, event)
% If the "PCO while stimulating" box is checked, Hermes must be also 
% connected with serial communication.
% It is better to do this check here, before disabling the GO buttons and 
% writing any logs.
            if app.PcoWhileStimulusCk.Value && (~app.HermesSerialFlag ||...
                    ~app.UdpConnectionFlag)
            	uialert(app.EscherMasterFig,['To record with PCO while stimulating, both',...
                 'Hermes and UDP-slave must be connected.'],'Error')
             return
            end
            
            %             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            if app.RetinotopyDefaultParametersCheckBox.Value
% To be filled with default values.
                if app.CalibratedButton.Value
% If the calibration of the screen is being used, consider the lowest and 
% highest possible values permitted by the screen.
                    [~,Blumi] = Lumi2Escher(0, app.white, app.ScreenFunc);
                    [~,Slumi] = Lumi2Escher(20, app.white, app.ScreenFunc);
%                     Glumi = (Blumi+Slumi)/2;
                else
% If the calibration of the screen is not being used, set minimum and 
% maximum values for black and white, that are 0 and 1 in escher values.
                    Blumi = 0;
                    Slumi = 1;
%                     Glumi = 0.5;
                end
% Spatial frequency (cycles/degree) of the checkerboard.
                sF = 0.04;
% initial delay (seconds, it happens only once).
                Bt = app.initialDelay.Value;
% drifting speed of the stimulus in degrees/s.
                dS = 9;
% hemi-period (seconds). p is the time between a reversal and the next
% (2 reversals = 1 period)
                p = 0.166;
% post-grid duration. It's the pause length after the drifting rectangle (in seconds).
                PostG = 2;
% number of repetitions of the stimulus
%                 n = app.GridRepetitions.Value;
                n = 10;
            else
% load parameters in local variables
                Blumi = app.CheckLumiB.Value;
                Slumi = app.CheckLumiW.Value;
% gray luminance for equiluminant background.
%                 Glumi = app.CheckLumiMean.Value;
% spatial frequency of the checkerboard
                sF = app.spatialFrequency.Value;
                Bt = app.initialDelay.Value;
% hemi-period. p is the time between a reversal and the next (2 reversals = 1 period)
                p = app.CheckPeriod.Value;
% drifting speed of the stimulus in degrees/s.
                dS = app.IntrinsicStimBarSpeedEdit.Value;
% post-grid duration.
% Remember that one full trial is as long as the travel time of the bar
% plus this post-grid time.
                PostG = app.RetinotopyPostGTime.Value; % post-grid duration
% number of repetitions of the stimulus
                n = app.RetinotopyRepetitions.Value;
            end
            
            Glumi = 0;
            
% The dead time for each trial, expressed in s (originally it was in ms).
% This time has to be subtracted from the first baseline (the pre-stimulus
% baseline) to have the actual baseline. The second baseline (the
% after-stimulus baseline) lasts actually its full duration, but only the
% duration minus the dead time is acquired by the camera.
            TrialDeadTime = app.DeadTBeforeTrialEditField.Value/1000;
            
% angle of the stimulus. 0 means vertical bars moving horizontally
            if strcmp(app.IntrOrientationSwitch.Value,'Horizontal')
                Angle = 0;
            else
                Angle = 90;
            end
            
% The default grating movement will always be from left to right, then the 
% angle is changed.
            if ismember(app.IntrDirectionSwitch.Value,{'Right-to-left','Top-to-bottom'})
                Angle = Angle + 180;
            end
% Rectangle size in degrees
            RectangleSizeDeg = app.IntrRectSizeTxt.Value;
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.intrimg', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 13],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 11]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','sF','Bt','p',...
                'Angle','RectangleSizeDeg','dS','PostG','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [Blumi,Slumi,Glumi,...
                sF,Bt,p,Angle,RectangleSizeDeg,dS,PostG,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
% Other parameters to pass
% OneScreenFlag = app.OneScreenMode_ck.Value;
% CalibrationFlag = app.CalibratedButton.Value;
% oculusFlag = app.oculusCk.Value;
% ard_flag = app.ard_ck.Value;
% optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            
% ParameterVector is the vector of parameters that is passed as UDP 
% message. If UDP is not used, it is useful anyway as input for the 
% stimulus-presenting function.
% ParameterVector = [Blumi,Slumi,Glumi,sF,Bt,p,Angle,RectangleSizeDeg,dS,PostG,n,...
%                 OneScreenFlag,CalibrationFlag,oculusFlag,ard_flag,optDtrTime];
            ParameterVector = [Blumi,Slumi,Glumi,sF,Bt,p,Angle,...
                RectangleSizeDeg,dS,PostG,n,TrialDeadTime];
% Hermes has to trigger the PCO acquisition
            if app.PcoWhileStimulusCk.Value == 1
% Set PCO acquisition duration and number of frames.
% The PCO MUST acquire for all the duration of each trial (which is the
% travel time of the bar, plus the post-grid time) apart from the very last
% part of the trial (dead time) which is not recorded.
% This means that here, the exact calculation of the travel time is needed.
                
                if Angle==0 || Angle==180
                % vertical bar
                    TravelDistance = app.WidthPix.Value;
                    dSPx = dS/app.OnePxAngle;
                    travelAngle = 2*atand(app.ScreenWidth.Value/2/app.ScreenDistance.Value);
                else
                % horizontal
                    TravelDistance = app.HeightPix.Value;
                    dSPx = dS/app.OnePxAngle;
                    travelAngle = 2*atand(app.ScreenHeight.Value/2/app.ScreenDistance.Value);
                end
                TravelTime = TravelDistance/dSPx;
                disp(['Travel distance = ',num2str(TravelDistance)])
                disp(['Travel time = ',num2str(TravelTime)])
                disp(['PostG = ',num2str(PostG)])
                
% For this stimulation, the period is equal to the travel time plus a
% baseline that will be repeated twice (one before the bar travel and one
% after).
% A dead time will need to be subtracted from the pre-travel baseline.
% It needs also to be subtracted from the after-travel baseline, but that
% will be done in the function PcoSetForStimulation.
                PcoSetForStimulation(app,TravelTime+2*PostG-...
                    TrialDeadTime,n,Bt)
%                 PcoSetForStimulation(app,p,2*n,2*Bt)

%                 Create a message for Hermes
%                 HermesMsg = HermesMsgMake(app,'o');
                HermesMsgMake(app,'mod','camAc','o',app.PcoNumberFramesEditField.Value/(n));
                
% Set the parameters that will be saved.
                app.OutPcoStruct = struct;
                app.OutPcoStruct.Info.StimType = 'runningBar';
                app.OutPcoStruct.Info.nTrials = n;
                app.OutPcoStruct.Info.FramesPerTrial = app.PcoNumberFramesEditField.Value/n;
                app.OutPcoStruct.Info.BaselineTime = 2*PostG;
                app.OutPcoStruct.Info.DeadTime = TrialDeadTime;
                app.OutPcoStruct.Info.BarSpeed = dS;
                app.OutPcoStruct.Info.ScreenSize = travelAngle;
% The dead time (variable "TrialDeadTime") and baseline time (variable
% "PostG") must be added to info.
% PostG is repeated both at the beginning and at the end of the
% stimulation.
                
                switch Angle
                    case 0
% Vertical bar moving from left to right.
                        tempO = 'horz';
                        tempD = '+;';
                    case 90
% Horizontal bar moving from bottom to top.
                        tempO = 'vert';
                        tempD = '+;';
                    case 180
% Vertical bar moving from right to left.
                        tempO = 'horz';
                        tempD = '-;';
                    case 270
% Horizontal bar moving from top to bottom.
                        tempO = 'vert';
                        tempD = '-;';
                end
                app.OutPcoStruct.Info.Orientation = tempO;
                app.OutPcoStruct.Info.Direction = tempD;
                app.OutPcoStruct.Info.StimF = 1/p;
%             elseif app.HermesSerialFlag
% % If PCO is not used but Hermes is still used (to record the exact timing
% % of the stimulus, a message is sent to Hermes with 'mod=o' and number of
% % frames per trial = 1 (send only one TTL per stimulus).
%                 HermesMsgMake(app,'mod','o',1);
            end
            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'retinotopy'},...
                    arrayfun(@num2str,ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
                if app.PcoWhileStimulusCk.Value == 1
% Start the PCO and the acquisition timer, which will collect the frames
% that arrive from the camera. The camera will wait for Hermes to start
% acquire. And Hermes will wait for the phototransistor. Che al mercato mio
% padre comprò.
                    PcoStartAcquisition(app,app.PcoStruct.imahStim)
                end
            else
% Additional parameters to be passed: flags and synchronization parameters.
                OneScreenFlag = app.OneScreenMode_ck.Value;
                CalibrationFlag = app.CalibratedButton.Value;
                oculusFlag = app.oculusCk.Value;
                ard_flag = app.ard_ck.Value;
                optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            
%             Let's add some parameters to the ParameterVector for the
%             CheckPresent function.
                ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,oculusFlag,ard_flag,optDtrTime]);
%             Now starts the function for the presentation of the stimulus.
                timestamps = RetinotopyPresent(app,ParameterVector);
                
%             Log writing.
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
                app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
            % Write the table to the log file for this stimulation.
                writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
            %             Enables all "Go" buttons
                EnableGoBtn(app,1)
            end
        end

        % Value changed function: IntrOrientationSwitch
        function IntrOrientationSwitchValueChanged(app, event)
            if strcmp(app.IntrOrientationSwitch.Value, 'Horizontal')
                app.IntrDirectionSwitch.Items = {'Left-to-right','Right-to-left'};
            else
                app.IntrDirectionSwitch.Items = {'Bottom-to-top','Top-to-bottom'};
            end
            if app.KeepconstantspeedCheckBox.Value
                % update stimulus period
                IntrinsicStimBarSpeedEditValueChanged(app,[]);
            else
                % update bar speed
                TrialperiodsEditFieldValueChanged(app,[]);
            end
        end

        % Callback function
        function OpenFieldAnalyzerButtonValueChanged(app, event)
            value = app.OpenFieldAnalyzerButton.Value;
            if value
%                 If selected value is 1, it means that the analyzer app
%                 will be opened, and the button must be used to close it
%                 next time it will be pressed.
%                 Here, code to open the analyzer must be placed.
                app.VisualFieldAnalyzerApp = visualFieldAnalyzer2_App(app);
                app.OpenFieldAnalyzerButton.Text = 'Close Field Analyzer';
            else
                selection = uiconfirm(app.EscherMasterFig,'Do you really want to close the Analyzer?','Warning');
                if strcmp(selection,'OK')
%                     Here code to close it must be placed.
                    delete(app.VisualFieldAnalyzerApp)
                    app.OpenFieldAnalyzerButton.Text = 'Open Field Analyzer';
                else
                    app.OpenFieldAnalyzerButton.Value = 1;
                    app.OpenFieldAnalyzerButton.Text = 'Close Field Analyzer';
                end
            end
        end

        % Callback function
        function HermesLEDcontrolCheckBoxValueChanged(app, event)
            val = double(app.HermesLEDcontrolCheckBox.Value);
            if app.HermesSerialFlag
%                 HermesMsgMake(app,'parameters','solisEnabled')
                HermesMsgSend(app,['solisEnabled=' num2str(val)])
            end
        end

        % Value changed function: TrialperiodsEditField
        function TrialperiodsEditFieldValueChanged(app, event)
            period = app.TrialperiodsEditField.Value;
            switch app.IntrOrientationSwitch.Value
                case 'Vertical'
                    dim = app.screenRect(4);
                case 'Horizontal'
                    dim = app.screenRect(3);
            end
            speed_pxl = dim/period;
            app.IntrinsicStimBarSpeedEdit.Value = speed_pxl*app.OnePxAngle;
        end

        % Value changed function: IntrinsicStimBarSpeedEdit
        function IntrinsicStimBarSpeedEditValueChanged(app, event)
            speed_deg = app.IntrinsicStimBarSpeedEdit.Value; % deg/s
            speed_pxl = speed_deg/app.OnePxAngle; % pxl/s
            switch app.IntrOrientationSwitch.Value
                case 'Vertical'
                    dim = app.screenRect(4);
                case 'Horizontal'
                    dim = app.screenRect(3);
            end
            app.TrialperiodsEditField.Value = dim/speed_pxl;
        end

        % Value changed function: KeepconstantspeedCheckBox
        function KeepconstantspeedCheckBoxValueChanged(app, event)
            value = app.KeepconstantspeedCheckBox.Value;
            if value
                app.TrialperiodsEditField.Editable = 'off';
            else
                app.TrialperiodsEditField.Editable = 'on';
            end 
        end

        % Value changed function: GridDuration
        function GridDurationValueChanged(app, event)

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            mode = app.ModeDropDown.Value;
            switch app.TriggerDropDown.Value
                case 'phototransistor'
                    trig = 'p';
                case 'serial'
                    trig = 's';
            end
            HermesMsgMake(app,'mod',mode,trig)
        end

        % Callback function
        function optoStartEditFieldValueChanged(app, event)
            HermesMsgMake(app,'parameters','optoStart')
        end

        % Callback function
        function optoDurEditFieldValueChanged(app, event)
            HermesMsgMake(app,'parameters','optoDur')
        end

        % Button pushed function: GoGrid_2
        function GoGrid_2ButtonPushed(app, event)
                    
%             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % The two luminance values we need
            back_lumi = app.CheckLumiB.Value; % background luminance
            dots_lumi = app.CheckLumiW.Value; % luminance of the dots
%             Other values needed
            Angle = app.DotsOrientation.Value; % angle of the stimulus. 0 means vertical bars.
            dS = app.dotsDriftingSpeed.Value; % drifting speed of the dots in deg/s.
            Bt = app.initialDelay.Value; % initial delay
            Pre = app.PreDotDuration.Value; % pre-grid duration
            StimT = app.DotDuration.Value; % grid duration
            Post = app.PostDotDuration.Value; % post-grid duration
            n = app.DotsRepetitions.Value; % number of repetitions of the stimulus
            halflife = app.DotslifetimesEditField.Value;
            dotsSize = app.DotsdiameterdegEditField.Value;
            densdots = app.DotsdensityEditField.Value;
            randSize = app.RandSizeCheckBox.Value;
            randSpeed = app.RandSpeedCheckBox.Value;
            darkBack = app.DarkbackgroundCheckBox.Value;
            equiluminantBaseline = app.EquiluminantbaselineCheckBox.Value;
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.dots', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 15],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 13]),{'string'}],'VariableNames',...
                {'StartTime','BackgrLumi','DotsLumi','K','angle','speed',...
                'dotsSize','dotsN','Bt','pre','stimT','post','halflife','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [back_lumi,dots_lumi,...
                app.CheckContrast.Value,Angle,dS,dotsSize,densdots,...
                Bt,Pre,StimT,Post,halflife,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
% Extra parameter:
            
            ParameterVector = [back_lumi,dots_lumi,Angle,dS,Bt,Pre,StimT,Post,...
                halflife,dotsSize,densdots,randSize,randSpeed,darkBack,equiluminantBaseline,...
                n];
            

            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'dots'},arrayfun(@num2str,...
                    ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
            else
                OneScreenFlag = app.OneScreenMode_ck.Value;
                CalibrationFlag = app.CalibratedButton.Value;
                oculusFlag = app.oculusCk.Value;
                ard_flag = app.ard_ck.Value;
                optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
                
                ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,...
                    oculusFlag,ard_flag,optDtrTime]);
                timestamps = DotsPresent(app,ParameterVector);
                                
% Timestamps are stored in the log file as a character vector, in which 
% timestamps are separated by a semi-colon.
% Timestamps are saved with 6 significant digits after decimal point 
% (microseconds).
                app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
% Write the table to the log file for this stimulation.
                writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
% Enables all "Go" buttons
                EnableGoBtn(app,1)
            end
        end

        % Value changed function: DotsdensityEditField
        function DotsdensityEditFieldValueChanged(app, event)
            WAngle = 2*atand(app.ScreenWidth.Value/2/app.ScreenDistance.Value);
            HAngle = 2*atand(app.ScreenHeight.Value/2/app.ScreenDistance.Value);
            screenArea = WAngle.*HAngle;
            app.NdotsEditField.Value = round(app.DotsdensityEditField.Value .* screenArea);
            % adjust this value after rounding
            NdotsEditFieldValueChanged(app, [])

        end

        % Value changed function: NdotsEditField
        function NdotsEditFieldValueChanged(app, event)
            WAngle = 2*atand(app.ScreenWidth.Value/2/app.ScreenDistance.Value);
            HAngle = 2*atand(app.ScreenHeight.Value/2/app.ScreenDistance.Value);
            screenArea = WAngle.*HAngle;
            app.DotsdensityEditField.Value = app.NdotsEditField.Value ./ screenArea;
        end

        % Callback function
        function TypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeButtonGroup.SelectedObject;
            switch selectedButton.Text 
                case 'Continuous'
                    app.FrequencyHzEditField.Editable = 'off';
                    app.DutycycleEditField.Editable = 'off';
                    app.optoDurEditField.Editable = 'on';
                    
                case 'Pulsed'
                    app.FrequencyHzEditField.Editable = 'on';
                    app.DutycycleEditField.Editable = 'on';
                    app.optoDurEditField.Editable = 'off';
                
            end
        end

        % Callback function
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            app.optoDurEditField.Value = 1000*(1/value)/100*app.DutycycleEditField.Value;
            HermesMsgMake(app,'parameters',{'nStim','optoDur','optoPer'})
        end

        % Callback function
        function DutycycleEditFieldValueChanged(app, event)
            value = app.DutycycleEditField.Value;
            app.optoDurEditField.Value = 1000*(1/app.FrequencyHzEditField.Value)/100*value;
            HermesMsgMake(app,'parameters',{'nStim','optoDur','optoPer'})
        end

        % Callback function
        function nofpulsesEditFieldValueChanged(app, event)
            HermesMsgMake(app,'parameters','nStim')
        end

        % Value changed function: rowsEditField
        function rowsEditFieldValueChanged(app, event)
            value = app.rowsEditField.Value;
            app.UIAxes.YLim = [0,value];
            app.UIAxes.YTick = 0:1:value;
        end

        % Value changed function: columnsEditField
        function columnsEditFieldValueChanged(app, event)
            value = app.columnsEditField.Value;
            app.UIAxes.XLim = [0,value];
            app.UIAxes.XTick = 0:1:value;
        end

        % Value changed function: UsedefaultwidthandheightCheckBox
        function UsedefaultwidthandheightCheckBoxValueChanged(app, event)
            value = app.UsedefaultwidthandheightCheckBox.Value;
            if value
                app.wwTxt.Editable = 'off';
                app.hhTxt.Editable = 'off';
                if app.ApplysphericaldistortionCheckBox.Value
                    app.wwTxt.Value = 470;
                    app.hhTxt.Value = 320;
                else
                    app.wwTxt.Value = 130;
                    app.hhTxt.Value = 110;
                end
                wwTxtValueChanged(app,[])
            else
                app.wwTxt.Editable = 'on';
                app.hhTxt.Editable = 'on';
            end
        end

        % Value changed function: ApplysphericaldistortionCheckBox
        function ApplysphericaldistortionCheckBoxValueChanged(app, event)
            value = app.ApplysphericaldistortionCheckBox.Value;
            UsedefaultwidthandheightCheckBoxValueChanged(app, [])
        end

        % Button pushed function: OpenlogdirectoryButton
        function OpenlogdirectoryButtonPushed(app, event)
            winopen(app.logDir)
        end

        % Button pushed function: GenRandStim
        function GenRandStimButtonPushed(app, event)
            data_sf = app.SfFastTable.Data;
            data_or = app.OrientFastTable.Data;
            combs = mycombs(data_sf,data_or);
            combs=repmat(combs,app.NrepEditField.Value,1);
            combs=combs(randperm(size(combs,1)),:);
            nstim=size(combs,1);
            if app.AddrandomlagbetweentrialsCheckBox.Value
                delay=rand(nstim,1)*app.MaxlagsEditField.Value;
            else
                delay=zeros(nstim,1);
            end
            app.RandFastTable.Data=[combs,delay];
        end

        % Button pushed function: SaveRandStim
        function SaveRandStimButtonPushed(app, event)
            [file,path]=uiputfile('*.txt','Save table values','Orient_and_spatial_frequency.txt');
            figure(app.EscherMasterFig)
            writematrix(app.RandFastTable.Data,[path filesep file],"Delimiter",'tab')
        end

        % Button pushed function: LoadRandStim
        function LoadRandStimButtonPushed(app, event)
            
        end

        % Button pushed function: GoRandGrid
        function GoRandGridButtonPushed(app, event)
%             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % The two luminance values we need
            Glumi = app.CheckLumiMean.Value; % the average luminance
            inc = (app.CheckLumiW.Value-app.CheckLumiB.Value)/2; % the increment of luminance
%             Other values needed
            sF = app.RandFastTable.Data(:,1); % spatial frequency of the stimulus (in cycles/degree)
            gridType = app.SineGrid.Value; % 1 means sinusoidal, 0 is for square-wave (bar)
            Angle = app.RandFastTable.Data(:,2); % angle of the stimulus. 0 means vertical bars.
            dS = app.DriftingSpeed_txt.Value; % drifting speed of the stimulus in cycles/s.
            MaskFlag = app.DrawMask.Value; % 1 means that a gaussian mask is applied.
            GaussSize = app.GaussSize_txt.Value; % size of gaussian mask
            Bt = app.initialDelay.Value; % initial delay
            PreG = app.PreGridDuration.Value; % pre-grid duration
            GridT = app.GridDuration.Value; % grid duration
            PostG = app.PostGridDuration.Value+app.RandFastTable.Data(:,3); % post-grid duration
            n = size(app.RandFastTable.Data,1); % number of repetitions of the stimulus
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.grid', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[n, 17],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 14]),{'string'},{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','K','sF', 'sine','angle','speed',...
                'maskflag','masksize','Bt','preG','gridT','postG','Start_Timestamps','Stop_Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{:,[6,8,15]}=[sF,Angle,PostG]; %spatial freq, orientation, delay
            app.CurrentLogStruct.T{:,[2,3,4,5,7,9,10,11,12,13,14]} = ...
                repmat([app.CheckLumiB.Value,app.CheckLumiW.Value,Glumi,...
                app.CheckContrast.Value,gridType,dS,MaskFlag,GaussSize,...
                Bt,PreG,GridT],n,1);
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
% Extra parameter:
            
            ParameterVector = {Glumi,inc,sF,gridType,Angle,dS,MaskFlag,...
                GaussSize,Bt,PreG,GridT,PostG,n};
                        

            if app.UdpConnectionFlag
                % da rivedere GAB 2023/01/23
                msg=strjoin([{app.MasterMac.Value},{'grid_rand'},cell(@num2str,...
                    ParameterVector,'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                
            else
                OneScreenFlag = app.OneScreenMode_ck.Value;
                CalibrationFlag = app.CalibratedButton.Value;
                oculusFlag = app.oculusCk.Value;
                ard_flag = app.ard_ck.Value;
                optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
                
                ParameterVector = [ParameterVector,num2cell([OneScreenFlag,CalibrationFlag,...
                    oculusFlag,ard_flag,optDtrTime])];
                timestamps = GridPresent_randomized(app,ParameterVector);
                                
% Timestamps are stored in the log file as a character vector, in which 
% timestamps are separated by a semi-colon.
% Timestamps are saved with 6 significant digits after decimal point 
% (microseconds).
                app.CurrentLogStruct.T.Start_Timestamps(:) = string(num2str(timestamps(:,1),'%.3f'));
                app.CurrentLogStruct.T.Stop_Timestamps(:) = string(num2str(timestamps(:,2),'%.3f'));
% Write the table to the log file for this stimulation.
                writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
% Enables all "Go" buttons
                EnableGoBtn(app,1)
            end
        end

        % Button pushed function: generatestimuliSFBtn
        function generatestimuliSFBtnButtonPushed(app, event)
            minim = app.SfFastMinC.Value;
            maxim = app.SfFastMaxC.Value;
            n = app.NstimEditField.Value;
            if app.logspacedSfCk.Value
                sf = logspace(log10(minim),log10(maxim),n);
            else
                sf = linspace(minim,maxim,n);
            end
            app.SfFastTable.Data = sf';
        end

        % Button pushed function: SfFastSaveTable
        function SfFastSaveTableButtonPushed(app, event)
            [file,path]=uiputfile('*.txt');
            figure(app.EscherMasterFig)
            vals = app.SfFastTable.Data';
            writematrix(vals,[path filesep file],"Delimiter",',');
            
        end

        % Button pushed function: SfFastLoadTable
        function SfFastLoadTableButtonPushed(app, event)
            [file,path]=uigetfile('*.txt');
            figure(app.EscherMasterFig)
            vals=readmatrix([path filesep file],"Delimiter",',');
            app.SfFastTable.Data= vals';
        end

        % Value changed function: SfFastN
        function SfFastNValueChanged(app, event)
            value = min(app.SfFastN.Value,length(app.SfFastTable.Data));
            app.spatialFrequency.Value = app.SfFastTable.Data(value);
        end

        % Value changed function: ControlIRLEDwithHermesCheckBox, 
        % ...and 1 other component
        function IR_led_switchValueChanged(app, event)
            % GAB: this callback is intended to switch the LED OFF when 
            % pupillometry is NOT running            
            if app.HermesSerialFlag && app.ControlIRLEDwithHermesCheckBox.Value
                switch app.IR_led_switch.Value
                    case "ON = HIGH"
                        HermesMsgSend(app,'TTL2=0')
                    case "ON = LOW"
                        HermesMsgSend(app,'TTL2=1')
                end
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            folder = app.SaveFolderEditField.Value;
            folder_new = uigetdir(folder,'Select output folder');
            figure(app.EscherMasterFig)
            if isa(folder_new,'double')
                % selection aborted
                return
            else
                app.SaveFolderEditField.Value = folder_new;
            end

        end

        % Value changed function: GrabaframeeveryEditField
        function GrabaframeeveryEditFieldValueChanged(app, event)
            k = app.GrabaframeeveryEditField.Value;
            app.ActualframerateHzEditField.Value = app.FramerateHzEditField.Value/k;
            app.NframestoacquireEditField.Value = ceil(...
                app.ActualframerateHzEditField.Value * app.DurationsEditField.Value);
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Threshold_EditField.Value = changingValue;
        end

        % Value changed function: Threshold_EditField
        function Threshold_EditFieldValueChanged(app, event)
            value = app.Threshold_EditField.Value;
            app.ThresholdSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EscherMasterFig = uifigure('Visible', 'off');
            colormap(app.EscherMasterFig, 'parula');
            app.EscherMasterFig.Name = 'EscherMaster';
            app.EscherMasterFig.CloseRequestFcn = createCallbackFcn(app, @EscherMasterFigCloseRequest, true);
            app.TabGroup = uitabgroup(app.EscherMasterFig);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.uipanel6 = uipanel(app.SettingsTab);
            app.uipanel6.Title = 'Screen Basic Settings';
            app.OpenScreenBtn = uibutton(app.uipanel6, 'push');
            app.OpenScreenBtn.ButtonPushedFcn = createCallbackFcn(app, @OpenScreenBtn_Callback, true);
            app.OpenScreenBtn.Tooltip = {'Open screen when in dual monitor mode (default)'};
            app.OpenScreenBtn.Text = 'Open Screen';
            app.RefreshStBy = uibutton(app.uipanel6, 'push');
            app.RefreshStBy.ButtonPushedFcn = createCallbackFcn(app, @RefreshStByButtonPushed, true);
            app.RefreshStBy.Tooltip = {'Fill screen with stand by luminance'};
            app.RefreshStBy.Text = 'Standby';
            app.OneScreenMode_ck = uicheckbox(app.uipanel6);
            app.OneScreenMode_ck.Text = 'One Screen Mode';
            app.oculusCk = uicheckbox(app.uipanel6);
            app.oculusCk.Text = 'VR goggles';
            app.SetstandbyluminanceEditFieldLabel = uilabel(app.uipanel6);
            app.SetstandbyluminanceEditFieldLabel.Text = 'Set standby luminance';
            app.StandbyL = uieditfield(app.uipanel6, 'numeric');
            app.LabelLumi_1 = uilabel(app.uipanel6);
            app.LabelLumi_1.Text = 'cd/m²';
            app.ApplysphericaldistortionCheckBox = uicheckbox(app.uipanel6);
            app.ApplysphericaldistortionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplysphericaldistortionCheckBoxValueChanged, true);
            app.ApplysphericaldistortionCheckBox.Text = 'Apply spherical distortion';
            app.uipanel4 = uipanel(app.SettingsTab);
            app.uipanel4.Title = 'Monitor Size and Geometry';
            app.ScreendistancecmLabel = uilabel(app.uipanel4);
            app.ScreendistancecmLabel.Tooltip = {'Enter the three parameters definig the screen angular size. '};
            app.ScreendistancecmLabel.Text = 'Screen distance (cm):';
            app.ScreenDistance = uieditfield(app.uipanel4, 'numeric');
            app.ScreenDistance.Limits = [0 120];
            app.ScreenDistance.RoundFractionalValues = 'on';
            app.ScreenDistance.ValueDisplayFormat = '%4.3g';
            app.ScreenDistance.ValueChangedFcn = createCallbackFcn(app, @ScreenDistanceValueChanged, true);
            app.ScreenDistance.Value = 15;
            app.WidthpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.WidthpixelsEditFieldLabel.Text = 'Width (pixels):';
            app.WidthPix = uieditfield(app.uipanel4, 'numeric');
            app.WidthPix.Limits = [0 Inf];
            app.WidthPix.RoundFractionalValues = 'on';
            app.WidthPix.Editable = 'off';
            app.WidthPix.Value = 1920;
            app.HeightpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.HeightpixelsEditFieldLabel.Text = 'Height (pixels):';
            app.HeightPix = uieditfield(app.uipanel4, 'numeric');
            app.HeightPix.Limits = [0 Inf];
            app.HeightPix.RoundFractionalValues = 'on';
            app.HeightPix.Editable = 'off';
            app.HeightPix.Value = 1080;
            app.WidthcmLabel = uilabel(app.uipanel4);
            app.WidthcmLabel.Text = 'Width (cm)';
            app.ScreenWidth = uieditfield(app.uipanel4, 'numeric');
            app.ScreenWidth.Limits = [0 Inf];
            app.ScreenWidth.ValueChangedFcn = createCallbackFcn(app, @ScreenWidthValueChanged, true);
            app.ScreenWidth.Value = 59.5303;
            app.HeightcmEditFieldLabel = uilabel(app.uipanel4);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.ScreenHeight = uieditfield(app.uipanel4, 'numeric');
            app.ScreenHeight.Limits = [0 Inf];
            app.ScreenHeight.ValueChangedFcn = createCallbackFcn(app, @ScreenHeightValueChanged, true);
            app.ScreenHeight.Value = 33.6312;
            app.AngularsizeofscreenEditFieldLabel = uilabel(app.uipanel4);
            app.AngularsizeofscreenEditFieldLabel.Text = 'Angular size of screen';
            app.ScreenSizeAngle = uieditfield(app.uipanel4, 'text');
            app.SaveScreenDistortionButton = uibutton(app.uipanel4, 'push');
            app.SaveScreenDistortionButton.Text = 'Save Screen Distortion';
            app.UDPConnectionPanel = uipanel(app.SettingsTab);
            app.UDPConnectionPanel.Title = 'UDP Connection';
            app.StartUdpBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.StartUdpBtn.ButtonPushedFcn = createCallbackFcn(app, @StartUdpBtnButtonPushed, true);
            app.StartUdpBtn.Tooltip = {'Start UDP connection to the slave Escher app. The slave must be in listening mode.'};
            app.StartUdpBtn.Text = 'Start Connection';
            app.SlaveIPLabel = uilabel(app.UDPConnectionPanel);
            app.SlaveIPLabel.Text = 'Slave IP:';
            app.SlaveIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveIp.ValueChangedFcn = createCallbackFcn(app, @SlaveIpValueChanged, true);
            app.SlaveIp.Tooltip = {'Set here the IP address of the slave app.'};
            app.SlaveIp.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port:';
            app.SlavePort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.SlavePort.Limits = [1023 65535];
            app.SlavePort.RoundFractionalValues = 'on';
            app.SlavePort.ValueDisplayFormat = '%8.5g';
            app.SlavePort.Tooltip = {'Port for the UDP connection on the slave side. It must be the same port on which the slave is listening. Default: 8866.'};
            app.SlavePort.Value = 8866;
            app.MasterIPLabel = uilabel(app.UDPConnectionPanel);
            app.MasterIPLabel.Text = 'Master IP:';
            app.MasterIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterIp.ValueChangedFcn = createCallbackFcn(app, @MasterIpValueChanged, true);
            app.MasterIp.Tooltip = {'Set here the IP address of the master (this app).'};
            app.MasterIp.Value = '0.0.0.0';
            app.PortEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel_2.Text = 'Port:';
            app.MasterPort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.MasterPort.Limits = [1023 65535];
            app.MasterPort.RoundFractionalValues = 'on';
            app.MasterPort.ValueDisplayFormat = '%8.5g';
            app.MasterPort.Tooltip = {'Port for the UDP connection on the master side. Default: 8844.'};
            app.MasterPort.Value = 8844;
            app.GetMasterIPandMACBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.GetMasterIPandMACBtn.ButtonPushedFcn = createCallbackFcn(app, @GetMasterIPandMACBtnPushed, true);
            app.GetMasterIPandMACBtn.Tooltip = {'Get the IP and MAC of this computer (master).'};
            app.GetMasterIPandMACBtn.Text = 'Get Master IP and MAC';
            app.StatusEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.StatusEditFieldLabel_2.Text = 'Status:';
            app.ConnectionStatus = uieditfield(app.UDPConnectionPanel, 'text');
            app.ConnectionStatus.Editable = 'off';
            app.ConnectionStatus.Value = 'Disconnected.';
            app.MACLabel_3 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_3.Text = 'MAC:';
            app.MasterMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterMac.ValueChangedFcn = createCallbackFcn(app, @MasterMacValueChanged, true);
            app.MasterMac.Tooltip = {'The physical address of the master (this app). It is used by the app as identifier.'};
            app.MasterMac.Value = '000000000000';
            app.MACLabel_2 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_2.Text = 'MAC:';
            app.SlaveMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveMac.Editable = 'off';
            app.SlaveMac.Tooltip = {'The physical address of the slave app. It is going to be given by the slave itself.'};
            app.SlaveMac.Value = '000000000000';
            app.TimeouttimesEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.TimeouttimesEditFieldLabel.Text = 'Timeout time (s)';
            app.TimeoutTime = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.TimeoutTime.Limits = [0 Inf];
            app.TimeoutTime.Tooltip = {'The time after which'; ' if the master does not receive any answer from the slave'; ' the UDP connection will be cut.'};
            app.TimeoutTime.Value = 20;
            app.ResendScreenParams = uibutton(app.UDPConnectionPanel, 'push');
            app.ResendScreenParams.ButtonPushedFcn = createCallbackFcn(app, @ResendScreenParamsButtonPushed, true);
            app.ResendScreenParams.Enable = 'off';
            app.ResendScreenParams.Tooltip = {'Send again the screen parameters of the Settings Tab to the slave app.'};
            app.ResendScreenParams.Text = {'Send Screen'; 'Parameters'};
            app.SubnetmaskEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.SubnetmaskEditFieldLabel.Text = 'Subnet mask';
            app.SubnetMask = uieditfield(app.UDPConnectionPanel, 'text');
            app.SubnetMask.ValueChangedFcn = createCallbackFcn(app, @SubnetMaskValueChanged, true);
            app.SubnetMask.Tooltip = {'Subnet mask of the network (only for general UDP broadcast)'};
            app.SubnetMask.Value = '0.0.0.0';
            app.BroadcastEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.BroadcastEditFieldLabel.Text = 'Broadcast';
            app.BroadcastAddress = uieditfield(app.UDPConnectionPanel, 'text');
            app.BroadcastAddress.Value = '0.0.0.0.';
            app.UDPBroadcastDropDownLabel = uilabel(app.UDPConnectionPanel);
            app.UDPBroadcastDropDownLabel.Text = 'UDP Broadcast:';
            app.UDPBroadcastDropDown = uidropdown(app.UDPConnectionPanel);
            app.UDPBroadcastDropDown.Items = {'No broadcast', 'Broadcast', 'Send to all'};
            app.UDPBroadcastDropDown.Tooltip = {'Choose how the connection is started:'; '-No broadcast. The communication is started only with slave IP typed by the user.'; '-Broadcast. The communication is started through a true UDP broadcast. Slave IP is ignored and the connection is set with the computer that answers.'; '-Send to all. A communication is sent to all IPs in the subnet.'};
            app.UDPBroadcastDropDown.Value = 'No broadcast';
            app.SendtoallstartaddressLabel = uilabel(app.UDPConnectionPanel);
            app.SendtoallstartaddressLabel.Text = {'Send-to-all'; 'start address'};
            app.STAstartTxt = uieditfield(app.UDPConnectionPanel, 'text');
            app.STAstartTxt.ValueChangedFcn = createCallbackFcn(app, @STAstartTxtValueChanged, true);
            app.STAstartTxt.Value = '0.0.0.0';
            app.SendtoallendaddressEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.SendtoallendaddressEditFieldLabel.Text = {'Send-to-all'; 'end address'};
            app.STAendTxt = uieditfield(app.UDPConnectionPanel, 'text');
            app.STAendTxt.ValueChangedFcn = createCallbackFcn(app, @STAendTxtValueChanged, true);
            app.STAendTxt.Value = '0.0.0.0';
            app.LoadScreenInformationPanel = uipanel(app.SettingsTab);
            app.LoadScreenInformationPanel.Title = 'Load Screen Information';
            app.LoadScreenFileButton = uibutton(app.LoadScreenInformationPanel, 'push');
            app.LoadScreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScreenFileButtonPushed, true);
            app.LoadScreenFileButton.Text = 'Load Screen File';
            app.ScreennameEditFieldLabel = uilabel(app.LoadScreenInformationPanel);
            app.ScreennameEditFieldLabel.Text = 'Screen name';
            app.ScreenNameTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenNameTxt.Value = 'Asus ROG XG27VQ';
            app.ScreenTransferFormulaLabel = uilabel(app.LoadScreenInformationPanel);
            app.ScreenTransferFormulaLabel.Text = 'Luminance calibration =';
            app.ScreenFuncTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenFuncTxt.ValueChangedFcn = createCallbackFcn(app, @ScreenFuncTxtValueChanged, true);
            app.ScreenFuncTxt.Value = '@(x)0.4+34.4*x.^1.88';
            app.NotesLabel = uilabel(app.LoadScreenInformationPanel);
            app.NotesLabel.Text = 'Notes:';
            app.ScreenNotesTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenNotesTxt.Value = 'Racing mode; K=100; B=0; S=NA; Gamma=2.2';
            app.SaveScreenFileButton = uibutton(app.LoadScreenInformationPanel, 'push');
            app.SaveScreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScreenFileButtonPushed, true);
            app.SaveScreenFileButton.Text = 'Save Screen File';
            app.CalModeRBGroup = uibuttongroup(app.LoadScreenInformationPanel);
            app.CalModeRBGroup.SelectionChangedFcn = createCallbackFcn(app, @CalModeRBGroupSelectionChanged, true);
            app.CalModeRBGroup.Title = 'Luminamce calibration';
            app.UncalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.UncalibratedButton.Text = 'Uncalibrated';
            app.CalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.CalibratedButton.Text = 'Calibrated';
            app.CalibratedButton.Value = true;
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.TabGroup2 = uitabgroup(app.StimulationTab);
            app.ContrastTab = uitab(app.TabGroup2);
            app.ContrastTab.Title = 'Contrast';
            app.LabelLumi_9 = uilabel(app.ContrastTab);
            app.LabelLumi_9.Text = 'cd/m²';
            app.LabelLumi_10 = uilabel(app.ContrastTab);
            app.LabelLumi_10.Text = 'cd/m²';
            app.LabelLumi_11 = uilabel(app.ContrastTab);
            app.LabelLumi_11.Text = 'cd/m²';
            app.CheckLumiBEsch = uieditfield(app.ContrastTab, 'numeric');
            app.CheckLumiBEsch.Limits = [0 Inf];
            app.CheckLumiBEsch.Editable = 'off';
            app.CheckLumiBEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiWEsch = uieditfield(app.ContrastTab, 'numeric');
            app.CheckLumiWEsch.Limits = [0 Inf];
            app.CheckLumiWEsch.Editable = 'off';
            app.CheckLumiWEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiWEsch.Value = 0.6;
            app.CheckLumiMeanEsch = uieditfield(app.ContrastTab, 'numeric');
            app.CheckLumiMeanEsch.Limits = [0 Inf];
            app.CheckLumiMeanEsch.ValueDisplayFormat = '%5.3g';
            app.CheckLumiMeanEsch.Editable = 'off';
            app.CheckLumiMeanEsch.Tooltip = {'Luminance of the background'};
            app.CheckLumiMeanEsch.Value = 0.3;
            app.LabelLumiEsch_9 = uilabel(app.ContrastTab);
            app.LabelLumiEsch_9.Text = '(0-1)';
            app.LabelLumiEsch_10 = uilabel(app.ContrastTab);
            app.LabelLumiEsch_10.Text = '(0-1)';
            app.LabelLumiEsch_11 = uilabel(app.ContrastTab);
            app.LabelLumiEsch_11.Text = '(0-1)';
            app.FrequencycyclesDegLabel = uilabel(app.ContrastTab);
            app.FrequencycyclesDegLabel.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.FrequencycyclesDegLabel.Text = 'Frequency (cycles/Deg):';
            app.spatialFrequency = uieditfield(app.ContrastTab, 'numeric');
            app.spatialFrequency.Limits = [0 Inf];
            app.spatialFrequency.ValueDisplayFormat = '%5.3g';
            app.spatialFrequency.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.spatialFrequency.Value = 0.04;
            app.MeanluminanceLabel = uilabel(app.ContrastTab);
            app.MeanluminanceLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.MeanluminanceLabel.Text = 'Mean luminance:';
            app.CheckLumiMean = uieditfield(app.ContrastTab, 'numeric');
            app.CheckLumiMean.Limits = [0 Inf];
            app.CheckLumiMean.ValueDisplayFormat = '%5.3g';
            app.CheckLumiMean.ValueChangedFcn = createCallbackFcn(app, @CheckLumiMeanValueChanged, true);
            app.CheckLumiMean.Tooltip = {'Luminance of the background'};
            app.CheckLumiMean.Value = 0.3;
            app.BlackluminanceLabel = uilabel(app.ContrastTab);
            app.BlackluminanceLabel.Text = {'Black luminance:'; ''};
            app.CheckLumiB = uieditfield(app.ContrastTab, 'numeric');
            app.CheckLumiB.Limits = [0 Inf];
            app.CheckLumiB.ValueChangedFcn = createCallbackFcn(app, @CheckLumiBValueChanged, true);
            app.CheckLumiB.Tooltip = {'Luminance of the flash stimulus'};
            app.WhiteluminanceLabel = uilabel(app.ContrastTab);
            app.WhiteluminanceLabel.Text = {'White luminance:'; ''};
            app.CheckLumiW = uieditfield(app.ContrastTab, 'numeric');
            app.CheckLumiW.Limits = [0 Inf];
            app.CheckLumiW.ValueChangedFcn = createCallbackFcn(app, @CheckLumiWValueChanged, true);
            app.CheckLumiW.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiW.Value = 0.6;
            app.ContrastLabel = uilabel(app.ContrastTab);
            app.ContrastLabel.Text = 'Contrast (%)';
            app.CheckContrast = uieditfield(app.ContrastTab, 'numeric');
            app.CheckContrast.Limits = [0 100];
            app.CheckContrast.ValueChangedFcn = createCallbackFcn(app, @CheckContrastValueChanged, true);
            app.CheckContrast.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckContrast.Value = 100;
            app.TabGroup3 = uitabgroup(app.ContrastTab);
            app.CheckerbTab = uitab(app.TabGroup3);
            app.CheckerbTab.Title = 'Checkerb';
            app.CheckGo = uibutton(app.CheckerbTab, 'push');
            app.CheckGo.ButtonPushedFcn = createCallbackFcn(app, @GoCheck_Callback, true);
            app.CheckGo.Text = 'Checkerboard GO!';
            app.RepetitionsEditField_2Label = uilabel(app.CheckerbTab);
            app.RepetitionsEditField_2Label.Text = 'Repetitions:';
            app.CheckRepetitions = uieditfield(app.CheckerbTab, 'numeric');
            app.CheckRepetitions.Value = 20;
            app.PeriodsEditField_2Label = uilabel(app.CheckerbTab);
            app.PeriodsEditField_2Label.Text = 'Period (s)';
            app.CheckPeriod = uieditfield(app.CheckerbTab, 'numeric');
            app.CheckPeriod.Limits = [0 Inf];
            app.CheckPeriod.Value = 4;
            app.GratingsTab = uitab(app.TabGroup3);
            app.GratingsTab.Title = 'Gratings';
            app.SliderOrientation = uislider(app.GratingsTab);
            app.SliderOrientation.Limits = [0 360];
            app.SliderOrientation.MajorTicks = [];
            app.SliderOrientation.ValueChangedFcn = createCallbackFcn(app, @SliderOrientation_Callback, true);
            app.SliderOrientation.MinorTicks = [];
            app.GoGrid = uibutton(app.GratingsTab, 'push');
            app.GoGrid.ButtonPushedFcn = createCallbackFcn(app, @GoGrid_Callback, true);
            app.GoGrid.Text = 'Grating GO!';
            app.DrawMask = uicheckbox(app.GratingsTab);
            app.DrawMask.Text = '';
            app.DrawMask.Value = true;
            app.ButtonGroup_4 = uibuttongroup(app.GratingsTab);
            app.SineGrid = uiradiobutton(app.ButtonGroup_4);
            app.SineGrid.Text = 'Sinusoidal';
            app.SquareGrid = uiradiobutton(app.ButtonGroup_4);
            app.SquareGrid.Text = 'Square';
            app.SquareGrid.Value = true;
            app.GridDuration = uieditfield(app.GratingsTab, 'numeric');
            app.GridDuration.Limits = [0 Inf];
            app.GridDuration.ValueChangedFcn = createCallbackFcn(app, @GridDurationValueChanged, true);
            app.GridDuration.Value = 2;
            app.PostGridDuration = uieditfield(app.GratingsTab, 'numeric');
            app.PostGridDuration.Limits = [0 Inf];
            app.PostGridDuration.Value = 2;
            app.FastOrientationStimulusSelectionPanel = uipanel(app.GratingsTab);
            app.FastOrientationStimulusSelectionPanel.Title = 'Fast Orientation Stimulus Selection';
            app.generatestimuliOrientBtn = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.generatestimuliOrientBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliOrientBtnButtonPushed, true);
            app.generatestimuliOrientBtn.Text = {'generate stimuli'; 'table'};
            app.OrientFastSaveTable = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.OrientFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @OrientFastSaveTableButtonPushed, true);
            app.OrientFastSaveTable.Text = 'save table';
            app.OrientFastLoadTable = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.OrientFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @OrientFastLoadTableButtonPushed, true);
            app.OrientFastLoadTable.Text = 'load table';
            app.noftotstimLabel_5 = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.noftotstimLabel_5.Text = 'n. of tot stim';
            app.OrientFastTotN = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastTotN.Value = 4;
            app.StartAngleEditFieldLabel = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.StartAngleEditFieldLabel.Text = 'Start Angle';
            app.OrientFastMinA = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.EndAngleEditFieldLabel = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.EndAngleEditFieldLabel.Text = 'End Angle';
            app.OrientFastMaxA = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastMaxA.Value = 270;
            app.OrientFastTable = uitable(app.FastOrientationStimulusSelectionPanel);
            app.OrientFastTable.ColumnName = {'Orientation'};
            app.OrientFastTable.ColumnEditable = true;
            app.ChoosestimEditFieldLabel_5 = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel_5.Text = 'Choose stim.';
            app.OrientFastN = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastN.Limits = [1 Inf];
            app.OrientFastN.RoundFractionalValues = 'on';
            app.OrientFastN.ValueDisplayFormat = '%.0f';
            app.OrientFastN.ValueChangedFcn = createCallbackFcn(app, @OrientFastNValueChanged, true);
            app.OrientFastN.Value = 1;
            app.GridorientationdegEditFieldLabel = uilabel(app.GratingsTab);
            app.GridorientationdegEditFieldLabel.Text = 'Grid orientation (deg):';
            app.GridOrientation = uieditfield(app.GratingsTab, 'numeric');
            app.GridOrientation.Limits = [0 360];
            app.GridOrientation.ValueChangedFcn = createCallbackFcn(app, @GridOrientationValueChanged, true);
            app.RepetitionsEditFieldLabel_3 = uilabel(app.GratingsTab);
            app.RepetitionsEditFieldLabel_3.Text = 'Repetitions';
            app.GridRepetitions = uieditfield(app.GratingsTab, 'numeric');
            app.GridRepetitions.Limits = [0 Inf];
            app.GridRepetitions.Value = 20;
            app.BaselineGratingPostsLabel = uilabel(app.GratingsTab);
            app.BaselineGratingPostsLabel.Text = 'Baseline, Grating, Post (s)';
            app.PreGridDuration = uieditfield(app.GratingsTab, 'numeric');
            app.PreGridDuration.Limits = [0 Inf];
            app.DriftingspeedcyclessecEditFieldLabel = uilabel(app.GratingsTab);
            app.DriftingspeedcyclessecEditFieldLabel.Text = 'Drifting speed (cycles/sec):';
            app.DriftingSpeed_txt = uieditfield(app.GratingsTab, 'numeric');
            app.DriftingSpeed_txt.Limits = [0 Inf];
            app.DriftingSpeed_txt.Value = 2;
            app.SizegaussianmaskpixEditFieldLabel = uilabel(app.GratingsTab);
            app.SizegaussianmaskpixEditFieldLabel.Text = 'Size gaussian mask (pix):';
            app.GaussSize_txt = uieditfield(app.GratingsTab, 'numeric');
            app.GaussSize_txt.Value = 300;
            app.DotsTab = uitab(app.TabGroup3);
            app.DotsTab.Title = 'Dots';
            app.DarkbackgroundCheckBox = uicheckbox(app.DotsTab);
            app.DarkbackgroundCheckBox.Tooltip = {'If checked, dots are lighter than background. Dots and background intensity depends on contrast'};
            app.DarkbackgroundCheckBox.Text = 'Dark background';
            app.DarkbackgroundCheckBox.Value = true;
            app.Dotsdensitydeg2Label = uilabel(app.DotsTab);
            app.Dotsdensitydeg2Label.Text = 'Dots density  (deg^-2)';
            app.DotsdensityEditField = uieditfield(app.DotsTab, 'numeric');
            app.DotsdensityEditField.ValueChangedFcn = createCallbackFcn(app, @DotsdensityEditFieldValueChanged, true);
            app.OrientationdegLabel = uilabel(app.DotsTab);
            app.OrientationdegLabel.Text = 'Orientation (deg):';
            app.DotsOrientation = uieditfield(app.DotsTab, 'numeric');
            app.DotsOrientation.Limits = [0 360];
            app.DriftingspeeddegsecLabel = uilabel(app.DotsTab);
            app.DriftingspeeddegsecLabel.Text = 'Drifting speed (deg/sec):';
            app.dotsDriftingSpeed = uieditfield(app.DotsTab, 'numeric');
            app.dotsDriftingSpeed.Limits = [0 Inf];
            app.dotsDriftingSpeed.Value = 50;
            app.DotsdiameterdegLabel = uilabel(app.DotsTab);
            app.DotsdiameterdegLabel.Text = 'Dots diameter (deg)';
            app.DotsdiameterdegEditField = uieditfield(app.DotsTab, 'numeric');
            app.DotsdiameterdegEditField.Value = 12;
            app.BaselineGratingPostEditFieldLabel_2 = uilabel(app.DotsTab);
            app.BaselineGratingPostEditFieldLabel_2.Text = 'Baseline, Grating, Post';
            app.PreDotDuration = uieditfield(app.DotsTab, 'numeric');
            app.PreDotDuration.Limits = [0 Inf];
            app.DotslifetimesEditFieldLabel = uilabel(app.DotsTab);
            app.DotslifetimesEditFieldLabel.Text = 'Dots lifetime (s)';
            app.DotslifetimesEditField = uieditfield(app.DotsTab, 'numeric');
            app.DotslifetimesEditField.Tooltip = {'If ''Inf'', no dot is killed.'};
            app.DotslifetimesEditField.Value = 4;
            app.NdotsEditFieldLabel = uilabel(app.DotsTab);
            app.NdotsEditFieldLabel.Text = 'N dots';
            app.NdotsEditField = uieditfield(app.DotsTab, 'numeric');
            app.NdotsEditField.ValueChangedFcn = createCallbackFcn(app, @NdotsEditFieldValueChanged, true);
            app.RandSizeCheckBox = uicheckbox(app.DotsTab);
            app.RandSizeCheckBox.Text = 'Randomize';
            app.RandSpeedCheckBox = uicheckbox(app.DotsTab);
            app.RandSpeedCheckBox.Text = 'Randomize';
            app.PostDotDuration = uieditfield(app.DotsTab, 'numeric');
            app.PostDotDuration.Limits = [0 Inf];
            app.PostDotDuration.Value = 2;
            app.DotDuration = uieditfield(app.DotsTab, 'numeric');
            app.DotDuration.Limits = [0 Inf];
            app.DotDuration.Value = 2;
            app.EquiluminantbaselineCheckBox = uicheckbox(app.DotsTab);
            app.EquiluminantbaselineCheckBox.Tooltip = {'If checked, dots are lighter than background. Dots and background intensity depends on contrast'};
            app.EquiluminantbaselineCheckBox.Text = 'Equiluminant baseline';
            app.EquiluminantbaselineCheckBox.Value = true;
            app.RepetitionsEditField_2Label_2 = uilabel(app.DotsTab);
            app.RepetitionsEditField_2Label_2.Text = 'Repetitions:';
            app.DotsRepetitions = uieditfield(app.DotsTab, 'numeric');
            app.DotsRepetitions.Value = 20;
            app.GoGrid_2 = uibutton(app.DotsTab, 'push');
            app.GoGrid_2.ButtonPushedFcn = createCallbackFcn(app, @GoGrid_2ButtonPushed, true);
            app.GoGrid_2.Text = 'Dots GO!';
            app.RetinotopyTab = uitab(app.TabGroup3);
            app.RetinotopyTab.Title = 'Retinotopy';
            app.RetinotopyDefaultParametersCheckBox = uicheckbox(app.RetinotopyTab);
            app.RetinotopyDefaultParametersCheckBox.Text = {'Retinotopy Default'; 'Parameters'};
            app.RetinotopyGOButton = uibutton(app.RetinotopyTab, 'push');
            app.RetinotopyGOButton.ButtonPushedFcn = createCallbackFcn(app, @GoRetinotopy_Callback, true);
            app.RetinotopyGOButton.Text = 'Retinotopy GO!';
            app.Panel = uipanel(app.RetinotopyTab);
            app.TrialperiodsEditFieldLabel = uilabel(app.Panel);
            app.TrialperiodsEditFieldLabel.Tooltip = {'The time needed for the rectangle to run through the whole screen.'};
            app.TrialperiodsEditFieldLabel.Text = 'Trial period (s):';
            app.TrialperiodsEditField = uieditfield(app.Panel, 'numeric');
            app.TrialperiodsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialperiodsEditFieldValueChanged, true);
            app.TrialperiodsEditField.Tooltip = {'The time needed for the rectangle to run through the largest dimension of the screen.'};
            app.TrialperiodsEditField.Value = 10;
            app.KeepconstantspeedCheckBox = uicheckbox(app.Panel);
            app.KeepconstantspeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepconstantspeedCheckBoxValueChanged, true);
            app.KeepconstantspeedCheckBox.Text = 'Keep constant speed';
            app.KeepconstantspeedCheckBox.Value = true;
            app.BarspeeddegsLabel = uilabel(app.Panel);
            app.BarspeeddegsLabel.Tooltip = {'The time needed for the rectangle to run through the whole screen.'};
            app.BarspeeddegsLabel.Text = 'Bar speed (deg/s):';
            app.IntrinsicStimBarSpeedEdit = uieditfield(app.Panel, 'numeric');
            app.IntrinsicStimBarSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @IntrinsicStimBarSpeedEditValueChanged, true);
            app.IntrinsicStimBarSpeedEdit.Tooltip = {'The time needed for the rectangle to run through the largest dimension of the screen.'};
            app.IntrinsicStimBarSpeedEdit.Value = 12;
            app.MovementLabel = uilabel(app.RetinotopyTab);
            app.MovementLabel.Text = 'Movement';
            app.IntrOrientationSwitch = uiswitch(app.RetinotopyTab, 'toggle');
            app.IntrOrientationSwitch.Items = {'Horizontal', 'Vertical'};
            app.IntrOrientationSwitch.ValueChangedFcn = createCallbackFcn(app, @IntrOrientationSwitchValueChanged, true);
            app.IntrOrientationSwitch.Value = 'Horizontal';
            app.DirectionLabel = uilabel(app.RetinotopyTab);
            app.DirectionLabel.Text = 'Direction';
            app.IntrDirectionSwitch = uiswitch(app.RetinotopyTab, 'toggle');
            app.IntrDirectionSwitch.Items = {'Left-to-right', 'Right-to-left'};
            app.IntrDirectionSwitch.Value = 'Left-to-right';
            app.RectanglesizedegLabel = uilabel(app.RetinotopyTab);
            app.RectanglesizedegLabel.Text = 'Rectangle size (deg):';
            app.IntrRectSizeTxt = uieditfield(app.RetinotopyTab, 'numeric');
            app.IntrRectSizeTxt.Limits = [0 Inf];
            app.IntrRectSizeTxt.Value = 10;
            app.BaselinetimesEditFieldLabel = uilabel(app.RetinotopyTab);
            app.BaselinetimesEditFieldLabel.Text = {'Baseline'; 'time (s):'};
            app.RetinotopyPostGTime = uieditfield(app.RetinotopyTab, 'numeric');
            app.RetinotopyPostGTime.Limits = [0 Inf];
            app.RetinotopyPostGTime.Value = 1;
            app.RepetitionsEditFieldLabel_4 = uilabel(app.RetinotopyTab);
            app.RepetitionsEditFieldLabel_4.Text = 'Repetitions:';
            app.RetinotopyRepetitions = uieditfield(app.RetinotopyTab, 'numeric');
            app.RetinotopyRepetitions.Limits = [0 Inf];
            app.RetinotopyRepetitions.RoundFractionalValues = 'on';
            app.RetinotopyRepetitions.Value = 10;
            app.RandomizedorientationandcontrastPanel = uipanel(app.ContrastTab);
            app.RandomizedorientationandcontrastPanel.Title = 'Randomized orientation and contrast';
            app.GoRandGrid = uibutton(app.RandomizedorientationandcontrastPanel, 'push');
            app.GoRandGrid.ButtonPushedFcn = createCallbackFcn(app, @GoRandGridButtonPushed, true);
            app.GoRandGrid.Text = 'Randomized Grating GO!';
            app.RandFastTable = uitable(app.RandomizedorientationandcontrastPanel);
            app.RandFastTable.ColumnName = {'Spat freq'; 'Ori'; 'Lag'};
            app.RandFastTable.ColumnEditable = [false false true];
            app.UsecontrastandorientationvaluesfromLabel = uilabel(app.RandomizedorientationandcontrastPanel);
            app.UsecontrastandorientationvaluesfromLabel.Text = 'Use spatial frequency and orientation values from "Fast Spatial freq selection" and "Fast orientation stimulus selection" tables. Randomizes order. Use grating params from Gratings tab.';
            app.AddrandomlagbetweentrialsCheckBox = uicheckbox(app.RandomizedorientationandcontrastPanel);
            app.AddrandomlagbetweentrialsCheckBox.Text = 'Add random lag between trials.';
            app.AddrandomlagbetweentrialsCheckBox.Value = true;
            app.MaxlagsLabel = uilabel(app.RandomizedorientationandcontrastPanel);
            app.MaxlagsLabel.Text = 'Max lag (s):';
            app.MaxlagsEditField = uieditfield(app.RandomizedorientationandcontrastPanel, 'numeric');
            app.MaxlagsEditField.Value = 0.5;
            app.GenRandStim = uibutton(app.RandomizedorientationandcontrastPanel, 'push');
            app.GenRandStim.ButtonPushedFcn = createCallbackFcn(app, @GenRandStimButtonPushed, true);
            app.GenRandStim.Text = 'Generate stimuli';
            app.SaveRandStim = uibutton(app.RandomizedorientationandcontrastPanel, 'push');
            app.SaveRandStim.ButtonPushedFcn = createCallbackFcn(app, @SaveRandStimButtonPushed, true);
            app.SaveRandStim.Text = 'Save stimuli';
            app.LoadRandStim = uibutton(app.RandomizedorientationandcontrastPanel, 'push');
            app.LoadRandStim.ButtonPushedFcn = createCallbackFcn(app, @LoadRandStimButtonPushed, true);
            app.LoadRandStim.Text = 'Load stimuli';
            app.NrepEditFieldLabel = uilabel(app.RandomizedorientationandcontrastPanel);
            app.NrepEditFieldLabel.Text = 'N rep';
            app.NrepEditField = uieditfield(app.RandomizedorientationandcontrastPanel, 'numeric');
            app.NrepEditField.Value = 5;
            app.TabGroup4 = uitabgroup(app.ContrastTab);
            app.ContraststimtabTab = uitab(app.TabGroup4);
            app.ContraststimtabTab.Title = 'Contrast stim tab';
            app.Panel_2 = uipanel(app.ContraststimtabTab);
            app.CheckFastTable = uitable(app.Panel_2);
            app.CheckFastTable.ColumnName = {'Contrast'; 'Mean L'};
            app.CheckFastTable.ColumnEditable = [true true];
            app.ChoosestimEditFieldLabel_6 = uilabel(app.Panel_2);
            app.ChoosestimEditFieldLabel_6.Text = 'Choose stim.';
            app.CheckFastN = uieditfield(app.Panel_2, 'numeric');
            app.CheckFastN.Limits = [1 Inf];
            app.CheckFastN.RoundFractionalValues = 'on';
            app.CheckFastN.ValueDisplayFormat = '%.0f';
            app.CheckFastN.ValueChangedFcn = createCallbackFcn(app, @CheckFastNValueChanged, true);
            app.CheckFastN.Value = 1;
            app.CheckFastSaveTable = uibutton(app.Panel_2, 'push');
            app.CheckFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @CheckFastSaveTableButtonPushed, true);
            app.CheckFastSaveTable.Text = 'save table';
            app.CheckFastLoadTable = uibutton(app.Panel_2, 'push');
            app.CheckFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @CheckFastLoadTableButtonPushed, true);
            app.CheckFastLoadTable.Text = 'load table';
            app.generatestimuliCheckBtn = uibutton(app.Panel_2, 'push');
            app.generatestimuliCheckBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliCheckBtnButtonPushed, true);
            app.generatestimuliCheckBtn.Text = {'generate stimuli'; 'table'};
            app.logspacedCheckCk = uicheckbox(app.Panel_2);
            app.logspacedCheckCk.ValueChangedFcn = createCallbackFcn(app, @logspacedCheckCkValueChanged, true);
            app.logspacedCheckCk.Text = 'log spaced';
            app.logspacedCheckCk.Value = true;
            app.includezeroCheckCk = uicheckbox(app.Panel_2);
            app.includezeroCheckCk.Text = 'include zero';
            app.includezeroCheckCk.Value = true;
            app.MinLEditFieldLabel_4 = uilabel(app.Panel_2);
            app.MinLEditFieldLabel_4.Text = 'Min C';
            app.CheckFastMinC = uieditfield(app.Panel_2, 'numeric');
            app.CheckFastMinC.Limits = [0 100];
            app.CheckFastMinC.ValueChangedFcn = createCallbackFcn(app, @CheckFastMinCValueChanged, true);
            app.CheckFastMinC.Value = 6;
            app.MaxLEditFieldLabel_4 = uilabel(app.Panel_2);
            app.MaxLEditFieldLabel_4.Text = 'Max C';
            app.CheckFastMaxC = uieditfield(app.Panel_2, 'numeric');
            app.CheckFastMaxC.Limits = [0 100];
            app.CheckFastMaxC.Value = 95;
            app.noftotstimLabel_6 = uilabel(app.Panel_2);
            app.noftotstimLabel_6.Text = 'n. of tot stim';
            app.CheckFastTotN = uieditfield(app.Panel_2, 'numeric');
            app.CheckFastTotN.Limits = [1 Inf];
            app.CheckFastTotN.RoundFractionalValues = 'on';
            app.CheckFastTotN.ValueChangedFcn = createCallbackFcn(app, @CheckFastTotNValueChanged, true);
            app.CheckFastTotN.Value = 6;
            app.MeanLEditFieldLabel = uilabel(app.Panel_2);
            app.MeanLEditFieldLabel.Text = 'Mean L';
            app.CheckFastMeanL = uieditfield(app.Panel_2, 'numeric');
            app.CheckFastMeanL.Limits = [0 Inf];
            app.CheckFastMeanL.Value = 0.6;
            app.SpatialfreqtabTab = uitab(app.TabGroup4);
            app.SpatialfreqtabTab.Title = 'Spatial freq tab';
            app.generatestimuliSFBtn = uibutton(app.SpatialfreqtabTab, 'push');
            app.generatestimuliSFBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliSFBtnButtonPushed, true);
            app.generatestimuliSFBtn.Text = {'generate'; 'stimuli'};
            app.logspacedSfCk = uicheckbox(app.SpatialfreqtabTab);
            app.logspacedSfCk.Text = 'log spaced';
            app.logspacedSfCk.Value = true;
            app.SfFastTable = uitable(app.SpatialfreqtabTab);
            app.SfFastTable.ColumnName = {'Spat freq (cyc/deg)'};
            app.SfFastTable.ColumnEditable = true;
            app.SfFastSaveTable = uibutton(app.SpatialfreqtabTab, 'push');
            app.SfFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @SfFastSaveTableButtonPushed, true);
            app.SfFastSaveTable.Text = 'save stim';
            app.SfFastLoadTable = uibutton(app.SpatialfreqtabTab, 'push');
            app.SfFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @SfFastLoadTableButtonPushed, true);
            app.SfFastLoadTable.Text = 'load stim';
            app.ChoosestimLabel = uilabel(app.SpatialfreqtabTab);
            app.ChoosestimLabel.Text = {'Choose'; 'stim.'};
            app.SfFastN = uieditfield(app.SpatialfreqtabTab, 'numeric');
            app.SfFastN.Limits = [1 Inf];
            app.SfFastN.RoundFractionalValues = 'on';
            app.SfFastN.ValueDisplayFormat = '%.0f';
            app.SfFastN.ValueChangedFcn = createCallbackFcn(app, @SfFastNValueChanged, true);
            app.SfFastN.Value = 1;
            app.MinLEditFieldLabel_5 = uilabel(app.SpatialfreqtabTab);
            app.MinLEditFieldLabel_5.Text = 'Min sf (cyc/deg)';
            app.SfFastMinC = uieditfield(app.SpatialfreqtabTab, 'numeric');
            app.SfFastMinC.Limits = [0 100];
            app.SfFastMinC.Value = 0.01;
            app.MaxLEditFieldLabel_5 = uilabel(app.SpatialfreqtabTab);
            app.MaxLEditFieldLabel_5.Text = 'Max sf (cyc/deg)';
            app.SfFastMaxC = uieditfield(app.SpatialfreqtabTab, 'numeric');
            app.SfFastMaxC.Limits = [0 100];
            app.SfFastMaxC.Value = 1;
            app.NstimEditFieldLabel = uilabel(app.SpatialfreqtabTab);
            app.NstimEditFieldLabel.Text = 'N stim';
            app.NstimEditField = uieditfield(app.SpatialfreqtabTab, 'numeric');
            app.NstimEditField.Value = 2;
            app.FlashTab = uitab(app.TabGroup2);
            app.FlashTab.Title = 'Flash';
            app.LabelLumi_7 = uilabel(app.FlashTab);
            app.LabelLumi_7.Text = 'cd/m²';
            app.LabelLumi_8 = uilabel(app.FlashTab);
            app.LabelLumi_8.Text = 'cd/m²';
            app.FSbaselineLEsch = uieditfield(app.FlashTab, 'numeric');
            app.FSbaselineLEsch.Limits = [0 Inf];
            app.FSbaselineLEsch.ValueDisplayFormat = '%5.3g';
            app.FSbaselineLEsch.Editable = 'off';
            app.FSbaselineLEsch.Tooltip = {'Luminance of the background'};
            app.LabelLumiEsch_7 = uilabel(app.FlashTab);
            app.LabelLumiEsch_7.Text = '(0-1)';
            app.FSflashLEsch = uieditfield(app.FlashTab, 'numeric');
            app.FSflashLEsch.Limits = [0 Inf];
            app.FSflashLEsch.Editable = 'off';
            app.FSflashLEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashLEsch.Value = 0.8;
            app.LabelLumiEsch_8 = uilabel(app.FlashTab);
            app.LabelLumiEsch_8.Text = '(0-1)';
            app.BaselineluminanceLabel = uilabel(app.FlashTab);
            app.BaselineluminanceLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.BaselineluminanceLabel.Text = 'Baseline luminance:';
            app.FSbaselineL = uieditfield(app.FlashTab, 'numeric');
            app.FSbaselineL.Limits = [0 Inf];
            app.FSbaselineL.ValueDisplayFormat = '%5.3g';
            app.FSbaselineL.ValueChangedFcn = createCallbackFcn(app, @FSbaselineLValueChanged, true);
            app.FSbaselineL.Tooltip = {'Luminance of the background'};
            app.FlashluminanceLabel = uilabel(app.FlashTab);
            app.FlashluminanceLabel.Text = {'Flash luminance:'; ''};
            app.FSflashL = uieditfield(app.FlashTab, 'numeric');
            app.FSflashL.Limits = [0 Inf];
            app.FSflashL.ValueChangedFcn = createCallbackFcn(app, @FSflashLValueChanged, true);
            app.FSflashL.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashL.Value = 0.8;
            app.FlashdurationsLabel = uilabel(app.FlashTab);
            app.FlashdurationsLabel.Text = 'Flash duration (s)';
            app.FlashDuration = uieditfield(app.FlashTab, 'numeric');
            app.FlashDuration.Limits = [0 Inf];
            app.FlashDuration.Value = 0.1;
            app.RepetitionsLabel_4 = uilabel(app.FlashTab);
            app.RepetitionsLabel_4.Text = 'Repetitions:';
            app.FlashRepetitions = uieditfield(app.FlashTab, 'numeric');
            app.FlashRepetitions.Limits = [0 Inf];
            app.FlashRepetitions.Value = 20;
            app.PeriodsEditFieldLabel = uilabel(app.FlashTab);
            app.PeriodsEditFieldLabel.Text = 'Period (s)';
            app.FlashPeriod = uieditfield(app.FlashTab, 'numeric');
            app.FlashPeriod.Limits = [0 Inf];
            app.FlashPeriod.Value = 4;
            app.FastFlashStimulusSelectionPanel = uipanel(app.FlashTab);
            app.FastFlashStimulusSelectionPanel.Title = 'Fast Flash Stimulus Selection';
            app.FlashFastTable = uitable(app.FastFlashStimulusSelectionPanel);
            app.FlashFastTable.ColumnName = {'Flash L'; 'Base L'};
            app.FlashFastTable.ColumnEditable = [true true];
            app.noftotstimLabel_4 = uilabel(app.FastFlashStimulusSelectionPanel);
            app.noftotstimLabel_4.Text = 'n. of tot stim';
            app.FlashFastTotN = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastTotN.Limits = [1 Inf];
            app.FlashFastTotN.RoundFractionalValues = 'on';
            app.FlashFastTotN.ValueChangedFcn = createCallbackFcn(app, @FlashFastTotNValueChanged, true);
            app.FlashFastTotN.Value = 9;
            app.MinLEditFieldLabel_3 = uilabel(app.FastFlashStimulusSelectionPanel);
            app.MinLEditFieldLabel_3.Text = 'Min L';
            app.FlashFastMinL = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastMinL.Limits = [0 Inf];
            app.FlashFastMinL.ValueChangedFcn = createCallbackFcn(app, @FlashFastMinLValueChanged, true);
            app.FlashFastMinL.Value = 0.1;
            app.MaxLEditFieldLabel_3 = uilabel(app.FastFlashStimulusSelectionPanel);
            app.MaxLEditFieldLabel_3.Text = 'Max L';
            app.FlashFastMaxL = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastMaxL.Limits = [0 Inf];
            app.FlashFastMaxL.Value = 0.6;
            app.logspacedFlashCk = uicheckbox(app.FastFlashStimulusSelectionPanel);
            app.logspacedFlashCk.ValueChangedFcn = createCallbackFcn(app, @logspacedFlashCkValueChanged, true);
            app.logspacedFlashCk.Text = 'log spaced';
            app.logspacedFlashCk.Value = true;
            app.includezeroFlashCk = uicheckbox(app.FastFlashStimulusSelectionPanel);
            app.includezeroFlashCk.Text = 'include zero';
            app.includezeroFlashCk.Value = true;
            app.generatestimuliFlashBtn = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.generatestimuliFlashBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliFlashBtnPushed, true);
            app.generatestimuliFlashBtn.Text = {'generate stimuli'; 'table'};
            app.savetableButton = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.savetableButton.ButtonPushedFcn = createCallbackFcn(app, @savetableButtonPushed, true);
            app.savetableButton.Text = 'save table';
            app.FlashFastLoadTable = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.FlashFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @FlashFastLoadTableButtonPushed, true);
            app.FlashFastLoadTable.Text = 'load table';
            app.ChoosestimEditFieldLabel_4 = uilabel(app.FastFlashStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel_4.Text = 'Choose stim.';
            app.FlashFastN = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastN.Limits = [1 Inf];
            app.FlashFastN.RoundFractionalValues = 'on';
            app.FlashFastN.ValueDisplayFormat = '%.0f';
            app.FlashFastN.ValueChangedFcn = createCallbackFcn(app, @FlashFastN_Callback, true);
            app.FlashFastN.Value = 1;
            app.FSFlashGo = uibutton(app.FlashTab, 'push');
            app.FSFlashGo.ButtonPushedFcn = createCallbackFcn(app, @GoFlash_Callback, true);
            app.FSFlashGo.Text = 'Flash GO!';
            app.ReceptivefieldPanel = uipanel(app.FlashTab);
            app.ReceptivefieldPanel.Title = 'Receptive field';
            app.UIAxes = uiaxes(app.ReceptivefieldPanel);
            title(app.UIAxes, 'Screen')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = {''; ''};
            app.rowsEditFieldLabel = uilabel(app.ReceptivefieldPanel);
            app.rowsEditFieldLabel.Text = '# rows';
            app.rowsEditField = uieditfield(app.ReceptivefieldPanel, 'numeric');
            app.rowsEditField.ValueChangedFcn = createCallbackFcn(app, @rowsEditFieldValueChanged, true);
            app.rowsEditField.Value = 9;
            app.columnsEditFieldLabel = uilabel(app.ReceptivefieldPanel);
            app.columnsEditFieldLabel.Text = '# columns';
            app.columnsEditField = uieditfield(app.ReceptivefieldPanel, 'numeric');
            app.columnsEditField.ValueChangedFcn = createCallbackFcn(app, @columnsEditFieldValueChanged, true);
            app.columnsEditField.Value = 16;
            app.randomizeorderCheckBox = uicheckbox(app.ReceptivefieldPanel);
            app.randomizeorderCheckBox.Text = 'randomize order';
            app.RecptiveFieldGo = uibutton(app.ReceptivefieldPanel, 'push');
            app.RecptiveFieldGo.ButtonPushedFcn = createCallbackFcn(app, @RecptiveFieldGoButtonPushed, true);
            app.RecptiveFieldGo.Text = 'Receptivefield GO!';
            app.LogfilenameEditFieldLabel = uilabel(app.ReceptivefieldPanel);
            app.LogfilenameEditFieldLabel.Text = 'Log file name';
            app.LogfilenameEditField = uieditfield(app.ReceptivefieldPanel, 'text');
            app.LogfilenameEditField.Value = 'name';
            app.OpenlogdirectoryButton = uibutton(app.ReceptivefieldPanel, 'push');
            app.OpenlogdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenlogdirectoryButtonPushed, true);
            app.OpenlogdirectoryButton.Text = 'Open log directory';
            app.SomatosensoryTab = uitab(app.TabGroup2);
            app.SomatosensoryTab.Title = 'Somatosensory';
            app.SomatosensoryPanel = uipanel(app.SomatosensoryTab);
            app.SomatosensoryPanel.Title = 'Somatosensory';
            app.RepetitionsLabel_5 = uilabel(app.SomatosensoryPanel);
            app.RepetitionsLabel_5.Text = 'Repetitions:';
            app.PuffRepetitions = uieditfield(app.SomatosensoryPanel, 'numeric');
            app.PuffRepetitions.Limits = [0 Inf];
            app.PuffRepetitions.Value = 40;
            app.PuffdurationmsLabel = uilabel(app.SomatosensoryPanel);
            app.PuffdurationmsLabel.Text = 'Puff duration (ms):';
            app.PuffDuration = uieditfield(app.SomatosensoryPanel, 'numeric');
            app.PuffDuration.Limits = [0 Inf];
            app.PuffDuration.Editable = 'off';
            app.PuffDuration.Value = 0.1;
            app.PeriodsLabel = uilabel(app.SomatosensoryPanel);
            app.PeriodsLabel.Text = 'Period (s):';
            app.PuffPeriod = uieditfield(app.SomatosensoryPanel, 'numeric');
            app.PuffPeriod.Limits = [0 Inf];
            app.PuffPeriod.Value = 2;
            app.PuffGo = uibutton(app.SomatosensoryPanel, 'push');
            app.PuffGo.ButtonPushedFcn = createCallbackFcn(app, @PuffGoButtonPushed, true);
            app.PuffGo.Text = 'GO!';
            app.uipanel14 = uipanel(app.StimulationTab);
            app.uipanel14.Title = 'General Settings';
            app.InitialdelaysLabel = uilabel(app.uipanel14);
            app.InitialdelaysLabel.Text = 'Initial delay (s):';
            app.initialDelay = uieditfield(app.uipanel14, 'numeric');
            app.initialDelay.Limits = [0 Inf];
            app.initialDelay.Tooltip = {'Initial delay from the start of the sequence to the apparition of the first stimulus. The screen is set to the baseline or mean luminance'};
            app.initialDelay.Value = 2;
            app.ExperimentlistTab = uitab(app.TabGroup);
            app.ExperimentlistTab.Title = 'Experiment list';
            app.ExperimentTable = uitable(app.ExperimentlistTab);
            app.ExperimentTable.ColumnName = {'Run n.'; 'Stimulus type'; 'Stimulus n.'; 'Notes'};
            app.ExperimentTable.RowName = {};
            app.ExperimentTable.ColumnEditable = [false true true true];
            app.ExperimentTable.CellSelectionCallback = createCallbackFcn(app, @ExperimentTableCellSelection, true);
            app.RandomizeexperimentPanel = uipanel(app.ExperimentlistTab);
            app.RandomizeexperimentPanel.Title = 'Randomize experiment';
            app.ExpListFlashCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListFlashCk.Text = 'Flash';
            app.ExpListFlashCk.Value = true;
            app.ExpListCheckCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListCheckCk.Text = 'Checkerboard (contrast)';
            app.ExpListCheckCk.Value = true;
            app.ExpListGratingsOrientCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListGratingsOrientCk.Text = 'Grating (orientation)';
            app.ExpListGratingsOrientCk.Value = true;
            app.RandomizeButton = uibutton(app.RandomizeexperimentPanel, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.NumberofsessionsEditFieldLabel = uilabel(app.RandomizeexperimentPanel);
            app.NumberofsessionsEditFieldLabel.Text = 'Number of sessions';
            app.ExpListSessionN = uieditfield(app.RandomizeexperimentPanel, 'numeric');
            app.ExpListSessionN.Limits = [1 Inf];
            app.ExpListSessionN.Value = 2;
            app.ExpListGratingsContrastCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListGratingsContrastCk.Text = 'Grating (contrast)';
            app.ExpListGratingsContrastCk.Value = true;
            app.ExpListGo = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListGo.ButtonPushedFcn = createCallbackFcn(app, @ExpListGoPushed, true);
            app.ExpListGo.Enable = 'off';
            app.ExpListGo.Text = 'Go!';
            app.ExpListAddRows = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListAddRows.ButtonPushedFcn = createCallbackFcn(app, @ExpListAddRowsButtonPushed, true);
            app.ExpListAddRows.Text = 'add';
            app.ExpListDeleteRows = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListDeleteRows.ButtonPushedFcn = createCallbackFcn(app, @ExpListDeleteRowsButtonPushed, true);
            app.ExpListDeleteRows.Enable = 'off';
            app.ExpListDeleteRows.Text = 'del';
            app.ExpMoveUp = uibutton(app.ExperimentlistTab, 'push');
            app.ExpMoveUp.ButtonPushedFcn = createCallbackFcn(app, @ExpMoveUpPushed, true);
            app.ExpMoveUp.Text = '↑';
            app.ExpMoveDown = uibutton(app.ExperimentlistTab, 'push');
            app.ExpMoveDown.ButtonPushedFcn = createCallbackFcn(app, @ExpMoveDownButtonPushed, true);
            app.ExpMoveDown.Text = '↓';
            app.CopytableButton = uibutton(app.ExperimentlistTab, 'push');
            app.CopytableButton.ButtonPushedFcn = createCallbackFcn(app, @CopytableButtonPushed, true);
            app.CopytableButton.Text = 'Copy table';
            app.CurrentrunnEditFieldLabel = uilabel(app.ExperimentlistTab);
            app.CurrentrunnEditFieldLabel.Text = 'Current run n.';
            app.ExpListCurrentNum = uieditfield(app.ExperimentlistTab, 'numeric');
            app.ExpListCurrentNum.Editable = 'off';
            app.RepeatStimBtn = uibutton(app.ExperimentlistTab, 'push');
            app.RepeatStimBtn.ButtonPushedFcn = createCallbackFcn(app, @RepeatStimBtnButtonPushed, true);
            app.RepeatStimBtn.Enable = 'off';
            app.RepeatStimBtn.Text = {'Repeat'; 'last'; 'stimulus'};
            app.ExpListFirstNum = uicheckbox(app.ExperimentlistTab);
            app.ExpListFirstNum.Text = 'ExpListFirstNum';
            app.PupilTab = uitab(app.TabGroup);
            app.PupilTab.Title = 'Pupil';
            app.PupilImage1UIAxes = uiaxes(app.PupilTab);
            app.PupilImage1UIAxes.XTick = [];
            app.PupilImage1UIAxes.YTick = [];
            app.PupilImage2UIAxes = uiaxes(app.PupilTab);
            app.PupilImage2UIAxes.XTick = [];
            app.PupilImage2UIAxes.YTick = [];
            app.PupilGraphUIAxes = uiaxes(app.PupilTab);
            title(app.PupilGraphUIAxes, 'Pupil Measurement')
            xlabel(app.PupilGraphUIAxes, 'Time (s)')
            ylabel(app.PupilGraphUIAxes, 'Pupil Dimension (px)')
            app.CameraPanel = uipanel(app.PupilTab);
            app.CameraPanel.Title = 'Camera Panel';
            app.RefreshDevicesButton = uibutton(app.CameraPanel, 'push');
            app.RefreshDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDevicesButtonPushed, true);
            app.RefreshDevicesButton.Text = 'Refresh Devices';
            app.CameraDropDownLabel = uilabel(app.CameraPanel);
            app.CameraDropDownLabel.Text = 'Camera:';
            app.CameraDropDown = uidropdown(app.CameraPanel);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = {};
            app.ImageSizeDropDownLabel = uilabel(app.CameraPanel);
            app.ImageSizeDropDownLabel.Text = 'Image Size:';
            app.ImageSizeDropDown = uidropdown(app.CameraPanel);
            app.ImageSizeDropDown.Items = {};
            app.ImageSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSizeDropDownValueChanged, true);
            app.ImageSizeDropDown.Value = {};
            app.NocameraLampLabel = uilabel(app.CameraPanel);
            app.NocameraLampLabel.Text = 'No camera';
            app.NocameraLamp = uilamp(app.CameraPanel);
            app.FramerateHzEditFieldLabel = uilabel(app.CameraPanel);
            app.FramerateHzEditFieldLabel.Text = 'Frame rate (Hz)';
            app.FramerateHzEditField = uieditfield(app.CameraPanel, 'numeric');
            app.PreviewPanel = uipanel(app.PupilTab);
            app.PreviewPanel.Title = 'Preview Panel';
            app.NumberofPointsinPreviewGraphEditFieldLabel = uilabel(app.PreviewPanel);
            app.NumberofPointsinPreviewGraphEditFieldLabel.Text = 'Number of Points in Preview Graph';
            app.NumberofPointsinPreviewGraphEditField = uieditfield(app.PreviewPanel, 'numeric');
            app.NumberofPointsinPreviewGraphEditField.Limits = [1 Inf];
            app.NumberofPointsinPreviewGraphEditField.RoundFractionalValues = 'on';
            app.NumberofPointsinPreviewGraphEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsinPreviewGraphEditFieldValueChanged, true);
            app.NumberofPointsinPreviewGraphEditField.Value = 100;
            app.StartPreviewButton = uibutton(app.PreviewPanel, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start Preview';
            app.skipPupilPrevCk = uicheckbox(app.PreviewPanel);
            app.skipPupilPrevCk.Text = 'Skip pupil analysis in preview';
            app.ResetAxesButton = uibutton(app.PreviewPanel, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset Axes';
            app.ThresholdSliderLabel = uilabel(app.PupilTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.PupilTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 0.5;
            app.AcquisitionPanel = uipanel(app.PupilTab);
            app.AcquisitionPanel.Title = 'Acquisition Panel';
            app.DurationsEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s):';
            app.DurationsEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.DurationsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsEditFieldValueChanged, true);
            app.DurationsEditField.Tooltip = {'Set to "Inf" to record until STOP ACQUISITION is pressed'};
            app.DurationsEditField.Value = Inf;
            app.PupilStartAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.PupilStartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @PupilStartAcquisitionButtonPushed, true);
            app.PupilStartAcquisitionButton.Text = 'START ACQUISITION';
            app.PreviewWhileAcquiringCheckBox = uicheckbox(app.AcquisitionPanel);
            app.PreviewWhileAcquiringCheckBox.Text = 'Preview While Acquiring';
            app.PreviewWhileAcquiringCheckBox.Value = true;
            app.SaveFolderEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.SaveFolderEditFieldLabel.Text = 'Save Folder';
            app.SaveFolderEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.SaveFolderEditField.Value = 'D:\Enrico\Documents\MATLAB\PupillometryCalibration';
            app.FilenEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FilenEditFieldLabel.Text = 'File n.';
            app.OutputFileN = uieditfield(app.AcquisitionPanel, 'numeric');
            app.OutputFileN.Limits = [1 Inf];
            app.OutputFileN.RoundFractionalValues = 'on';
            app.OutputFileN.ValueDisplayFormat = '%.0f';
            app.OutputFileN.Value = 1;
            app.StatusEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.acqStatusTxt = uieditfield(app.AcquisitionPanel, 'text');
            app.acqStatusTxt.Editable = 'off';
            app.acqStatusTxt.Value = 'Not acquiring';
            app.skipPupilAcqCk = uicheckbox(app.AcquisitionPanel);
            app.skipPupilAcqCk.Text = 'Skip pupil analysis';
            app.skipPupilAcqCk.Value = true;
            app.acqLamp = uilamp(app.AcquisitionPanel);
            app.FilenameEditFieldLabel_2 = uilabel(app.AcquisitionPanel);
            app.FilenameEditFieldLabel_2.Text = 'File name';
            app.PupilFilenameEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.PupilFilenameEditField.Value = 'pupillometry';
            app.Panel_3 = uipanel(app.AcquisitionPanel);
            app.ControlIRLEDwithHermesCheckBox = uicheckbox(app.Panel_3);
            app.ControlIRLEDwithHermesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IR_led_switchValueChanged, true);
            app.ControlIRLEDwithHermesCheckBox.Tooltip = {'Sync pupillometry with imaging or ephys'};
            app.ControlIRLEDwithHermesCheckBox.Text = 'Control IR LED with Hermes';
            app.ControlIRLEDwithHermesCheckBox.Value = true;
            app.IR_led_switch = uiswitch(app.Panel_3, 'slider');
            app.IR_led_switch.Items = {'ON = LOW', 'ON = HIGH'};
            app.IR_led_switch.ValueChangedFcn = createCallbackFcn(app, @IR_led_switchValueChanged, true);
            app.IR_led_switch.Value = 'ON = LOW';
            app.SelectButton = uibutton(app.AcquisitionPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.GrabaframeeveryEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.GrabaframeeveryEditFieldLabel.Text = 'Grab a frame every ';
            app.GrabaframeeveryEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.GrabaframeeveryEditField.ValueChangedFcn = createCallbackFcn(app, @GrabaframeeveryEditFieldValueChanged, true);
            app.GrabaframeeveryEditField.Tooltip = {'Set to "Inf" to record until STOP ACQUISITION is pressed'};
            app.GrabaframeeveryEditField.Value = 5;
            app.ActualframerateHzEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.ActualframerateHzEditFieldLabel.Text = 'Actual frame rate (Hz)';
            app.ActualframerateHzEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.ActualframerateHzEditField.Editable = 'off';
            app.ActualframerateHzEditField.Tooltip = {'Set to "Inf" to record until STOP ACQUISITION is pressed'};
            app.NframestoacquireEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.NframestoacquireEditFieldLabel.Text = 'N frames to acquire';
            app.NframestoacquireEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.NframestoacquireEditField.Editable = 'off';
            app.NframestoacquireEditField.Tooltip = {'Set to "Inf" to record until STOP ACQUISITION is pressed'};
            app.NframestoacquireEditField.Value = Inf;
            app.Threshold_EditField = uieditfield(app.PupilTab, 'numeric');
            app.Threshold_EditField.ValueChangedFcn = createCallbackFcn(app, @Threshold_EditFieldValueChanged, true);
            app.Threshold_EditField.Value = 0.5;
            app.HermesTab = uitab(app.TabGroup);
            app.HermesTab.Title = 'Hermes';
            app.SerialPanel = uipanel(app.HermesTab);
            app.SerialPanel.Title = 'Serial';
            app.RefreshCOMButton = uibutton(app.SerialPanel, 'push');
            app.RefreshCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMButtonPushed, true);
            app.RefreshCOMButton.Text = 'Refresh COM';
            app.SerialCOMDropDownLabel = uilabel(app.SerialPanel);
            app.SerialCOMDropDownLabel.Text = 'Serial COM:';
            app.SerialCOMDropDown = uidropdown(app.SerialPanel);
            app.SerialCOMDropDown.Items = {};
            app.SerialCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialCOMDropDownValueChanged, true);
            app.SerialCOMDropDown.Value = {};
            app.PrintSerialCommCk = uicheckbox(app.SerialPanel);
            app.PrintSerialCommCk.Text = 'Print Serial Comm';
            app.PrintSerialCommCk.Value = true;
            app.WorkingconfigurationPanel = uipanel(app.HermesTab);
            app.WorkingconfigurationPanel.Title = 'Working configuration';
            app.ModeDropDownLabel = uilabel(app.WorkingconfigurationPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.WorkingconfigurationPanel);
            app.ModeDropDown.Items = {'classic', 'opto'};
            app.ModeDropDown.Value = 'classic';
            app.TriggerDropDownLabel = uilabel(app.WorkingconfigurationPanel);
            app.TriggerDropDownLabel.Text = 'Trigger';
            app.TriggerDropDown = uidropdown(app.WorkingconfigurationPanel);
            app.TriggerDropDown.Items = {'phototransistor', 'serial'};
            app.TriggerDropDown.Value = 'phototransistor';
            app.ApplyButton = uibutton(app.WorkingconfigurationPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.VisualCuePanel = uipanel(app.HermesTab);
            app.VisualCuePanel.Title = 'Visual Cue';
            app.HeightpxEditFieldLabel = uilabel(app.VisualCuePanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.hhTxt = uieditfield(app.VisualCuePanel, 'numeric');
            app.hhTxt.Limits = [0 Inf];
            app.hhTxt.ValueChangedFcn = createCallbackFcn(app, @hhTxtValueChanged, true);
            app.hhTxt.Editable = 'off';
            app.hhTxt.Value = 320;
            app.CueWidthpxEditFieldLabel = uilabel(app.VisualCuePanel);
            app.CueWidthpxEditFieldLabel.Text = 'Cue Width (px)';
            app.wwTxt = uieditfield(app.VisualCuePanel, 'numeric');
            app.wwTxt.Limits = [0 Inf];
            app.wwTxt.ValueChangedFcn = createCallbackFcn(app, @wwTxtValueChanged, true);
            app.wwTxt.Editable = 'off';
            app.wwTxt.Value = 470;
            app.CuepositionPanel = uipanel(app.VisualCuePanel);
            app.CuepositionPanel.Title = 'Cue position';
            app.ButtonGroup_2 = uibuttongroup(app.CuepositionPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.CueTopRB = uiradiobutton(app.ButtonGroup_2);
            app.CueTopRB.Text = 'Top';
            app.CueTopRB.Value = true;
            app.CueBotRB = uiradiobutton(app.ButtonGroup_2);
            app.CueBotRB.Text = 'Bottom';
            app.ButtonGroup_3 = uibuttongroup(app.CuepositionPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.CueLeftRB = uiradiobutton(app.ButtonGroup_3);
            app.CueLeftRB.Text = 'Left';
            app.CueLeftRB.Value = true;
            app.CueRightRB = uiradiobutton(app.ButtonGroup_3);
            app.CueRightRB.Text = 'Right';
            app.DurationofcuemsEditFieldLabel = uilabel(app.VisualCuePanel);
            app.DurationofcuemsEditFieldLabel.Text = 'Duration of cue (ms):';
            app.optDtrTimeTxt = uieditfield(app.VisualCuePanel, 'numeric');
            app.optDtrTimeTxt.Limits = [0 Inf];
            app.optDtrTimeTxt.Value = 100;
            app.UsedefaultwidthandheightCheckBox = uicheckbox(app.VisualCuePanel);
            app.UsedefaultwidthandheightCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsedefaultwidthandheightCheckBoxValueChanged, true);
            app.UsedefaultwidthandheightCheckBox.Text = 'Use default width and height';
            app.UsedefaultwidthandheightCheckBox.Value = true;
            app.ard_ck = uicheckbox(app.HermesTab);
            app.ard_ck.Tooltip = {'Display the Hermes synchronisation cue'};
            app.ard_ck.Text = 'Enable Hermes Synch';
            app.ard_ck.Value = true;
            app.CalibrationPanel = uipanel(app.HermesTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.calblack_btn = uibutton(app.CalibrationPanel, 'push');
            app.calblack_btn.ButtonPushedFcn = createCallbackFcn(app, @calblack_btn_Callback, true);
            app.calblack_btn.Text = 'C-Black';
            app.calwhite_btn = uibutton(app.CalibrationPanel, 'push');
            app.calwhite_btn.ButtonPushedFcn = createCallbackFcn(app, @calwhite_btn_Callback, true);
            app.calwhite_btn.Text = 'C-White';
            app.TimingPanel = uipanel(app.HermesTab);
            app.TimingPanel.Title = 'Timing';
            app.TTLdurationmsLabel = uilabel(app.TimingPanel);
            app.TTLdurationmsLabel.Text = 'TTL duration (ms):';
            app.HermesTtlDurationEditField = uieditfield(app.TimingPanel, 'numeric');
            app.HermesTtlDurationEditField.Limits = [0 Inf];
            app.HermesTtlDurationEditField.ValueChangedFcn = createCallbackFcn(app, @HermesTtlDurationEditFieldValueChanged, true);
            app.HermesTtlDurationEditField.Value = 2;
            app.HermesIdletimemsLabel = uilabel(app.TimingPanel);
            app.HermesIdletimemsLabel.Text = 'Hermes Idle time (ms):';
            app.HermesIdleTimeEditField = uieditfield(app.TimingPanel, 'numeric');
            app.HermesIdleTimeEditField.Limits = [0 Inf];
            app.HermesIdleTimeEditField.RoundFractionalValues = 'on';
            app.HermesIdleTimeEditField.ValueChangedFcn = createCallbackFcn(app, @HermesIdleTimeEditFieldValueChanged, true);
            app.HermesIdleTimeEditField.Tooltip = {'Amount of time Hermes will wait to start a new TTL sognal after a TTL signal. Rounded to millisecond precision.'};
            app.HermesIdleTimeEditField.Value = 200;
            app.BusyStatusLampLabel = uilabel(app.EscherMasterFig);
            app.BusyStatusLampLabel.Text = 'Busy Status:';
            app.BusyStatusLamp = uilamp(app.EscherMasterFig);
            app.DeBusyButton = uibutton(app.EscherMasterFig, 'push');
            app.DeBusyButton.ButtonPushedFcn = createCallbackFcn(app, @DeBusyButtonPushed, true);
            app.DeBusyButton.Text = 'De-Busy';
            app.UDPConnectionLampLabel = uilabel(app.EscherMasterFig);
            app.UDPConnectionLampLabel.Text = 'UDP Connection:';
            app.UDPConnectionLamp = uilamp(app.EscherMasterFig);
            % Show the figure after all components are created
            app.EscherMasterFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EscherAppMaster_boston_newpupil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EscherMasterFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EscherMasterFig)
        end
    end
end