classdef EscherAppSlave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EscherSlaveFig                 matlab.ui.Figure
        UDPConnectionPanel             matlab.ui.container.Panel
        ListenBtn                      matlab.ui.control.Button
        SlaveIPLabel                   matlab.ui.control.Label
        SlaveIp                        matlab.ui.control.EditField
        PortEditFieldLabel             matlab.ui.control.Label
        SlavePort                      matlab.ui.control.NumericEditField
        MasterIPLabel                  matlab.ui.control.Label
        MasterIp                       matlab.ui.control.EditField
        PortEditFieldLabel_2           matlab.ui.control.Label
        MasterPort                     matlab.ui.control.NumericEditField
        GetSlaveIPandMACBtn            matlab.ui.control.Button
        StatusEditFieldLabel           matlab.ui.control.Label
        ConnectionStatus               matlab.ui.control.EditField
        MACLabel                       matlab.ui.control.Label
        MasterMac                      matlab.ui.control.EditField
        MACLabel_2                     matlab.ui.control.Label
        SlaveMac                       matlab.ui.control.EditField
        ScreenFeaturesPanel            matlab.ui.container.Panel
        ScreendistancecmLabel          matlab.ui.control.Label
        ScreenDistance                 matlab.ui.control.NumericEditField
        WidthcmLabel                   matlab.ui.control.Label
        ScreenWidth                    matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel         matlab.ui.control.Label
        ScreenHeight                   matlab.ui.control.NumericEditField
        ScreenFormulaLabel             matlab.ui.control.Label
        ScreenFuncTxt                  matlab.ui.control.EditField
        LuminanceLabel                 matlab.ui.control.Label
        SynchronizationPanel           matlab.ui.container.Panel
        ard_ck                         matlab.ui.control.CheckBox
        HeightpxEditFieldLabel         matlab.ui.control.Label
        hhTxt                          matlab.ui.control.NumericEditField
        CueWidthpxEditFieldLabel       matlab.ui.control.Label
        wwTxt                          matlab.ui.control.NumericEditField
        CuepositionPanel               matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        CueTopRB                       matlab.ui.control.RadioButton
        CueBotRB                       matlab.ui.control.RadioButton
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        CueLeftRB                      matlab.ui.control.RadioButton
        CueRightRB                     matlab.ui.control.RadioButton
        DurationofcuemsEditFieldLabel  matlab.ui.control.Label
        optDtrTimeTxt                  matlab.ui.control.NumericEditField
        CalModeRBGroup                 matlab.ui.container.ButtonGroup
        UncalibratedButton             matlab.ui.control.RadioButton
        CalibratedButton               matlab.ui.control.RadioButton
        oculusCk                       matlab.ui.control.CheckBox
        OneScreenMode_ck               matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        w % pointer to the open screen
        ifi % interval between frames
        black % value of black (usually 0)
        white % value of white (usually 255)
        screenRect % (4-element vector of the screen coordinates)
        ScreenBitDepth % bit depth of the colors (usually 8)
        
        UdpObj % The actual UDP object
        
        ListeningFlag % This is 1 when listening is active, 0 when it's off
        BusyFlag % It's 1 if the slave is busy
    end
        
    methods (Access = private)
        
        function UdpDataReceivedCallback(app,obj)
            MsgIn = fscanf(obj);
%             Remove any white-space character.
            MsgIn = regexprep(MsgIn,'\s','');
%             Then split the message, according to the separator commas.
            MsgCellArray = strsplit(MsgIn,',');
            %             The first element of the cell array contains usually the MAC
            %             address of the slave, but when the connection is started it
            %             contains the word 'start'.
            if strcmp(MsgCellArray{1},'start')
%                 Close any open screen.
                CloseScreen(app)
%                 First of all, we must ask for confirmation to accept this
%                 connection.
                selection = uiconfirm(app.EscherSlaveFig,['Accept connection from IP ',...
                    MsgCellArray{2},' and MAC ',MsgCellArray{3},'?'],'Connection confirmation');
                if strcmp(selection,'Cancel')
                    return
                end
%                 If the connection is accepted, we can go on.
%                 The second and third word contain the master IP and MAC
%                 respectively. The fourth is the master port.
                app.MasterIp.Value = MsgCellArray{2};
                app.MasterMac.Value = MsgCellArray{3};
                app.MasterPort.Value = str2double(MsgCellArray{4});
                
%                 The UDP object must be closed and then modified.
                stopasync(app.UdpObj)
                fclose(app.UdpObj);
%                 Set the remote (master) IP and port.
                app.UdpObj.RemoteHost = app.MasterIp.Value;
                app.UdpObj.RemotePort = app.MasterPort.Value;
%                 Then open the connection again.
                fopen(app.UdpObj);
                readasync(app.UdpObj)
                app.UdpObj.ReadAsyncMode = 'continuous';
                
%                 The connection is set, so the buttons must be changed
%                 accordingly.
                app.ConnectionStatus.Value = 'Connected.';
                
%                 Send a confirmation message, containing the word 'start', the IP and MAC addresses.
                msg=strjoin([{'start'},{app.SlaveIp.Value},{app.SlaveMac.Value}],',');
                fprintf(app.UdpObj,msg);
                
            elseif strcmp(MsgCellArray{1},app.MasterMac.Value)
                switch MsgCellArray{2}
%                     The second word in the message contains the
%                     instruction.
                    case 'screen'
%                         The screen was opened or closed successfully, so the
%                         parameters of the screen are returned.
%                         0=screen has to be closed
%                         1=screen has to be opened
%                         2=screen parameters have to be refreshed. Can be
%                         used to check the connection.
                        if strcmp(MsgCellArray{3},'0')
%                             Close the screen
                            CloseScreen(app)
                        else
%                             First of all, set all parameters.
                            app.ScreenDistance.Value=str2double(MsgCellArray{4});
                            app.ScreenWidth.Value=str2double(MsgCellArray{5});
                            app.ScreenHeight.Value=str2double(MsgCellArray{6});
                            app.CalibratedButton.Value=str2double(MsgCellArray{7});
                            app.ScreenFuncTxt.Value=MsgCellArray{8};
                            app.ard_ck.Value=str2double(MsgCellArray{9});
                            app.wwTxt.Value=str2double(MsgCellArray{10});
                            app.hhTxt.Value=str2double(MsgCellArray{11});
                            app.optDtrTimeTxt.Value=str2double(MsgCellArray{12});
                            app.CueTopRB.Value=str2double(MsgCellArray{13});
                            app.CueLeftRB.Value=str2double(MsgCellArray{14});
                            app.OneScreenMode_ck.Value=str2double(MsgCellArray{15});
                            app.oculusCk.Value=str2double(MsgCellArray{16});
                            if strcmp(MsgCellArray{3},'1')
                %                 Close any open screen.
                                CloseScreen(app)
%                                 Open the screen.
                                OpenScreen(app)
                            end
%                             Sends the dimensions of the screen to the
%                             master.
                            msg=strjoin([{app.SlaveMac.Value},{'screenok'},{num2str(app.screenRect(3))},...
                                {num2str(app.screenRect(4))}],',');
                            fprintf(app.UdpObj,msg);
                        end
                    case 'flash'
                        FlashStruct.Blumi=str2double(MsgCellArray{3});
                        FlashStruct.Slumi=str2double(MsgCellArray{4});
                        FlashStruct.Bt=str2double(MsgCellArray{5});
                        FlashStruct.St=str2double(MsgCellArray{6});
                        FlashStruct.p=str2double(MsgCellArray{7});
                        FlashStruct.n=str2double(MsgCellArray{8});
                        
%                         Send a message to confirm that the stimulus is
%                         ongoing and that it must wait for a number of
%                         seconds.
                        TotalTime=FlashStruct.Bt+FlashStruct.p*(FlashStruct.n-1);
                        msg=strjoin([{app.SlaveMac.Value},'wait',num2str(TotalTime)],',');
                        fprintf(app.UdpObj,msg);
                        
%                         Call the Flash function
                        GoFlash(app,FlashStruct)
                        
                    case 'check'
                        CheckStruct.Blumi=str2double(MsgCellArray{3});
                        CheckStruct.Slumi=str2double(MsgCellArray{4});
                        CheckStruct.sF=str2double(MsgCellArray{5});
                        CheckStruct.Bt=str2double(MsgCellArray{6});
                        CheckStruct.p=str2double(MsgCellArray{7});
                        CheckStruct.n=str2double(MsgCellArray{8});
                        
%                         Send a message to confirm that the stimulus is
%                         ongoing and that it must wait for a number of
%                         seconds.
                        TotalTime=CheckStruct.Bt+2*CheckStruct.p*CheckStruct.n;
                        msg=strjoin([{app.SlaveMac.Value},'wait',num2str(TotalTime)],',');
                        fprintf(app.UdpObj,msg);
                        
%                         Call the Flash function
                        GoCheck(app,CheckStruct)
                    case 'grid'
                        GridStruct.Glumi = str2double(MsgCellArray{3}); % the average luminance
                        GridStruct.inc = str2double(MsgCellArray{4}); % the increment of luminance
                        GridStruct.sF = str2double(MsgCellArray{5}); % spatial frequency of the stimulus (in cycles/degree)
                        GridStruct.GridType = str2double(MsgCellArray{6}); % 1 means sinusoidal, 0 is for square-wave (bar)
                        GridStruct.Angle = str2double(MsgCellArray{7}); % angle of the stimulus
                        GridStruct.dS = str2double(MsgCellArray{8}); % drifting speed of the stimulus in cycles/second.
                        GridStruct.MaskFlag = str2double(MsgCellArray{9}); % 1 means that a gaussian mask is applied.
                        GridStruct.GaussSize = str2double(MsgCellArray{10}); % size of gaussian mask
                        GridStruct.Bt = str2double(MsgCellArray{11}); % initial delay
                        GridStruct.PreG = str2double(MsgCellArray{12}); % pre-grid duration
                        GridStruct.GridT = str2double(MsgCellArray{13}); % grid duration
                        GridStruct.PostG = str2double(MsgCellArray{14}); % post-grid duration
                        GridStruct.n = str2double(MsgCellArray{15}); % number of repetitions of the stimulus
                        
%                         Send a message to confirm that the stimulus is
%                         ongoing and that it must wait for a number of
%                         seconds.
                        TotalTime=GridStruct.Bt+(GridStruct.PreG+GridStruct.GridT+GridStruct.PostG)*GridStruct.n;
                        msg=strjoin([{app.SlaveMac.Value},'wait',num2str(TotalTime)],',');
                        fprintf(app.UdpObj,msg);
                        
%                         Call the Flash function
                        GoGrid(app,GridStruct)
                    case 'background'
%                         A single screen projection.
                        ard_flag = app.ard_ck.Value;
                        Blumi = str2double(MsgCellArray{3});
                        if app.CalibratedButton.Value
                            Blumi = Lumi2Escher(app,Blumi);
                        end
                        Blumi = app.white*Blumi;
                        if ard_flag
                            dtrRect = DtrRectCalc(app);
                            BaselineColor = cast([[Blumi;Blumi;Blumi], [0;0;0]], app.ScreenBitDepth);
                            cellRects = [app.screenRect; dtrRect]';
                            %     This is commented because in flashes, the optical DTR
                            %     duration is the same as the flash duration
                            %     optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
                        else
                            BaselineColor = cast(Blumi, app.ScreenBitDepth);
                           	cellRects = app.screenRect;
                        end
                        Screen('FillRect', app.w, BaselineColor, cellRects); % paints the rectangle (entire screen)
                        Screen('Flip', app.w);
                        
                        msg=strjoin([{app.SlaveMac.Value},{'ok'}],',');
                        fprintf(app.UdpObj,msg);
                    case 'calibration'
                        if strcmp(MsgCellArray{3},'1')
%                             If the calibration number is 1, the black
%                             calibration is performed (the screen becomes
%                             all white apart from the small Hermes patch).
%                             If the calibration number is 2, the white
%                             calibration is performed and the screen turns
%                             all black apart for the Hermes patch.
                            ScreenColor=app.white;
                            HermesColor=0;
                        else
                            ScreenColor=0;
                            HermesColor=app.white;
                        end
                        dtrRect = DtrRectCalc(app);
                        BaselineColor = cast([[ScreenColor;ScreenColor;ScreenColor], [HermesColor;HermesColor;HermesColor]], app.ScreenBitDepth);
                        BaselineScreen = [app.screenRect; dtrRect]';
                        
                        Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                        Screen('Flip', app.w);
                        
                        msg=strjoin([{app.SlaveMac.Value},{'ok'}],',');
                        fprintf(app.UdpObj,msg);
                    case 'done'
                        %                         A stimulation is over, another one can be
                        %                         started.
                end
            end
        end
        
        function OpenScreen(app)
%             This is necessary for new Windows systems, that are not
%             reliable for fine timing control.
            Screen('Preference', 'SkipSyncTests', 1)
            
            try
               	% This script calls Psychtoolbox commands available only in OpenGL-based
               	% versions of the Psychtoolbox. (So far, the OS X Psychtoolbox is the
               	% only OpenGL-base Psychtoolbox.)  The Psychtoolbox command AssertPsychOpenGL will issue
               	% an error message if someone tries to execute this script on a computer without
               	% an OpenGL Psychtoolbox
                AssertOpenGL;
               	
               	% Get the list of screens and choose the one with the highest screen number.
               	% Screen 0 is, by definition, the display with the menu bar. Often when
               	% two monitors are connected the one without the menu bar is used as
               	% the stimulus display.  Chosing the display with the highest dislay number is
               	% a best guess about where you want the stimulus displayed.
               	screens = Screen('Screens');
                if length(screens)==1 && ~app.OneScreenMode_ck.Value
                    uialert(app.EscherSlaveFig,'Only one screen present!','Warning')
                    return
                end
               	screenNumber = max(screens);
                
                % Find the color values which correspond to white and black: Usually
               	% black is always 0 and white 255, but this rule is not true if one of
               	% the high precision framebuffer modes is enabled via the
               	% PsychImaging() commmand, so we query the true values via the
               	% functions WhiteIndex and BlackIndex:
               	app.white = WhiteIndex(screenNumber);
               	app.black = BlackIndex(screenNumber);                
                
                % Open a double buffered fullscreen window and set default background
               	% color to black:
               	[app.w, app.screenRect] = Screen('OpenWindow',screenNumber, app.black);
                
                % if drawmask
                % Enable alpha blending for proper combination of the gaussian aperture
                % with the drifting sine grating:
                Screen('BlendFunction', app.w, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);
                % end
                
                % Query maximum useable priorityLevel on this system:
               	priorityLevel = MaxPriority(app.w); %#ok<NASGU>
                
                % We don't use Priority() in order to not accidentally overload older
                % machines that can't handle a redraw every 40 ms. If your machine is
                % fast enough, uncomment this to get more accurate timing.
                % Priority(priorityLevel);
                
                
                %                NEW Enrico 20190524
                %                According to the value in app.white, the ScreenBitDepth is set to
                %                either 8-bit unsigned integer or 16-bit unsigned integer.
                switch app.white
                    case 255
                        app.ScreenBitDepth = 'uint8';
                    case 65535
                        app.ScreenBitDepth = 'uint16';
                    otherwise
                        error(['Data type was not reckognized please check the code ' ...
                            'to include this data. White value is' str2double(app.white)])
                end
                
                % Query duration of one monitor refresh interval:
                app.ifi=Screen('GetFlipInterval', app.w);
                
                % Translate that into the amount of seconds to wait between screen
                % redraws/updates:
                
                % waitframes = 1 means: Redraw every monitor refresh. If your GPU is
                % not fast enough to do this, you can increment this to only redraw
                % every n'th refresh. All animation paramters will adapt to still
                % provide the proper grating. However, if you have a fine grating
                % drifting at a high speed, the refresh rate must exceed that
                % "effective" grating speed to avoid aliasing artifacts in time, i.e.,
                % to make sure to satisfy the constraints of the sampling theorem
                % (See Wikipedia: "Nyquist?Shannon sampling theorem" for a starter, if
                % you don't know what this means):
                waitframes = 1;
                
                % Translate frames into seconds for screen update interval:
                waitduration = waitframes * app.ifi;
                
                % Perform initial Flip to sync us to the VBL and for getting an initial
                % VBL-Timestamp as timing baseline for our redraw loop:
                vbl=Screen('Flip', app.w);
                
            catch
                %this "catch" section executes in case of an error in the "try" section
                %above.  Importantly, it closes the onscreen window if its open.
                Screen('CloseAll');
                Priority(0);
                psychrethrow(psychlasterror);
            end %try..catch..
        end
        
        
        function CloseAllUdp(app)
            AllUdpObj = instrfindall('Type','udp');
            if ~isempty(AllUdpObj)
                stopasync(AllUdpObj)
                fclose(AllUdpObj);
                delete(AllUdpObj)
            end
        end
        
        function GoFlash(app,FlashStruct)
            Blumi = FlashStruct.Blumi;
            Slumi = FlashStruct.Slumi;
            Bt = FlashStruct.Bt;
            St = FlashStruct.St;
            p = FlashStruct.p;
            n = FlashStruct.n;
            
            if app.OneScreenMode_ck.Value
                OpenScreen(app)
            end
            
            ard_flag = app.ard_ck.Value;
            
            if app.CalibratedButton.Value
                Blumi = Lumi2Escher(app,Blumi);
                Slumi = Lumi2Escher(app,Slumi);
            end
            
            Blumi = app.white*Blumi;             % Luminance expressed as fractions of 'white'
            Slumi = app.white*Slumi;
            
            %             New part. Enrico 2019/05/24
            if ard_flag
                dtrRect = DtrRectCalc(app);
                BaselineColor = cast([[Blumi;Blumi;Blumi], [0;0;0]], app.ScreenBitDepth);
                StimColor = cast([[Slumi;Slumi;Slumi], [app.white;app.white;app.white]], app.ScreenBitDepth);
                cellRects = [app.screenRect; dtrRect]';
                %                 This is commented because in flashes, the optical DTR
                %                 duration is the same as the flash duration
                %                 optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            else
                BaselineColor = cast(Blumi, app.ScreenBitDepth);
                StimColor = cast(Slumi, app.ScreenBitDepth);
                cellRects = app.screenRect;
            end
            
%             This vector contains two timestamps for each flash. One is
%             for the flash turning on and one for the flash turning off.
            timestamps = NaN(1,2*n);
            % Load the WaitSecs function. The first load might take some
            % time.
            WaitSecs(0);
            
%             ACTUAL START OF STIMULATION
            % December 2018. The initial delay is outside of the stimulus loop
            % Paint the screen for the initial delay
            % the following two lines takes a variable time in the range of 7-19 ms.
            try
                Screen('FillRect', app.w, BaselineColor, cellRects); % paints the rectangle (entire screen)
                Screen('Flip', app.w);                            % bring the buffered screen to forefront
%             Let's acquire the starting time.
                timZero = WaitSecs(0);
                
                timOffset = timZero + Bt;
                
                for i = 1 : n
                    % Wait for the end of the period. For the first period,
                    % it just waits for the end of the baseline.
                    timStart = timOffset + (i-1) * p;
                    timEnd = timStart+St;
                    
                    % next lines flashes the screen with or without the Hermes rectangle. 6-12 ms on the Acer
                    %for flashes we suppose that stim duration is 20-100ms, which is compatible with Hermes detection and idle time
                    Screen('FillRect', app.w, StimColor, cellRects); % paint the rectangle (entire screen)
                    timestamps(2*i-1) = Screen('Flip', app.w, timStart);
                    
                    % the flash is complete
                    Screen('FillRect', app.w, BaselineColor, cellRects); % paint the rectangle (entire screen)
                    timestamps(2*i) = Screen('Flip', app.w, timEnd); % wait for the end of stim
                end
            catch ME
                warning(ME.message)
            end
            if app.OneScreenMode_ck.Value
                CloseScreen(app)
            end
            
%             The starting time is removed from  timestamps and then 
%             timestamps are sent to the master.
            timestamps = timestamps - timZero;
            
            msg=strjoin([{app.SlaveMac.Value},{'stimok'},...
                {strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
            fprintf(app.UdpObj,msg);
        end
        
        function escherValue = Lumi2Escher(app,lumiValue)
            %         First of all, we need the gamma correction function of the screen
            screenFunc = str2func(app.ScreenFuncTxt.Value);
            %         Let's generate a vector of all possible values of escher,
            %         considering that psychtoolbox can only receive integer values
            %         that are in the range 0-app.white. Usually the range is 0-255 but
            %         let's be cautious
            allEscherX = (((1:app.white+1)-1)/app.white)';
            %             Now we generate a vector of all possible luminances that can
            %             be generated by Escher through psychtoolbox, by using the
            %             screen calibration function
            allLumiY = screenFunc(allEscherX);
            %             We look for the index of the value on the allLumiY vector
            %             that is close to "lumivalue" (our input).
            tmpValue = abs(allLumiY-lumiValue);
            [~,Idx] = min(tmpValue,[],1);
            %             Now we use that index to find the value on allEscherX: that
            %             is the value that we want as Escher input!
            escherValue = allEscherX(Idx);
        end
        
        function dtrRect = DtrRectCalc(app)
            sR = app.screenRect;
            ww = app.wwTxt.Value; %width of the optical DTR
            hh = app.hhTxt.Value; %height of the optical DTR
            %             The optical DTR must be placed according to the selection
            %             of the position
            % position of DtrRect (x0, y0, x4, y4)
            
            dtrRect = [app.CueRightRB.Value*(sR(3)-ww),...
                app.CueBotRB.Value*(sR(4)-hh),...
                sR(3)-app.CueLeftRB.Value*(sR(3)-ww),...
                sR(4)-app.CueTopRB.Value*(sR(4)-hh)];
        end
        
        function GoCheck(app,CheckStruct)
            Blumi = CheckStruct.Blumi; % the "black" square luminance
            Slumi = CheckStruct.Slumi; % the stimulus luminance ("white" squares)
            Glumi = (Blumi+Slumi)/2; % The gray luminance
            sF = CheckStruct.sF; % spatial frequency of each square (in cycle/degree)
            Bt = CheckStruct.Bt; % initial delay
            p = CheckStruct.p; % hemi-period. p is the time between a reversal and the next (2 reversals = 1 period)
            n = CheckStruct.n; % The number of repetitions (number of full periods, each full stimulation is 2 reversals)
            
            if app.OneScreenMode_ck.Value
                OpenScreen(app)
            end
            
            ard_flag = app.ard_ck.Value;
            oculusFlag = app.oculusCk.Value;
            
%             Let's convert the stimuli if the calibration button is
%             checked.
            if app.CalibratedButton.Value
                Blumi = Lumi2Escher(app,Blumi);
                Slumi = Lumi2Escher(app,Slumi);
                Glumi = Lumi2Escher(app,Glumi);
            end
            % Then the value between 0 and 1 is converted to a value
            % between 0 and app.white.
            Blumi = app.white*Blumi;             % Luminance expressed as fractions of 'white'
            Slumi = app.white*Slumi;
            Glumi = app.white*Glumi;
            
%             Angle of a single pixel
            OnePxAngle = PixelAngleCalc(app);
%             The spatial frequency of the stimulus in cycles/pixel is
%             calculated.
            f = OnePxAngle*sF;
%             Number of pixels for one spatial cycle of stimlulation
%             (rounded up to integer).
            pixSize = ceil(1/f);
            
            % compute the checkerboard_ck and the associate "colour" arrays
            side=pixSize/2;
            % screenRect contains the screen coordinates. x  y min (0 and 0,
            % strangely) and then x max and y max
            % If the oculusMode flag is true than the stimulu is prepared to be
            % displaied on the VR goggles. The screen width is divided in half
            % and two identical and aligned checkerboards are presented on each
            % screen
            if oculusFlag
                halfWidth = app.screenRect(3)/2;
                nSquareX = ceil((halfWidth/side));
            else
                nSquareX = ceil(app.screenRect(3)/side);
            end
            nSquareY = ceil(app.screenRect(4)/side);
            
            %             the square row must be odd in order to have a correct
            %             alternation of on and off squares.
            nSquareX = nSquareX - mod(nSquareX,2) + 1; %Makes the number odd by adding 1 if it's even
            % the square row must be odd in order to have a correct
            % alternation of on and off squares.
            
            % Total area (in pixels) occupied by the checkerboard_ck. If this is larger than
            % the screen it is cropped on the right and bottom sides.
            % if in "oculus mode" the crop is performed on the left side
            %        if oculusFlag,
            % checkerW = side*nSquareX;
            % winRectAdapted= [0 0 halfWidth side*nSquareY];
            %        else
            winRectAdapted= [0 0 side*nSquareX side*nSquareY];
            %        end
            ntot = nSquareX*nSquareY;
            % Create the tiling squares stored in the array cellRects.
            % "ArrangeRects" is a function provided by the toolbox.
            % cellRects contains the rectangle coordinates. Two screen buffer are
            % created: one with the "white" stim and one with the "black".
            % Then, at the correct timing the screens are flipped.
            
            cellRects = ArrangeRects(ntot,[0 0 2 2],winRectAdapted);
            
            %             If the Oculus Flag is on, cellRects will be changed so that
            %             the second screen will have the same stimuli (necessary?)
            if oculusFlag
                %                 now double the cellRects array... to do that 2 words about
                %                 its structure: it contains the list of the xmin ymin xmax ymax
                %                 coodinates of the checkers
                checkerW = side*nSquareX;
                
                %                 now we build the second half of the screen by copying the
                %                 left half plus the half width of the screen
                cellRects2 = cellRects;
                %                 halfWidth is added to the first and third column
                cellRects2(:,[1 3]) = cellRects(:,[1 3]) + halfWidth;
                
                cellRects = [CellRects, CellRects2]';
            end
            
            cellColor1On = ones(3,ntot, app.ScreenBitDepth) .* Slumi;
            cellColor2On = zeros(3,ntot, app.ScreenBitDepth) + Blumi;
            cellColor1On(1:3,1:2:ntot) = Blumi;
            cellColor2On(1:3,1:2:ntot) = Slumi;
            
            %             New part. Enrico 2019/05/24
            if ard_flag
                dtrRect = DtrRectCalc(app);
                optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
                
                BaselineColor = cast([[Glumi;Glumi;Glumi], [0;0;0]], app.ScreenBitDepth);
                BaselineRect = [app.screenRect; dtrRect]';
                
                cellColor1Off = [cellColor1On, [0; 0; 0]];
                cellColor2Off = [cellColor2On, [0; 0; 0]];
                cellColor1On = [cellColor1On, [app.white; app.white; app.white]];
                cellColor2On = [cellColor2On, [app.white; app.white; app.white]];
                cellRects = [cellRects; dtrRect]';
            else
                BaselineColor = cast(Glumi, app.ScreenBitDepth);
                BaselineRect = app.screenRect;
            end
            
%             This vector contains two timestamps for each period, one for
%             each checkerboard reversal.
            timestamps = NaN(1,2*n);
            % load the WaitSecs function
            WaitSecs (0);
            
%             ACTUAL START OF STIMULATION
            try
                %                 Fill screen with the baseline equiluminant gray
                %                 OK, let's start. First thing first: edge out and wait for end of baseline
                %                 if handles.SerialSynch == 1     % Out an edge on the DTR line to synchronize the sweep beginning
                %                 Synch by Kaboum is performed on the rising edge
                %  ComHandle.DataTerminalReady = 'on';   comments to get rid of
                %  the initial trigger!
                %  timA = WaitSecs (0.002);
                %  ComHandle.DataTerminalReady = 'off';
                %        end
                % Remember to check data analysis. Synch on falling edge
                % The following statement takes about 0.0009 sec on the VAIO
                Screen('FillRect', app.w, BaselineColor, BaselineRect)     % paints the rectangle (entire screen)
                Screen('Flip', app.w);  % with equiluminant gray.
                i=1;
%                 Let's acquire the starting time.
                timZero = WaitSecs(0);
%               --- Prepare first stimulus (from baseline to cellColor1)
                Screen('FillRect', app.w, cellColor1On, cellRects)
                while i <= n
%                     It used to be:
%                 while ~KbCheck && i<=n
                    FirstTim = timZero+Bt+2*(i-1)*p; % remember: p is the half of the period!
                    timestamps(2*i-1) = Screen('Flip', app.w, FirstTim);
%                     if app.SerialSynch == 1     % Out an edge on the DTR line to synchronize the sweep beginning
%                         % give out a rising edge at the beginning of the sweep to synchronize clampex
%                         ComHandle.DataTerminalReady = 'on';
%                         timA = WaitSecs (0.002);
%                         ComHandle.DataTerminalReady = 'off';
%                     end
                    
%                     --- Screen flip to terminate optical DTR
                    if ard_flag
                        Screen('FillRect',app.w,cellColor1Off,cellRects)
                        %                         cellColor1On stays on for "optDtrTime" seconds; arduino must wait more than that.
                        Screen('Flip', app.w, FirstTim+optDtrTime);
                    end
                    
%                     --- Second stimulus (from cellColor1 to cellColor2)
                    SecondTim = timZero+Bt+(2*i-1)*p; % remember: p is the half of the period!
                    Screen('FillRect',app.w,cellColor2On,cellRects);
                    timestamps(2*i) = Screen('Flip', app.w, SecondTim);
%                     if app.SerialSynch == 1
%                         ComHandle.DataTerminalReady = 'on';
%                         timA = WaitSecs (0.002);
%                         ComHandle.DataTerminalReady = 'off';
%                     end
                    
%                     --- Screen flip to terminate optical DTR
                    if ard_flag
                        Screen('FillRect', app.w,cellColor2Off,cellRects)
                        Screen('Flip', app.w, SecondTim+optDtrTime); %colOn1 stays on for "optDtrTime" s; arduino must wait more than that.
                    end
                    %---
                    
%                     --- Prepare first stimulus (from cellColor2 to cellColor1)
                    Screen('FillRect', app.w,cellColor1On,cellRects)
                    i=i+1;
                end
                
                Screen('FillRect', app.w, BaselineColor, BaselineRect)     % paints the rectangle (entire screen)
                Screen('Flip', app.w);                           % with equiluminant gray.
                
                if app.OneScreenMode_ck.Value == 1
                    CloseScreen(app)
                end
            catch ME
                warning(ME.message)
            end
%%            STIMULATION END

            if app.OneScreenMode_ck.Value
                CloseScreen(app)
            end

            timestamps = timestamps - timZero;
            
            msg=strjoin([{app.SlaveMac.Value},{'stimok'},...
                {strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
            fprintf(app.UdpObj,msg);
        end
        
        function GoGrid(app,GridStruct)
            % The two luminance values we need
            Glumi = GridStruct.Glumi; % the average luminance
            inc = GridStruct.inc; % the increment of luminance
%             Other values needed
            sF = GridStruct.sF; % spatial frequency of the stimulus (in cycles/degree)
            gridType = GridStruct.GridType; % 1 means sinusoidal, 0 is for square-wave (bar)
            Angle = GridStruct.Angle; % angle of the stimulus. 0 means vertical bars.
            dS = GridStruct.dS; % drifting speed of the stimulus in cycles/second.
            MaskFlag = GridStruct.MaskFlag; % 1 means that a gaussian mask is applied.
            GaussSize = GridStruct.GaussSize; % size of gaussian mask
            Bt = GridStruct.Bt; % initial delay
            PreG = GridStruct.PreG; % pre-grid duration
            GridT = GridStruct.GridT; % grid duration
            PostG = GridStruct.PostG; % post-grid duration
            n = GridStruct.n; % number of repetitions of the stimulus
            
            if app.OneScreenMode_ck.Value
                OpenScreen(app,app.CheckLumiMean.Value)
            end
            
            ard_flag = app.ard_ck.Value;
            sR = app.screenRect;
            
            % Compute angular size of single pixel
            OnePxAngle = PixelAngleCalc(app);    % atand returns the angle in degree
            f = OnePxAngle*sF;
            
            %             The stimulus has to be prepared BEFORE the conversion to the
            %             calibrated look up table
            %             ENRICO 2018/05/02 Let's prepare the gratings and masks in
            %             advance
            GratingStruct = CreateGratings(app, Glumi, inc, f, gridType, MaskFlag, GaussSize);
            
            %             This calibration shouldn't be performed before this point, because
            %             the luminance is needed as a non-corrected value for the
            %             CreateGratings function.
            if app.CalibratedButton.Value
                Glumi = Lumi2Escher(app,Glumi);
            end
            Glumi = Glumi*app.white;
            
%             Outside the DTR conditional block, because some DTR
%             parameters are used anyway.
            dtrRect = DtrRectCalc(app);
            optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            if ard_flag % prepare the additional rectangle for optical synch signal
                BaselineColor = cast([[Glumi;Glumi;Glumi], [0;0;0]], app.ScreenBitDepth);
                BaselineScreen = [app.screenRect; dtrRect]';
            else
                BaselineColor = cast(Glumi, app.ScreenBitDepth);
                BaselineScreen = app.screenRect;
            end
                        
%             Before starting we need to compute some parameters needed for
%             the animation of the grating.
%             First of all, the total duration of one period is computed.
            totPeriod = PreG+GridT+PostG;
%             Definition of the drawn rectangle on the screen:
%             Compute it to  be the visible size of the grating, centered on the
%             screen:
%             ENRICO 20190504 Removed the destination rect of variable
%             size. Now the destination rect is the whole screen.
%             dstRect = [0 0 visiblesize visiblesize];
%             dstRect = CenterRect(dstRect, app.screenRect);
%             Translate that into the amount of seconds to wait between screen
%             redraws/updates:
%             waitframes = 1 means: Redraw every monitor refresh. If your GPU is
%             not fast enough to do this, you can increment this to only redraw
%             every n'th refresh. All animation paramters will adapt to still
%             provide the proper grating. However, if you have a fine grating
%             drifting at a high speed, the refresh rate must exceed that
%             "effective" grating speed to avoid aliasing artifacts in time, i.e.,
%             to make sure to satisfy the constraints of the sampling theorem
%             (See Wikipedia: "Nyquist?Shannon sampling theorem" for a starter, if
%             you don't know what this means):
            waitframes = 1;
%             Translate frames into seconds for screen update interval:
            waitduration = waitframes * app.ifi;
%             Recompute p, this time without the ceil() operation from above.
%             Otherwise we will get wrong drift speed due to rounding errors!
            p = 1/f;  % pixels/cycle
%             Translate requested speed of the grating (in cycles per second) into
%             a shift value in "pixels per frame", for given waitduration: This is
%             the amount of pixels to shift our srcRect "aperture" in horizontal
%             directionat each redraw:
            shiftperframe = dS * p * waitduration;
%             Prepare the grating textures in advance.
            TexStruct = CreateTextures(app, GratingStruct);
            
%             This vector contains two timestamps for each period, one for
%             the beginning of the grid and one for the end.
            timestamps = NaN(1,2*n);
%             Load the WaitSecs function.
            WaitSecs(0);
            
%             START OF THE ACTUAL STIMULATION
%             ENRICO. 26/08/2019 Added an initial delay
            try
                Screen('FillRect', app.w, BaselineColor, BaselineScreen);     % paints the rectangle (entire screen)
                Screen('Flip', app.w);  % bring the buffered screen to forefront
                timZero = WaitSecs(0);
                timOffset = timZero + Bt;
                
                i = 1;
                while i<=n
%                     First off, fill the screen with uniform gray background
%                     for the pre-stimulus baseline.
                    Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                    timStart = timOffset+(i-1)*totPeriod+PreG; % start of the grid
                    dtrendtime = timOffset+(i-1)*totPeriod+PreG+optDtrTime;
                    timEnd = timOffset+(i-1)*totPeriod+PreG+GridT; % end of the grid
                    
%                     This flip is at the beginning of the Grid
%                     presentation, right after the pre-grid period.
                    vbl = Screen('Flip', app.w, timStart);  % bring the buffered screen to forefront
                    timestamps(2*i-1) = vbl;
                    
                    ii=0;
        %             Animationloop:
                    while vbl < timEnd
                        
        %                 Shift the grating by "shiftperframe" pixels per frame:
        %                 the modulo operation makes sure that our "aperture" will snap
        %                 back to the beginning of the grating, once the border is reached.
        %                 Fractional values of 'xoffset' are fine here. The GPU will
        %                 perform proper interpolation of color values in the grating
        %                 texture image to draw a grating that corresponds as closely as
        %                 technical possible to that fractional 'xoffset'. GPU's use
        %                 bilinear interpolation whose accuracy depends on the GPU at hand.
        %                 Consumer ATI hardware usually resolves 1/64 of a pixel, whereas
        %                 consumer NVidia hardware usually resolves 1/256 of a pixel. You
        %                 can run the script "DriftTexturePrecisionTest" to test your
        %                 hardware...
                        xoffset = mod(ii*shiftperframe, p);
                        
        %                 Define shifted srcRect that cuts out the properly shifted rectangular
        %                 area from the texture: We cut out the range 0 to visiblesize in
        %                 the vertical direction although the texture is only 1 pixel in
        %                 height! This works because the hardware will automatically
        %                 replicate pixels in one dimension if we exceed the real borders
        %                 of the stored texture. This allows us to save storage space here,
        %                 as our 2-D grating is essentially only defined in 1-D:
                        srcRect = [xoffset, 0, ceil(sqrt(sR(3)^2+sR(4)^2))+xoffset, ceil(sqrt(sR(3)^2+sR(4)^2))];
                        dstRect = [0, 0, ceil(sqrt(sR(3)^2+sR(4)^2)), ceil(sqrt(sR(3)^2+sR(4)^2))];
                        dstRect = CenterRect(dstRect, app.screenRect);
                        
                        if vbl<dtrendtime
        %                     This is the beginning of the stimulus, when the
        %                     optical DTR has to be white.
                            
        %                     Draw grating texture, rotated by "angle".
        %                     Then it draws the gaussian mask.
        %                     Finally, it draws the optical DTR for Arduino.
                            Screen('DrawTextures', app.w,...
                                [TexStruct.Grating, TexStruct.GaussMask, TexStruct.DtrOnMask],...
                                [srcRect', sR', sR'],...
                                [dstRect', sR', sR'], [Angle, 0, 0]);
                        else
        %                     This is the grating when the optical DTR has to be
        %                     black.
                            
                            Screen('DrawTextures', app.w,...
                                [TexStruct.Grating, TexStruct.GaussMask, TexStruct.DtrOffMask],...
                                [srcRect', sR', sR'],...
                                [dstRect', sR', sR'], [Angle, 0, 0]);
                        end
                        
        %                 Flip 'waitframes' monitor refresh intervals after last redraw.
        %                 Providing this 'when' timestamp allows for optimal timing
        %                 precision in stimulus onset, a stable animation framerate and at
        %                 the same time allows the built-in "skipped frames" detector to
        %                 work optimally and report skipped frames due to hardware
        %                 overload:
                        vbl = Screen('Flip', app.w, vbl + (waitframes - 0.5) * app.ifi);
                        
        %                 ENRICO. Removed.
        %                 % Abort function if any key is pressed:
        %                 if KbCheck
        %                     break
        %                 end
                        ii=ii+1;
                    end
                    timestamps(2*i) = vbl; % The last vbl acquired.
                    Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                    Screen('Flip', app.w);
                    i=i+1;
                end
%                 Is closing the textures really necessary?
                Screen('Close',TexStruct.Grating)
                Screen('Close',TexStruct.GaussMask)
                Screen('Close',TexStruct.DtrOnMask)
                Screen('Close',TexStruct.DtrOffMask)
%--------------------------------------------------------------------------
            catch ME
                warning(ME.message)
            end %try..catch..
            
            if app.OneScreenMode_ck.Value
                CloseScreen(app)
            end
            
%             Log writing.
            timestamps = timestamps - timZero;
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
            msg=strjoin([{app.SlaveMac.Value},{'stimok'},{strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
            fprintf(app.UdpObj,msg);
        end
        
        function OnePxAngle = PixelAngleCalc(app)
            % Compute angular size of single pixel
            Wpx = app.screenRect(3); % Number of pixels in the screen width
            ScWidth = app.ScreenWidth.Value; % Screen width in centimeters
            ScDist = app.ScreenDistance.Value; % Distance of the screen in centimeters
%             The angle of a single pixel is returned.
            OnePxAngle = atand((ScWidth/Wpx)/ScDist);    % atand returns the angle in degrees
        end
        
        function GratingStruct = CreateGratings(app, Glumi, inc, f, gridType, MaskFlag, GaussSize)
%             this function creates the grid texture (either square of sinusoidal grid)
%             of frequency f and the Gaus mask of size GaussSize
%             The original outputs were [gratingsize, visiblesize, gratingtex,
%             masktex].
%             Right now:
%             Glumi is the average luminance of the stimulus: (Slumi+Blumi)/2
%             Contrast 'inc'rement range for given white and gray values: (Slumi-Blumi)/2
%             f is the spatial frequency (in terms of cycles/pixel)
%             gridType is 1 is 1 if the grid is sinusoidal. 0 for square-wave grid.
%             MaskFlag 1 means that gaussian mask is applied to the stimulus.
%             GaussSize is the size of gaussian mask in pixels.
            
            sR = app.screenRect;
            
            % Calculate parameters of the grating:
            
            % First we compute pixels per cycle, rounded up to full pixels, as we
            % need this to create a grating of proper size below:
            p = ceil(1/f);
            
            % Also need frequency in radians:
            fr = f*2*pi;
            
            %             ENRICO 20190504 I removed this
            %             % This is the visible size of the grating. It is twice the half-width
            %             % of the texture plus one pixel to make sure it has an odd number of
            %             % pixels and is therefore symmetric around the center of the texture:
            %             visiblesize = 2*texsize+1;
            
            % Create one single static grating image:
            %
            % We only need a texture with a single row of pixels(i.e. 1 pixel in height) to
            % define the whole grating! If the 'srcRect' in the 'Drawtexture' call
            % below is "higher" than that (i.e. visibleSize >> 1), the GPU will
            % automatically replicate pixel rows. This 1 pixel height saves memory
            % and memory bandwith, ie. it is potentially faster on some GPUs.
            %
            % However it does need 2 * texsize + p columns, i.e. the visible size
            % of the grating extended by the length of 1 period (repetition) of the
            % sine-wave in pixels 'p':
            
            %             ENRICO 20190504 Why meshgrid here? A vector is enough
            %             x = meshgrid(-texsize:texsize + p, 1);
            %             The dimension is the length of the diagonal of the screen
            %             (calculated by Pythagorean theorem. Plus one period, to cover
            %             all the screen during the drifting.
            x = 0:ceil(sqrt(sR(3)^2+sR(4)^2))+p;
            
            %             Compute actual cosine grating:
            if gridType == 1
                grating = Glumi + inc*cos(fr*x);
            else
                %                 Square-wave stimulus
                GridUp = Glumi + inc;
                GridDown = Glumi - inc;
                grating = zeros(size(x));
                for ii = 1:length(x)
                    if cos(fr*x(ii))>0
                        grating(ii) = GridUp;
                    else
                        grating(ii) = GridDown;
                    end
                end
            end
            
            if app.CalibratedButton.Value
                grating = arrayfun(@(x) Lumi2Escher(app,x), grating, 'un',1);
                
                %                 You cannot convert the Glumi value before this point
                %                 because you need the uncalibrated value for the
                %                 generation of the stimulus.
                Glumi = Lumi2Escher(app, Glumi);
            end
            Glumi = app.white * Glumi;
            %             Convert grating to values from 0 to app.white (usually 255)
            grating = grating*app.white;
            
%             Here it could be possible not to execute this block and let the graphic card repeat the grating.
%             Instead, we create a full 2-D grating.
                %                 Horizontally stacks the row vector "grating" a number of
                %                 times equal to its length. "grating" becomes a square
                %                 matrix.
            grating = repmat(grating, length(grating), 1);
            
            % Create a single gaussian transparency mask and store it to a texture:
            % The mask must have the same size as the visible size of the grating
            % to fully cover it. Here we must define it in 2 dimensions and can't
            % get easily away with one single row of pixels.
            %
            % We create a  two-layer texture: One unused luminance channel which we
            % just fill with the same color as the background color of the screen
            % 'gray'. The transparency (aka alpha) channel is filled with a
            % gaussian (exp()) aperture mask.
            
            % from here to ...
            
            %             ENRICO 20190504 Let's try to change the gauss mask size.
            %             gaussmask = ones(2*texsize+1, 2*texsize+1, 2) * Glumi;
            %             [x,y] = meshgrid(-1*texsize:1*texsize,-1*texsize:1*texsize);
            gaussmask = Glumi * ones(sR(4), sR(3), 2);
            [x,y] = meshgrid(-(sR(3)-1)/2 : (sR(3)-1)/2,...
                -(sR(4)-1)/2 : (sR(4)-1)/2);
            %             Now the gaussian mask has the same dimension as the screen.
            
            if MaskFlag
                %                 ENRICO 20190504 Here, a 2-D Gaussian function is used, to
                %                 fill the alpha channel of gaussmask. The function is 0 at
                %                 the center of the screen (transparent) and goes up to 255
                %                 as it goes further away from the center (opaque).
                %                 The sigma (standard deviation) of the Gaussian function
                %                 is sqrt(2)*GaussSize. This means that at the distance
                %                 GaussSize, the function is 63% of the maximum (160/255).
                gaussmask(:, :, 2) = 255 * (1 - exp(-((x/GaussSize).^2)-((y/GaussSize).^2)));
                
            else
                gaussmask(:, :, 2) = 0;
            end
            %             Now:
            %             gaussmask(:,:,1) is gray everywhere.
            %             gaussmask(:,:,2) has value of 255 where the mask is opaque. While
            %             it's 0 where the mask is transparent.
            
            
            %             OPTICAL DTR PREPARATION
            %             The mask for the optical DTR is prepared anyway, but it stays
            %             transparent (alpha=0) if the optical DTR is not checked on
            %             the GUI.
            mask_on = zeros(sR(4), sR(3), 2);
            mask_on(:, :, 1) = app.white; % white everywhere
            mask_off = zeros(sR(4), sR(3), 2); %black everywhere
            %             Only if the optical DTR is checked, the alpha channel is made
            %             opaque only where the optical DTR should appear.
            if app.ard_ck.Value
                dtrRect = DtrRectCalc(app);
                mask_on(dtrRect(2)+1:dtrRect(4), dtrRect(1)+1:dtrRect(3), 2) = 255; %255 is opaque
                mask_off(dtrRect(2)+1:dtrRect(4), dtrRect(1)+1:dtrRect(3), 2) = 255; %255 is opaque
            end
            
            %             Cast grating, gaussmask and mask_on mask_off to the correct
            %             screen bit depth (usually 8 bit).
            grating = cast(grating, app.ScreenBitDepth);
            gaussmask = cast(gaussmask, app.ScreenBitDepth);
            mask_on = cast(mask_on, app.ScreenBitDepth);
            mask_off = cast(mask_off, app.ScreenBitDepth);
            
            %             Now all the gratings (including the masks) are passed to a
            %             structure called GratingStruct, which is the function output.
            GratingStruct.Grating = grating;
            GratingStruct.GaussMask = gaussmask;
            GratingStruct.DtrOnMask = mask_on;
            GratingStruct.DtrOffMask = mask_off;
        end
        
        function TexStruct = CreateTextures(app, GratingStruct)
            TexStruct.Grating = Screen('MakeTexture', app.w, GratingStruct.Grating);   % about 2.5 ms
            TexStruct.GaussMask = Screen('MakeTexture', app.w, GratingStruct.GaussMask);
            TexStruct.DtrOnMask = Screen('MakeTexture', app.w, GratingStruct.DtrOnMask);
            TexStruct.DtrOffMask = Screen('MakeTexture', app.w, GratingStruct.DtrOffMask);
        end
        
        function AnimateGrid(app, TexStruct, movieDurationSecs, f, dS, Angle)
%             ENRICO 20190502
%             Changed the input of the function. It used to be:
%             AnimateGrid(app, movieDurationSecs, visiblesize, f, dS, gratingtex, Angle, masktex, drawmask, revFlag)
%             But then I put all the gratings in GratingStruct
            
%             Definition of the drawn rectangle on the screen:
%             Compute it to  be the visible size of the grating, centered on the
%             screen:
            
%             ENRICO 20190504 Removed the destination rect of variable
%             size. Now the destination rect is the whole screen.
%             dstRect = [0 0 visiblesize visiblesize];
%             dstRect = CenterRect(dstRect, app.screenRect);
            sR = app.screenRect;
            
%             Translate that into the amount of seconds to wait between screen
%             redraws/updates:
            
%             waitframes = 1 means: Redraw every monitor refresh. If your GPU is
%             not fast enough to do this, you can increment this to only redraw
%             every n'th refresh. All animation paramters will adapt to still
%             provide the proper grating. However, if you have a fine grating
%             drifting at a high speed, the refresh rate must exceed that
%             "effective" grating speed to avoid aliasing artifacts in time, i.e.,
%             to make sure to satisfy the constraints of the sampling theorem
%             (See Wikipedia: "Nyquist?Shannon sampling theorem" for a starter, if
%             you don't know what this means):
            waitframes = 1;
            
%             Translate frames into seconds for screen update interval:
            waitduration = waitframes * app.ifi;
%             Recompute p, this time without the ceil() operation from above.
%             Otherwise we will get wrong drift speed due to rounding errors!
            p = 1/f;  % pixels/cycle
            
%             Translate requested speed of the grating (in cycles per second) into
%             a shift value in "pixels per frame", for given waitduration: This is
%             the amount of pixels to shift our srcRect "aperture" in horizontal
%             directionat each redraw:
            shiftperframe = dS * p * waitduration;
            
%             Perform initial Flip to sync us to the VBL and for getting an initial
%             VBL-Timestamp as timing baseline for our redraw loop:
            vbl = Screen('Flip', app.w);
            
%             We run at most 'movieDurationSecs' seconds if user doesn't abort via keypress.
            vblendtime = vbl + movieDurationSecs;
            dtrendtime = vbl + 0.001*app.optDtrTimeTxt.Value;
            ii=0;
            
%             Animationloop:
            while vbl < vblendtime
                
%                 Shift the grating by "shiftperframe" pixels per frame:
%                 the modulo operation makes sure that our "aperture" will snap
%                 back to the beginning of the grating, once the border is reached.
%                 Fractional values of 'xoffset' are fine here. The GPU will
%                 perform proper interpolation of color values in the grating
%                 texture image to draw a grating that corresponds as closely as
%                 technical possible to that fractional 'xoffset'. GPU's use
%                 bilinear interpolation whose accuracy depends on the GPU at hand.
%                 Consumer ATI hardware usually resolves 1/64 of a pixel, whereas
%                 consumer NVidia hardware usually resolves 1/256 of a pixel. You
%                 can run the script "DriftTexturePrecisionTest" to test your
%                 hardware...
                xoffset = mod(ii*shiftperframe, p);
                ii=ii+1;
                
%                 Define shifted srcRect that cuts out the properly shifted rectangular
%                 area from the texture: We cut out the range 0 to visiblesize in
%                 the vertical direction although the texture is only 1 pixel in
%                 height! This works because the hardware will automatically
%                 replicate pixels in one dimension if we exceed the real borders
%                 of the stored texture. This allows us to save storage space here,
%                 as our 2-D grating is essentially only defined in 1-D:
                srcRect = [xoffset 0 ceil(sqrt(sR(3)^2+sR(4)^2))+xoffset ceil(sqrt(sR(3)^2+sR(4)^2))];
                dstRect = [0 0 ceil(sqrt(sR(3)^2+sR(4)^2)) ceil(sqrt(sR(3)^2+sR(4)^2))];
                dstRect = CenterRect(dstRect, app.screenRect);
                
                if vbl<dtrendtime
%                     This is the beginning of the stimulus, when the
%                     optical DTR has to be white.
                    
%                     Here, I wanted to use "DrawTextures" to
%                     draw all three textures together, but I was not able
%                     to do so. So now it's commented.
%                     Screen('DrawTextures', app.w,...
%                         [TexStruct.Grating, TexStruct.GaussMask, TexStruct.DtrOnMask],...
%                         [srcRect; sR; sR],...
%                         [sR; sR; sR], [Angle, 0, 0]);
                    
%                     Draw grating texture, rotated by "angle".
%                     Then it draws the gaussian mask.
%                     Finally, it draws the optical DTR for Arduino.
                    Screen('DrawTexture', app.w, TexStruct.Grating, srcRect, dstRect, Angle)
                    Screen('DrawTexture', app.w, TexStruct.GaussMask, sR, sR, 0)
                    Screen('DrawTexture', app.w, TexStruct.DtrOnMask, sR, sR, 0)
                else
%                     This is the grating when the optical DTR has to be
%                     black.
                    
%                     Screen('DrawTextures', app.w,...
%                         [TexStruct.Grating, TexStruct.GaussMask, TexStruct.DtrOffMask],...
%                         [srcRect; sR; sR],...
%                         [sR; sR; sR], [Angle, 0, 0]);
                    Screen('DrawTexture', app.w, TexStruct.Grating, srcRect, dstRect, Angle)
                    Screen('DrawTexture', app.w, TexStruct.GaussMask, sR, sR, 0)
                    Screen('DrawTexture', app.w, TexStruct.DtrOffMask, sR, sR, 0)
                end
                
%                 Flip 'waitframes' monitor refresh intervals after last redraw.
%                 Providing this 'when' timestamp allows for optimal timing
%                 precision in stimulus onset, a stable animation framerate and at
%                 the same time allows the built-in "skipped frames" detector to
%                 work optimally and report skipped frames due to hardware
%                 overload:
                vbl = Screen('Flip', app.w, vbl + (waitframes - 0.5) * app.ifi);
                
%                 % Abort function if any key is pressed:
%                 if KbCheck
%                     break
%                 end
            end
        end
        
        function CloseScreen(app)
            % function CloseScreen
            % ___________________________________________________________________
            %
            % Closes the OpenGL session and the buffered screen. Restores the prioriity level
            
            % HISTORY
            %  08/05/2011 gmr
            
            try
               	% This script calls Psychtoolbox commands available only in OpenGL-based
               	% versions of the Psychtoolbox. (So far, the OS X Psychtoolbox is the
               	% only OpenGL-base Psychtoolbox.)  The Psychtoolbox command AssertPsychOpenGL will issue
               	% an error message if someone tries to execute this script on a computer without
               	% an OpenGL Psychtoolbox
               	% AssertOpenGL;
                
                
                % following stuff to close the OpenGL session. To be transferred to
                % CloseScreen function
                % Restore normal priority scheduling in case something else was set
                % before:
                Priority(0);
                
               	%The same commands wich close onscreen and offscreen windows also close
               	%textures.
               	Screen('CloseAll');
                
            catch
                %this "catch" section executes in case of an error in the "try" section
                %above.  Importantly, it closes the onscreen window if its open.
                Screen('CloseAll');
                Priority(0);
                psychrethrow(psychlasterror);
            end %try..catch..
            
            % We're done!
            return
        end
        
        function ScalarLogicOut = ValidateIp(app,StrIn)
%         Check that the IP address is valid and the StrIn contain only a
%         IP address and nothing more.
            ScalarLogicOut = regexp(StrIn,'^(?:(?:25[0-5]|2[0-4][0-9]|[01]?[0-9][0-9]?)\.){3}(?:25[0-5]|2[0-4][0-9]|[01]?[0-9][0-9]?)$');
        end
        
        function ScalarLogicOut = ValidateMac(app,StrIn)
%         Check that the MAC address is valid and the StrIn contain only a
%         MAC address and nothing more.
            ScalarLogicOut = regexp(StrIn,'^[0-9a-fA-F]{12}$');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             Flag initialization.
            app.ListeningFlag = 0;
            
%             UDP object initialization.
            CloseAllUdp(app)
            app.UdpObj = udp('127.0.0.1','LocalPort',app.SlavePort.Value);
            
%             Try to get the IP automatically
            GetSlaveIPandMACBtnPushed(app,[])
%             Then starts listening for a UDP message.
            ListenBtnButtonPushed(app, [])
        end

        % Button pushed function: ListenBtn
        function ListenBtnButtonPushed(app, event)
            if ~app.ListeningFlag
%                 If listening was not active, the button starts it.
                if strcmp(app.SlaveIp.Value,'0.0.0.0')||isempty(app.SlaveMac.Value)
                    uialert(app.EscherSlaveFig,'Invalid addresses!','Error')
                    return
                end
%                 Create a read-only UDP object first. When the start
%                 message will be read, then the UDP object will be
%                 modified to be able to write back.
                try
                    app.UdpObj.RemoteHost = '127.0.0.1';
                    app.UdpObj.LocalPort = app.SlavePort.Value;
                    fopen(app.UdpObj);
                catch
%                     If fails opening a UDP connection, close all UDP
%                     connections.
                    CloseAllUdp(app)
                    app.UdpObj = udp('127.0.0.1','LocalPort',app.SlavePort.Value);
                    fopen(app.UdpObj);
                end
%                 Sets the callback function for when data is received.
                app.UdpObj.DatagramReceivedFcn = @(obj,~) UdpDataReceivedCallback(app,obj);
%                 Starts reading.
                readasync(app.UdpObj)
                app.UdpObj.ReadAsyncMode = 'continuous';

                app.ListenBtn.Text = 'Stop Listening';
                app.ListeningFlag = 1;
%                 Disable the three boxes for slave connection parameters.
                set([app.SlaveIp,app.SlaveMac,app.SlavePort],'Editable',0)
                app.GetSlaveIPandMACBtn.Enable = 0;
            else
%                 If the slave was already listening, pressing the button
%                 again stops it.
                stopasync(app.UdpObj)
                fclose(app.UdpObj);
                app.ListenBtn.Text = 'Start Listening';
%                 Re-enable the three boxes for slave connection parameters.
                set([app.SlaveIp,app.SlaveMac,app.SlavePort],'Editable',1)
                app.GetSlaveIPandMACBtn.Enable = 1;
                app.ListeningFlag = 0;
            end
        end

        % Button pushed function: GetSlaveIPandMACBtn
        function GetSlaveIPandMACBtnPushed(app, event)
            if ispc
                %                 This is the solution for a Windows-running computer
                [dosStatus,dosReply] = dos('ipconfig /all');
                if dosStatus == 0
                    %                     If DosStatus is 0, the command was executed
                    %                     correctly.
                    
                    %                     First, let's divide the message returned by the DOS
                    %                     command into blocks, that are separated by two
                    %                     consecutive line feeds (\n\n) and NOT followed by a
                    %                     whitespace (\s). I used regex inside strsplit:
                    blocks=strsplit(dosReply,'\n\n(?!\s)','DelimiterType','RegularExpression');
                    %                     Now, usually the first block is for general
                    %                     information, but I prefer not to skip it.
                    %                     We run a search in all blocks for an IP:
                    IpMatch=regexp(blocks,'(?<=IP.*)(?:(?:25[0-5]|2[0-4][0-9]|[01]?[0-9][0-9]?)\.){3}(?:25[0-5]|2[0-4][0-9]|[01]?[0-9][0-9]?)','match','dotexceptnewline');
                    %                     Now we look for the MAC address (physical address).
                    %                     The default format should be XX-XX-XX-XX-XX-XX. It's
                    %                     composed by 12 total hexadecimal digits (= 6 bytes =
                    %                     48 bits).
                    MacMatch=regexp(blocks,'(?<![:-])[A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}(?![:-])','match');
                    %                 Find which cells are non-empty in the IpMatch.
               	    LogicIpArray = ~cellfun('isempty',IpMatch);
                    if sum(LogicIpArray)==1
                        %                     Finally, we get the IP!
                        myIp = IpMatch{LogicIpArray}{:};
                        myMac = MacMatch{LogicIpArray}{:};
                        %                         Let's remove the ":" or "-" separator from the
                        %                         MAC address, to make it more general.
                        myMac = regexprep(myMac,'[:-]','');
                    elseif sum(LogicIpArray)>1
                        IpCellArray = [IpMatch{LogicIpArray}];
                        MacCellArray = [MacMatch{LogicIpArray}];
                        MacCellArray = regexprep(MacCellArray,'[:-]','');
                        [indx,tf] = listdlg('PromptString','More than one IP was found. Please choose one:',...
                            'SelectionMode','single','ListString',IpCellArray);
                        if tf == 0
                            return
                        end
                        %                     Finally, we get the IP!
                        myIp = IpCellArray{indx};
                        myMac = MacCellArray{indx};
                    else
                        uialert(app.EscherSlaveFig,'IP search did not get any result.',...
                            'Error')
                    end
                else
                    uialert(app.EscherSlaveFig,'DOS command was not correctly executed.','Error')
                end
                app.SlaveIp.Value = myIp;
                app.SlaveMac.Value = myMac;
            else
                uialert(app.EscherSlaveFig,'Currently only for Windows','Warning')
            end
        end

        % Close request function: EscherSlaveFig
        function EscherSlaveFigCloseRequest(app, event)
            try
                CloseAllUdp(app)
            catch ME
%                 warning('off','backtrace') % maybe not necessary
                warning(ME.message)
            end
            delete(app)
        end

        % Value changed function: SlaveIp
        function SlaveIpValueChanged(app, event)
            LogicValue = ValidateIp(app,app.SlaveIp.Value);
            if ~LogicValue
                uialert(app.EscherSlaveFig,'Invalid IP address!','Error')
                app.SlaveIp.Value = event.OldValue;
            end
        end

        % Value changed function: SlaveMac
        function SlaveMacValueChanged(app, event)
            LogicValue = ValidateMac(app,app.SlaveMac.Value);
            if ~LogicValue
                uialert(app.EscherSlaveFig,'Invalid IP address!','Error')
                app.SlaveMac.Value = event.OldValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EscherSlaveFig = uifigure('Visible', 'off');
            app.EscherSlaveFig.Name = 'EscherSlave';
            app.EscherSlaveFig.CloseRequestFcn = createCallbackFcn(app, @EscherSlaveFigCloseRequest, true);
            app.UDPConnectionPanel = uipanel(app.EscherSlaveFig);
            app.UDPConnectionPanel.Title = 'UDP Connection';
            app.ListenBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.ListenBtn.ButtonPushedFcn = createCallbackFcn(app, @ListenBtnButtonPushed, true);
            app.ListenBtn.Tooltip = {'Start listening for any incoming UDP message on the selected port.'};
            app.ListenBtn.Text = 'Start Listening';
            app.SlaveIPLabel = uilabel(app.UDPConnectionPanel);
            app.SlaveIPLabel.Text = 'Slave IP:';
            app.SlaveIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveIp.ValueChangedFcn = createCallbackFcn(app, @SlaveIpValueChanged, true);
            app.SlaveIp.Tooltip = {'IP address of the slave (this app).'};
            app.SlaveIp.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port:';
            app.SlavePort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.SlavePort.Limits = [1023 65535];
            app.SlavePort.RoundFractionalValues = 'on';
            app.SlavePort.ValueDisplayFormat = '%8.5g';
            app.SlavePort.Tooltip = {'Port for the UDP connection on the slave side. Default: 8866.'};
            app.SlavePort.Value = 8866;
            app.MasterIPLabel = uilabel(app.UDPConnectionPanel);
            app.MasterIPLabel.Text = 'Master IP:';
            app.MasterIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterIp.Editable = 'off';
            app.MasterIp.Tooltip = {'IP address of the master app. It is going to be given by the master when it starts the connection.'};
            app.MasterIp.Value = '0.0.0.0';
            app.PortEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel_2.Text = 'Port:';
            app.MasterPort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.MasterPort.Limits = [1023 65535];
            app.MasterPort.RoundFractionalValues = 'on';
            app.MasterPort.ValueDisplayFormat = '%8.5g';
            app.MasterPort.Editable = 'off';
            app.MasterPort.Tooltip = {'Port for the UDP connection on the master side. Default: 8844.'};
            app.MasterPort.Value = 8844;
            app.GetSlaveIPandMACBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.GetSlaveIPandMACBtn.ButtonPushedFcn = createCallbackFcn(app, @GetSlaveIPandMACBtnPushed, true);
            app.GetSlaveIPandMACBtn.Tooltip = {'Get the IP of this computer (slave).'};
            app.GetSlaveIPandMACBtn.Text = 'Get Slave IP and MAC';
            app.StatusEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.ConnectionStatus = uieditfield(app.UDPConnectionPanel, 'text');
            app.ConnectionStatus.Editable = 'off';
            app.ConnectionStatus.Value = 'Disconnected.';
            app.MACLabel = uilabel(app.UDPConnectionPanel);
            app.MACLabel.Text = 'MAC:';
            app.MasterMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterMac.Editable = 'off';
            app.MasterMac.Tooltip = {'Physical address of the master. It is going to be given by the master when it starts the connection.'};
            app.MasterMac.Value = '000000000000';
            app.MACLabel_2 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_2.Text = 'MAC:';
            app.SlaveMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveMac.ValueChangedFcn = createCallbackFcn(app, @SlaveMacValueChanged, true);
            app.SlaveMac.Tooltip = {'Physical address of the slave (this app).'};
            app.SlaveMac.Value = '000000000000';
            app.ScreenFeaturesPanel = uipanel(app.EscherSlaveFig);
            app.ScreenFeaturesPanel.Title = 'Screen Features';
            app.ScreendistancecmLabel = uilabel(app.ScreenFeaturesPanel);
            app.ScreendistancecmLabel.Tooltip = {'Enter the three parameters definig the screen angular size. '};
            app.ScreendistancecmLabel.Text = 'Screen distance (cm):';
            app.ScreenDistance = uieditfield(app.ScreenFeaturesPanel, 'numeric');
            app.ScreenDistance.Limits = [0 Inf];
            app.ScreenDistance.ValueDisplayFormat = '%4.3g';
            app.ScreenDistance.Editable = 'off';
            app.WidthcmLabel = uilabel(app.ScreenFeaturesPanel);
            app.WidthcmLabel.Text = 'Width (cm)';
            app.ScreenWidth = uieditfield(app.ScreenFeaturesPanel, 'numeric');
            app.ScreenWidth.Editable = 'off';
            app.HeightcmEditFieldLabel = uilabel(app.ScreenFeaturesPanel);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.ScreenHeight = uieditfield(app.ScreenFeaturesPanel, 'numeric');
            app.ScreenHeight.Editable = 'off';
            app.ScreenFormulaLabel = uilabel(app.ScreenFeaturesPanel);
            app.ScreenFormulaLabel.Text = {'Screen '; 'Formula:'};
            app.ScreenFuncTxt = uieditfield(app.ScreenFeaturesPanel, 'text');
            app.ScreenFuncTxt.Editable = 'off';
            app.LuminanceLabel = uilabel(app.ScreenFeaturesPanel);
            app.LuminanceLabel.Text = 'Luminance=';
            app.SynchronizationPanel = uipanel(app.ScreenFeaturesPanel);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.ard_ck = uicheckbox(app.SynchronizationPanel);
            app.ard_ck.Enable = 'off';
            app.ard_ck.Tooltip = {'Display the Hermes synchronisation cue'};
            app.ard_ck.Text = 'Hermes Synch';
            app.HeightpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.hhTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.hhTxt.Editable = 'off';
            app.CueWidthpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.CueWidthpxEditFieldLabel.Text = 'Cue Width (px)';
            app.wwTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.wwTxt.Editable = 'off';
            app.CuepositionPanel = uipanel(app.SynchronizationPanel);
            app.CuepositionPanel.Title = 'Cue position';
            app.ButtonGroup = uibuttongroup(app.CuepositionPanel);
            app.CueTopRB = uiradiobutton(app.ButtonGroup);
            app.CueTopRB.Enable = 'off';
            app.CueTopRB.Text = 'Top';
            app.CueBotRB = uiradiobutton(app.ButtonGroup);
            app.CueBotRB.Enable = 'off';
            app.CueBotRB.Text = 'Bottom';
            app.CueBotRB.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.CuepositionPanel);
            app.CueLeftRB = uiradiobutton(app.ButtonGroup_2);
            app.CueLeftRB.Enable = 'off';
            app.CueLeftRB.Text = 'Left';
            app.CueRightRB = uiradiobutton(app.ButtonGroup_2);
            app.CueRightRB.Enable = 'off';
            app.CueRightRB.Text = 'Right';
            app.CueRightRB.Value = true;
            app.DurationofcuemsEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.DurationofcuemsEditFieldLabel.Text = 'Duration of cue (ms):';
            app.optDtrTimeTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.optDtrTimeTxt.Limits = [0 Inf];
            app.optDtrTimeTxt.Editable = 'off';
            app.CalModeRBGroup = uibuttongroup(app.ScreenFeaturesPanel);
            app.CalModeRBGroup.Title = 'Mode:';
            app.UncalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.UncalibratedButton.Enable = 'off';
            app.UncalibratedButton.Text = 'Uncalibrated';
            app.CalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.CalibratedButton.Enable = 'off';
            app.CalibratedButton.Text = 'Calibrated';
            app.CalibratedButton.Value = true;
            app.oculusCk = uicheckbox(app.ScreenFeaturesPanel);
            app.oculusCk.Enable = 'off';
            app.oculusCk.Text = 'VR headset';
            app.OneScreenMode_ck = uicheckbox(app.ScreenFeaturesPanel);
            app.OneScreenMode_ck.Enable = 'off';
            app.OneScreenMode_ck.Text = 'One Screen Mode';
            % Show the figure after all components are created
            app.EscherSlaveFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EscherAppSlave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EscherSlaveFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EscherSlaveFig)
        end
    end
end