classdef QuickWideFieldAnalyzer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFig                    matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        VisualizeTab               matlab.ui.container.Tab
        FrameSlider                matlab.ui.control.Slider
        FrameSpinnerLabel          matlab.ui.control.Label
        FrameSpinner               matlab.ui.control.Spinner
        PlayButton                 matlab.ui.control.Button
        FrameEditFieldLabel        matlab.ui.control.Label
        shownFrameEditField        matlab.ui.control.NumericEditField
        oftrialEditFieldLabel      matlab.ui.control.Label
        shownTrialEditField        matlab.ui.control.NumericEditField
        movieMeanSwitch            matlab.ui.control.Switch
        movieAxes                  matlab.ui.control.UIAxes
        projAxes                   matlab.ui.control.UIAxes
        BusyLampLabel              matlab.ui.control.Label
        BusyLamp                   matlab.ui.control.Lamp
        LUTmanagerButton           matlab.ui.control.StateButton
        TabGroup2                  matlab.ui.container.TabGroup
        LoadTab                    matlab.ui.container.Tab
        ButtonGroup                matlab.ui.container.ButtonGroup
        IntensityButton            matlab.ui.control.RadioButton
        ComputedeltaFFButton       matlab.ui.control.RadioButton
        LoadfileButton             matlab.ui.control.Button
        getFromEscherButton        matlab.ui.control.Button
        ClearselectedButton        matlab.ui.control.Button
        replace1stFrameCheckBox    matlab.ui.control.CheckBox
        DarkButtonGroup            matlab.ui.container.ButtonGroup
        NodarkButton               matlab.ui.control.RadioButton
        StaticalButton             matlab.ui.control.RadioButton
        DynamicalButton            matlab.ui.control.RadioButton
        LoaddarkButton             matlab.ui.control.Button
        BinningTab                 matlab.ui.container.Tab
        ButtonGroup_2              matlab.ui.container.ButtonGroup
        NobinningButton            matlab.ui.control.RadioButton
        BinningButton              matlab.ui.control.RadioButton
        pixelsEditFieldLabel       matlab.ui.control.Label
        pixelsBinEditField         matlab.ui.control.NumericEditField
        OpeningfilterTab           matlab.ui.container.Tab
        xysizeEditFieldLabel       matlab.ui.control.Label
        MedianFiltXYSizeEditField  matlab.ui.control.NumericEditField
        tsizeEditFieldLabel        matlab.ui.control.Label
        MedianFiltTSizeEditField   matlab.ui.control.NumericEditField
        MedianfilteringLabel       matlab.ui.control.Label
        pixelsLabel                matlab.ui.control.Label
        framesLabel                matlab.ui.control.Label
        deltaFFparamsTab           matlab.ui.container.Tab
        Tau0sEditFieldLabel        matlab.ui.control.Label
        tau0EditField              matlab.ui.control.NumericEditField
        Tau1sEditFieldLabel        matlab.ui.control.Label
        tau1EditField              matlab.ui.control.NumericEditField
        Tau2sEditFieldLabel        matlab.ui.control.Label
        tau2EditField              matlab.ui.control.NumericEditField
        roimanagerButton           matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        exper % structure array that stores the loaded experiments
        experMaps % structure array that stores the retinotopic maps and the amplitude maps of every exper
                % its fields are:
                % retinotopyFreq: structure array of length exper. Its only field, Map, is a
                        % matrix of dimensions x*y. It stores the receptive field of each pixel as a phase
                % retinGradientFreq: structure array of length exper. Its only field, Map, is a
                        % matrix of dimensions x*y*direction. It stores the gradient of retinotopyFreq.
                        % Direction has two values representing d/dy and d/dx respectively.
                % amplitudeFreqs
                % retinotopyTime: the same as retinotopyFreq, but calculated in the time domain
                % retinGradientTime: the same as retinGradientFreq, but calculated in the time domain
                % amplitudeTime
        models % stores models to create or preallocate exper structure or exper table
        experTableIndices % stores the indices of the selected cells of experTable
        cBar % stores colorbar objects
        lutManagerHandle % stores the handle of the child app LUTmanager
        experMean % structure array that stores the mean of the loaded experiments over trials
        roiManagerHandle % stores the handle of the child app ROImanager
        parentApp % Description
        MovieAxesImg % Description
        ProjAxesImg % Description
        path % Description
        dark % dark file structure
    end
    
    methods (Access = public)
        
        function importFunction(app, mode, params)

            
            app.exper=struct('Images',[],'Info',[]); % 3d matrix with movie
            n = numel( params.namesCell );
            for i=1:n
                fprintf('Loading experiment %i of %i...',i,n)
                try
                    tic
                    switch mode
                        case 'fromFile'
                            load([params.path, params.namesCell{i}]); %the loaded structure is called 'OutPcoStruct'
                            
                            
                        case 'fromEscher'
                            OutPcoStruct = app.parentApp.OutPcoStruct;
                    end
                    
                    % check for missing frames:
                    OutPcoStruct.Images = double(fillMissingFrames(OutPcoStruct.Images, OutPcoStruct.ImgCounter));
                    [ny,nx,nframes]=size(OutPcoStruct.Images);
                    
                    % If binning is selected, bin the image
                    if app.BinningButton.Value
                        tic
                        disp('Start binning...')
                        binF = app.pixelsBinEditField.Value;
                        binnedImg = nan(ny/binF,nx/binF,nframes);
                        for it = 1:nframes
                            binnedImg(:,:,it) = binImage(OutPcoStruct.Images(:,:,it),binF);
                        end
                        OutPcoStruct.Images = binnedImg;
                        OutPcoStruct.Info.PxlSize = OutPcoStruct.Info.PxlSize * app.pixelsBinEditField.Value;
                        disp(['Binning elapsed in ' num2str(toc) 's'])
                        % update movie size
                        [ny,nx,nframes]=size(OutPcoStruct.Images);
                    end
                    
                    % substitute the first image of each trial with the
                    % second one because of an artifact. we must understand
                    % what's going on!
                    if app.replace1stFrameCheckBox.Value
                        fpt=OutPcoStruct.Info.FramesPerTrial;
                        OutPcoStruct.Images(:,:,1:fpt:end-fpt+1) = OutPcoStruct.Images(:,:,2:fpt:end-fpt+2);
                    end
                    
                    % correct Info structure if needed
                    if ~isfield(OutPcoStruct.Info,'Baseline_bool')
                        OutPcoStruct.Info.Baseline_bool = false;
                    end
                    if OutPcoStruct.Info.Baseline_bool
                        OutPcoStruct.Info.FramesPerTrial=2*OutPcoStruct.Info.FramesPerTrial;
                        OutPcoStruct.Info.nTrials = OutPcoStruct.Info.nTrials/2;
                    end
                    
                    % if multiple movies, check for compatibility
                    if i>1
                    % same number of frames per trial
                        assert(OutPcoStruct.Info.FramesPerTrial==app.exper.Info.FramesPerTrial,...
                            ['File #%i not loaded: inconsistent number of frames per trial!\n...' ...
                            '\tThis file has %i frames per trial \n\twhile previous...' ...
                            'files has %i\n'],i,OutPcoStruct.Info.FramesPerTrial,app.exper.Info.FramesPerTrial)
                    
                    % same fps
                    
                    end
                    
                    % store images
                    app.exper.Images = cat(3, app.exper.Images, OutPcoStruct.Images);
                    
                    % also store info
                    if i==1
                        app.exper.Info=OutPcoStruct.Info;
                    else
                        % sum up trials
                        app.exper.Info.nTrials = app.exper.Info.nTrials+OutPcoStruct.Info.nTrials;
                    end
                    
                catch err
                    % No info about orientation and direction or wrong format
                    disp( getReport( err, 'extended', 'hyperlinks', 'on' ) )
                    disp('')
                    warning('Loading of exper has failed.')
                    % delete this exper from the app.exper structure and from
                    % the table
                    app.exper = [];
                end
            end
            
            fprintf('Files loaded in %.2f s. Processing files...\n',toc)
            
            
            % If any median filter in the opening filter tab is set
            % different from 0, here the file is
            % filtered accordingly.
            if app.MedianFiltXYSizeEditField.Value ~= 0 && app.MedianFiltTSizeEditField.Value ~= 0
                % All X, Y and T (time) dimensions are filtered.
                tic
                disp('Start median filtering over x, y and t dimensions...');
                % The original image series is therefore
                % substituted with the filtered series.
                app.exper.Images = medfilt3(app.exper.Images, [app.MedianFiltXYSizeEditField.Value,...
                    app.MedianFiltXYSizeEditField.Value, app.MedianFiltTSizeEditField.Value]);
                elapsT = toc;
                disp(['3-D median filtering elapsed in ' num2str(elapsT) ' s'])
            elseif app.MedianFiltXYSizeEditField.Value ~=0 && app.MedianFiltTSizeEditField.Value == 0
                % Only X, Y dimensions are filtered.
                tic
                disp('Start median filtering over x and y dimensions...');
                % The original image series is therefore
                % substituted with the filtered series.
                app.exper.Images = medfilt3(app.exper.Images, [app.MedianFiltXYSizeEditField.Value,...
                    app.MedianFiltXYSizeEditField.Value, 1]);
                elapsT = toc;
                disp(['2-D median filtering elapsed in ' num2str(elapsT) ' s'])
            elseif app.MedianFiltXYSizeEditField.Value ==0 && app.MedianFiltTSizeEditField.Value ~= 0
                % Only T dimension is filtered.
                tic
                disp('Start median filtering over t dimension...');
                % The original image series is therefore
                % substituted with the filtered series.
                app.exper.Images = medfilt3(app.exper.Images, [1,...
                    1, app.MedianFiltTSizeEditField.Value]);
                elapsT = toc;
                disp(['1-D median filtering elapsed in ' num2str(elapsT) ' s'])
            end
            
            % dark subtraction
            if app.StaticalButton.Value
                app.exper.Images = app.exper.Images - app.dark.Images;
            elseif app.DynamicalButton.Value
                % dynamic dark
                % check that n of frames per trial match
                assert(app.exper.Info.FramesPerTrial==app.dark.Info.FramesPerTrial,...
                    'Dark and movie have different number of frames petrials.')
                app.exper.Images = app.exper.Images - repmat(app.dark.Images,1,1,app.exper.Info.nTrials);
            end
            
            
            % apply dF/F
            if app.IntensityButton.Value
                app.exper.Images = double(app.exper.Images);
            else
                % Apply the function 'adaptiveDF3' (adapted from 'adaptiveDF4' by
                % Enrico) to obtain a smoothened deltaF/F.
                tic
                disp('Start computing deltaF/F...')
                %                         app.exper(nOldExp+i-nFailed).Images = retrieveTrials( adaptiveDF3( app.exper.Images, 1/app.exper.Info.SamplingF,'tau', [app.tau1EditField.Value, app.tau2EditField.Value, app.tau0EditField.Value]), app.exper.Info); %[tau1 tau2 tau0]
                app.exper.Images = adaptiveDF3( app.exper.Images, 1/app.exper.Info.SamplingF,'tau', [app.tau1EditField.Value, app.tau2EditField.Value, app.tau0EditField.Value]);
                disp('deltaF/F computed.')
            end
            
            
            % compute the average over trials
            if app.exper.Info.nTrials>1
                
                
                % if the stimulus also involves a baseline for every stimulus,
                % concatenate each baseline to its stimulus
                if app.exper.Info.Baseline_bool
                    tmp = retrieveTrials(app.exper.Images, app.exper.Info.nTrials*2, app.exper.Info.FramesPerTrial/2);
                    tmp = cat(4, ...
                        tmp(:,:,1:2:end-1,:), ...baselines
                        tmp(:,:,2:2:end,:)); % stimuli
%                     app.exper.Info.FramesPerTrial=2*app.exper.Info.FramesPerTrial;
%                     app.exper.Info.nTrials = app.exper.Info.nTrials/2;
                else
                    tmp = retrieveTrials(app.exper.Images, app.exper.Info.nTrials, app.exper.Info.FramesPerTrial);
                end
                app.experMean.Images = squeeze(mean( tmp, 3));
                app.movieMeanSwitch.Enable = 'on';
            else
                app.experMean.Images = [];
                app.movieMeanSwitch.Value = Frames;
                app.movieMeanSwitch.Enable = 'off';
            end
            
            % done
            cla(app.movieAxes)
            app.MovieAxesImg=imagesc(app.movieAxes,app.exper.Images(:,:,1));
            disp('Experiments loaded')
            plotAllVisualize(app)
            app.ProjAxesImg=imagesc(app.projAxes,nan);
            plotOne(app,app.projAxes,mean(app.exper.Images,3),[])
            busyLampOFF(app)
        end
        
        
        function plotAllVisualize(app)
            
            % update frame spinner and slider
            updateSpinnerSlider(app)
            
            if ~isempty(app.exper)
                
                % plot the movie
                switch app.movieMeanSwitch.Value
                    case 'Frames'
                        mat = squeeze(app.exper.Images(:,:,1));
                        pointers.data = libpointer('MATLAB array',app.exper.Images);
                        pointers.x =  (0:size(app.exper.Images,3)-1)/app.exper.Info.SamplingF;
%                         pointers.stimuli = app.exper(currExp_i).Timestamps(1:app.exper(currExp_i).Info.FramesPerTrial:end);
%                         st = (0:app.exper.Info.nTrials)/app.exper.Info.nTrials*pointers.x(end);
                        fpt=app.exper.Info.FramesPerTrial;
                        st=((1:fpt:size(app.exper.Images,3)-fpt+1)-1)/app.exper.Info.SamplingF;
                        pointers.stimuli = st;
                    case 'Mean'
                        mat = squeeze(app.experMean.Images(:,:,1));
                        pointers.data = libpointer('MATLAB array',app.experMean.Images);
                        pointers.x = (0:app.exper.Info.FramesPerTrial-1)/app.exper.Info.SamplingF;
                        if app.exper.Info.Baseline_bool
                            pointers.stimuli = app.exper.Info.FramesPerTrial/2/app.exper.Info.SamplingF;
                        end
                end
                
                plotOne(app,app.movieAxes,mat,pointers)
            else
                initializeAxes(app)
            end
        end
        
        function plotOne(app,ax,mat,pointers)
            % plot the frame mat in a single axes.
            % d is a two elemnt vector storing dy and dx
            
            
            % get the image shown onto this axes
            switch ax
                case app.movieAxes
                    myImage = app.MovieAxesImg;
                    
                case app.projAxes
                    myImage = app.ProjAxesImg;
                    
            end
            x = (0:size(mat,2)-1);
            y = (0:size(mat,1)-1);
%             cla(ax)
%             imagesc(ax,x,y,mat)
            myImage.CData = mat;
            myImage.XData = x;
            myImage.YData = y;
            axis(ax,[x(1), x(end), y(1), y(end)])
            lims = quantile(mat,[0.01, 0.99],'all');
            if lims(2) > lims(1)
                ax.CLim = [lims(1), lims(2)];
            end
            ax.UserData = pointers;
            
        end
        
        function busyLampON(app)
            app.BusyLamp.Color = [1,0,0]; %red
        end
        
        function busyLampOFF(app)
            app.BusyLamp.Color = [0.65,0.65,0.65]; %grey
        end
        
        
        function updateSpinnerSlider(app)
            
            app.FrameSpinner.Value = 1;
            app.FrameSlider.Value = 1;
            
            if ~isempty(app.exper)
                switch app.movieMeanSwitch.Value
                    case 'Frames'
                        nFrames = size(app.exper.Images,3);
%                         app.TrialSpinner.Enable = 'on';
                    case 'Mean'
                        nFrames = app.exper.Info.FramesPerTrial;
%                         app.TrialSpinner.Enable = 'off';
                end
            else
                nFrames = 1;
            end
            
            if nFrames>1
                app.FrameSpinner.Limits = [1,nFrames];
                app.FrameSlider.Limits = [1,nFrames];
                app.FrameSpinner.Enable = 'on';
                app.FrameSlider.Enable = 'on';
            else
                app.FrameSpinner.Limits = [1,2];
                app.FrameSlider.Limits = [1,2];
                app.FrameSpinner.Enable = 'off';
                app.FrameSlider.Enable = 'off';
            end
        end
        
        function updateMovie(app,selFrame,event)
            
            % plot the movie
            switch app.movieMeanSwitch.Value
                case 'Frames'
                    selTrial = 1+floor((selFrame-1)/app.exper.Info.FramesPerTrial);
                    trialFrame = 1+mod(selFrame-1,app.exper.Info.FramesPerTrial);
                    mat = app.exper.Images(:,:,selFrame);
                    app.shownTrialEditField.Value = selTrial;
                    app.shownFrameEditField.Value = trialFrame;
                    if ~isempty(event)
                        pointers.data = libpointer('MATLAB array', app.exper.Images);
                        pointers.x = (0:size(app.exper.Images,3)-1)/app.exper.Info.SamplingF;
                        pointers.stimuli = app.exper.Timestamps(1:app.exper.Info.FramesPerTrial:end);
                    end
                case 'Mean'
                    mat = app.experMean.Images(:,:,selFrame);
                    if ~isempty(event)
                        pointers.data = libpointer('MATLAB array', app.experMean.Images);
                        pointers.x = (0:size(app.experMean.Images,3)-1)/app.exper.Info.SamplingF;
                    end
            end
%           % Find axes children objects. ROIs are children too
            allChildren = findobj(app.movieAxes,'Type','Image');
            % Among all the children, get the image
            myImage = allChildren(strcmp( arrayfun(@(x) x.Type, allChildren,'UniformOutput',false),'image'));
            myImage.CData = mat;
            if ~isempty(event)
                % this means that this function has been called by the
                % the movieMeanSwitch callback, i.e. the source image stack
                % of movieAxes has been changed. Udpate the source pointer!
                app.movieAxes.UserData = pointers;
                app.projAxes.UserData = pointers;
            end
            
            % sync slider and spinner
            app.FrameSlider.Value = selFrame;
            app.FrameSpinner.Value = selFrame;
        end
        
        
        function [map, ptr] = map2plotInVisualize(app,currExp_i)
            if app.AmplofdFFpeakButton.Value
                map = app.experMaps(currExp_i).amplitudeTime;
%                 ptr = libpointer('MATLAB array', app.experMaps(currExp_i).amplitudeTime);
            end
            if app.TimeofdFFpeakButton.Value
                map = app.experMaps(currExp_i).retinotopyTime;
%                 ptr = libpointer('MATLAB array', app.experMaps(currExp_i).retinotopyTime);
            end
            if app.AmpliatstimulfreqButton.Value
                map = app.experMaps(currExp_i).amplitudeFreq;
%                 ptr = libpointer('MATLAB array', app.experMaps(currExp_i).amplitudeFreq);
            end
            if app.PhaseatstimulfreqButton.Value
                map = app.experMaps(currExp_i).retinotopyFreq;
%                 ptr = libpointer('MATLAB array', app.experMaps(currExp_i).retinotopyFreq);
            end
            pointers.data = libpointer('MATLAB array',app.experMean(currExp_i).Images);
            pointers.x = (0:app.exper(currExp_i).Info.FramesPerTrial-1)/app.exper(currExp_i).Info.SamplingF;
            ptr = pointers;
        end
        
        function plotAllCombine(app)
            selCombo = app.SelectedComboSpinner.Value;
            if ~isempty(app.comboMaps(selCombo))
                if app.PhaseButton.Value
                    aziMat = app.comboMaps(selCombo).aziP;
                    sourcePtrAzi = libpointer('MATLAB array', app.comboMaps(selCombo).aziP);
                    altiMat = app.comboMaps(selCombo).altiP;
                    sourcePtrAlti = libpointer('MATLAB array', app.comboMaps(selCombo).altiP);
                    fieldMat = app.comboMaps(selCombo).fieldP;
                    sourcePtrField = libpointer('MATLAB array', app.comboMaps(selCombo).fieldP);
                else
                    aziMat = app.comboMaps(selCombo).aziT;
                    sourcePtrAzi = libpointer('MATLAB array', app.comboMaps(selCombo).aziT);
                    altiMat = app.comboMaps(selCombo).altiT;
                    sourcePtrAlti = libpointer('MATLAB array', app.comboMaps(selCombo).altiT);
                    fieldMat = app.comboMaps(selCombo).fieldT;
                    sourcePtrField = libpointer('MATLAB array', app.comboMaps(selCombo).fieldT);
                end
%                 plotOne(app,app.azimuthAxes,aziMat,app.comboMaps(selCombo).pxlSize,sourcePtrAzi)
%                 plotOne(app,app.altitudeAxes,altiMat,app.comboMaps(selCombo).pxlSize,sourcePtrAlti)
%                 plotOne(app,app.visualFieldAxes,fieldMat,app.comboMaps(selCombo).pxlSize,sourcePtrField)   
                plotOne(app,app.azimuthAxes,aziMat,[],sourcePtrAzi)
                plotOne(app,app.altitudeAxes,altiMat,[],sourcePtrAlti)
                plotOne(app,app.visualFieldAxes,fieldMat,[],sourcePtrField)   
            end
        end
        
        function initializeAxes(app)
            % assign a Tag to all the axes. It is necessary for the child
            % app LUTmanager.
            app.movieAxes.Tag = 'movieAxes';
            app.projAxes.Tag = 'projAxes';
            app.movieAxes.Colormap = gray;    
            app.projAxes.Colormap = gray;
            app.cBar.movieVisualize = colorbar(app.movieAxes); 
            app.cBar.retinotopyVisualize = colorbar(app.projAxes);
            % initialize an image in all the axes.
            imagesc(app.movieAxes,NaN)
            imagesc(app.projAxes,NaN)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                app.parentApp = varargin{1};
                app.getFromEscherButton.Enable = 'on';
            end
            
            app.path = [pwd filesep];
            
            % Initialize all axes
            initializeAxes(app)
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            busyLampON(app) 
        
            % select one or more exper    
            [namesCell,Path] = uigetfile([app.path '*.mat'],'Select exp','',"MultiSelect","on");
            
            % refocus on the app
            figure(app.mainFig)
            drawnow
            
            if isa(namesCell,'double')
                % if nothing has been selected, namesCell is a double with
                % value 0
                busyLampOFF(app)
                return
            end
            
            app.path=Path; % store the path
            
            % if a single exper has been selected, store its name into a cell
            % array anyway
            if ischar(namesCell)
                clear('tmp')
                tmp{1} = namesCell;
                namesCell = tmp;
            end
            
            params.namesCell = namesCell;
            params.path = Path;
            importFunction(app,'fromFile',params)
        end

        % Callback function
        function StoredExpListBoxValueChanged(app, event)
            showRetinotopy(app)
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            updateMovie(app,value,[])
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = round(app.FrameSlider.Value);
            updateMovie(app,value,[])
        end

        % Callback function
        function AnalyzeButtonPushed(app, event)
            busyLampON(app)
            drawnow
            
            % save the exper that have to be analyzed in a logical array
            toBeAnalyzed = double(~[app.experTable.Data{:,3}]);
        
            if app.MergeCheckBox.Value
                % you decided to merge exper with the same stimulus!
                
                % save the column containing the stimulus description
                col2 = app.experTable.Data(:,2);
                
                % - uni contains col2 elements with no repetitions.
                % - i_col2(i) contains the index of the 1st occurrence of
                %       uni(i) into the col2 vector. i.e. uni=col2(i_col2);
                % - i_uni(i) contains the index of the element of uni that
                %       matches col2(i). i.e. col2=uni(i_uni); 
                [uni, i_col2, i_uni] = unique(col2);
                
                % - groups contains col2 elements with no repetitions. (the
                %       same as uni)
                % - counts(i) contains how many times groups(i) appears in col2.
                [counts, groups] = groupcounts(col2);
                                
                % Save the info of the repeated expereriments
                repStim = col2(i_col2(counts>1));
                
                % prepare some room for the merged experiments
                nNewExp = length(repStim);
                nOldExp = length(app.exper);
                app.experTable.Data = cat(1, app.experTable.Data, repmat(app.models.defTab,nNewExp,1));
                app.exper = cat(1, app.exper, repmat(app.models.nanExp,nNewExp,1));
                app.experMean = cat(1,app.experMean,struct('Images',NaN));
                
                nFailed = 0;
                for i=1:nNewExp
                    try
                        % find the indixes of the current repeated expereriments
                        currExpIdx = strcmp(col2, repStim(i));
                        
                        % They will not be analyzed
                        toBeAnalyzed(currExpIdx) = false;
                        
                        % check if it is possilbe to merge them, i.e. if frame dimentions
                        % and the stim parameters are the same
                                                
                        % merge them, store them into app.exper
                                %               % orientation, stim orientation expressed in degrees
                                %               % direction, stim direction, either '+' or '-'
                                %               % PxlSize, as a two-elements vector
                                %               % StimF, the stimulus frequency
                                %               % SamplingF, the frame frequency expressed in Hz
                        tmp = {app.exper(currExpIdx).Images};
                        app.exper(nOldExp+i-nFailed).Images               = cat( 3,tmp{:}); % concatenate movies along trials
                        tmpStruct = { app.exper(currExpIdx).Info }; % stack the Info structures into a cell array
                        app.exper(nOldExp+i-nFailed).Info.nTrials         = sum(cellfun(@(x) (x.nTrials), tmpStruct,'uniformoutput',1), 'all'); % sum the trials
                        app.exper(nOldExp+i-nFailed).Info.Orientation     = app.exper( find(currExpIdx,1) ).Info.Orientation;
                        app.exper(nOldExp+i-nFailed).Info.Direction       = app.exper( find(currExpIdx,1) ).Info.Direction;
                        
                        % check that the following info are the same in all
                        % the experiments
                        % first look at the pixel size
                        tmpPxlSize = cellfun(@(x) (x.PxlSize), tmpStruct,'uniformoutput',0);
                        differentPxlSizes = unique(cat(1,tmpPxlSize{:}),'rows'); % count the number of different pxlSize vectors
                        if size(differentPxlSizes,1) == 1
                            app.exper(nOldExp+i-nFailed).Info.PxlSize       = differentPxlSizes;
                        else
                            error('Pixel sizes do no match')
                        end
                        % then look at the kind of stimulus
                        stim = unique(cellfun(@(x) (x.StimType), tmpStruct,'uniformoutput',0));
                        if length(stim) == 1
                            app.exper(nOldExp+i-nFailed).Info.StimType = stim;
                        else
                            error('Number of frames per trial do no match')
                        end
                        % then look at the number of frames per trial FramesPerTrial
                        differentFramesPerTrial = unique(cellfun(@(x) (x.FramesPerTrial), tmpStruct,'uniformoutput',1));
                        if length(differentFramesPerTrial) == 1
                            app.exper(nOldExp+i-nFailed).Info.FramesPerTrial = differentFramesPerTrial;
                        else
                            error('Number of frames per trial do no match')
                        end
                        % then look at the stimulus frequency
                        differentStimF = unique(cellfun(@(x) (x.StimF), tmpStruct,'uniformoutput',1));
                        if length(differentStimF) == 1
                            app.exper(nOldExp+i-nFailed).Info.StimF = differentStimF;
                        else
                            error('Stimulation frequencies do no match')
                        end
                        % finally, look at the sampling frequency
                        differentSamplingF = unique(cellfun(@(x) (x.SamplingF), tmpStruct,'uniformoutput',1));
                        if length(differentSamplingF) == 1
                            app.exper(nOldExp+i-nFailed).Info.SamplingF = differentSamplingF;
                        else
                            error('Sampling frequencies do no match')
                        end
                        % now look at the baseline time
                        differentBaselineTime = unique(cellfun(@(x) (x.BaselineTime), tmpStruct,'uniformoutput',1));
                        if length(differentBaselineTime) == 1
                            app.exper(nOldExp+i-nFailed).Info.BaselineTime = differentBaselineTime;
                        else
                            error('Baseline times do no match')
                        end
                        % finally, look at the dead time
                        differentDeadTime = unique(cellfun(@(x) (x.DeadTime), tmpStruct,'uniformoutput',1));
                        if length(differentDeadTime) == 1
                            app.exper(nOldExp+i-nFailed).Info.DeadTime = differentDeadTime;
                        else
                            error('Dead times do no match')
                        end
                        % finally, look at the Screen Size
                        differentScreenSize = unique(cellfun(@(x) (x.ScreenSize), tmpStruct,'uniformoutput',1));
                        if length(differentScreenSize) == 1
                            app.exper(nOldExp+i-nFailed).Info.ScreenSize = differentScreenSize;
                        else
                            error('Screen sizes do no match')
                        end
                        % finally, look at the bar speed
                        differentBarSpeed = unique(cellfun(@(x) (x.BarSpeed), tmpStruct,'uniformoutput',1));
                        if length(differentBarSpeed) == 1
                            app.exper(nOldExp+i-nFailed).Info.BarSpeed = differentBarSpeed;
                        else
                            error('Bar Speeds do no match')
                        end
                        % if all the previous info match, then also the
                        % apparent stimulation frequency matches: 
                        app.exper(nOldExp+i-nFailed).Info.ApparentStimF = app.exper(find(currExpIdx,1)).Info.ApparentStimF;
                        
                        % Apply this 'tag' stating this is not an original exp
                        app.exper(nOldExp+i-nFailed).Info.IsMerged = true; 
                                            
                        % fill their names in the appTable
                        app.experTable.Data(nOldExp+i,:) = [{['Merged_' num2str(i)]}, repStim(i), {false}];
                        
                        % Preallocate retinotopy and retinGradientFreq vectors.
                        app.experMaps(nOldExp+i-nFailed).retinotopyFreq = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2));
                        app.experMaps(nOldExp+i-nFailed).retinotopyTime = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2));
                        app.experMaps(nOldExp+i-nFailed).retinGradientFreq = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2), 2);
                        app.experMaps(nOldExp+i-nFailed).retinGradientTime = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2), 2);
                        app.experMaps(nOldExp+i-nFailed).amplitudeFreq = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2));
                        app.experMaps(nOldExp+i-nFailed).amplitudeTime = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2));
                        
                        % Compute the mean of the merged experiment
                        app.experMean(nOldExp+i-nFailed).Images = squeeze(mean( retrieveTrials(app.exper(nOldExp+i-nFailed).Images, app.exper(nOldExp+i-nFailed).Info),3));
                    catch err
                        disp( getReport( err, 'extended', 'hyperlinks', 'on' ) )
                        disp('')
                        warning(['Exper named: ''' [app.experTable.Data{currExpIdx},' '] ''', can not be concatenated'])
                        app.exper(nOldExp+i-nFailed) = [];
                        app.experTable.Data(nOldExp+i-nFailed,:) = [];
                        nFailed = nFailed+1;
                    end
                end
                
                % merged exper have to be analyzed
                toBeAnalyzed = [reshape((toBeAnalyzed),[],1);... % to be sure they're column vectors
                                reshape(true(nNewExp-nFailed,1),[],1)];
            end            
            
            % compute the retinotopic map for every exper, one by one
            toBeAnalyzed_idx = find(toBeAnalyzed);
            for j = 1:length(toBeAnalyzed_idx)
                frequencyAnalysis(app,toBeAnalyzed_idx(j))
                timeAnalysis(app,toBeAnalyzed_idx(j))
                app.experTable.Data{toBeAnalyzed_idx(j),3} = true;
            end
            disp('Selected experiments have been analyzed')
            plotAllVisualize(app)
            syncExpObjects(app) % to include analyzed and merged exper
            busyLampOFF(app)
        end

        % Callback function
        function experTableCellSelection(app, event)
            app.experTableIndices = event.Indices;
        end

        % Callback function
        function experTableCellEdit(app, event)
            syncExpObjects(app)
        end

        % Callback function
        function SelectedExpSpinnerValueChanged(app, event)
            plotAllVisualize(app)
        end

        % Callback function
        function ChoosemapButtonGroupSelectionChanged(app, event)
            % retrieve the index of the selected experiment
            currExp_i = app.SelectedExpSpinner.Value;
            
            % store this values into temporary matrices for legibility purposes
            PxlSize = app.exper(currExp_i).Info.PxlSize; % [dy, dx]
        
            % plot the retinotopic map
            [mat, sourcePtr] = map2plotInVisualize(app,currExp_i);
            plotOne(app,app.projAxes,mat,PxlSize,sourcePtr)
        end

        % Button pushed function: ClearselectedButton
        function ClearselectedButtonPushed(app, event)
            % delete stuff
            selExp = unique(app.experTableIndices(:,1));
            app.exper(selExp) = [];
            app.experMaps(selExp) = [];
            app.experMean(selExp) = [];
            app.experTable.Data(selExp,:) = [];
            
%             % check if the exper currently shown has been deleted
%             isDel = sum(selExp == app.SelectedExpSpinner.Value);
            
            syncExpObjects(app)
            plotAllVisualize(app)
            
        end

        % Value changed function: movieMeanSwitch
        function movieMeanSwitchValueChanged(app, event)
            value = app.movieMeanSwitch.Value;
            switch value
                case 'Frames'
                    app.shownFrameEditField.Visible = 'on';
                    app.shownTrialEditField.Visible = 'on';
                case 'Mean'
                    app.shownFrameEditField.Visible = 'off';
                    app.shownTrialEditField.Visible = 'off';
            end
            updateSpinnerSlider(app)
%             updateMovie(app,1,event)
            plotAllVisualize(app)
        end

        % Value changed function: LUTmanagerButton
        function LUTmanagerButtonValueChanged(app, event)
            if app.LUTmanagerButton.Value
                app.lutManagerHandle = LUTmanager(app);
            else
                app.lutManagerHandle.delete;
                app.lutManagerHandle = [];
            end
        end

        % Callback function
        function computeMapButtonPushed(app, event)
            % retrieve the selected experiments                         
            mapIdx = false(2,2,length(app.experMaps));
            % structure of mapIdx first two dimensions:                 plus   minus
            %                                               azimuth     1,1     1,2
            %                                               altitude    2,1     2,2
            
            % get maps
            mapIdx(1,1,:) = strcmp( app.azimuthPlusDropDown.Value, app.experTable.Data(:,1) );
            mapIdx(1,2,:) = strcmp( app.azimuthMinusDropDown.Value, app.experTable.Data(:,1) );
            mapIdx(2,1,:) = strcmp( app.altitudePlusDropDown.Value, app.experTable.Data(:,1) );
            mapIdx(2,2,:) = strcmp( app.altitudeMinusDropDown.Value, app.experTable.Data(:,1) );
            
            mapSelected = logical( sum( mapIdx, 3)); % true if the map is selected, false if not
            counter = sum(mapSelected,2);
            
            % at least one 'azimuth' and one 'altitude' have to be selected
            if sum(logical(counter)) % OR operator
                
                nOldExp = size(app.CombinationsTable.Data,1);
                
                % azimuth first
                if counter(1) > 1
%                     dx = app.exper(mapIdx(1,1,:)).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                     dy = app.exper(mapIdx(1,1,:)).Info.PxlSize(1);
                    
                    % correct for intrinsic response phase delay using both
                    % directions. The correction is the following:
                    % actualPhase = (phasePlus - PhaseMinus)/2
                    aziP = (app.experMaps(mapIdx(1,1,:)).retinotopyFreq - app.experMaps(mapIdx(1,2,:)).retinotopyFreq) ./2;
                    aziP_grad = NaN(size(aziP,1),size(aziP,2),2);
%                     [aziP_grad(:,:,1), aziP_grad(:,:,2)] = gradient(aziP,dy,dx);
                    [aziP_grad(:,:,1), aziP_grad(:,:,2)] = imgradient(aziP,'central');
                    
                    % the same for retinotopy in time domain
                    % For times belonging to [-T,T], where T is the stimulus
                    % period, the correction is the following:
                    % actualTime = (timePlus - timeMinus)/2 + T/2
                    stimT = 1/app.exper(mapIdx(1,1,:)).Info.StimF;
                    aziT = stimT/2 + (app.experMaps(mapIdx(1,1,:)).retinotopyTime - app.experMaps(mapIdx(1,2,:)).retinotopyTime) ./2;
                    aziT_grad = NaN(size(aziT,1),size(aziT,2),2);
%                     [aziT_grad(:,:,1), aziT_grad(:,:,2)] = gradient(aziT,dy,dx);
                    [aziT_grad(:,:,1), aziT_grad(:,:,2)] = imgradient(aziT,'central');
                    aziIsHere = true;
                elseif counter(1)==1
                    % just get the maps and dx,dy
                    selExp = mapIdx( 1, mapSelected(1,:), :);
%                     dx = app.exper(selExp).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                     dy = app.exper(selExp).Info.PxlSize(1);
                    aziP = app.experMaps(selExp).retinotopyFreq;
                    aziP_grad = app.experMaps(selExp).retinGradientFreq;
                    aziT = app.experMaps(selExp).retinotopyTime;
                    aziT_grad = app.experMaps(selExp).retinGradientTime;
                    aziIsHere = true;
                else
                    aziIsHere = false;
                end
                
                % then altitude
                if counter(2) > 1
%                     dx = app.exper(mapIdx(2,1,:)).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                     dy = app.exper(mapIdx(2,1,:)).Info.PxlSize(1);
                    
                    % correct for intrinsic response phase delay using both
                    % directions. For phases belonging to [-pi,pi], the
                    % correction is the following:
                    % actualPhase = (phasePlus - PhaseMinus)/2
                    altiP = (app.experMaps(mapIdx(2,1,:)).retinotopyFreq - app.experMaps(mapIdx(2,2,:)).retinotopyFreq) ./2;
                    altiP_grad = NaN(size(aziP,1),size(aziP,2),2);
%                     [altiP_grad(:,:,1), altiP_grad(:,:,2)] = gradient(altiP,dy,dx);
                    [altiP_grad(:,:,1), altiP_grad(:,:,2)] = imgradient(altiP,'central');
                    
                    % the same for retinotopy in time domain
                    % For times belonging to [-T,T], where T is the stimulus
                    % period, the correction is the following:
                    % actualTime = (timePlus - timeMinus)/2 + T/2
                    stimT = 1/app.exper(mapIdx(1,1,:)).Info.StimF;
                    altiT = stimT/2 + (app.experMaps(mapIdx(2,1,:)).retinotopyTime - app.experMaps(mapIdx(2,2,:)).retinotopyTime) ./2;
                    altiT_grad = NaN(size(aziT,1),size(aziT,2),2);
%                     [altiT_grad(:,:,1), altiT_grad(:,:,2)] = gradient(altiT,dy,dx);
                    [altiT_grad(:,:,1), altiT_grad(:,:,2)] = imgradient(altiT,'central');
                    altiIsHere = true;
                    
                elseif counter(2)==1
                    % just get the maps and dy,dx
                    selExp = mapIdx( 2, mapSelected(2,:), :);
%                     dx = app.exper(selExp).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                     dy = app.exper(selExp).Info.PxlSize(1);
                    altiP = app.experMaps(selExp).retinotopyFreq;
                    altiP_grad = app.experMaps(selExp).retinGradientFreq;
                    altiT = app.experMaps(selExp).retinotopyTime;
                    altiT_grad = app.experMaps(selExp).retinGradientTime;
                    altiIsHere = true;
                else
                    altiIsHere = false;
                end
                
                % at this point, we have at least one among altitude and azimuth
                
                if prod(counter) % AND operator
                    % both azimuth and altitude are selected! Compute the
                    % field map
                    
%                     aziP_gradComplex = aziP_grad(:,:,2) + 1i* aziP_grad(:,:,1);
%                     altiP_gradComplex = altiP_grad(:,:,2) + 1i* altiP_grad(:,:,1);
%                     fieldP = sin(angle(aziP_gradComplex)-angle(altiP_gradComplex));
                    fieldP = sin(aziP_grad(:,:,2)-altiP_grad(:,:,2));
                    if app.SignthresholdCheckBox.Value
                        thr = app.signThresholdEditField.Value * nanstd(fieldP(round(32/app.pixelsBinEditField.Value):end,:),[],'all');
                        above = fieldP>thr;
                        below = fieldP<-thr;
                        fieldP(above) = 1;
                        fieldP(below) = -1;
                        fieldP(~(above|below)) = 0;
                    end
%                     aziT_gradComplex = aziT_grad(:,:,2) + 1i* aziT_grad(:,:,1);
%                     altiT_gradComplex = altiT_grad(:,:,2) + 1i* altiT_grad(:,:,1);
%                     fieldT = sin(angle(aziT_gradComplex)-angle(altiT_gradComplex));
                    fieldT = sin(aziT_grad(:,:,2)-altiT_grad(:,:,2));
                    % thresholding
                    if app.SignthresholdCheckBox.Value
                        thr = app.signThresholdEditField.Value * std(fieldT,[],'all');
                        above = fieldT>thr;
                        below = fieldT<-thr;
                        fieldT(above) = 1;
                        fieldT(below) = -1;
                        fieldT(~(above|below)) = 0;
                    end
                else
                    if aziIsHere
                        % only azumuth is selected
                        altiP = NaN(size(aziP));
                        altiT = NaN(size(aziT));
                        fieldP = NaN(size(aziP));
                        fieldT = NaN(size(aziT));
                    else
                        % only altitude is selected
                        aziP = NaN(size(altiP));
                        aziT = NaN(size(altiT));
                        fieldP = NaN(size(altiP));
                        fieldT = NaN(size(altiT));
                    end
                end
                
                % now store the combo
                if nOldExp >= 1
                    app.comboMaps = cat(1,app.comboMaps,app.models.comboMapEmpty);
                    app.CombinationsTable.Data = cat(1,app.CombinationsTable.Data,[{app.azimuthPlusDropDown.Value},{app.azimuthMinusDropDown.Value},{app.altitudePlusDropDown.Value},{app.altitudeMinusDropDown.Value}]); % cambiare
                    app.SelectedComboSpinner.Limits(2) = app.SelectedComboSpinner.Limits(2)+1;
                    app.SelectedComboSpinner.Enable = 'on';
                    app.SelectedComboSpinner.Value = nOldExp+1;
                else
                    app.comboMaps = app.models.comboMapEmpty;
                    app.CombinationsTable.Data = [{app.azimuthPlusDropDown.Value},{app.azimuthMinusDropDown.Value},{app.altitudePlusDropDown.Value},{app.altitudeMinusDropDown.Value}];
                    app.SelectedComboSpinner.Limits(2) = Inf;
                    app.SelectedComboSpinner.Enable = 'off';
                    app.SelectedComboSpinner.Value = 1;
                end
                app.comboMaps(end).altiP = altiP;
                app.comboMaps(end).aziP = aziP;
                app.comboMaps(end).altiT = altiT;
                app.comboMaps(end).aziT = aziT;
                app.comboMaps(end).fieldP = fieldP;
                app.comboMaps(end).fieldT = fieldT;
%                 app.comboMaps(end).pxlSize = [dy,dx];


                % plot the three maps accordingly to the selected option
                plotAllCombine(app)
            end
            
            
        end

        % Callback function
        function SelectedComboSpinnerValueChanged(app, event)
            plotAllCombine(app)
        end

        % Callback function
        function ChoosemapButtonGroup_2SelectionChanged(app, event)
            plotAllCombine(app)
        end

        % Callback function
        function CombinationsTableCellSelection(app, event)
            app.comboTableIndices = event.Indices;
        end

        % Callback function
        function ClearselectedButton_2Pushed(app, event)
            selCombo = unique(app.comboTableIndices(:,1)); % selected rows
            app.comboMaps(selCombo) = [];
            app.CombinationsTable.Data(selCombo,:) = [];
            
            newComboN = length(app.comboMaps);
            if newComboN > 1
                app.SelectedComboSpinner.Enable = 'on';
                app.SelectedComboSpinner.Limits(2) = newComboN;
            else
                app.SelectedComboSpinner.Enable = 'off';
                app.SelectedComboSpinner.Limits(2) = Inf;
                app.SelectedComboSpinner.Value = 1;
            end
            plotAllCombine(app)
        end

        % Value changed function: roimanagerButton
        function roimanagerButtonValueChanged(app, event)
            if app.roimanagerButton.Value
                app.roiManagerHandle = ROImanager(app);
            else
                if isvalid(app.roiManagerHandle)
%                     app.roiManagerHandle.delete;
                    app.roiManagerHandle.myCloseRequestFunc
                end
                app.roiManagerHandle = [];
            end
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            updateMovie(app,round(app.FrameSlider.Value),[])
        end

        % Button pushed function: getFromEscherButton
        function getFromEscherButtonPushed(app, event)
            params.namesCell = {'movieFromEscher'};
            params.path = '';
            importFunction(app,'fromEscher',params)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            switch app.movieMeanSwitch.Value
                case 'Frames'
                    implay(app.exper.Images,app.exper.Info.SamplingF*5)
                case 'Mean'
                    implay(app.experMean.Images,app.exper.Info.SamplingF)
            end
        end

        % Button pushed function: LoaddarkButton
        function LoaddarkButtonPushed(app, event)
            if app.NodarkButton.Value
                % do nothing
            else
                % select one file
                [name,path] = uigetfile([app.path '*.mat'],'Select dark');
                if name==0
                    return
                end
                app.path=path;
                
                load([app.path name])
                app.dark.Images=OutPcoStruct.Images;
                app.dark.Info=OutPcoStruct.Info;
                
                % replace first frame
                if app.replace1stFrameCheckBox.Value
                    fpt=app.dark.Info.FramesPerTrial;
                    app.dark.Images(:,:,1:fpt:end-fpt+1) = app.dark.Images(:,:,2:fpt:end-fpt+2);
                end
                
                [ny,nx,nframes]=size(app.dark.Images);
                % binning
                disp('Binning dark...')
                if app.BinningButton.Value
                    binF = app.pixelsBinEditField.Value;
                    binnedImg = nan(ny/binF,nx/binF,nframes);
                    for it = 1:nframes
                        binnedImg(:,:,it) = binImage(app.dark.Images(:,:,it),binF);
                    end
                    app.dark.Images = binnedImg;
                    app.dark.Info.PxlSize = app.dark.Info.PxlSize * app.pixelsBinEditField.Value;
                    % update movie size
                    [ny,nx,nframes]=size(app.dark.Images);
                end
                
                % average dark
                disp('Averaging dark...')
                if app.StaticalButton.Value % produce one average frame
                    app.dark.Images = mean(app.dark.Images,3);
                else
                    if app.dark.Info.nTrials>1
                        tmp = retrieveTrials(app.dark.Images, app.dark.Info.nTrials, app.dark.Info.FramesPerTrial);
                        
                        % if the stimulus also involves a baseline for every stimulus,
                        % concatenate each baseline to its stimulus
                        if app.dark.Info.Baseline_bool
                            tmp = cat(4, ...
                                tmp(:,:,1:2:end-1,:), ...baselines
                                tmp(:,:,2:2:end,:)); % stimuli
                            app.dark.Info.FramesPerTrial=2*app.dark.Info.FramesPerTrial;
                            app.dark.Info.nTrials = app.dark.Info.nTrials/2;
                        end
                        app.dark.Images = squeeze(mean( tmp, 3));
                    else
                        % Images is already the dynamic dark we want
                    end
                end
                
                disp('Dark loaded')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFig = uifigure('Visible', 'off');
            app.mainFig.Name = 'Receptive Field Analyzer';
            app.TabGroup = uitabgroup(app.mainFig);
            app.VisualizeTab = uitab(app.TabGroup);
            app.VisualizeTab.Title = 'Visualize';
            app.FrameSlider = uislider(app.VisualizeTab);
            app.FrameSlider.Limits = [0 1];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSpinnerLabel = uilabel(app.VisualizeTab);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.VisualizeTab);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.PlayButton = uibutton(app.VisualizeTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FrameEditFieldLabel = uilabel(app.VisualizeTab);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.shownFrameEditField = uieditfield(app.VisualizeTab, 'numeric');
            app.oftrialEditFieldLabel = uilabel(app.VisualizeTab);
            app.oftrialEditFieldLabel.Text = 'of trial';
            app.shownTrialEditField = uieditfield(app.VisualizeTab, 'numeric');
            app.movieMeanSwitch = uiswitch(app.VisualizeTab, 'slider');
            app.movieMeanSwitch.Items = {'Frames', 'Mean'};
            app.movieMeanSwitch.ValueChangedFcn = createCallbackFcn(app, @movieMeanSwitchValueChanged, true);
            app.movieMeanSwitch.Value = 'Frames';
            app.movieAxes = uiaxes(app.VisualizeTab);
            title(app.movieAxes, 'Movie')
            app.movieAxes.XTick = [];
            app.movieAxes.YTick = [];
            app.projAxes = uiaxes(app.VisualizeTab);
            title(app.projAxes, 'Avg projection')
            app.projAxes.XTick = [];
            app.projAxes.YTick = [];
            app.BusyLampLabel = uilabel(app.mainFig);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.mainFig);
            app.LUTmanagerButton = uibutton(app.mainFig, 'state');
            app.LUTmanagerButton.ValueChangedFcn = createCallbackFcn(app, @LUTmanagerButtonValueChanged, true);
            app.LUTmanagerButton.Text = 'LUT manager';
            app.TabGroup2 = uitabgroup(app.mainFig);
            app.LoadTab = uitab(app.TabGroup2);
            app.LoadTab.Title = 'Load';
            app.ButtonGroup = uibuttongroup(app.LoadTab);
            app.IntensityButton = uiradiobutton(app.ButtonGroup);
            app.IntensityButton.Text = 'Intensity';
            app.ComputedeltaFFButton = uiradiobutton(app.ButtonGroup);
            app.ComputedeltaFFButton.Text = {'Compute'; 'deltaF/F'};
            app.ComputedeltaFFButton.Value = true;
            app.LoadfileButton = uibutton(app.ButtonGroup, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.getFromEscherButton = uibutton(app.ButtonGroup, 'push');
            app.getFromEscherButton.ButtonPushedFcn = createCallbackFcn(app, @getFromEscherButtonPushed, true);
            app.getFromEscherButton.Enable = 'off';
            app.getFromEscherButton.Text = 'Get from Escher';
            app.ClearselectedButton = uibutton(app.LoadTab, 'push');
            app.ClearselectedButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectedButtonPushed, true);
            app.ClearselectedButton.Text = 'Clear selected';
            app.replace1stFrameCheckBox = uicheckbox(app.LoadTab);
            app.replace1stFrameCheckBox.Tooltip = {'TEMPORARY TRICK! Somehow the 1st frame of each trial is affected by an artifact; replace it with the 2nd one'};
            app.replace1stFrameCheckBox.Text = 'replace 1st frame';
            app.replace1stFrameCheckBox.Value = true;
            app.DarkButtonGroup = uibuttongroup(app.LoadTab);
            app.DarkButtonGroup.Title = 'Dark';
            app.NodarkButton = uiradiobutton(app.DarkButtonGroup);
            app.NodarkButton.Text = 'No dark';
            app.NodarkButton.Value = true;
            app.StaticalButton = uiradiobutton(app.DarkButtonGroup);
            app.StaticalButton.Text = 'Statical';
            app.DynamicalButton = uiradiobutton(app.DarkButtonGroup);
            app.DynamicalButton.Text = 'Dynamical';
            app.LoaddarkButton = uibutton(app.DarkButtonGroup, 'push');
            app.LoaddarkButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddarkButtonPushed, true);
            app.LoaddarkButton.Text = 'Load dark';
            app.BinningTab = uitab(app.TabGroup2);
            app.BinningTab.Title = 'Binning';
            app.ButtonGroup_2 = uibuttongroup(app.BinningTab);
            app.ButtonGroup_2.Title = 'Button Group';
            app.NobinningButton = uiradiobutton(app.ButtonGroup_2);
            app.NobinningButton.Text = 'No binning';
            app.BinningButton = uiradiobutton(app.ButtonGroup_2);
            app.BinningButton.Text = 'Binning';
            app.BinningButton.Value = true;
            app.pixelsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.pixelsEditFieldLabel.Text = '(pixels)';
            app.pixelsBinEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.pixelsBinEditField.Value = 4;
            app.OpeningfilterTab = uitab(app.TabGroup2);
            app.OpeningfilterTab.Title = 'Opening filter';
            app.xysizeEditFieldLabel = uilabel(app.OpeningfilterTab);
            app.xysizeEditFieldLabel.Text = 'xy size';
            app.MedianFiltXYSizeEditField = uieditfield(app.OpeningfilterTab, 'numeric');
            app.MedianFiltXYSizeEditField.Limits = [0 Inf];
            app.MedianFiltXYSizeEditField.RoundFractionalValues = 'on';
            app.tsizeEditFieldLabel = uilabel(app.OpeningfilterTab);
            app.tsizeEditFieldLabel.Text = 't size';
            app.MedianFiltTSizeEditField = uieditfield(app.OpeningfilterTab, 'numeric');
            app.MedianFiltTSizeEditField.Limits = [0 Inf];
            app.MedianFiltTSizeEditField.RoundFractionalValues = 'on';
            app.MedianfilteringLabel = uilabel(app.OpeningfilterTab);
            app.MedianfilteringLabel.Text = 'Median filtering:';
            app.pixelsLabel = uilabel(app.OpeningfilterTab);
            app.pixelsLabel.Text = 'pixel(s)';
            app.framesLabel = uilabel(app.OpeningfilterTab);
            app.framesLabel.Text = 'frame(s)';
            app.deltaFFparamsTab = uitab(app.TabGroup2);
            app.deltaFFparamsTab.Title = 'deltaF/F params';
            app.Tau0sEditFieldLabel = uilabel(app.deltaFFparamsTab);
            app.Tau0sEditFieldLabel.Text = 'Tau0 (s):';
            app.tau0EditField = uieditfield(app.deltaFFparamsTab, 'numeric');
            app.tau0EditField.Tooltip = {'See the function "adaptiveDF3" for details'};
            app.tau0EditField.Value = -1;
            app.Tau1sEditFieldLabel = uilabel(app.deltaFFparamsTab);
            app.Tau1sEditFieldLabel.Text = 'Tau1 (s):';
            app.tau1EditField = uieditfield(app.deltaFFparamsTab, 'numeric');
            app.tau1EditField.Tooltip = {'See the function "adaptiveDF3" for details'};
            app.tau1EditField.Value = -1;
            app.Tau2sEditFieldLabel = uilabel(app.deltaFFparamsTab);
            app.Tau2sEditFieldLabel.Text = 'Tau2 (s):';
            app.tau2EditField = uieditfield(app.deltaFFparamsTab, 'numeric');
            app.tau2EditField.Tooltip = {'See the function "adaptiveDF3" for details'};
            app.tau2EditField.Value = -1;
            app.roimanagerButton = uibutton(app.mainFig, 'state');
            app.roimanagerButton.ValueChangedFcn = createCallbackFcn(app, @roimanagerButtonValueChanged, true);
            app.roimanagerButton.Text = 'ROI manager';
            % Show the figure after all components are created
            app.mainFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuickWideFieldAnalyzer_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFig)
        end
    end
end