classdef visualFieldAnalyzer2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFig                        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        VisualizeTab                   matlab.ui.container.Tab
        FrameSlider                    matlab.ui.control.Slider
        FrameSpinnerLabel              matlab.ui.control.Label
        FrameSpinner                   matlab.ui.control.Spinner
        ChoosemapButtonGroup           matlab.ui.container.ButtonGroup
        PhaseatstimulfreqButton        matlab.ui.control.RadioButton
        AmpliatstimulfreqButton        matlab.ui.control.RadioButton
        AmplofdFFpeakButton            matlab.ui.control.RadioButton
        TimeofdFFpeakButton            matlab.ui.control.RadioButton
        SelectedExpSpinnerLabel        matlab.ui.control.Label
        SelectedExpSpinner             matlab.ui.control.Spinner
        movieMeanSwitch                matlab.ui.control.Switch
        FrameEditFieldLabel            matlab.ui.control.Label
        shownFrameEditField            matlab.ui.control.NumericEditField
        oftrialEditFieldLabel          matlab.ui.control.Label
        shownTrialEditField            matlab.ui.control.NumericEditField
        PlayButton                     matlab.ui.control.Button
        movieAxes                      matlab.ui.control.UIAxes
        retinotopyAxes                 matlab.ui.control.UIAxes
        CombineTab                     matlab.ui.container.Tab
        CombineexperimentsPanel        matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        azimuthPlusDropDown            matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        altitudePlusDropDown           matlab.ui.control.DropDown
        computeMapButton               matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        azimuthMinusDropDown           matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        altitudeMinusDropDown          matlab.ui.control.DropDown
        AzimuthLabel                   matlab.ui.control.Label
        AltitudeLabel                  matlab.ui.control.Label
        SignthresholdCheckBox          matlab.ui.control.CheckBox
        timesstdLabel                  matlab.ui.control.Label
        signThresholdEditField         matlab.ui.control.NumericEditField
        ExportButton                   matlab.ui.control.Button
        CombinationsTable              matlab.ui.control.Table
        SelectedComboSpinnerLabel      matlab.ui.control.Label
        SelectedComboSpinner           matlab.ui.control.Spinner
        ChoosemapButtonGroup_2         matlab.ui.container.ButtonGroup
        PhaseButton                    matlab.ui.control.RadioButton
        TimeButton                     matlab.ui.control.RadioButton
        ClearselectedButton_2          matlab.ui.control.Button
        visualFieldAxes                matlab.ui.control.UIAxes
        altitudeAxes                   matlab.ui.control.UIAxes
        azimuthAxes                    matlab.ui.control.UIAxes
        BusyLampLabel                  matlab.ui.control.Label
        BusyLamp                       matlab.ui.control.Lamp
        experTable                     matlab.ui.control.Table
        LUTmanagerButton               matlab.ui.control.StateButton
        AnalysisparametersPanel        matlab.ui.container.Panel
        AnalyzeButton                  matlab.ui.control.Button
        MergeCheckBox                  matlab.ui.control.CheckBox
        gpuCheckBox                    matlab.ui.control.CheckBox
        ShowexternalfftfigureCheckBox  matlab.ui.control.CheckBox
        PhasemapgaussfilterCheckBox    matlab.ui.control.CheckBox
        pxlsEditFieldLabel             matlab.ui.control.Label
        MapMedFiltPxlsEditField        matlab.ui.control.NumericEditField
        TabGroup2                      matlab.ui.container.TabGroup
        LoadTab                        matlab.ui.container.Tab
        ButtonGroup                    matlab.ui.container.ButtonGroup
        IntensityButton                matlab.ui.control.RadioButton
        ComputedeltaFFButton           matlab.ui.control.RadioButton
        LoadfileButton                 matlab.ui.control.Button
        getFromEscherButton            matlab.ui.control.Button
        ClearselectedButton            matlab.ui.control.Button
        replace1stFrameCheckBox        matlab.ui.control.CheckBox
        BinningTab                     matlab.ui.container.Tab
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        NobinningButton                matlab.ui.control.RadioButton
        BinningButton                  matlab.ui.control.RadioButton
        pixelsEditFieldLabel           matlab.ui.control.Label
        pixelsBinEditField             matlab.ui.control.NumericEditField
        OpeningfilterTab               matlab.ui.container.Tab
        xysizeEditFieldLabel           matlab.ui.control.Label
        MedianFiltXYSizeEditField      matlab.ui.control.NumericEditField
        tsizeEditFieldLabel            matlab.ui.control.Label
        MedianFiltTSizeEditField       matlab.ui.control.NumericEditField
        MedianfilteringLabel           matlab.ui.control.Label
        pixelsLabel                    matlab.ui.control.Label
        framesLabel                    matlab.ui.control.Label
        deltaFFparamsTab               matlab.ui.container.Tab
        Tau0sEditFieldLabel            matlab.ui.control.Label
        tau0EditField                  matlab.ui.control.NumericEditField
        Tau1sEditFieldLabel            matlab.ui.control.Label
        tau1EditField                  matlab.ui.control.NumericEditField
        Tau2sEditFieldLabel            matlab.ui.control.Label
        tau2EditField                  matlab.ui.control.NumericEditField
        roimanagerButton               matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        exper % structure array that stores the loaded experiments
        experMaps % structure array that stores the retinotopic maps and the amplitude maps of every exper
                % its fields are:
                % retinotopyFreq: structure array of length exper. Its only field, Map, is a
                        % matrix of dimensions x*y. It stores the receptive field of each pixel as a phase
                % retinGradientFreq: structure array of length exper. Its only field, Map, is a
                        % matrix of dimensions x*y*direction. It stores the gradient of retinotopyFreq.
                        % Direction has two values representing d/dy and d/dx respectively.
                % amplitudeFreqs
                % retinotopyTime: the same as retinotopyFreq, but calculated in the time domain
                % retinGradientTime: the same as retinGradientFreq, but calculated in the time domain
                % amplitudeTime
        models % stores models to create or preallocate exper structure or exper table
        experTableIndices % stores the indices of the selected cells of experTable
        cBar % stores colorbar objects
        lutManagerHandle % stores the handle of the child app LUTmanager
        comboMaps % structure array that stores the combos from altitude and latitude
        comboTableIndices % stores the indices of the selected cells of comboTable
        experMean % structure array that stores the mean of the loaded experiments over trials
        roiManagerHandle % stores the handle of the child app ROImanager
        parentApp % Description
        Gmag % Description
        Gdir
    end
    
    methods (Access = public)
        
        function importFunction(app, mode, params)
            % create new elements of app.exper and app.experTable for the new
            % expereriments
            nNewExp = length(params.namesCell);
            nOldExp = length(app.exper);
        
            if (nOldExp >= 1) && ~isempty(app.exper)
                % concatenate new empty matrices
                app.experTable.Data = cat(1, app.experTable.Data, repmat(app.models.defTab,nNewExp,1));
                app.exper = cat(1, app.exper, repmat(app.models.nanExp,nNewExp,1));
                app.experMean = cat(1, app.experMean, repmat(app.models.nanExperMean,nNewExp,1));
            else
                % create new matrices
                app.experTable.Data = repmat(app.models.defTab,nNewExp,1);
                app.exper = repmat(app.models.nanExp,nNewExp,1);
                app.experMean = repmat(app.models.nanExperMean,nNewExp,1);
            end
        
            % loop on the new experiments
            nFailed = 0; % counter for the number of failed uploads
            for i = 1:nNewExp
                 fprintf('Loading exp %i of %i...\n',i,nNewExp)
                 try   
                    % load the i-th experiment
                    tic
                    switch mode
                        case 'fromFile'
%                             load([params.path, params.namesCell{i}]); %the loaded structure is called 'OutPcoStruct'
                            vr = VideoReader([params.path, params.namesCell{i}]);
                            currentStruct.Images = squeeze(read(vr));
                            load(strrep([params.path, params.namesCell{i}],'.mj2','.mat')); % variable called 'metafile'
                            currentStruct.StimInfo = metafile.StimInfo;
                            currentStruct.AcqInfo = metafile.AcqInfo;
                            
                            % if missing frames, fill them
                            % get timestamps
                            [n,t] = getPcoBinaryTimestamps(currentStruct.Images);


                            clear metafile

                        case 'fromEscher'
%                             OutPcoStruct = app.parentApp.OutPcoStruct;
                            currentStruct.Images = app.parentApp.PcoStruct.Images;
                            currentStruct.StimInfo = app.parentApp.PcoMeta.StimInfo;
                            currentStruct.AcqInfo = app.parentApp.PcoMeta.AcqInfo;
                    end
                         
                    % Calculate the "apparent" stimulation frequency
                    apparPeriod = currentStruct.StimInfo.FramesPerTrial/currentStruct.AcqInfo.FrameRate;
                    currentStruct.StimInfo.ApparentStimF = 1/apparPeriod;
                    
                    % check for missing frames:
                    % omitted since we are now using the Image Acquisition
                    % Toolbox functions for movie acquisition, and it works
                    % very well.
                    
                    % substitute the first image of each trial with the
                    % second one because of an artifact. (we should understand
                    % what's going on!)
                    if app.replace1stFrameCheckBox.Value
                        fpt = currentStruct.StimInfo.FramesPerTrial;
                        currentStruct.Images(:,:,1:fpt:end-fpt+1) = currentStruct.Images(:,:,2:fpt:end-fpt+2);
                    end
                    
                    % add a field to the Info structure to say this is an "original" experiment 
                    currentStruct.IsMerged = false;
                    
                    app.exper(nOldExp+i-nFailed) = currentStruct;
                    elapsT = toc;
                    fprintf('Exp loaded in %.2f s\n',elapsT)
                    
                    % If binning is selected, bin the image
                    if app.BinningButton.Value
                        tic
                        disp('Start binning...')
                        binF = app.pixelsBinEditField.Value;
                        binnedImg = nan(size(currentStruct.Images,1)/binF,size(currentStruct.Images,2)/binF,size(currentStruct.Images,3));
                        for nFr = 1:size(currentStruct.Images,3)
                            binnedImg(:,:,nFr) = binImage(currentStruct.Images(:,:,nFr),binF);
                        end
                        currentStruct.Images = binnedImg;
%                         currentStruct.Info.PxlSize = OutPcoStruct.Info.PxlSize * app.pixelsBinEditField.Value;
                        disp(['Binning elapsed in ' num2str(toc) 's'])
                    end
                    % If any median filter in the opening filter tab is set
                    % different from 0, here the openend file is
                    % filtered accordingly.
                    if app.MedianFiltXYSizeEditField.Value ~= 0 && app.MedianFiltTSizeEditField.Value ~= 0
                        % All X, Y and T (time) dimensions are filtered.
                        tic
                        disp('Start median filtering over x, y and t dimensions...');
                        % The original image series is therefore
                        % substituted with the filtered series.
                        currentStruct.Images = medfilt3(currentStruct.Images, [app.MedianFiltXYSizeEditField.Value,...
                            app.MedianFiltXYSizeEditField.Value, app.MedianFiltTSizeEditField.Value]);
                        elapsT = toc;
                        disp(['3-D median filtering elapsed in ' num2str(elapsT) ' s'])
                    elseif app.MedianFiltXYSizeEditField.Value ~=0 && app.MedianFiltTSizeEditField.Value == 0
                        % Only X, Y dimensions are filtered.
                        tic
                        disp('Start median filtering over x and y dimensions...');
                        % The original image series is therefore
                        % substituted with the filtered series.
                        currentStruct.Images = medfilt3(currentStruct.Images, [app.MedianFiltXYSizeEditField.Value,...
                            app.MedianFiltXYSizeEditField.Value, 1]);
                        elapsT = toc;
                        disp(['2-D median filtering elapsed in ' num2str(elapsT) ' s'])
                    elseif app.MedianFiltXYSizeEditField.Value ==0 && app.MedianFiltTSizeEditField.Value ~= 0
                        % Only T dimension is filtered.
                        tic
                        disp('Start median filtering over t dimension...');
                        % The original image series is therefore
                        % substituted with the filtered series.
                        currentStruct.Images = medfilt3(currentStruct.Images, [1,...
                            1, app.MedianFiltTSizeEditField.Value]);
                        elapsT = toc;
                        disp(['1-D median filtering elapsed in ' num2str(elapsT) ' s'])
                    end
        
                    if app.IntensityButton.Value
%                         app.exper(nOldExp+i-nFailed).Images = double( retrieveTrials( OutPcoStruct.Images, OutPcoStruct.Info));
                        app.exper(nOldExp+i-nFailed).Images = double(currentStruct.Images);
                    else
                        % Apply the function 'adaptiveDF3' (adapted from 'adaptiveDF4' by
                        % Enrico) to obtain a smoothened deltaF/F.
                        tic
                        disp('Start computing deltaF/F and filtering...')
                        app.exper(nOldExp+i-nFailed).Images = adaptiveDF3( currentStruct.Images, 1/currentStruct.AcqInfo.FrameRate,'tau', [app.tau1EditField.Value, app.tau2EditField.Value, app.tau0EditField.Value]);
                        elapsT = toc;
                        disp(['deltaF/F and smoothing elapsed in ' num2str(elapsT) ' s'])
                    end
                                         
                    % Preallocate retinotopy and retinGradientFreq vectors.
                    sizeY = size(currentStruct.Images,1);
                    sizeX = size(currentStruct.Images,2);
                    app.experMaps(nOldExp+i-nFailed).retinotopyFreq = NaN(sizeY, sizeX);
                    app.experMaps(nOldExp+i-nFailed).retinotopyTime = NaN(sizeY, sizeX);
                    app.experMaps(nOldExp+i-nFailed).retinGradientFreq = NaN(sizeY, sizeX, 2);
                    app.experMaps(nOldExp+i-nFailed).retinGradientTime = NaN(sizeY, sizeX, 2);
                    app.experMaps(nOldExp+i-nFailed).amplitudeFreq = NaN(sizeY, sizeX);
                    app.experMaps(nOldExp+i-nFailed).amplitudeTime = NaN(sizeY, sizeX);
        
                    % calculate the mean
                    app.experMean(nOldExp+i-nFailed).Images = squeeze( mean( retrieveTrials(app.exper(nOldExp+i-nFailed).Images,...
                            currentStruct.StimInfo.nTrials, currentStruct.StimInfo.FramesPerTrial), ...
                            3));
        
                    % store the exper name and info into the table
                    % check for repeated names
%                     if any(strcmp(app.experTable.Data(1:nOldExp+i-nFailed-1,1),params.namesCell(i)))
%                         newname = 
%                     end
                    app.experTable.Data(nOldExp+i-nFailed,1) = params.namesCell(i);
                    app.experTable.Data(nOldExp+i-nFailed,2) = {[app.exper(nOldExp+i-nFailed).StimInfo.Orientation ', ' app.exper(nOldExp+i-nFailed).StimInfo.Direction]};
                catch err
                    % No info about orientation and direction or wrong format
                    disp( getReport( err, 'extended', 'hyperlinks', 'on' ) )
                    disp('')
                    warning(['Loading of exper named ''' app.experTable.Data{nOldExp+i-nFailed,1} ''' has failed. This exper has been removed from the list'])
                    % delete this exper from the app.exper structure and from
                    % the table
                    app.exper(nOldExp+i-nFailed) = [];
                    app.experTable.Data(nOldExp+i-nFailed,:) = [];
                    % update the counter for failed uploads
                    nFailed = nFailed+1;
                 end
            end
        
            % done
            disp('Experiments loaded')
            syncExpObjects(app)
            plotAllVisualize(app)
            busyLampOFF(app)
        end
        
        function frequencyAnalysis(app,currExp_i)
            % opzione 1: l'acquisizione è continua, non divisa in trial
            %           distanziati nel tempo
            % opzione 2: l'acquisizione è divisa in trial distanziati nel tempo:
            %           bisogna correggere la StimF accordingly
            % app.exper is a structure. its fields are:
            %   Images: timestack x*y*time
            %   ImgCounter: frame progeressive numbers
            %   Timestamps: frame timestamps in seconds, double
            %   TimestampsTxt: frame timestamps in datetime, char
            %   Info: info about the experiment. TO BE ADDED!
            %           % must contain:
            %               % orientation, stim orientation expressed in degrees
            %               % direction, stim direction, either '+' or '-'
            %               % PxlSize, as a two-elements vector
            %               % StimF, the stimulus frequency
            %               % SamplingF, the frame frequency expressed in Hz
            
            try
                % store the image stack in a temporary matrix for legibility
                % purposes
%                 mat = app.exper(currExp_i).Images;
                % unroll Images along trials
%                 mat = reshape(permute(mat,[1,2,4,3]), size(mat,1),size(mat,2),[]);
                StimF = app.exper(currExp_i).StimInfo.ApparentStimF;
                sF = app.exper(currExp_i).AcqInfo.FrameRate;
                
                l = size(app.exper(currExp_i).Images,3);
                
                %nFreq = 2^nextpow2(l);
                disp('Compute pixel fft...')
                tic
                if app.gpuCheckBox.Value
%                     mat = gpuArray(mat-mean(mat,3));
                    mat = gpuArray(app.exper(currExp_i).Images-mean(app.exper(currExp_i).Images,3));
                    matFft = gather(fft(mat,l,3));
                else
%                     matFft = fft(mat-mean(mat,3),l,3); %remove the mean to avoid leakage at low freq
                    matFft = fft(app.exper(currExp_i).Images-mean(app.exper(currExp_i).Images,3),l,3);
                end
                elaps = toc;
                disp(['Fft computation elapsed in ' num2str(elaps) ' s'])
                
                % custom function to get the frequencies of fft
                f = fft_freqs(sF,l);                     
                
                % get the real part of fft and normalize the amplitudes
                matFft = matFft(:,:,1:(floor(l/2))+1);
                matFft(2:end) = 2*matFft(2:end);
                
                matAmpl = abs(matFft./l); % vettore delle ampiezze
                matPhi = angle(matFft); % vettore delle fasi
                              
                stimWindow = [0.8*StimF, 1.2*StimF]; % guardiamo una finestra intorno alla StimF con tolleranza 20%
                windowF = (f >= stimWindow(1)) & (f <= stimWindow(2)); % vettore logico delle freq da guardare
                [app.experMaps(currExp_i).amplitudeFreq, maxF] = max(matAmpl(:,:, windowF),[], 3,'linear'); % frequenza del picco di risposta allo stimolo. Meglio uno smoothing? Quanto saranno rumorose le fasi?
                
                matPhi_selF = matPhi(:,:, windowF).*180/pi; % only consider the part of the phases matrix corresponding to the selected freq
                % rescale the phase
%                 pos = matPhi_selF>=0;
%                 matPhi_selF(pos) = matPhi_selF(pos)-180;
%                 matPhi_selF(~pos) = matPhi_selF(~pos)+180;
%                 wait = app.exper(currExp_i).StimInfo.BaselineTime; % inizio + fine che non tiene conto del tempo morto
%                 dead = app.exper(currExp_i).StimInfo.DeadTime; % tempo che non viene registrato dalla camera alla fine dell'acquisizione
%                 screenSize = app.exper(currExp_i).StimInfo.ScreenSize; % dimensione schermo lungo la dimensione percorsa dallo stimolo
%                 v = app.exper(currExp_i).StimInfo.BarSpeed; % in gradi al secondo
%                 matPhi_selF = matPhi_selF/180*(screenSize/2+ (wait-dead)/2*v);
                matPhi_selF((matPhi_selF)>0) = matPhi_selF((matPhi_selF)>0)-360;
                matPhi_selF = -matPhi_selF;

%                 medFilt = round((app.MapMedFiltPxlsEditField.Value-1)/2);
                sigm = app.MapMedFiltPxlsEditField.Value;
                app.experMaps(currExp_i).retinotopyFreq = imgaussfilt(matPhi_selF(maxF),sigm); % apply the linear logical indexing on it
%                 dx = app.exper(currExp_i).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                 dy = app.exper(currExp_i).Info.PxlSize(1);
%                 [app.experMaps(currExp_i).retinGradientFreq(:,:,1), app.experMaps(currExp_i).retinGradientFreq(:,:,2)] = gradient(app.experMaps(currExp_i).retinotopyFreq, dy, dx);
                [app.experMaps(currExp_i).retinGradientFreq(:,:,1), app.experMaps(currExp_i).retinGradientFreq(:,:,2)] = imgradient(app.experMaps(currExp_i).retinotopyFreq,'central');
%                 [gx,gy] = imgradientxy(app.experMaps(currExp_i).retinotopyFreq,'central');
%                 [app.Gmag(:,:,currExp_i),app.Gdir(:,:,currExp_i)] = imgradient(app.experMaps(currExp_i).retinotopyFreq);
                
                %% Debug purpose
                if app.ShowexternalfftfigureCheckBox.Value
                    figure('Name',app.experTable.Data{currExp_i,1})
    %                 params.Fs = sF;
    %                 params.tapers = [3 5];
    %                 [dimX,dimY,dimT] = size(app.exper(currExp_i).Images);
    %                 [matAmpl2,f2] = mtspectrumc( reshape(permute(app.exper(currExp_i).Images-mean(app.exper(currExp_i).Images,3),[3 1 2]),dimT,dimY*dimX) ,params);
                    ax = axes;
                    imagesc(ax,app.experMaps(currExp_i).amplitudeFreq)
                    M = quantile(app.experMaps(currExp_i).amplitudeFreq,0.9,'all');
                    m = quantile(app.experMaps(currExp_i).amplitudeFreq,0.1,'all');
                    ax.CLim = [m,M];
    %                 pointers.data = permute(reshape(matAmpl2,size(matAmpl2,1),dimY,dimX),[2 3 1]);
                    pointers.data = permute(cat(4,matAmpl,matPhi.*180/pi),[1 2 4 3]);
                    pointers.x = f;
                    ax.UserData = pointers;
                end
                
            catch err
                disp( getReport( err, 'extended', 'hyperlinks', 'on' ) )
                disp('')
                warning(['Analysis of exper named ''' app.experTable.Data{currExp_i,1} ''' has failed. Consider removing this exper from the list'])
            end
            
        end
        
        function plotAllVisualize(app)
            % retrieve the index of the selected experiment
            currExp_i = app.SelectedExpSpinner.Value;
            
            % update frame spinner and slider
            updateSpinnerSlider(app)
            
            if ~isempty(app.exper)
                % store this values into temporary matrices for ease of reading
%                 PxlSize = app.exper(currExp_i).Info.PxlSize; % [dy, dx]
                
                % plot the movie
                switch app.movieMeanSwitch.Value
                    case 'Frames'
                        mat = squeeze(app.exper(currExp_i).Images(:,:,1,1));
                        pointers.data = libpointer('MATLAB array',app.exper(currExp_i).Images);
                        pointers.x =  (0:size(app.exper(currExp_i).Images,3)-1)/app.exper(currExp_i).AcqInfo.FrameRate;
%                         pointers.stimuli = app.exper(currExp_i).Timestamps(1:app.exper(currExp_i).StimInfo.FramesPerTrial:end);
                        st = (0:app.exper(currExp_i).StimInfo.nTrials)/app.exper(currExp_i).StimInfo.nTrials*pointers.x(end);
                        pointers.stimuli = st(1:end-1);
                    case 'Mean'
                        mat = squeeze(app.experMean(currExp_i).Images(:,:,1));
                        pointers.data = libpointer('MATLAB array',app.experMean(currExp_i).Images);
                        pointers.x = (0:app.exper(currExp_i).StimInfo.FramesPerTrial-1)/app.exper(currExp_i).AcqInfo.FrameRate;
                end
                
                plotOne(app,app.movieAxes,mat,[],pointers)
                
                % plot the retinotopic map
                switch app.ChoosemapButtonGroup.SelectedObject.Text
                    case 'Phase at stimul freq'
                        map = app.experMaps(currExp_i).retinotopyFreq;
                        
                    case 'Ampli at stimul freq'
                        map = app.experMaps(currExp_i).amplitudeFreq;
                        
                    case 'Ampl of dF/F peak'
                        map = app.experMaps(currExp_i).amplitudeTime;
                        
                    case 'Time of dF/F peak'
                        map = app.experMaps(currExp_i).retinotopyTime;
                        
                end
%                 map = app.experMaps(currExp_i).retinotopyFreq;
%                 [mat, pointers] = map2plotInVisualize(app,currExp_i);
                plotOne(app,app.retinotopyAxes,map,[],pointers)
            else
                initializeAxes(app)
            end
        end
        
        function plotOne(app,ax,mat,d,pointers)
            % plot the frame mat in a single axes.
            % d is a two elemnt vector storing dy and dx
            
            if isempty(d)
                d = [1 1];
            end
            
            % get the image shown onto this axes
            allChildren = findobj(ax,'Type','Image');
            myImage = allChildren(strcmp( arrayfun(@(x) x.Type, allChildren,'UniformOutput',false),'image'));
            x = (0:size(mat,2)-1)*d(2);
            y = (0:size(mat,1)-1)*d(1);
%             cla(ax)
%             imagesc(ax,x,y,mat)
            myImage.CData = mat;
            myImage.XData = x;
            myImage.YData = y;
            axis(ax,[x(1), x(end), y(1), y(end)])
            lims = quantile(mat,[0.1, 0.9],'all');
            if lims(2) > lims(1)
                ax.CLim = [lims(1), lims(2)];
            end
            if app.PhaseatstimulfreqButton.Value && ax==app.retinotopyAxes
                app.retinotopyAxes.CLim = [0 360];
            end
            ax.UserData = pointers;
            
            % update the LUT manager
%             if app.LUTmanagerButton.Value
%                 app.lutManagerHandle.refreshHisto()
%             end
        end
        
        function busyLampON(app)
            app.BusyLamp.Color = [1,0,0]; %red
        end
        
        function busyLampOFF(app)
            app.BusyLamp.Color = [0.65,0.65,0.65]; %grey
        end
        
        
        function updateSpinnerSlider(app)
            % retrieve the index of the selected experiment
            currExp_i = app.SelectedExpSpinner.Value;
            
            app.FrameSpinner.Value = 1;
            app.FrameSlider.Value = 1;
            
            if ~isempty(app.exper)
                app.SelectedExpSpinner.Enable = 'on';
                switch app.movieMeanSwitch.Value
                    case 'Frames'
                        nFrames = app.exper(currExp_i).StimInfo.FramesPerTrial * app.exper(currExp_i).StimInfo.nTrials;
                    case 'Mean'
                        nFrames = app.exper(currExp_i).StimInfo.FramesPerTrial;
                end
            else
                app.SelectedExpSpinner.Enable = 'off';
                nFrames = 1;
            end
            
            if nFrames>1
                app.FrameSpinner.Limits = [1,nFrames];
                app.FrameSlider.Limits = [1,nFrames];
                app.FrameSpinner.Enable = 'on';
                app.FrameSlider.Enable = 'on';
            else
                app.FrameSpinner.Limits = [1,2];
                app.FrameSlider.Limits = [1,2];
                app.FrameSpinner.Enable = 'off';
                app.FrameSlider.Enable = 'off';
            end
        end
        
        function updateMovie(app,selFrame,event)
            % retrieve the index of the selected experiment
            currExp_i = app.SelectedExpSpinner.Value;
            
            % plot the movie
            switch app.movieMeanSwitch.Value
                case 'Frames'
                    selTrial = 1+floor((selFrame-1)/app.exper(currExp_i).StimInfo.FramesPerTrial);
                    trialFrame = 1+mod(selFrame-1,app.exper(currExp_i).StimInfo.FramesPerTrial);
                    mat = app.exper(currExp_i).Images(:,:,selFrame);
                    app.shownTrialEditField.Value = selTrial;
                    app.shownFrameEditField.Value = trialFrame;
                    if ~isempty(event)
                        pointers.data = libpointer('MATLAB array', app.exper(currExp_i).Images);
                        pointers.x = (0:size(app.exper(currExp_i).Images,3)-1)/app.exper(currExp_i).AcqInfo.FrameRate;
%                         pointers.stimuli = app.exper(currExp_i).Timestamps(1:app.exper(currExp_i).StimInfo.FramesPerTrial:end);
                    end
                case 'Mean'
                    mat = app.experMean(currExp_i).Images(:,:,selFrame);
                    if ~isempty(event)
                        pointers.data = libpointer('MATLAB array', app.experMean(currExp_i).Images);
                        pointers.x = (0:size(app.experMean(currExp_i).Images,3)-1)/app.exper(currExp_i).AcqInfo.FrameRate;
                    end
            end
%           % Find axes children objects. ROIs are children too
            allChildren = findobj(app.movieAxes,'Type','Image');
            % Among all the children, get the image
            myImage = allChildren(strcmp( arrayfun(@(x) x.Type, allChildren,'UniformOutput',false),'image'));
            myImage.CData = mat;
            if ~isempty(event)
                % this means that this function has been called by the
                % the movieMeanSwitch callback, i.e. the source image stack
                % of movieAxes has been changed. Udpate the source pointer!
                app.movieAxes.UserData = pointers;
                app.retinotopyAxes.UserData = pointers;
            end
            
            % sync slider and spinner
            app.FrameSlider.Value = selFrame;
            app.FrameSpinner.Value = selFrame;
        end
        
        function timeAnalysis(app,currExp_i)
            % store the image stack in a temporary matrix for legibility
            % purposes
            sp = 1./app.exper(currExp_i).AcqInfo.FrameRate; % sampling period
            
            [app.experMaps(currExp_i).amplitudeTime, t] = max(app.experMean(currExp_i).Images,[],3); % response peak amplitude
%             medFilt = round((app.MapMedFiltPxlsEditField.Value-1)/2);
%             app.experMaps(currExp_i).retinotopyTime =  medfilt2((t-1).*sp,[medFilt medFilt]); % response peak time
            sigma = app.MapMedFiltPxlsEditField.Value;
            app.experMaps(currExp_i).retinotopyTime =  imgaussfilt((t-1).*sp,sigma); % response peak time
            
%             dx = app.exper(currExp_i).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%             dy = app.exper(currExp_i).Info.PxlSize(1);
%             [app.experMaps(currExp_i).retinGradientTime(:,:,1), app.experMaps(currExp_i).retinGradientTime(:,:,2)] = gradient(app.experMaps(currExp_i).retinotopyTime, dy, dx);
            [app.experMaps(currExp_i).retinGradientTime(:,:,1), app.experMaps(currExp_i).retinGradientTime(:,:,2)] = gradient(app.experMaps(currExp_i).retinotopyTime, 1, 1);
        end
        
        function syncExpObjects(app)
            % reads expTable and set the properties of the other objects
            % accordingly to the current set of experiments
            
            % exp spinner from the tab 'Visualize'
            nExp = size(app.experTable.Data,1);
            if nExp>1
                app.SelectedExpSpinner.Enable = 'on';
                app.SelectedExpSpinner.Limits(2) = nExp;
            else
                app.SelectedExpSpinner.Enable = 'off';
                app.SelectedExpSpinner.Value = 1;
                app.SelectedExpSpinner.Limits(2) = Inf;
            end
            
            % drop down menu from the tab 'Combine'. Only analyzed
            % experiments can be included
            experNames = app.experTable.Data([app.experTable.Data{:,3}],1);
            app.altitudePlusDropDown.Items = cat(1,{''},experNames);
            app.altitudeMinusDropDown.Items = cat(1,{''},experNames);
            app.azimuthPlusDropDown.Items = cat(1,{''},experNames);
            app.azimuthMinusDropDown.Items = cat(1,{''},experNames);
        end
        
        function [map, ptr] = map2plotInVisualize(app,currExp_i)
            if app.AmplofdFFpeakButton.Value
                map = app.experMaps(currExp_i).amplitudeTime;
%                 ptr = libpointer('MATLAB array', app.experMaps(currExp_i).amplitudeTime);
            end
            if app.TimeofdFFpeakButton.Value
                map = app.experMaps(currExp_i).retinotopyTime;
%                 ptr = libpointer('MATLAB array', app.experMaps(currExp_i).retinotopyTime);
            end
            if app.AmpliatstimulfreqButton.Value
                map = app.experMaps(currExp_i).amplitudeFreq;
%                 ptr = libpointer('MATLAB array', app.experMaps(currExp_i).amplitudeFreq);
            end
            if app.PhaseatstimulfreqButton.Value
                map = app.experMaps(currExp_i).retinotopyFreq;
%                 ptr = libpointer('MATLAB array', app.experMaps(currExp_i).retinotopyFreq);
            end
            pointers.data = libpointer('MATLAB array',app.experMean(currExp_i).Images);
            pointers.x = (0:app.exper(currExp_i).StimInfo.FramesPerTrial-1)/app.exper(currExp_i).AcqInfo.FrameRate;
            ptr = pointers;
        end
        
        function plotAllCombine(app)
            selCombo = app.SelectedComboSpinner.Value;
            if ~isempty(app.comboMaps(selCombo))
                if app.PhaseButton.Value
                    aziMat = app.comboMaps(selCombo).aziP;
                    sourcePtrAzi = libpointer('MATLAB array', app.comboMaps(selCombo).aziP);
                    altiMat = app.comboMaps(selCombo).altiP;
                    sourcePtrAlti = libpointer('MATLAB array', app.comboMaps(selCombo).altiP);
                    fieldMat = app.comboMaps(selCombo).fieldP;
                    sourcePtrField = libpointer('MATLAB array', app.comboMaps(selCombo).fieldP);
                else
                    aziMat = app.comboMaps(selCombo).aziT;
                    sourcePtrAzi = libpointer('MATLAB array', app.comboMaps(selCombo).aziT);
                    altiMat = app.comboMaps(selCombo).altiT;
                    sourcePtrAlti = libpointer('MATLAB array', app.comboMaps(selCombo).altiT);
                    fieldMat = app.comboMaps(selCombo).fieldT;
                    sourcePtrField = libpointer('MATLAB array', app.comboMaps(selCombo).fieldT);
                end
%                 plotOne(app,app.azimuthAxes,aziMat,app.comboMaps(selCombo).pxlSize,sourcePtrAzi)
%                 plotOne(app,app.altitudeAxes,altiMat,app.comboMaps(selCombo).pxlSize,sourcePtrAlti)
%                 plotOne(app,app.visualFieldAxes,fieldMat,app.comboMaps(selCombo).pxlSize,sourcePtrField)   
                plotOne(app,app.azimuthAxes,aziMat,[],sourcePtrAzi)
                plotOne(app,app.altitudeAxes,altiMat,[],sourcePtrAlti)
                plotOne(app,app.visualFieldAxes,fieldMat,[],sourcePtrField)   
            end
        end
        
        function initializeAxes(app)
            % assign a Tag to all the axes. It is necessary for the child
            % app LUTmanager.
            app.movieAxes.Tag = 'movieAxes';
            app.retinotopyAxes.Tag = 'retinotopyAxes';
            app.azimuthAxes.Tag = 'azimuthAxes';
            app.altitudeAxes.Tag = 'altitudeAxes';
            app.visualFieldAxes.Tag = 'visualFieldAxes';
            app.movieAxes.Colormap = gray;    
            app.retinotopyAxes.Colormap = jet;
            app.azimuthAxes.Colormap = jet;
            app.altitudeAxes.Colormap = jet;
%             app.visualFieldAxes.Colormap = unionjack;
            app.visualFieldAxes.Colormap = jet;
            app.cBar.movieVisualize = colorbar(app.movieAxes); 
            app.cBar.retinotopyVisualize = colorbar(app.retinotopyAxes);
            app.cBar.azimuthVisualize = colorbar(app.azimuthAxes,'southoutside'); 
            app.cBar.altitudeVisualize = colorbar(app.altitudeAxes,'southoutside');
            app.cBar.visualFielVisualize = colorbar(app.visualFieldAxes,'southoutside');
            % initialize an image in all the axes.
            imagesc(app.movieAxes,NaN)
            imagesc(app.retinotopyAxes,NaN)
            imagesc(app.azimuthAxes,NaN)
            imagesc(app.altitudeAxes,NaN)
            imagesc(app.visualFieldAxes,NaN)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                app.parentApp = varargin{1};
                app.getFromEscherButton.Enable = 'on';
            end
            app.models.defTab = [{'New exper'},{'?, ?'},{false}];
            app.models.emptyExp = struct('Images',[],'StimInfo',[],'AcqInfo',[],'IsMerged',[]);
            app.models.StimInfo = struct('StimType',[],'nTrials',[],'FramesPerTrial',[],...
                'BaselineTime',[],'DeadTime',[],'BarSpeed',[],'ScreenSize',[],...
                'Orientation',[],'Direction',[],'StimF',[],'ApparentStimF',[]); 
            app.models.AcqInfo = struct('HorizBinning',[],'VertBinning',[],'Exposure',[],...
                'FrameRate',[]);
            app.models.nanExp = struct('Images',nan,'StimInfo',app.models.StimInfo,'AcqInfo',app.models.AcqInfo,'IsMerged',nan);
            app.models.nanExperMean = struct('Images',NaN);
            app.models.comboMapEmpty = struct('aziP',[],'altiP',[],'fieldP',[],'aziT',[],'altiT',[],'fieldT',[],'pxlSize',[]);
%             app.exper = app.models.emptyExp; % NO!! This is done in the LoadButtonPushed callback when the first exper is uploaded
            app.experTable.RowName = 'numbered';
            app.CombinationsTable.RowName = 'numbered';
            
            % Initialize all axes
            initializeAxes(app)
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            busyLampON(app)
        
            % select one or more exper    
            [namesCell,Path] = uigetfile('*.*','Select one or more exper','Multiselect','on');
            
            % refocus on the app
            figure(app.mainFig)
            drawnow
            
            if isa(namesCell,'double')
                % if nothing has been selected, namesCell is a double with
                % value 0
                busyLampOFF(app)
                return
            end
            
            % if a single exper has been selected, store its name into a cell
            % array anyway
            if ischar(namesCell)
                clear('tmp')
                tmp{1} = namesCell;
                namesCell = tmp;
            end
            
            params.namesCell = namesCell;
            params.path = Path;
            importFunction(app,'fromFile',params)
        end

        % Callback function
        function StoredExpListBoxValueChanged(app, event)
            showRetinotopy(app)
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            updateMovie(app,value,[])
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = round(app.FrameSlider.Value);
            updateMovie(app,value,[])
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            busyLampON(app)
            drawnow
            
            % save the exper that have to be analyzed in a logical array
            toBeAnalyzed = double(~[app.experTable.Data{:,3}]);
        
            if app.MergeCheckBox.Value
                % you decided to merge exper with the same stimulus!
                
                % save the column containing the stimulus description
                col2 = app.experTable.Data(:,2);
                
                % - uni contains col2 elements with no repetitions.
                % - i_col2(i) contains the index of the 1st occurrence of
                %       uni(i) into the col2 vector. i.e. uni=col2(i_col2);
                % - i_uni(i) contains the index of the element of uni that
                %       matches col2(i). i.e. col2=uni(i_uni); 
                [uni, i_col2, i_uni] = unique(col2);
                
                % - groups contains col2 elements with no repetitions. (the
                %       same as uni)
                % - counts(i) contains how many times groups(i) appears in col2.
                [counts, groups] = groupcounts(col2);
                                
                % Save the info of the repeated expereriments
                repStim = col2(i_col2(counts>1));
                
                % prepare some room for the merged experiments
                nNewExp = length(repStim);
                nOldExp = length(app.exper);
                app.experTable.Data = cat(1, app.experTable.Data, repmat(app.models.defTab,nNewExp,1));
                app.exper = cat(1, app.exper, repmat(app.models.nanExp,nNewExp,1));
                app.experMean = cat(1,app.experMean,struct('Images',NaN));
                
                
                nFailed = 0;
                for i=1:nNewExp
                    try
                        % find the indixes of the current repeated expereriments
                        currExpIdx = strcmp(col2, repStim(i));
                        
                        % They will not be analyzed
                        toBeAnalyzed(currExpIdx) = false;
                        
                        % check if it is possilbe to merge them, i.e. if frame dimentions
                        % and the stim parameters are the same
                                                
                        % merge them, store them into app.exper
                                %               % orientation, stim orientation expressed in degrees
                                %               % direction, stim direction, either '+' or '-'
                                %               % PxlSize, as a two-elements vector
                                %               % StimF, the stimulus frequency
                                %               % SamplingF, the frame frequency expressed in Hz
                        tmp = {app.exper(currExpIdx).Images};
                        app.exper(nOldExp+i-nFailed).Images               = cat( 3,tmp{:}); % concatenate movies along trials
                        tmpStruct = { app.exper(currExpIdx).StimInfo }; % stack the Info structures into a cell array
                        tmpAcqStruct = { app.exper(currExpIdx).AcqInfo }; % stack the Info structures into a cell array

                        app.exper(nOldExp+i-nFailed).StimInfo.nTrials         = sum(cellfun(@(x) (x.nTrials), tmpStruct,'uniformoutput',1), 'all'); % sum the trials
                        app.exper(nOldExp+i-nFailed).StimInfo.Orientation     = app.exper( find(currExpIdx,1) ).StimInfo.Orientation;
                        app.exper(nOldExp+i-nFailed).StimInfo.Direction       = app.exper( find(currExpIdx,1) ).StimInfo.Direction;
                        
                        % check that the following info are the same in all
                        % the experiments
                        % first look at the pixel size
%                         tmpPxlSize = cellfun(@(x) (x.PxlSize), tmpStruct,'uniformoutput',0);
%                         differentPxlSizes = unique(cat(1,tmpPxlSize{:}),'rows'); % count the number of different pxlSize vectors
%                         if size(differentPxlSizes,1) == 1
%                             app.exper(nOldExp+i-nFailed).Info.PxlSize       = differentPxlSizes;
%                         else
%                             error('Pixel sizes do no match')
%                         end
                        % then look at the kind of stimulus
                        stim = unique(cellfun(@(x) (x.StimType), tmpStruct,'uniformoutput',0));
                        if length(stim) == 1
                            app.exper(nOldExp+i-nFailed).StimInfo.StimType = stim;
                        else
                            error('Number of frames per trial do no match')
                        end
                        % then look at the number of frames per trial FramesPerTrial
                        differentFramesPerTrial = unique(cellfun(@(x) (x.FramesPerTrial), tmpStruct,'uniformoutput',1));
                        if length(differentFramesPerTrial) == 1
                            app.exper(nOldExp+i-nFailed).StimInfo.FramesPerTrial = differentFramesPerTrial;
                        else
                            error('Number of frames per trial do no match')
                        end
                        % then look at the stimulus frequency
                        differentStimF = unique(cellfun(@(x) (x.StimF), tmpStruct,'uniformoutput',1));
                        if length(differentStimF) == 1
                            app.exper(nOldExp+i-nFailed).StimInfo.StimF = differentStimF;
                        else
                            error('Stimulation frequencies do no match')
                        end
                        % finally, look at the sampling frequency
                        differentSamplingF = unique(cellfun(@(x) (x.FrameRate), tmpAcqStruct,'uniformoutput',1));
                        if length(differentSamplingF) == 1
                            app.exper(nOldExp+i-nFailed).AcqInfo.FrameRate = differentSamplingF;
                        else
                            error('Sampling frequencies do no match')
                        end
                        % now look at the baseline time
                        differentBaselineTime = unique(cellfun(@(x) (x.BaselineTime), tmpStruct,'uniformoutput',1));
                        if length(differentBaselineTime) == 1
                            app.exper(nOldExp+i-nFailed).StimInfo.BaselineTime = differentBaselineTime;
                        else
                            error('Baseline times do no match')
                        end
                        % finally, look at the dead time
                        differentDeadTime = unique(cellfun(@(x) (x.DeadTime), tmpStruct,'uniformoutput',1));
                        if length(differentDeadTime) == 1
                            app.exper(nOldExp+i-nFailed).StimInfo.DeadTime = differentDeadTime;
                        else
                            error('Dead times do no match')
                        end
                        % finally, look at the Screen Size
                        differentScreenSize = unique(cellfun(@(x) (x.ScreenSize), tmpStruct,'uniformoutput',1));
                        if length(differentScreenSize) == 1
                            app.exper(nOldExp+i-nFailed).StimInfo.ScreenSize = differentScreenSize;
                        else
                            error('Screen sizes do no match')
                        end
                        % finally, look at the bar speed
                        differentBarSpeed = unique(cellfun(@(x) (x.BarSpeed), tmpStruct,'uniformoutput',1));
                        if length(differentBarSpeed) == 1
                            app.exper(nOldExp+i-nFailed).StimInfo.BarSpeed = differentBarSpeed;
                        else
                            error('Bar Speeds do no match')
                        end
                        % if all the previous info match, then also the
                        % apparent stimulation frequency matches: 
                        app.exper(nOldExp+i-nFailed).StimInfo.ApparentStimF = app.exper(find(currExpIdx,1)).StimInfo.ApparentStimF;
%                         app.exper(nOldExp+i-nFailed).StimInfo.ApparentStimF = app.exper(find(currExpIdx,1)).StimInfo.ApparentStimF;
                        
                        % Apply this 'tag' stating this is not an original exp
                        app.exper(nOldExp+i-nFailed).IsMerged = true; 
                                            
                        % fill their names in the appTable
                        app.experTable.Data(nOldExp+i,:) = [{['Merged_' num2str(i)]}, repStim(i), {false}];
                        
                        % Preallocate retinotopy and retinGradientFreq vectors.
                        app.experMaps(nOldExp+i-nFailed).retinotopyFreq = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2));
                        app.experMaps(nOldExp+i-nFailed).retinotopyTime = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2));
                        app.experMaps(nOldExp+i-nFailed).retinGradientFreq = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2), 2);
                        app.experMaps(nOldExp+i-nFailed).retinGradientTime = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2), 2);
                        app.experMaps(nOldExp+i-nFailed).amplitudeFreq = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2));
                        app.experMaps(nOldExp+i-nFailed).amplitudeTime = NaN(size(app.exper(find(currExpIdx,1)).Images,1), size(app.exper(find(currExpIdx,1)).Images,2));
                        
                        % Compute the mean of the merged experiment
                        app.experMean(nOldExp+i-nFailed).Images = squeeze(mean( retrieveTrials(app.exper(nOldExp+i-nFailed).Images, app.exper(nOldExp+i-nFailed).StimInfo.nTrials,app.exper(nOldExp+i-nFailed).StimInfo.FramesPerTrial),3));
                    catch err
                        disp( getReport( err, 'extended', 'hyperlinks', 'on' ) )
                        disp('')
                        warning(['Exper named: ''' [app.experTable.Data{currExpIdx},' '] ''', can not be concatenated'])
                        app.exper(nOldExp+i-nFailed) = [];
                        app.experTable.Data(nOldExp+i-nFailed,:) = [];
                        nFailed = nFailed+1;
                    end
                end
                
                % merged exper have to be analyzed
                toBeAnalyzed = [reshape((toBeAnalyzed),[],1);... % to be sure they're column vectors
                                reshape(true(nNewExp-nFailed,1),[],1)];
            end            
            
            % compute the retinotopic map for every exper, one by one
            toBeAnalyzed_idx = find(toBeAnalyzed);
            for j = 1:length(toBeAnalyzed_idx)
                frequencyAnalysis(app,toBeAnalyzed_idx(j))
                timeAnalysis(app,toBeAnalyzed_idx(j))
                app.experTable.Data{toBeAnalyzed_idx(j),3} = true;
            end
            disp('Selected experiments have been analyzed')
            plotAllVisualize(app)
            syncExpObjects(app) % to include analyzed and merged exper
            busyLampOFF(app)
        end

        % Cell selection callback: experTable
        function experTableCellSelection(app, event)
            app.experTableIndices = event.Indices;
        end

        % Cell edit callback: experTable
        function experTableCellEdit(app, event)
            syncExpObjects(app)
        end

        % Value changed function: SelectedExpSpinner
        function SelectedExpSpinnerValueChanged(app, event)
            plotAllVisualize(app)
        end

        % Selection changed function: ChoosemapButtonGroup
        function ChoosemapButtonGroupSelectionChanged(app, event)
            % retrieve the index of the selected experiment
            currExp_i = app.SelectedExpSpinner.Value;
            
            % store this values into temporary matrices for legibility purposes
%             PxlSize = app.exper(currExp_i).Info.PxlSize; % [dy, dx]
        
            % plot the retinotopic map
            [mat, sourcePtr] = map2plotInVisualize(app,currExp_i);
%             plotOne(app,app.retinotopyAxes,mat,PxlSize,sourcePtr)
            plotOne(app,app.retinotopyAxes,mat,[],sourcePtr)
        end

        % Button pushed function: ClearselectedButton
        function ClearselectedButtonPushed(app, event)
            % delete stuff
            selExp = unique(app.experTableIndices(:,1));
            app.exper(selExp) = [];
            app.experMaps(selExp) = [];
            app.experMean(selExp) = [];
            app.experTable.Data(selExp,:) = [];
            
%             % check if the exper currently shown has been deleted
%             isDel = sum(selExp == app.SelectedExpSpinner.Value);
            
            syncExpObjects(app)
            plotAllVisualize(app)
            
        end

        % Value changed function: movieMeanSwitch
        function movieMeanSwitchValueChanged(app, event)
            value = app.movieMeanSwitch.Value;
            switch value
                case 'Frames'
                    app.shownFrameEditField.Visible = 'on';
                    app.shownTrialEditField.Visible = 'on';
                case 'Mean'
                    app.shownFrameEditField.Visible = 'off';
                    app.shownTrialEditField.Visible = 'off';
            end
            updateSpinnerSlider(app)
            updateMovie(app,1,event)
%             plotAllVisualize(app)
        end

        % Value changed function: LUTmanagerButton
        function LUTmanagerButtonValueChanged(app, event)
            if app.LUTmanagerButton.Value
                app.lutManagerHandle = LUTmanager(app);
            else
                app.lutManagerHandle.delete;
                app.lutManagerHandle = [];
            end
        end

        % Button pushed function: computeMapButton
        function computeMapButtonPushed(app, event)
            % retrieve the selected experiments                         
            mapIdx = false(2,2,length(app.experMaps));
            % structure of mapIdx first two dimensions:                 plus   minus
            %                                               azimuth     1,1     1,2
            %                                               altitude    2,1     2,2
            
            % get maps
            mapIdx(1,1,:) = strcmp( app.azimuthPlusDropDown.Value, app.experTable.Data(:,1) ) & ... 
                            strcmp( 'horz, +;', app.experTable.Data(:,2) );
            mapIdx(1,2,:) = strcmp( app.azimuthMinusDropDown.Value, app.experTable.Data(:,1) )& ... 
                            strcmp( 'horz, -;', app.experTable.Data(:,2) );
            mapIdx(2,1,:) = strcmp( app.altitudePlusDropDown.Value, app.experTable.Data(:,1) )& ... 
                            strcmp( 'vert, +;', app.experTable.Data(:,2) );
            mapIdx(2,2,:) = strcmp( app.altitudeMinusDropDown.Value, app.experTable.Data(:,1) )& ... 
                            strcmp( 'vert, -;', app.experTable.Data(:,2) );
            
            mapSelected = logical( sum( mapIdx, 3)); % true if the map is selected, false if not
            counter = sum(mapSelected,2);
             
            % at least one 'azimuth' and one 'altitude' have to be selected
            if sum(logical(counter)) % OR operator
                
                nOldExp = size(app.CombinationsTable.Data,1);
                
                % azimuth first
                if counter(1) > 1
%                     dx = app.exper(mapIdx(1,1,:)).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                     dy = app.exper(mapIdx(1,1,:)).Info.PxlSize(1);
                     
                    % correct for intrinsic response phase delay using both
                    % directions. The correction is the following:
                    % actualPhase = (phasePlus - PhaseMinus)/2 % old
                    % actualPhase = (phasePlus - PhaseMinus + 360)/2 % new
                    % versione: phases are now between 0 and 360
                    aziP = (app.experMaps(mapIdx(1,1,:)).retinotopyFreq - app.experMaps(mapIdx(1,2,:)).retinotopyFreq...
                                +360) ./2;
                    aziP_grad = NaN(size(aziP,1),size(aziP,2),2);
%                     [aziP_grad(:,:,1), aziP_grad(:,:,2)] = gradient(aziP,dy,dx);
                    [aziP_grad(:,:,1), aziP_grad(:,:,2)] = imgradient(aziP,'central');
                    
                    % the same for retinotopy in time domain
                    % For times belonging to [-T,T], where T is the stimulus
                    % period, the correction is the following:
                    % actualTime = (timePlus - timeMinus)/2 + T/2
                    stimT = 1/app.exper(mapIdx(1,1,:)).StimInfo.StimF;
                    aziT = stimT/2 + (app.experMaps(mapIdx(1,1,:)).retinotopyTime - app.experMaps(mapIdx(1,2,:)).retinotopyTime) ./2;
                    aziT_grad = NaN(size(aziT,1),size(aziT,2),2);
%                     [aziT_grad(:,:,1), aziT_grad(:,:,2)] = gradient(aziT,dy,dx);
                    [aziT_grad(:,:,1), aziT_grad(:,:,2)] = imgradient(aziT,'central');
                    aziIsHere = true;
                elseif counter(1)==1
                    % just get the maps and dx,dy
                    selExp = mapIdx( 1, mapSelected(1,:), :);
%                     dx = app.exper(selExp).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                     dy = app.exper(selExp).Info.PxlSize(1);
                    aziP = app.experMaps(selExp).retinotopyFreq;
                    aziP_grad = app.experMaps(selExp).retinGradientFreq;
                    aziT = app.experMaps(selExp).retinotopyTime;
                    aziT_grad = app.experMaps(selExp).retinGradientTime;
                    aziIsHere = true;
                else
                    aziIsHere = false;
                end
                
                % then altitude
                if counter(2) > 1
%                     dx = app.exper(mapIdx(2,1,:)).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                     dy = app.exper(mapIdx(2,1,:)).Info.PxlSize(1);
                    
                    % correct for intrinsic response phase delay using both
                    % directions. For phases belonging to [-pi,pi], the
                    % correction is the following:
                    % actualPhase = (phasePlus - PhaseMinus + 360)/2
                    altiP = (app.experMaps(mapIdx(2,1,:)).retinotopyFreq - app.experMaps(mapIdx(2,2,:)).retinotopyFreq...
                        + 360) ./2;
                    altiP_grad = NaN(size(altiP,1),size(altiP,2),2);
%                     [altiP_grad(:,:,1), altiP_grad(:,:,2)] = gradient(altiP,dy,dx);
                    [altiP_grad(:,:,1), altiP_grad(:,:,2)] = imgradient(altiP,'central');
                    
                    % the same for retinotopy in time domain
                    % For times belonging to [-T,T], where T is the stimulus
                    % period, the correction is the following:
                    % actualTime = (timePlus - timeMinus)/2 + T/2
                    stimT = 1/app.exper(mapIdx(2,1,:)).StimInfo.StimF;
                    altiT = stimT/2 + (app.experMaps(mapIdx(2,1,:)).retinotopyTime - app.experMaps(mapIdx(2,2,:)).retinotopyTime) ./2;
                    altiT_grad = NaN(size(altiP,1),size(altiP,2),2);
%                     [altiT_grad(:,:,1), altiT_grad(:,:,2)] = gradient(altiT,dy,dx);
                    [altiT_grad(:,:,1), altiT_grad(:,:,2)] = imgradient(altiT,'central');
                    altiIsHere = true;
                    
                elseif counter(2)==1
                    % just get the maps and dy,dx
                    selExp = mapIdx( 2, mapSelected(2,:), :);
%                     dx = app.exper(selExp).Info.PxlSize(2); % eventualmente info.PxlSize deve salvare le pixel size in y e x.
%                     dy = app.exper(selExp).Info.PxlSize(1);
                    altiP = app.experMaps(selExp).retinotopyFreq;
                    altiP_grad = app.experMaps(selExp).retinGradientFreq;
                    altiT = app.experMaps(selExp).retinotopyTime;
                    altiT_grad = app.experMaps(selExp).retinGradientTime;
                    altiIsHere = true;
                else
                    altiIsHere = false;
                end
                
                % at this point, we have at least one among altitude and azimuth
                
                if prod(counter) % AND operator
                    % both azimuth and altitude are selected! Compute the
                    % field map
                    
%                     aziP_gradComplex = aziP_grad(:,:,2) + 1i* aziP_grad(:,:,1);
%                     altiP_gradComplex = altiP_grad(:,:,2) + 1i* altiP_grad(:,:,1);
%                     fieldP = sin(angle(aziP_gradComplex)-angle(altiP_gradComplex));
                    fieldP = sin(aziP_grad(:,:,2)-altiP_grad(:,:,2));
                    if app.SignthresholdCheckBox.Value
                        thr = app.signThresholdEditField.Value * nanstd(fieldP(round(32/app.pixelsBinEditField.Value):end,:),[],'all');
                        above = fieldP>thr;
                        below = fieldP<-thr;
                        fieldP(above) = 1;
                        fieldP(below) = -1;
                        fieldP(~(above|below)) = 0;
                    end
%                     aziT_gradComplex = aziT_grad(:,:,2) + 1i* aziT_grad(:,:,1);
%                     altiT_gradComplex = altiT_grad(:,:,2) + 1i* altiT_grad(:,:,1);
%                     fieldT = sin(angle(aziT_gradComplex)-angle(altiT_gradComplex));
                    fieldT = sin(aziT_grad(:,:,2)-altiT_grad(:,:,2));
                    % thresholding
                    if app.SignthresholdCheckBox.Value
                        thr = app.signThresholdEditField.Value * std(fieldT,[],'all');
                        above = fieldT>thr;
                        below = fieldT<-thr;
                        fieldT(above) = 1;
                        fieldT(below) = -1;
                        fieldT(~(above|below)) = 0;
                    end
                else
                    if aziIsHere
                        % only azumuth is selected
                        altiP = NaN(size(aziP));
                        altiT = NaN(size(aziT));
                        fieldP = NaN(size(aziP));
                        fieldT = NaN(size(aziT));
                    else
                        % only altitude is selected
                        aziP = NaN(size(altiP));
                        aziT = NaN(size(altiT));
                        fieldP = NaN(size(altiP));
                        fieldT = NaN(size(altiT));
                    end
                end
                
                % now store the combo
                if nOldExp >= 1
                    app.comboMaps = cat(1,app.comboMaps,app.models.comboMapEmpty);
                    app.CombinationsTable.Data = cat(1,app.CombinationsTable.Data,[{app.azimuthPlusDropDown.Value},{app.azimuthMinusDropDown.Value},{app.altitudePlusDropDown.Value},{app.altitudeMinusDropDown.Value}]); % cambiare
                    app.SelectedComboSpinner.Limits(2) = app.SelectedComboSpinner.Limits(2)+1;
                    app.SelectedComboSpinner.Enable = 'on';
                    app.SelectedComboSpinner.Value = nOldExp+1;
                else
                    app.comboMaps = app.models.comboMapEmpty;
                    app.CombinationsTable.Data = [{app.azimuthPlusDropDown.Value},{app.azimuthMinusDropDown.Value},{app.altitudePlusDropDown.Value},{app.altitudeMinusDropDown.Value}];
                    app.SelectedComboSpinner.Limits(2) = Inf;
                    app.SelectedComboSpinner.Enable = 'off';
                    app.SelectedComboSpinner.Value = 1;
                end
                app.comboMaps(end).altiP = altiP;
                app.comboMaps(end).aziP = aziP;
                app.comboMaps(end).altiT = altiT;
                app.comboMaps(end).aziT = aziT;
                app.comboMaps(end).fieldP = fieldP;
                app.comboMaps(end).fieldT = fieldT;
%                 app.comboMaps(end).pxlSize = [dy,dx];


                % plot the three maps accordingly to the selected option
                plotAllCombine(app)
            end
            
            
        end

        % Value changed function: SelectedComboSpinner
        function SelectedComboSpinnerValueChanged(app, event)
            plotAllCombine(app)
        end

        % Selection changed function: ChoosemapButtonGroup_2
        function ChoosemapButtonGroup_2SelectionChanged(app, event)
            plotAllCombine(app)
        end

        % Cell selection callback: CombinationsTable
        function CombinationsTableCellSelection(app, event)
            app.comboTableIndices = event.Indices;
        end

        % Button pushed function: ClearselectedButton_2
        function ClearselectedButton_2Pushed(app, event)
            selCombo = unique(app.comboTableIndices(:,1)); % selected rows
            app.comboMaps(selCombo) = [];
            app.CombinationsTable.Data(selCombo,:) = [];
            
            newComboN = length(app.comboMaps);
            if newComboN > 1
                app.SelectedComboSpinner.Enable = 'on';
                app.SelectedComboSpinner.Limits(2) = newComboN;
            else
                app.SelectedComboSpinner.Enable = 'off';
                app.SelectedComboSpinner.Limits(2) = Inf;
                app.SelectedComboSpinner.Value = 1;
            end
            plotAllCombine(app)
        end

        % Value changed function: roimanagerButton
        function roimanagerButtonValueChanged(app, event)
            if app.roimanagerButton.Value
                app.roiManagerHandle = ROImanager(app);
            else
                if isvalid(app.roiManagerHandle)
%                     app.roiManagerHandle.delete;
                    app.roiManagerHandle.myCloseRequestFunc
                end
                app.roiManagerHandle = [];
            end
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            updateMovie(app,round(app.FrameSlider.Value),[])
        end

        % Button pushed function: getFromEscherButton
        function getFromEscherButtonPushed(app, event)
            params.namesCell = {'movieFromEscher'};
            params.path = '';
            importFunction(app,'fromEscher',params)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            currExp_i = app.SelectedExpSpinner.Value;
            switch app.movieMeanSwitch.Value
                case 'Frames'
                    implay(app.exper(currExp_i).Images,app.exper(currExp_i).AcqInfo.FrameRate*5)
                case 'Mean'
                    implay(app.experMean(currExp_i).Images,app.exper(currExp_i).AcqInfo.FrameRate*5)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFig = uifigure('Visible', 'off');
            app.mainFig.Name = 'Receptive Field Analyzer';
            app.TabGroup = uitabgroup(app.mainFig);
            app.VisualizeTab = uitab(app.TabGroup);
            app.VisualizeTab.Title = 'Visualize';
            app.FrameSlider = uislider(app.VisualizeTab);
            app.FrameSlider.Limits = [0 1];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSpinnerLabel = uilabel(app.VisualizeTab);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.VisualizeTab);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.ChoosemapButtonGroup = uibuttongroup(app.VisualizeTab);
            app.ChoosemapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosemapButtonGroupSelectionChanged, true);
            app.ChoosemapButtonGroup.Title = 'Choose map';
            app.PhaseatstimulfreqButton = uiradiobutton(app.ChoosemapButtonGroup);
            app.PhaseatstimulfreqButton.Text = 'Phase at stimul freq';
            app.PhaseatstimulfreqButton.Value = true;
            app.AmpliatstimulfreqButton = uiradiobutton(app.ChoosemapButtonGroup);
            app.AmpliatstimulfreqButton.Text = 'Ampli at stimul freq';
            app.AmplofdFFpeakButton = uiradiobutton(app.ChoosemapButtonGroup);
            app.AmplofdFFpeakButton.Text = 'Ampl of dF/F peak';
            app.TimeofdFFpeakButton = uiradiobutton(app.ChoosemapButtonGroup);
            app.TimeofdFFpeakButton.Text = 'Time of dF/F peak';
            app.SelectedExpSpinnerLabel = uilabel(app.VisualizeTab);
            app.SelectedExpSpinnerLabel.Text = 'Selected Exp';
            app.SelectedExpSpinner = uispinner(app.VisualizeTab);
            app.SelectedExpSpinner.Limits = [1 Inf];
            app.SelectedExpSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectedExpSpinnerValueChanged, true);
            app.SelectedExpSpinner.Value = 1;
            app.movieMeanSwitch = uiswitch(app.VisualizeTab, 'slider');
            app.movieMeanSwitch.Items = {'Frames', 'Mean'};
            app.movieMeanSwitch.ValueChangedFcn = createCallbackFcn(app, @movieMeanSwitchValueChanged, true);
            app.movieMeanSwitch.Value = 'Frames';
            app.FrameEditFieldLabel = uilabel(app.VisualizeTab);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.shownFrameEditField = uieditfield(app.VisualizeTab, 'numeric');
            app.shownFrameEditField.Editable = 'off';
            app.shownFrameEditField.Value = 1;
            app.oftrialEditFieldLabel = uilabel(app.VisualizeTab);
            app.oftrialEditFieldLabel.Text = 'of trial';
            app.shownTrialEditField = uieditfield(app.VisualizeTab, 'numeric');
            app.shownTrialEditField.Editable = 'off';
            app.shownTrialEditField.Value = 1;
            app.PlayButton = uibutton(app.VisualizeTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.movieAxes = uiaxes(app.VisualizeTab);
            title(app.movieAxes, 'Movie')
            app.movieAxes.XTick = [];
            app.movieAxes.YTick = [];
            app.retinotopyAxes = uiaxes(app.VisualizeTab);
            title(app.retinotopyAxes, 'Map')
            app.retinotopyAxes.XTick = [];
            app.retinotopyAxes.YTick = [];
            app.CombineTab = uitab(app.TabGroup);
            app.CombineTab.Title = 'Combine';
            app.CombineexperimentsPanel = uipanel(app.CombineTab);
            app.CombineexperimentsPanel.Title = 'Combine experiments';
            app.Label_2 = uilabel(app.CombineexperimentsPanel);
            app.Label_2.Text = '+';
            app.azimuthPlusDropDown = uidropdown(app.CombineexperimentsPanel);
            app.azimuthPlusDropDown.Items = {};
            app.azimuthPlusDropDown.Value = {};
            app.Label = uilabel(app.CombineexperimentsPanel);
            app.Label.Text = '+';
            app.altitudePlusDropDown = uidropdown(app.CombineexperimentsPanel);
            app.altitudePlusDropDown.Items = {};
            app.altitudePlusDropDown.Value = {};
            app.computeMapButton = uibutton(app.CombineexperimentsPanel, 'push');
            app.computeMapButton.ButtonPushedFcn = createCallbackFcn(app, @computeMapButtonPushed, true);
            app.computeMapButton.Text = 'Compute map';
            app.Label_3 = uilabel(app.CombineexperimentsPanel);
            app.Label_3.Text = '-';
            app.azimuthMinusDropDown = uidropdown(app.CombineexperimentsPanel);
            app.azimuthMinusDropDown.Items = {};
            app.azimuthMinusDropDown.Value = {};
            app.Label_4 = uilabel(app.CombineexperimentsPanel);
            app.Label_4.Text = '-';
            app.altitudeMinusDropDown = uidropdown(app.CombineexperimentsPanel);
            app.altitudeMinusDropDown.Items = {};
            app.altitudeMinusDropDown.Value = {};
            app.AzimuthLabel = uilabel(app.CombineexperimentsPanel);
            app.AzimuthLabel.Text = 'Azimuth';
            app.AltitudeLabel = uilabel(app.CombineexperimentsPanel);
            app.AltitudeLabel.Text = 'Altitude';
            app.SignthresholdCheckBox = uicheckbox(app.CombineexperimentsPanel);
            app.SignthresholdCheckBox.Text = 'Sign threshold: ±';
            app.SignthresholdCheckBox.Value = true;
            app.timesstdLabel = uilabel(app.CombineexperimentsPanel);
            app.timesstdLabel.Text = 'times std';
            app.signThresholdEditField = uieditfield(app.CombineexperimentsPanel, 'numeric');
            app.signThresholdEditField.Value = 1.5;
            app.ExportButton = uibutton(app.CombineTab, 'push');
            app.ExportButton.Text = 'Export';
            app.CombinationsTable = uitable(app.CombineTab);
            app.CombinationsTable.ColumnName = {'Azi +'; 'Azi -'; 'Alti +'; 'Alti -'};
            app.CombinationsTable.RowName = {};
            app.CombinationsTable.CellSelectionCallback = createCallbackFcn(app, @CombinationsTableCellSelection, true);
            app.SelectedComboSpinnerLabel = uilabel(app.CombineTab);
            app.SelectedComboSpinnerLabel.Text = 'Selected Combo:';
            app.SelectedComboSpinner = uispinner(app.CombineTab);
            app.SelectedComboSpinner.Limits = [0 Inf];
            app.SelectedComboSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectedComboSpinnerValueChanged, true);
            app.SelectedComboSpinner.Value = 1;
            app.ChoosemapButtonGroup_2 = uibuttongroup(app.CombineTab);
            app.ChoosemapButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ChoosemapButtonGroup_2SelectionChanged, true);
            app.ChoosemapButtonGroup_2.Title = 'Choose map';
            app.PhaseButton = uiradiobutton(app.ChoosemapButtonGroup_2);
            app.PhaseButton.Text = 'Phase';
            app.PhaseButton.Value = true;
            app.TimeButton = uiradiobutton(app.ChoosemapButtonGroup_2);
            app.TimeButton.Text = 'Time';
            app.ClearselectedButton_2 = uibutton(app.CombineTab, 'push');
            app.ClearselectedButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearselectedButton_2Pushed, true);
            app.ClearselectedButton_2.Text = {'Clear'; 'selected'};
            app.visualFieldAxes = uiaxes(app.CombineTab);
            title(app.visualFieldAxes, 'Visual field sign')
            app.visualFieldAxes.XTick = [];
            app.visualFieldAxes.YTick = [];
            app.altitudeAxes = uiaxes(app.CombineTab);
            title(app.altitudeAxes, 'Altitude retinotopy')
            app.altitudeAxes.XTick = [];
            app.altitudeAxes.YTick = [];
            app.azimuthAxes = uiaxes(app.CombineTab);
            title(app.azimuthAxes, 'Azimuth retinotopy')
            app.azimuthAxes.XTick = [];
            app.azimuthAxes.YTick = [];
            app.BusyLampLabel = uilabel(app.mainFig);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.mainFig);
            app.experTable = uitable(app.mainFig);
            app.experTable.ColumnName = {'Name'; 'Stimulus'; 'Analyzed'};
            app.experTable.RowName = {''};
            app.experTable.ColumnEditable = [true false true];
            app.experTable.CellEditCallback = createCallbackFcn(app, @experTableCellEdit, true);
            app.experTable.CellSelectionCallback = createCallbackFcn(app, @experTableCellSelection, true);
            app.LUTmanagerButton = uibutton(app.mainFig, 'state');
            app.LUTmanagerButton.ValueChangedFcn = createCallbackFcn(app, @LUTmanagerButtonValueChanged, true);
            app.LUTmanagerButton.Text = 'LUT manager';
            app.AnalysisparametersPanel = uipanel(app.mainFig);
            app.AnalysisparametersPanel.Title = 'Analysis parameters';
            app.AnalyzeButton = uibutton(app.AnalysisparametersPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.MergeCheckBox = uicheckbox(app.AnalysisparametersPanel);
            app.MergeCheckBox.Tooltip = {'Merge experiments with the same stimuli'};
            app.MergeCheckBox.Text = 'Merge';
            app.MergeCheckBox.Value = true;
            app.gpuCheckBox = uicheckbox(app.AnalysisparametersPanel);
            app.gpuCheckBox.Tooltip = {'Merge experiments with the same stimuli'};
            app.gpuCheckBox.Text = 'Use GPU for FFT';
            app.ShowexternalfftfigureCheckBox = uicheckbox(app.AnalysisparametersPanel);
            app.ShowexternalfftfigureCheckBox.Text = 'Show external fft figure';
            app.PhasemapgaussfilterCheckBox = uicheckbox(app.AnalysisparametersPanel);
            app.PhasemapgaussfilterCheckBox.Text = 'Phase map gauss filter:';
            app.PhasemapgaussfilterCheckBox.Value = true;
            app.pxlsEditFieldLabel = uilabel(app.AnalysisparametersPanel);
            app.pxlsEditFieldLabel.Text = 'pxls';
            app.MapMedFiltPxlsEditField = uieditfield(app.AnalysisparametersPanel, 'numeric');
            app.MapMedFiltPxlsEditField.Value = 2;
            app.TabGroup2 = uitabgroup(app.mainFig);
            app.LoadTab = uitab(app.TabGroup2);
            app.LoadTab.Title = 'Load';
            app.ButtonGroup = uibuttongroup(app.LoadTab);
            app.IntensityButton = uiradiobutton(app.ButtonGroup);
            app.IntensityButton.Text = 'Intensity';
            app.IntensityButton.Value = true;
            app.ComputedeltaFFButton = uiradiobutton(app.ButtonGroup);
            app.ComputedeltaFFButton.Text = {'Compute'; 'deltaF/F'};
            app.LoadfileButton = uibutton(app.ButtonGroup, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.getFromEscherButton = uibutton(app.ButtonGroup, 'push');
            app.getFromEscherButton.ButtonPushedFcn = createCallbackFcn(app, @getFromEscherButtonPushed, true);
            app.getFromEscherButton.Enable = 'off';
            app.getFromEscherButton.Text = 'Get from Escher';
            app.ClearselectedButton = uibutton(app.LoadTab, 'push');
            app.ClearselectedButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectedButtonPushed, true);
            app.ClearselectedButton.Text = 'Clear selected';
            app.replace1stFrameCheckBox = uicheckbox(app.LoadTab);
            app.replace1stFrameCheckBox.Tooltip = {'TEMPORARY TRICK! Somehow the 1st frame of each trial is affected by an artifact; replace it with the 2nd one'};
            app.replace1stFrameCheckBox.Text = 'replace 1st frame';
            app.replace1stFrameCheckBox.Value = true;
            app.BinningTab = uitab(app.TabGroup2);
            app.BinningTab.Title = 'Binning';
            app.ButtonGroup_2 = uibuttongroup(app.BinningTab);
            app.ButtonGroup_2.Title = 'Button Group';
            app.NobinningButton = uiradiobutton(app.ButtonGroup_2);
            app.NobinningButton.Text = 'No binning';
            app.BinningButton = uiradiobutton(app.ButtonGroup_2);
            app.BinningButton.Text = 'Binning';
            app.BinningButton.Value = true;
            app.pixelsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.pixelsEditFieldLabel.Text = '(pixels)';
            app.pixelsBinEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.pixelsBinEditField.Value = 4;
            app.OpeningfilterTab = uitab(app.TabGroup2);
            app.OpeningfilterTab.Title = 'Opening filter';
            app.xysizeEditFieldLabel = uilabel(app.OpeningfilterTab);
            app.xysizeEditFieldLabel.Text = 'xy size';
            app.MedianFiltXYSizeEditField = uieditfield(app.OpeningfilterTab, 'numeric');
            app.MedianFiltXYSizeEditField.Limits = [0 Inf];
            app.MedianFiltXYSizeEditField.RoundFractionalValues = 'on';
            app.tsizeEditFieldLabel = uilabel(app.OpeningfilterTab);
            app.tsizeEditFieldLabel.Text = 't size';
            app.MedianFiltTSizeEditField = uieditfield(app.OpeningfilterTab, 'numeric');
            app.MedianFiltTSizeEditField.Limits = [0 Inf];
            app.MedianFiltTSizeEditField.RoundFractionalValues = 'on';
            app.MedianfilteringLabel = uilabel(app.OpeningfilterTab);
            app.MedianfilteringLabel.Text = 'Median filtering:';
            app.pixelsLabel = uilabel(app.OpeningfilterTab);
            app.pixelsLabel.Text = 'pixel(s)';
            app.framesLabel = uilabel(app.OpeningfilterTab);
            app.framesLabel.Text = 'frame(s)';
            app.deltaFFparamsTab = uitab(app.TabGroup2);
            app.deltaFFparamsTab.Title = 'deltaF/F params';
            app.Tau0sEditFieldLabel = uilabel(app.deltaFFparamsTab);
            app.Tau0sEditFieldLabel.Text = 'Tau0 (s):';
            app.tau0EditField = uieditfield(app.deltaFFparamsTab, 'numeric');
            app.tau0EditField.Tooltip = {'See the function "adaptiveDF3" for details'};
            app.tau0EditField.Value = -1;
            app.Tau1sEditFieldLabel = uilabel(app.deltaFFparamsTab);
            app.Tau1sEditFieldLabel.Text = 'Tau1 (s):';
            app.tau1EditField = uieditfield(app.deltaFFparamsTab, 'numeric');
            app.tau1EditField.Tooltip = {'See the function "adaptiveDF3" for details'};
            app.tau1EditField.Value = -1;
            app.Tau2sEditFieldLabel = uilabel(app.deltaFFparamsTab);
            app.Tau2sEditFieldLabel.Text = 'Tau2 (s):';
            app.tau2EditField = uieditfield(app.deltaFFparamsTab, 'numeric');
            app.tau2EditField.Tooltip = {'See the function "adaptiveDF3" for details'};
            app.tau2EditField.Value = -1;
            app.roimanagerButton = uibutton(app.mainFig, 'state');
            app.roimanagerButton.ValueChangedFcn = createCallbackFcn(app, @roimanagerButtonValueChanged, true);
            app.roimanagerButton.Text = 'ROI manager';
            % Show the figure after all components are created
            app.mainFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualFieldAnalyzer2_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFig)
        end
    end
end